classdef ea_set_lighting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SpecularColorReflectanceSliderLabel  matlab.ui.control.Label
        SpecularColorReflectanceSlider  matlab.ui.control.Slider
        SpecularExponentSliderLabel     matlab.ui.control.Label
        SpecularExponentSlider          matlab.ui.control.Slider
        SpecularStrengthSliderLabel     matlab.ui.control.Label
        SpecularStrengthSlider          matlab.ui.control.Slider
        DiffuseStrengthSliderLabel      matlab.ui.control.Label
        DiffuseStrengthSlider           matlab.ui.control.Slider
        AmbientStrengthSliderLabel      matlab.ui.control.Label
        AmbientStrengthSlider           matlab.ui.control.Slider
        LightingParametersLabel         matlab.ui.control.Label
        CamLightButton                  matlab.ui.control.StateButton
        CeilingLightButton              matlab.ui.control.StateButton
        LeftLightButton                 matlab.ui.control.StateButton
        RightLightButton                matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        atlassurfs % atlassurfaces
        elrender % ea_trajectory objects
        RightLight
        LeftLight
        CeilingLight
        CamLight
    end
    
    methods (Access = private)
        
        function objvisible(app,atls)
            set(atls, 'Visible', 'on');
        end
        
        function results = objinvisible(app,atls)
            set(atls, 'Visible', 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, resultfig)
            app.UIFigure.Name='Set Lighting';
            
            app.RightLight=getappdata(resultfig,'RightLight');
            app.LeftLight=getappdata(resultfig,'LeftLight');
            app.CeilingLight=getappdata(resultfig,'CeilingLight');
            app.CamLight=getappdata(resultfig,'CamLight');
            
            app.RightLightButton.Value=app.RightLight.Visible;
            app.LeftLightButton.Value=app.LeftLight.Visible;
            app.CeilingLightButton.Value=app.CeilingLight.Visible;
            app.CamLightButton.Value=app.CamLight.Visible;
            
            app.atlassurfs=getappdata(resultfig,'atlassurfs');
            app.elrender=getappdata(resultfig,'el_render');
            
            onesurf=app.atlassurfs{1};
            app.SpecularColorReflectanceSlider.Value=onesurf.SpecularColorReflectance;
            app.SpecularExponentSlider.Value=onesurf.SpecularExponent;
            app.SpecularStrengthSlider.Value=onesurf.SpecularStrength;
            app.DiffuseStrengthSlider.Value=onesurf.DiffuseStrength;
            app.AmbientStrengthSlider.Value=onesurf.AmbientStrength;
            
        end

        % Value changed function: CamLightButton
        function CamLightButtonValueChanged(app, event)
            value = app.CamLightButton.Value;
            app.CamLight.Visible=(value);
            
        end

        % Value changed function: RightLightButton
        function RightLightButtonValueChanged(app, event)
            value = app.RightLightButton.Value;
            app.RightLight.Visible=(value);
        end

        % Value changed function: CeilingLightButton
        function CeilingLightButtonValueChanged(app, event)
            value = app.CeilingLightButton.Value;
            app.CeilingLight.Visible=(value);
            
        end

        % Value changed function: LeftLightButton
        function LeftLightButtonValueChanged(app, event)
            value = app.LeftLightButton.Value;
            app.LeftLight.Visible=(value);
            
        end

        % Value changing function: AmbientStrengthSlider
        function AmbientStrengthSliderValueChanging(app, event)
            changingValue = event.Value;
            for atl=1:length(app.atlassurfs)
                app.atlassurfs{atl}.AmbientStrength=changingValue;
            end
            for el=1:length(app.elrender)
                for elpatch=1:length(app.elrender(el).elpatch)
                    try % could be pointclouds
                        app.elrender(el).elpatch(elpatch).AmbientStrength=changingValue;
                    end
                end
            end
        end

        % Value changing function: DiffuseStrengthSlider
        function DiffuseStrengthSliderValueChanging(app, event)
            changingValue = event.Value;
            for atl=1:length(app.atlassurfs)
                app.atlassurfs{atl}.DiffuseStrength=changingValue;
            end
            for el=1:length(app.elrender)
                for elpatch=1:length(app.elrender(el).elpatch)
                    try % could be pointclouds
                        app.elrender(el).elpatch(elpatch).DiffuseStrength=changingValue;
                    end
                end
            end
        end

        % Value changing function: SpecularStrengthSlider
        function SpecularStrengthSliderValueChanging(app, event)
            changingValue = event.Value;
            for atl=1:length(app.atlassurfs)
                app.atlassurfs{atl}.SpecularStrength=changingValue;
            end
            for el=1:length(app.elrender)
                for elpatch=1:length(app.elrender(el).elpatch)
                    try % could be pointclouds
                        app.elrender(el).elpatch(elpatch).SpecularStrength=changingValue*0.7;
                    end
                end
            end
        end

        % Value changing function: SpecularExponentSlider
        function SpecularExponentSliderValueChanging(app, event)
            changingValue = event.Value;
            for atl=1:length(app.atlassurfs)
                app.atlassurfs{atl}.SpecularExponent=changingValue;
            end
            for el=1:length(app.elrender)
                for elpatch=1:length(app.elrender(el).elpatch)
                    try % could be pointclouds
                        app.elrender(el).elpatch(elpatch).SpecularExponent=changingValue;
                    end
                end
            end
        end

        % Value changing function: SpecularColorReflectanceSlider
        function SpecularColorReflectanceSliderValueChanging(app, event)
            changingValue = event.Value;
            for atl=1:length(app.atlassurfs)
                app.atlassurfs{atl}.SpecularColorReflectance=changingValue;
            end
            for el=1:length(app.elrender)
                for elpatch=1:length(app.elrender(el).elpatch)
                    try % could be pointclouds
                        app.elrender(el).elpatch(elpatch).SpecularColorReflectance=changingValue;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpecularColorReflectanceSliderLabel = uilabel(app.UIFigure);
            app.SpecularColorReflectanceSliderLabel.Text = 'Specular Color Reflectance';
            app.SpecularColorReflectanceSlider = uislider(app.UIFigure);
            app.SpecularColorReflectanceSlider.Limits = [0 1];
            app.SpecularColorReflectanceSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularColorReflectanceSliderValueChanging, true);
            app.SpecularExponentSliderLabel = uilabel(app.UIFigure);
            app.SpecularExponentSliderLabel.Text = 'Specular Exponent';
            app.SpecularExponentSlider = uislider(app.UIFigure);
            app.SpecularExponentSlider.Limits = [1 40];
            app.SpecularExponentSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularExponentSliderValueChanging, true);
            app.SpecularExponentSlider.Value = 1;
            app.SpecularStrengthSliderLabel = uilabel(app.UIFigure);
            app.SpecularStrengthSliderLabel.Text = 'Specular Strength';
            app.SpecularStrengthSlider = uislider(app.UIFigure);
            app.SpecularStrengthSlider.Limits = [0 1];
            app.SpecularStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularStrengthSliderValueChanging, true);
            app.DiffuseStrengthSliderLabel = uilabel(app.UIFigure);
            app.DiffuseStrengthSliderLabel.Text = 'Diffuse Strength';
            app.DiffuseStrengthSlider = uislider(app.UIFigure);
            app.DiffuseStrengthSlider.Limits = [0 1];
            app.DiffuseStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @DiffuseStrengthSliderValueChanging, true);
            app.AmbientStrengthSliderLabel = uilabel(app.UIFigure);
            app.AmbientStrengthSliderLabel.Text = 'Ambient Strength';
            app.AmbientStrengthSlider = uislider(app.UIFigure);
            app.AmbientStrengthSlider.Limits = [0 1];
            app.AmbientStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @AmbientStrengthSliderValueChanging, true);
            app.LightingParametersLabel = uilabel(app.UIFigure);
            app.LightingParametersLabel.Text = 'Lighting Parameters';
            app.CamLightButton = uibutton(app.UIFigure, 'state');
            app.CamLightButton.ValueChangedFcn = createCallbackFcn(app, @CamLightButtonValueChanged, true);
            app.CamLightButton.Text = {'Cam'; 'Light'};
            app.CeilingLightButton = uibutton(app.UIFigure, 'state');
            app.CeilingLightButton.ValueChangedFcn = createCallbackFcn(app, @CeilingLightButtonValueChanged, true);
            app.CeilingLightButton.Text = {'Ceiling'; 'Light'};
            app.LeftLightButton = uibutton(app.UIFigure, 'state');
            app.LeftLightButton.ValueChangedFcn = createCallbackFcn(app, @LeftLightButtonValueChanged, true);
            app.LeftLightButton.Text = {'Left'; 'Light'};
            app.RightLightButton = uibutton(app.UIFigure, 'state');
            app.RightLightButton.ValueChangedFcn = createCallbackFcn(app, @RightLightButtonValueChanged, true);
            app.RightLightButton.Text = {'Right'; 'Light'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_set_lighting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end