classdef ea_cleartune < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        OptimizeStimulationvolumecalculationsMenu  matlab.ui.container.Menu
        ProvideweightsasamatfileLabel   matlab.ui.control.Label
        providewithamatfileButton       matlab.ui.control.Button
        MaxSegmentedEditField           matlab.ui.control.NumericEditField
        MaxSegmentedEditFieldLabel      matlab.ui.control.Label
        MaxCylindricEditField           matlab.ui.control.NumericEditField
        MaxCylindricEditFieldLabel      matlab.ui.control.Label
        MinCylindricEditField_2         matlab.ui.control.NumericEditField
        MinCylindricEditField_2Label    matlab.ui.control.Label
        MinSegmentedEditField           matlab.ui.control.NumericEditField
        MinSegmentedEditFieldLabel      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        ConstCurrDropdown               matlab.ui.control.DropDown
        OptAmplitudesLabel              matlab.ui.control.Label
        ChooseSweetspotMappingModelButton  matlab.ui.control.Button
        PatientSelectionButtonGroup     matlab.ui.container.ButtonGroup
        UsepatientsfrommodelfilesleavenothingoutButton  matlab.ui.control.RadioButton
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        ChoosePatientFoldersButton      matlab.ui.control.Button
        UsepatientsfrommodelfilesleaveoneoutButton  matlab.ui.control.RadioButton
        SelectexternalPatientsButton    matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        SuggestStimulationParametersTab_2  matlab.ui.container.Tab
        SweetspotamplitudeEditField     matlab.ui.control.NumericEditField
        SweetspotamplitudeEditFieldLabel  matlab.ui.control.Label
        ApplypenaltyusingDropDown       matlab.ui.control.DropDown
        ApplypenaltyusingDropDownLabel  matlab.ui.control.Label
        StartatcontactEditField         matlab.ui.control.NumericEditField
        StartatcontactEditFieldLabel    matlab.ui.control.Label
        StimulationVolumeModelDropDown  matlab.ui.control.DropDown
        StimulationVolumeModelDropDownLabel  matlab.ui.control.Label
        OptimizeusingDropDown           matlab.ui.control.DropDown
        OptimizeusingDropDownLabel      matlab.ui.control.Label
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        VisualizeResultsCheckBox        matlab.ui.control.CheckBox
        SuggestStimulationParametersButton  matlab.ui.control.Button
        PredictOutcomesforExistingParametersTab  matlab.ui.container.Tab
        StimulationSettingDropDown      matlab.ui.control.DropDown
        StimulationSettingDropDownLabel  matlab.ui.control.Label
        PredictOutcomesButton           matlab.ui.control.Button
        PathwayTuneTab                  matlab.ui.container.Tab
        DistancemetricDropDown          matlab.ui.control.DropDown
        DistancemetricDropDownLabel     matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        OptimizewithANNButton           matlab.ui.control.Button
        ExportScoreGroupedPathwaysButton  matlab.ui.control.Button
        ANNModelPanel                   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TrainANNusingLabel              matlab.ui.control.Label
        SideeffecterrorthresholdEditField  matlab.ui.control.NumericEditField
        SideeffecterrorthresholdEditFieldLabel  matlab.ui.control.Label
        GenerateTrainTestsetsButton     matlab.ui.control.Button
        ErrorthresholdEditField         matlab.ui.control.NumericEditField
        ErrorthresholdEditFieldLabel    matlab.ui.control.Label
        VATButton                       matlab.ui.control.Button
        PAMButton                       matlab.ui.control.Button
        OptAlgorithmDropDown            matlab.ui.control.DropDown
        OptAlgorithmDropDownLabel       matlab.ui.control.Label
        OptimizeStimulationusingPAMButton  matlab.ui.control.Button
        PredictImprovementusingPAMButton  matlab.ui.control.Button
        SymptomWeightsPanel             matlab.ui.container.Panel
        ChooseNetworkMappingModelButton  matlab.ui.control.Button
        ChooseFiberfilteringModelButton  matlab.ui.control.Button
    end


    properties (Access = private)
        fibfiltmodelpath % which fibfiltmodel to use
        netmappingmodelpath % which netmapping model to use (not implemented)
        sweetspotmodelpath % which sweetspotmodel to use (not implemented)
        patientlist={} % cell with patient folders loaded
        resultfig % resultfigure
        visualizeresults=1 % show results
        
        elrender % handle to current electrode if present
        ellabel % label of current electrode
        percimp % estimates of improvements under present suggestions
        sliderimprovement = {} % handles for slider improvements
        slider = {}
        slider_name = {} % sliders
        slider_text_box = {}% text boxes
        slider_lab_name = {}% slider label name
        basepredlbl % base prediction on label
        basepreddpd % base prediction dropdown
        
        stimfolder = [];

        % additional properties for PathwayTune

        Optimize_Weight_CheckBox = {}
        ActivationProfileDict = '';
        SE_err_threshold = 5.0;
        Err_threshold = 10.0;
        %opt_weights = {};
        PenaltyVal = 0;
        % current settings (in V or mA)
        %min_conc = -4.0  
        %min_segm = -2.0
        %max_conc = 4.0  
        %max_segm = 2.0


    end
    
    properties (Access = public)
        tractset % tractset object loaded from fibfiltmodelpath or received from input config
         % efields & base vta protocol stored to not recalc each time
        trainings
        inputVars
        symptomWeightVar
        protocol % efields & base vta protocol stored to not recalc each time % Description
    end

    methods (Access = private)

        function results = enablerules(app)
            if isempty(app.patientlist)
                app.ChoosePatientFoldersButton.Text = 'Choose Patient Folder(s)...';
            elseif length(app.patientlist)==1
                app.ChoosePatientFoldersButton.Text=app.patientlist{1};
                app.VisualizeResultsCheckBox.Enable='on';
                app.visualizeresults=app.VisualizeResultsCheckBox.Value;
            else
                app.ChoosePatientFoldersButton.Text=['Multiple (',num2str(length(app.patientlist)),')'];
                app.VisualizeResultsCheckBox.Enable='off';
                app.visualizeresults=0;
            end
            
            app.refreshweightsliders;


            if ~isempty(app.patientlist)

                for pt=1:length(app.patientlist)
                    direc=[app.patientlist{pt},filesep,'stimulations',filesep,ea_getspace];
                    stims = ea_regexpdir(direc,'.*',0,'d');
                    %stims=ea_dir2cell(dir([direc,'stimulations',filesep,ea_getspace]));
                    if ~exist('remstims','var')
                        remstims=stims;
                    else
                        todel=[];
                        for d=1:length(remstims)
                            if ~ismember(remstims{d},stims)
                                todel(end+1)=d;
                            end
                        end
                        remstims(todel)=[];
                    end
                end
                [path,protocol_name,~] = fileparts(remstims);
                if length(remstims) == 1
                    protocol_name = {protocol_name};
                end
                app.StimulationSettingDropDown.Items=protocol_name;
            end

            switch app.PatientSelectionButtonGroup.SelectedObject.Text
                case 'Select external Patients:'
                    app.ChoosePatientFoldersButton.Enable='on';
                otherwise
                    app.ChoosePatientFoldersButton.Enable='off';
            end
        end

        function results = base_pred_fcn(app,i)
            
            
            if strcmp(app.basepreddpd{i}.Value,'None')
                app.slider_text_box{i}.Enable = 'on';
                app.slider{i}.Enable = 'on';
                app.tractset.cleartunevars.weightvars{i,1} = '';
                %app.tractset.cleartunevars.weights(i) = app.slider{i}.Value/100;

                % for now keep weights the same
                app.tractset.cleartunevars.weights(i,1) = app.slider{i}.Value/100;
                app.tractset.cleartunevars.weights(i,2) = app.tractset.cleartunevars.weights(i,1);
            else
                app.slider_text_box{i}.Enable = 'off';
                app.slider{i}.Enable = 'off';
                %index_of_new_subscore = cellfun(@(x)isequal(x,app.basepreddpd{i}.Value),app.tractset.M.clinical.labels);
                %app.tractset.subscore.weights{i,1} = app.tractset.M.clinical.labels{index_of_new_subscore};
                app.tractset.cleartunevars.weights(i) = nan;
                app.tractset.cleartunevars.weightvarlabels{i,1} = app.basepreddpd{i}.Value;
            end

        end

        function results = SliderChange_text(app,event,i)
            text_value = app.slider_text_box{i}.Value;
            remainingamount=1-(text_value/100);
            otherentries=1:length(app.slider);
            otherentries(i)=[];
            weights = app.tractset.cleartunevars.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=text_value/100;

            for slide_cnt=1:length(app.slider)
                if ~isnan(weights(slide_cnt))
                    app.slider_text_box{slide_cnt}.Value=weights(slide_cnt)*100;
                    app.tractset.cleartunevars.weights(slide_cnt,1) = [weights(slide_cnt)];
                    app.tractset.cleartunevars.weights(slide_cnt,2) = [weights(slide_cnt)];
                    app.slider{slide_cnt}.Value = weights(slide_cnt)*100;
                end
            end
            app.draw(1,event);
        end

        function results = SliderChange(app,event,i)
            %change value using sliders
            remainingamount=1-(app.slider{i}.Value/100);
            otherentries=1:length(app.slider);
            otherentries(i)=[];
            weights=app.tractset.cleartunevars.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=app.slider{i}.Value/100;

            for slide_cnt=1:length(app.slider)
                if ~isnan(weights(slide_cnt))
                    app.slider{slide_cnt}.Value=weights(slide_cnt)*100;
                    app.tractset.cleartunevars.weights(slide_cnt) = [weights(slide_cnt)];
                    app.slider_text_box{slide_cnt}.Value = weights(slide_cnt)*100;
                end
            end

            app.draw(1,event);
        end

        function results = SliderChanging(app,event,i)
            %app.tractset.cleartunevars.weights(i) = (event.Value/100);
            remainingamount=1-(event.Value/100);
            otherentries=1:length(app.slider);
            otherentries(i)=[];
            weights = app.tractset.cleartunevars.weights;
            %weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=event.Value/100;

            for slide_cnt=1:length(app.slider)
                if ~isnan(weights(slide_cnt))
                    app.slider{slide_cnt}.Value=weights(slide_cnt)*100;
                    % for now, weight bilateral equally
                    app.tractset.cleartunevars.weights(slide_cnt,1) = [weights(slide_cnt)];
                    app.tractset.cleartunevars.weights(slide_cnt,2) = [weights(slide_cnt)];
                    app.slider_text_box{slide_cnt}.Value = weights(slide_cnt)*100;
                end
            end
        end
        
        function results = draw(app,force,event)
            if ~exist('force','var')
                force=0;
            end
            if force || app.AutoRefreshCheckBox.Value
                app.isbusy(1);
            end

            switch app.PatientSelectionButtonGroup.SelectedObject.Text
                case 'Select external Patients:'
                    if isempty(app.patientlist)
                        ea_error('Please select one or more patients first.')
                    end
                    patlist=app.patientlist;
                    app.trainings=repmat({app.tractset.patientselection},length(patlist),1);
                case 'Use patients from model files (leave-one-out)'
                    patlist=app.tractset.M.patient.list(app.tractset.patientselection);
                    for i=1:length(app.tractset.patientselection)
                        app.trainings{i,:} = ones(1,length(app.tractset.patientselection));
                    end
                case 'Use patients from model files (leave-nothing-out)'
                    patlist=app.tractset.M.patient.list(app.tractset.patientselection);
                    app.trainings=repmat({app.tractset.patientselection},length(patlist),1);
            end

            for pt=1:length(patlist)
                switch event
                    case 'suggest'
                        inputs={patlist(pt),app.MinCylindricEditField_2.Value,app.MaxCylindricEditField.Value,app.StepSizeEditField.Value,app.ConstCurrDropdown.Value};
                        recalc=1;
                        if ~isempty(app.protocol) % base vtas have been calculated before. If they were done using the same inputs, no need to recalc
                            if length(app.protocol)>=pt
                                if isequal(app.protocol{pt}.inputs,inputs)
                                    recalc=0;
                                end
                            end
                        end
                        if recalc
                            [Efields,allS]=ea_generate_base_vats(inputs{:});
                            app.protocol{pt}.inputs=inputs;
                            app.protocol{pt}.Efields=Efields;
                            app.protocol{pt}.allS=allS;
                            % tried storing the protocol but it's >1 GB, faster to
                            % recalc each time since its quick.
                            %ea_mkdir(fullfile(app.patientlist{pt},'cleartune'));
                            %d=app.protocol{pt};
                            %save(fullfile(app.patientlist{pt},'cleartune','protocol.mat'),'-struct','d');
                            tractsetclone=ea_disctract;
                            app.tractset.copyobj(tractsetclone);
                            [~,Ihat,actualimprovs] = runcrossval(app,event,tractsetclone,patlist,side);
                        end
                        
                    case 'predict'
                        stim = app.StimulationSettingDropDown.Value;
                        Ihat = predictImprovement(app,patlist,stim,pt);
                        
                        
                end
                if isempty(app.tractset)
                    d=load(app.fibfiltmodelpath,'-mat');
                    app.tractset=d.tractset;
                    clear d
                end                
            end
        end


        function results = createS(app,Ihat,actualimprovs)
            %% run custom crossvalidation to determine optimal stimulation setting:
            try
                Ihat=[Ihat(1:length(Ihat)/2),Ihat(length(Ihat)/2+1:end)]; % Ihat is exported as a column vector for both sides. Reformat to Nx2.
                if sum(isnan(Ihat(:)))/length(Ihat(:))>0.3
                    ea_warning(['Many (',num2str(sum(isnan(Ihat(:)))*100/length(Ihat(:))),' percent) stimulation settings were not covered well by the model. Stimulation suggestions may not be meaningful. Please adjust model parameters in ',...
                        app.fibfiltmodelpath,'.']);
                end
                for side=1:2
                    [is,ix(side)]=ea_nanmax(Ihat(:,side));

                    S(side)=app.protocol{pt}.allS(ix(side)); % optimal setting for this electrode
                end

                % this is broken: Fix return of
                % actualimprovs to revive:

                for actimp=1:size(actualimprovs,1)
                    for side=1:2
                        app.percimp(actimp,side)=actualimprovs{actimp,side}(ix(side));
                    end
                    app.sliderimprovement{actimp}.Text=[sprintf('%.1f',app.percimp(actimp,1)*100),'% / ',sprintf('%.1f',app.percimp(actimp,2)*100),'%'];
                end

                S=ea_conflateS_lr(S); % merge entries from right (S(1)) and left (S(2)) stim params to one. => S
            catch
                ea_warning('Cleartune did not find a solution.')
            end
            %% cleanup again: not as important anymore since we are working on a clone of the object.

            % remove appends from responsevar and weightmatrices:
            tractsetclone.responsevar=tractsetclone.responsevar(1:origlen,:);
            tractsetclone.allpatients=tractsetclone.allpatients(1:origlen,:);

            if reduceresponsevar
                tractsetclone.responsevar=tractsetclone.responsevar(:,1);
            end
            switch tractsetclone.multitractmode
                case 'Single Tract Analysis'
                otherwise
                    for wv=1:length(tractsetclone.subscore.weights)
                        if isnan(tractsetclone.subscore.weights(wv))
                            tractsetclone.subscore.weightvars{wv}=tractsetclone.subscore.weightvars{wv}(1:origlen);
                        end
                    end
                    for wv=1:length(tractsetclone.subscore.vars)
                        tractsetclone.subscore.vars{wv}=tractsetclone.subscore.vars{wv}(1:origlen);
                    end
            end
            % remove cleartune results again:
            for side=1:2
                tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}=...
                    [tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,origix),...
                    tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,totallen+origix),...
                    ];
            end

            % check if all went correctly:
            if isequaln(tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval,tmpfibvals)
                tractsetclone.cleartuneinjected.status='clean';
            else
                ea_error('Something went wrong.');
            end

            %% END CLEANUP  <<<<<<<<
            switch event
                case 'suggest'
                    %% export optimal vta / stim setting
                    options=ea_getptopts(patlist{pt});
                    options=ea_defaultoptions(options);
                    options.native=0;
                    stimfolderout=fullfile(patlist{pt},'stimulations',ea_nt(options),['cleartune_',tractsetclone.ID]);
                    ea_mkdir(stimfolderout);
                    [options.root,options.patientname]=fileparts(patlist{pt});
                    options.root=[options.root,filesep];
                    save(fullfile(stimfolderout,[options.patientname,'_desc-stimparameters.mat']),'S');

                    % output VTA:
                    ea_genvat=@ea_genvat_fastfield;


                    options.prefs=ea_prefs;
                    % maybe double check this is the BIDS conform
                    % way of doing things:
                    [coords_mm,trajectory,markers,elmodel,manually_corrected,coords_acpc]=ea_load_reconstruction(patlist{pt});
                    elstruct.coords_mm=coords_mm;
                    elstruct.coords_acpc=coords_acpc;
                    elstruct.trajectory=trajectory;
                    elstruct.name = options.patientname;
                    elstruct.markers=markers;
                    options.elmodel=elmodel;
                    options=ea_resolve_elspec(options);

                    options.leadprod='dbs';
                    options=ea_detsides(options);
                    if isempty(app.resultfig) % cleartune was likely opened as standalone.
                        if app.visualizeresults
                            app.resultfig=ea_mnifigure;
                            % since was empty need to assume fibfilt and electrodes are not shown
                            % either:
                            [app.elrender,app.ellabel]=ea_showelectrodes(options,app.resultfig);
                            ea_addobj([], [], app.resultfig, {app.fibfiltmodelpath}, options);
                        else % create a fake resultfig hidden figure
                            app.resultfig=figure('Visible',"off");
                        end
                    else % cleartune was likely opened from within the fiber filtering explorer GUI.
                        % try delete(app.elrender); end
                        % maybe double check if this is the correct
                        % way to visualize electrodes:
                        if length(patlist) == 1
                            [el_render, el_label, elSide] = ea_renderelstruct(options,app.resultfig,elstruct);
                        else
                            if pt == 1
                                msgbox("Since there are multiple patients, showing the electrode reconstruction of the first patient");
                                [el_render, el_label, elSide] = ea_renderelstruct(options,app.resultfig,elstruct);
                            end
                        end
                        %don't give pt as an argument and it will
                        %always update to the next one
                        %[el_render, el_label, elSide] = ea_renderelstruct(options,app.resultfig,elstruct,pt);
                    end
                    setappdata(app.resultfig,'elstruct',elstruct);
                    setappdata(app.resultfig,'options',options);
                    setappdata(app.resultfig,'elspec',options.elspec);
                    setappdata(app.resultfig,'resultfig',app.resultfig);

                    stimparams = struct();

                    for side=1:2
                        [vtafv,vtavolume] = feval(ea_genvat,coords_mm,S,side,options,['cleartune_',tractsetclone.ID],app.resultfig);
                        stimparams(1,side).VAT.VAT = vtafv;
                        stimparams(1,side).volume = vtavolume;
                    end


                    if app.visualizeresults
                        %% visualize vta

                        PL=getappdata(app.resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        options.expstatvat.do=0;
                        options.sides=1:2;
                        options.writeoutstats=0;
                        setappdata(app.resultfig,'stimparams',stimparams(1,:));
                        setappdata(app.resultfig,'curS',S(1));

                        if ~exist('hmchanged','var')
                            hmchanged=1;
                        end
                        ea_calc_vatstats(app.resultfig,options,hmchanged);

                        %% activate fibs by optimal stim
                        app.tractset.activateby={['cleartune',num2str(ix(1)),'_1'],['cleartune',num2str(ix(2)),'_2']};
                        app.tractset.activate_tractset

                    end
                case 'predict'
            end
            app.enablerules;
            app.isbusy(0);
        end

        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
            drawnow;
        end
        function deletePreExistingComp(app)

            for i=1:length(app.tractset.subscore.vars)
               if ~isempty(app.slider)
                  if strcmp(app.slider{i}.Enable,'on')
                       delete(app.slider{i})
                  end
               end
               if ~isempty(app.slider_lab_name)
                   if ~strcmp(app.slider_lab_name{i}.Text,'')
                       app.slider_lab_name{i}.Text = '';
                   end
               end
               if ~isempty(app.slider_text_box)
                   if app.slider_text_box{i}.Value ~= 0
                       delete(app.slider_text_box{i})
                   end
               end
               if ~isempty(app.sliderimprovement)
                   if ~strcmp(app.sliderimprovement{i}.Text,'')
                       app.sliderimprovement{i}.Text = '';
                   end
               end
            end
        end
        function results = refreshweightsliders(app)
            if ~isempty(app.tractset)
                switch app.tractset.multitractmode
                    case 'Single Tract Analysis Button'
                    otherwise
                       %needs to be here since the components are not
                       %defined in properties, hence they won't be
                       %accessible outside the scope of this function
                      
                       deletePreExistingComp(app);

                        % weight sliders:
                        for i=1:length(app.tractset.subscore.vars)
                            app.slider_name{i} = app.tractset.subscore.labels{i};

                            % change - to _
                            app.slider_name{i} = strrep(app.slider_name{i},'-','_');
                        end

                        j = 390; %start position of the label
                        k = 380; %start position of the slider
                        l = 360;
                        m = 362;


                        for i=1:length(app.slider_name)
                            app.slider_lab_name{i} = uilabel(app.SymptomWeightsPanel,'Text',app.slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                            app.slider{i} = uislider(app.SymptomWeightsPanel,'Limits',[0 100]);
                            app.slider{i}.Position(1) = 250+30;
                            app.slider{i}.Position(2) = k;
                            app.slider{i}.Position(3) = 200;
                            app.slider_text_box{i} = uieditfield(app.SymptomWeightsPanel,'numeric','Position',[500+30,l,50,22]);
                            app.sliderimprovement{i} = uilabel(app.SymptomWeightsPanel,'Text','','Position',[500,j,500,22]);

                            app.Optimize_Weight_CheckBox{i} = uicheckbox(app.SymptomWeightsPanel,'Text', 'Optimize', 'Position',[200,m,80,25]);

                            % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                            app.basepredlbl{i} = uilabel(app.SymptomWeightsPanel,'Text','Weight Using..','Position',[20,m,80,25]);
                            app.basepreddpd{i} = uidropdown(app.SymptomWeightsPanel,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                            j = j-70;
                            k = k-70;
                            l = l-70;
                            m = m-70;

                            % update values already stored
                            if length(app.tractset.subscore.weights)>=i
                                if ~isnan(app.tractset.subscore.weights(i))
                                    app.slider{i}.Value=app.tractset.subscore.weights(i)*100;
                                    app.slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                                    app.slider{i}.Enable='on';
                                    app.slider_text_box{i}.Enable='on';
                                else
                                    app.slider{i}.Enable='off';
                                    app.slider_text_box{i}.Enable='off';
                                    app.basepreddpd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                                end
                            else
                                app.slider{i}.Enable='off';
                                app.slider_text_box{i}.Enable='off';
                                app.basepreddpd{i}.Value='None';
                            end

                        end

                        for i=1:length(app.slider)
                            app.slider{i}.ValueChangedFcn = @(slider,event) SliderChange(app,event,i);
                            app.slider{i}.ValueChangingFcn = @(slider,event) SliderChanging(app,event,i);
                            app.slider_text_box{i}.ValueChangedFcn = @(slider_text_box,event) SliderChange_text(app,event,i);

                            %Optimize_Weight_CheckBox{i}.ValueChangedFcn = @(app,event) Optimize_Weight_CheckBoxChanged();
                            app.Optimize_Weight_CheckBox{i}.ValueChangedFcn = @(app,event) Optimize_Weight_CheckBoxChanged();

                            app.basepreddpd{i}.ValueChangedFcn = @(basepreddpd,event) base_pred_fcn(app,i);                           
                        end
                end
            end
                        
            % PathwayTune
            function Optimize_Weight_CheckBoxChanged()

                % logic for weights' checkboxes and their effect on Pathway Tune
                % buttons

                % check which weights were selected to be optimized
                % hide the corresponding sliders
                N_fixed = 0;
                inx_opt = [];
                for k = 1:length(app.tractset.cleartunevars.weights) 
                    app.slider{k}.Visible = 1; % reinitialize
                    app.slider_text_box{k}.Visible = 1; % reinitialize
                    if app.Optimize_Weight_CheckBox{k}.Value == 1
                       inx_opt = [inx_opt,k]; 
                       app.slider{k}.Visible = 0;  % hide sliders for optimized weights
                       app.slider_text_box{k}.Visible = 0; % reinitialize
                    else
                       N_fixed = N_fixed + 1;
                    end
                end
    
                % initialize allowed states
                app.OptimizeStimulationusingPAMButton.Enable = 1;
                app.GenerateTrainTestsetsButton.Enable = 0;

                if N_fixed == length(app.tractset.cleartunevars.weights) 
                     disp("No weights to be optimized, current protocol optimization is enabled")
                elseif N_fixed == length(app.tractset.cleartunevars.weights) - 1
                    disp("Only one weight cannot be optimized, current protocol optimization is enabled")
                else
                    disp("Several weights to be optimized, current protocol optimization is disabled")
                    app.OptimizeStimulationusingPAMButton.Enable = 0;
                    app.GenerateTrainTestsetsButton.Enable = 1;

                end
            end


        end
    end
    
    methods (Access = public)

        function Ihat = predictImprovement(app,patlist,stimfolder,pt)
            [path,subjID,~] = fileparts(patlist{pt});
            tractsetclone=ea_disctract;
            app.tractset.copyobj(tractsetclone);
            tractsetclone.CleartuneOptim = 1;
            right_vta = fullfile(path,subjID,'stimulations','MNI152NLin2009bAsym',stimfolder,[subjID,'_sim-efield_model-simbio_hemi-R.nii']);
            left_vta = fullfile(path,subjID,'stimulations','MNI152NLin2009bAsym',stimfolder,[subjID,'_sim-efield_model-simbio_hemi-L.nii']);
            tractsetclone=ea_disctract;
            app.tractset.copyobj(tractsetclone);
            tractsetclone.CleartuneOptim = 0.5;

            if exist(right_vta,'file')
                Vvate=ea_load_nii(right_vta); % rh
                emptyVvate = Vvate;
                emptyVvate.img = zeros(size(emptyVvate.img));
                app.protocol{pt}.Efields(1,1)=Vvate;
                app.protocol{pt}.Efields(1,2)=emptyVvate;
                side = 1;
                [~,Ihat_pred,actualimprovs] = runcrossval(app,event,tractsetclone,patlist,side);
                Ihat = weightedIhat(app,actualimprovs,Ihat_pred,pt,side);
                fprintf('Ihat for side %d: %d\n',side,Ihat)
            end
            if exist(left_vta,'file')
                Vvate=ea_load_nii(left_vta); % lh
                emptyVvate = Vvate;
                emptyVvate.img = zeros(size(emptyVvate.img));
                app.protocol{pt}.Efields(1,1)=emptyVvate;
                app.protocol{pt}.Efields(1,2)=Vvate;
                side = 2;
                [~,Ihat_pred,actualimprovs]= runcrossval(app,event,tractsetclone,patlist,side);
                Ihat = weightedIhat(app,actualimprovs,Ihat_pred,pt,side);
                fprintf('Ihat for side %d: %d\n',side,Ihat)
            end
        end

        function Ihat = weightedIhat(app,actualimprovs,Ihat,ptindx,side)
            weightmatrix=zeros(size(actualimprovs,1),1); % in cleartune case always the same weights for any side and "patient" (which is VTA)
            for voter=1:length(weightmatrix)
                % same weight for all subjects in that voter (slider was used)
                weightmatrix(voter)=app.symptomWeightVar{ptindx,side}(voter); %for prediction, it will always be 1, but i will not hard code it.
            end
            weightmatrix_sum = ea_nansum(weightmatrix);
            for xx=1:size(weightmatrix,1) % make sure voter weights sum up to 1
                % for xx=1:size(Ihat_voters,1) % make sure voter weights sum up to 1
                % for yy=1:size(Ihat_voters,2)
                weightmatrix(xx)=weightmatrix(xx)./weightmatrix_sum;
            end
            for i=1:size(Ihat,1)
                for j=1:size(Ihat,2)
                    wt_Ihat(i,j) = Ihat(i,j).*weightmatrix(i);
                end
            end
            Ihat = ea_nansum(wt_Ihat(:,side)); %should be the same since we are doing only one side now
        end


        function [I,Ihat,actualimprovs] = runcrossval(app,event,tractsetclone,patlist,side)
            for pt = 1:length(patlist)
                tractsetclone.calculate_cleartune(app.protocol{pt}.Efields);
                app.tractset.cleartuneresults=tractsetclone.cleartuneresults; % this is fine to copy to the main object for visualization later.

                %% >>>>>>>>>> BEGIN INJECTION:

                %% temporarily append cleartune results to results to feed into
                % crossvalidation:

                % this is crucial to be cleaned up again and cannot go
                % wrong, i.e. anything in between injection and cleanup
                % needs to be in a try/catch statement.

                if ~isempty(tractsetclone.cleartuneinjected)
                    if ~strcmp(tractsetclone.cleartuneinjected.status,'clean')
                        ea_error('Fiber Filtering File is corrupted. Aborting.');
                    end
                end

                tractsetclone.cleartuneinjected.status='injected';
                origlen=size(tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{1},2)/2;
                numEfields=size(app.protocol{pt}.Efields(side),1);
                origix=1:origlen;
                efieldix=1:numEfields;
                efieldlen=length(efieldix);
                totallen=origlen+efieldlen;
                tractsetclone.cleartuneinjected.origlen=origlen; % protocol
                tractsetclone.cleartuneinjected.efieldlen=efieldlen;
                tractsetclone.cleartuneinjected.totallen=totallen;

                tmpfibvals=tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval;

                for hem=1:2
                    tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{hem}=...
                        [tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{hem}(:,origix),...
                        tractsetclone.cleartuneresults.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{hem}(:,efieldix),...
                        tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{hem}(:,origlen+origix),...
                        tractsetclone.cleartuneresults.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{hem}(:,efieldix)]; % no need to flip here but keep copy for flipped entries
                end

                tractsetclone.allpatients=[tractsetclone.allpatients;repmat({''},size(app.protocol{pt}.Efields,1),1)];

                tractsetclone.responsevar=[tractsetclone.responsevar;nan(size(app.protocol{pt}.Efields,1),size(tractsetclone.responsevar,2))];
                reduceresponsevar=0;
                switch event
                    case 'suggest'
                        if size(tractsetclone.responsevar,2) == 1 % global scores
                            tractsetclone.responsevar=[tractsetclone.responsevar,tractsetclone.responsevar];    % we will need maximal stim settings for each site separately, so we will pretend responsevar is bihemispheric
                            reduceresponsevar=1;
                        end
                end
                switch tractsetclone.multitractmode
                    case 'Single Tract Analysis'
                    otherwise
                        selectedpat = patlist{pt};
                        selectedIDX = cellfun(@(x)contains(x,selectedpat),tractsetclone.allpatients);
                        if ~isempty(find(selectedIDX,1))
                            for wv=1:length(tractsetclone.subscore.weights)
                                if isnan(tractsetclone.subscore.weights(wv))
                                    tractsetclone.subscore.weights{wv}=[tractsetclone.subscore.weightvars{wv}(:);repmat(app.tractsetclone.subscore.weights{wv}(selectedIDX),size(app.protocol{pt}.Efields,1),1)]; % need to add in weights here.
                                    % tractsetclone.subscore.weightvars{wv}=[app.tractset.subscore.weightvars{wv};ones(length(app.protocol{pt}.Efields),1)]; % need to add in weights here.
                                end
                            end
                            for wv=1:length(tractsetclone.subscore.vars)
                                tractsetclone.subscore.vars{wv}=[tractsetclone.subscore.vars{wv};repmat(app.tractsetclone.subscore.vars{wv}(selectedIDX),size(app.protocol{pt}.Efields,1),1)]; % need to add in weights here.
                            end
                        else %adding a new patient
                            for wv=1:length(tractsetclone.subscore.vars)
                                tractsetclone.subscore.weightvars{wv} = [tractsetclone.subscore.weightvars{wv}(:);repmat(app.symptomWeightVar{pt,side}(wv),size(app.protocol{pt}.Efields,1),1)];
                                tractsetclone.subscore.vars{wv}=[tractsetclone.subscore.vars{wv}(:);ones(size(app.protocol{pt}.Efields,1),1)]; % need to add in weights here.
                            end
                            %%
                        end
                        switch app.PatientSelectionButtonGroup.SelectedObject.Text
                            case 'Select external Patients:'
                                if isempty(app.patientlist)
                                    ea_error('Please select one or more patients first.')
                                end
                                patlist=app.patientlist;
                                app.trainings=repmat({app.tractset.patientselection},length(patlist),1);
                            case 'Use patients from model files (leave-one-out)'
                                patlist=app.tractset.M.patient.list(app.tractset.patientselection);
                                for i=1:length(app.tractset.patientselection)
                                    app.trainings{i,:} = ones(1,length(app.tractset.patientselection));
                                end
                            case 'Use patients from model files (leave-nothing-out)'
                                patlist=app.tractset.M.patient.list(app.tractset.patientselection);
                                app.trainings=repmat({app.tractset.patientselection},length(patlist),1);
                        end

                        try % this has to be in a try/catch because we still have to cleanup the fibfilt again if something would go wrong (next section).
                            cvp.NumTestSets = 1;
                            % training and test indices from the items list
                            test = origlen+1:origlen+size(app.protocol{pt}.Efields,1);
                            % Patient selected based on the training and test indices
                            tot_len = origlen+length(test);

                            if strcmp(app.PatientSelectionButtonGroup.SelectedObject.Text,'Use patients from model files (leave-one-out)')
                                new_trainings = [app.trainings{pt},zeros(1,length(test))];
                                tractsetclone.customselection = linspace(1,tot_len,tot_len);
                                cvp.training{1} = logical(new_trainings);
                                cvp.test{1} = ~cvp.training{1};
                            elseif strcmp(app.PatientSelectionButtonGroup.SelectedObject.Text,'Use patients from model files (leave-nothing-out)')
                                tractsetclone.customselection = unique([app.trainings{pt}, test]);
                                cvp.training{1} = ismember(tractsetclone.customselection, app.trainings{pt});
                                cvp.test{1} = ismember(tractsetclone.customselection, test);
                            else
                                tractsetclone.customselection = unique([app.trainings{pt}, test]);
                                cvp.training{1} = ismember(tractsetclone.customselection, app.trainings{pt});
                                cvp.test{1} = ismember(tractsetclone.customselection, test);

                            end

                            % Construct cvp struct

                            [I, Ihat, actualimprovs]=tractsetclone.crossval(cvp); % actualimprovs is for each side separately.
                            if ~tractsetclone.CleartuneOptim && strcmp(event,'suggest')
                                results = createS(app,Ihat,actualimprovs);
                            else
                                %remove results again
                                for side=1:2
                                    tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}=...
                                        [tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,origix),...
                                        tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,totallen+origix),...
                                        ];
                                end
                                if isequaln(tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval,tmpfibvals)
                                    tractsetclone.cleartuneinjected.status='clean';
                                else
                                    ea_error('Something went wrong.');
                                end
                                return
                            end
                        end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
            app.UIFigure.Name='Lead-DBS Cleartune';


            if exist('config','var')

                if isfield(config,'fibfiltmodelpath')

                    app.ChooseFiberfilteringModelButton.Text=config.fibfiltmodelpath;
                    app.fibfiltmodelpath=config.fibfiltmodelpath;

                end
                if isfield(config,'tractset')
                    app.tractset=config.tractset;
                end
                if isfield(config,'resultfig')
                    app.resultfig=config.resultfig;
                end
                if isfield(config,'patientlist')
                    if ~iscell(config.patientlist)
                        app.patientlist{1}=config.patientlist;
                    else
                        app.patientlist=config.patientlist;
                    end

                end

            end

            app.enablerules;


            app.isbusy(0);

        end

        % Button pushed function: SuggestStimulationParametersButton
        function SuggestStimulationParametersButtonPushed(app, event)
            if isempty(app.tractset)
                d=load(app.fibfiltmodelpath,'-mat');
                app.tractset=d.tractset;
                clear d
            end
            app.tractset.CleartuneOptim = 1;
            tractsetclone=ea_disctract;
            app.tractset.copyobj(tractsetclone);
            patlist = app.patientlist;
            app.inputVars.minCurr = abs(app.MinCylindricEditField_2.Value);
            app.inputVars.maxCurr = abs(app.MaxCylindricEditField.Value);
            app.inputVars.startContact = app.StartatcontactEditField.Value;
            options = ea_setopts_local;
            options.native = 0;
            options.groupmode = 1;
            options.groupid = 'cleartune';
            options = ea_getptopts(app.patientlist{1}, options);
            [coords_mm]=ea_load_reconstruction(options);
            app.inputVars.numContacts = size(coords_mm{1},1);
            app.inputVars.modelVTA = app.StimulationVolumeModelDropDown.Value;
            if strcmp(app.ConstCurrDropdown.Value,'mA')
                app.inputVars.constcurr = 1; %1 for mA, 0 for V
            else
                app.inputVars.constcurr = 0;
            end
            app.PenaltyVal = app.SweetspotamplitudeEditField.Value;
            app.isbusy(1)
            if strcmp(app.OptimizeusingDropDown.Value,'Surrogate Optimizer')
                ea_cleartune_optimize_surrogate(tractsetclone,patlist,app)
            elseif strcmp(app.OptimizeusingDropDown.Value,'Genetic Algorithm')
                ea_cleartune_optimize_ga(tractsetclone,patlist,app)
            elseif strcmp(app.OptimizeusingDropDown.Value,'Brute force')
                app.draw(1,'suggest');
            end
            function options=ea_setopts_local

                options.earoot=ea_getearoot;
                options.verbose=3;
                options.sides=1:2; % re-check this later..
                options.fiberthresh=1;
                options.writeoutstats=1;
                options.writeoutpm = 0;
                options.colormap=jet;
                options.d3.write=1;
                options.d3.prolong_electrode=2;
                options.d3.writeatlases=1;
                options.macaquemodus=0;
            end
            app.isbusy(0);
        end

        % Button pushed function: ChoosePatientFoldersButton
        function ChoosePatientFoldersButtonPushed(app, event)
            app.patientlist=ea_getpatients;
            app.enablerules;
            %app.draw(1,event)
            app.isbusy(0);
        end

        % Button pushed function: ChooseFiberfilteringModelButton
        function ChooseFiberfilteringModelButtonPushed(app, event)
            [ff,pth]=uigetfile('*.fibfilt');
            app.fibfiltmodelpath=fullfile(pth,ff);
            app.ChooseFiberfilteringModelButton.Text=app.fibfiltmodelpath;
            app.isbusy(1,'Loading FibFilt File...');
            d=load(app.fibfiltmodelpath,'-mat');
            app.tractset=d.tractset;
            clear d
            app.isbusy(0);
            app.enablerules;
            app.draw(1,event);
        end

        % Value changed function: VisualizeResultsCheckBox
        function VisualizeResultsCheckBoxValueChanged(app, event)
            value = app.VisualizeResultsCheckBox.Value;
            app.visualizeresults=value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.tractset.activateby={};
                app.tractset.activate_tractset;
            end
            for el=1:length(app.elrender)
                try delete(app.elrender(el).elpatch); end
            end
            try
                PL=getappdata(app.resultfig,'PL');
                for group=1:length(PL)
                    ea_deletePL(PL(group));
                end
            end
            
            
            delete(app)


        end

        % Callback function
        function MinCylindricEditFieldValueChanged(app, event)
            value = app.MinCylindricEditField_2.Value;
            %app.enablerules;
            app.draw(1,event);
        end

        % Callback function
        function MaxCylindricEditFieldValueChanged(app, event)
            value = app.MaxCylindricEditField.Value;
            %app.enablerules;
            app.draw(1,event);
        end

        % Callback function
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            app.enablerules;
            app.draw(1,event);
        end

        % Value changed function: ConstCurrDropdown
        function ConstCurrDropdownValueChanged(app, event)
            value = app.ConstCurrDropdown.Value;
            app.enablerules;
            app.draw(1,event);
        end

        % Button pushed function: PredictOutcomesButton
        function PredictOutcomesButtonPushed(app, event)
            app.draw(1,'predict');
        end

        % Selection changed function: PatientSelectionButtonGroup
        function PatientSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.PatientSelectionButtonGroup.SelectedObject;
            app.enablerules;
        end

        % Menu selected function: OptimizeStimulationvolumecalculationsMenu
        function OptimizeStimulationvolumecalculationsMenuSelected(app, event)
            if isempty(app.tractset)
                d=load(app.fibfiltmodelpath,'-mat');
                app.tractset=d.tractset;
                clear d
            end

            tractsetclone=ea_disctract;
            app.tractset.copyobj(tractsetclone);
            tractsetclone.CleartuneOptim = 1;
            % tractsetclone.ID = 'retest3';
            %treat both sides are two different patients
            patlist = app.patientlist;
            ea_cleartune_optimize(tractsetclone,patlist,app)
        end

        % Button pushed function: ExportScoreGroupedPathwaysButton
        function ExportScoreGroupedPathwaysButtonPushed(app, event)
            [FileName,PathName]=uiputfile('*.json','File to Save The Symptom-Tract Dictionary...');

            if strcmp(string(FileName),'0')
                return;
            end

            app.ActivationProfileDict = fullfile(PathName,FileName);
            ea_export_symptoms_tracts(app.tractset,app.ActivationProfileDict);            
        end

        % Button pushed function: GenerateTrainTestsetsButton
        function GenerateTrainTestsetsButtonPushed(app, event)

            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);

            % we will choose number of samples depending on the
            % electrode model within Network Blending

            % add a sanity check for current limits

            % for now, hardwired to run in native
            app.stimfolder = fullfile(patientPath , 'stimulations/native/NBstim');
            mkdir(app.stimfolder)

            % check the reconstruction file
            reconstruction_file = dir([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [~, el_model_right, el_model_left, side] = ea_get_reconstruction(reconstruction_file_path);

            % call python script to generate Current_protocols
            if length(side) == 2
                disp("Electrodes in both hemispheres")
                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/TrainTest_Generator.py ', ...
                    app.stimfolder, ' ', el_model_right, ' ', num2str(side(1)), ' ', num2str(app.MinCylindricEditField_2.Value), ' ', num2str(app.MaxCylindricEditField.Value), ' ', num2str(app.MinSegmentedEditField.Value), ' ', num2str(app.MaxSegmentedEditField.Value)]);	% 0 is right side, 1 is the left side here
                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/TrainTest_Generator.py ', ...
                    app.stimfolder, ' ', el_model_left, ' ', num2str(side(2)), ' ', num2str(app.MinCylindricEditField_2.Value), ' ', num2str(app.MaxCylindricEditField.Value), ' ', num2str(app.MinSegmentedEditField.Value), ' ', num2str(app.MaxSegmentedEditField.Value)]);	% 0 is right side, 1 is the left side here
            elseif side == 0
                disp("Only right electrode")
                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/TrainTest_Generator.py ', ...
                    app.stimfolder, ' ', el_model_right, ' ', num2str(side), ' ', num2str(app.MinCylindricEditField_2.Value), ' ', num2str(app.MaxCylindricEditField.Value), ' ', num2str(app.MinSegmentedEditField.Value), ' ', num2str(app.MaxSegmentedEditField.Value)]);	% 0 is right side, 1 is the left side here
            elseif side == 1
                disp("Only left electrode")
                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/TrainTest_Generator.py ', ...
                    app.stimfolder, ' ', el_model_left, ' ', num2str(side), ' ', num2str(app.MinCylindricEditField_2.Value), ' ', num2str(app.MaxCylindricEditField.Value), ' ', num2str(app.MinSegmentedEditField.Value), ' ', num2str(app.MaxSegmentedEditField.Value)]);	% 0 is right side, 1 is the left side here
            else
                disp("No electrodes found, check the reconstruction file")
            end

            ea_warndlg(['The Train/Test sets were saved in ', patientPath, '/stimulations/native/NBstim/'])
            app.PAMButton.Enable = 1;
            app.VATButton.Enable = 1;
        
            %% we can now enable ANN parameters
            %frame_ANN.Visible = 'on';
            %SE_err_threshold_Lbl.Visible = 'on';
            %SE_err_threshold.Visible = 'on';
            %Err_threshold_Lbl.Visible = 'on';
            %Err_threshold.Visible = 'on';
            %Launch_NetBlend.Visible = 'on';      

        end

        % Button pushed function: PredictImprovementusingPAMButton
        function PredictImprovementusingPAMButtonPushed(app, event)
        
            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);

            % this might be redundant!
            [selpath]=uigetdir(patientPath,'Please Choose a Stimulation Folder');
            if strcmp(string(selpath),'0')
                waitfor(msgbox('No stimulation folder was chosen!'));
                return;
            else
                app.stimfolder = selpath;
            end

            if isempty(app.ActivationProfileDict)
                [FileName,FilePath]=uigetfile('*.json','Please Choose a Symptom-Tract Dictionary or Export It');
                app.ActivationProfileDict = fullfile(FilePath,FileName);
            end

            % check if we have processed connectome and
            % Summary_status.h5 in stim folder, as well as correct
            % connectome name in oss-dbs_settings.mat 
            % it is NOT a comprehensive check though 
            try 
                load([app.stimfolder,filesep,'oss-dbs_parameters.mat'], 'settings');
                [~,connectomeName,~] = fileparts(settings.connectomePath);
                [~,connectomeExportedName,~] = fileparts(app.ActivationProfileDict);
                if ~strcmp(connectomeExportedName, connectomeName)
                    disp('Activation was computed for a different connectome')
                    return
                end
            catch
                disp("Please, run OSS-DBS for that stimulation")
                return
            end

            % dump but reliable way to check if we actually ran OSS-DBS
            % for these settings
            if ~isfile([app.stimfolder,filesep,'Brain_substitute.py'])
                disp('OSS-DBS simulation was not conducted')
                return
            end

            % check the reconstruction file
            reconstruction_file = dir([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [~, ~, ~, side] = ea_get_reconstruction(reconstruction_file_path);

            % for now, same weights for both sides!
            ea_save_fixed_weights(app.stimfolder, app.Optimize_Weight_CheckBox, app.tractset.cleartunevars.weights, app.slider_name)
            % (Fixed_symptoms is patient-specific, unlike ActivationProfileDict)

            fixed_symptom_file = [app.stimfolder,filesep,'Fixed_symptoms.json'];


            % check if activation results are available and then make predictions 
            if length(side) == 2
                if ~isfile([app.stimfolder,filesep,'Results_rh',filesep,'Summary_status.h5'])
                    disp('Activation was not computed for the right electrode')
                    if ~isfile([app.stimfolder,filesep,'Results_lh',filesep,'Summary_status.h5'])
                        disp('Activation was not computed for the left electrode')
                    end
                    return
                elseif ~isfile([app.stimfolder,filesep,'Results_lh',filesep,'Summary_status.h5'])
                    disp('Activation was not computed for the left electrode')
                    return
                else
                    % we need to add some packages to our mambaforge python
                    system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Improvement4Protocol.py ', ...
                            app.stimfolder, ' ', num2str(side(1)), ' ', app.ActivationProfileDict, ' ', fixed_symptom_file, ' ', app.DistancemetricDropDown.Value]);	% 0 is right side, 1 is the left side here
                                % we need to add some packages to our mambaforge python
                    system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Improvement4Protocol.py ', ...
                            app.stimfolder, ' ', num2str(side(2)), ' ', app.ActivationProfileDict, ' ', fixed_symptom_file, ' ', app.DistancemetricDropDown.Value]);	% 0 is right side, 1 is the left side here

                    % now we reconcile across hemispheres
                    system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Bilateral_results.py ', ...
                            app.stimfolder, ' ', 'improvement']);	% 0 is right side, 1 is the left side here
                end   
            elseif side == 0
                if ~isfile([app.stimfolder,filesep,'Results_rh',filesep,'Summary_status.h5'])
                    disp('Activation was not computed for the right electrode')
                    return
                else
                    system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Improvement4Protocol.py ', ...
                        app.stimfolder, ' ', num2str(side), ' ', app.ActivationProfileDict, ' ', fixed_symptom_file, ' ', app.DistancemetricDropDown.Value]);	% 0 is right side, 1 is the left side here
                end
            elseif side == 1
                if ~isfile([app.stimfolder,filesep,'Results_lh',filesep,'Summary_status.h5'])
                    disp('Activation was not computed for the left electrode')
                    return
                else
                    system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Improvement4Protocol.py ', ...
                        app.stimfolder, ' ', num2str(side), ' ', app.ActivationProfileDict, ' ', fixed_symptom_file, ' ', app.DistancemetricDropDown.Value]);	% 0 is right side, 1 is the left side here
                end 
            end
        end

        % Button pushed function: OptimizeStimulationusingPAMButton
        function OptimizeStimulationusingPAMButtonPushed(app, event)

            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);

            % for now, hardwired to run in native
            app.stimfolder = fullfile(patientPath, filesep, 'stimulations/native/NBstim');
            if ~isfolder(app.stimfolder)
                mkdir(app.stimfolder)
            end

            % for now, same weights for both sides!
            % for current optimization, they are all fixed!
            ea_save_fixed_weights(app.stimfolder, app.Optimize_Weight_CheckBox, app.tractset.cleartunevars.weights, app.slider_name)

            if isempty(app.ActivationProfileDict)
                [FileName,FilePath]=uigetfile('*.json','Please Choose a Symptom-Tract Dictionary or Export It');
                app.ActivationProfileDict = fullfile(FilePath,FileName);
                ActivationProfileDict_Docker = ['/opt/Patient/',FileName];
            else
                [~,FileName,Exten] = fileparts(app.ActivationProfileDict);
                ActivationProfileDict_Docker = ['/opt/Patient/',FileName,Exten];
            end
            [~,ConnectomeName,Exten] = fileparts(app.ActivationProfileDict);

            % copy activation profile dict to the patient folder
            copyfile(app.ActivationProfileDict, app.stimfolder)

            % load NB_default_settings.mat
            % and update some fields
            NB_default_settings = load([ea_getearoot,'cleartune/cleartunePAM/NB_default_settings.mat']);

            % check the reconstruction file
            reconstruction_file = dir([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [reconst, ~, ~, side] = ea_get_reconstruction(reconstruction_file_path);           

            % check how many tracts we have
            N_tracts = ea_get_N_pathways(app.ActivationProfileDict);

            % load options for OSS-DBS (including default axon properties)
            options = ea_get_OSS_DBS_options(NB_default_settings.varargin{1, 2}, patientPath, N_tracts, ConnectomeName);
         
            
            % assign per contact bounds based on the electrode type and
            % assigned thresholds
            [min_bound_per_contact, max_bound_per_contact, S] = ea_get_currents_per_contact(app.MinCylindricEditField_2.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value, reconst, side, 1);
            %[~, ~, S] = ea_get_currents_per_contact(app.MinCylindricEditField_2.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value, reconst, side + 1, 1);  % run twice here since we have the left electrode 
            S.label = 'NBstim';

            % create a master dictionary that will guide
            % Launcher_OSS_NetBlend.py
            % in this specific case, we convert to A
            jsonDict.netblendict.min_bound_per_contact = min_bound_per_contact .* 0.001;
            jsonDict.netblendict.max_bound_per_contact = max_bound_per_contact .* 0.001;

            % pass it as within a Docker
            jsonDict.netblendict.FF_dictionary = ActivationProfileDict_Docker;
            jsonDict.netblendict.symptom_weights_file = '/opt/Patient/Fixed_symptoms.json';
            jsonDict.netblendict.num_iterations = app.NumberofIterationsEditField.Value;
            jsonDict.netblendict.optim_alg = app.OptAlgorithmDropDown.Value;
            jsonDict.netblendict.similarity_metric = app.DistancemetricDropDown.Value;

            % IMPORTANT: if StimSets is 0, but netblend is provided, then
            % Launcher thinks it is a simple optimization
            jsonDict.netblendict.Optimizer = 1;
            jsonDict.netblendict.StimSets = 0;

            netblend_settings_file = [app.stimfolder,filesep, 'netblend_dict_file.json'];
            jsonText = jsonencode(jsonDict);
            fid = fopen(netblend_settings_file, 'w');
            fprintf(fid, '%s', jsonText)
            fclose(fid);

            % call ea_genvat_butenko(S,options), but change the Launcher
            ea_genvat_butenko(S,options)
         
            % reconcile prediction if bilateral based on sum vals
            if length(side) == 2 
                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Bilateral_results.py ', ...
                        app.stimfolder, ' ', 'improvement']);	% 0 is right side, 1 is the left side here
            end
        end

        % Button pushed function: PAMButton
        function PAMButtonPushed(app, event)

            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);

            % for now, hardwired to run in native
            % excessive, because we already created it in GenerateTrainTestsetsButtonPushed
            app.stimfolder = fullfile(patientPath, filesep, 'stimulations/native/NBstim');
            if ~isfolder(app.stimfolder)
                mkdir(app.stimfolder)
            end

            % for now, same weights for both sides!
            ea_save_fixed_weights(app.stimfolder, app.Optimize_Weight_CheckBox, app.tractset.cleartunevars.weights, app.slider_name)
            % (Fixed_symptoms is patient-specific, unlike ActivationProfileDict)

            % was it already copied before?
            fixed_symptom_file = [app.stimfolder,filesep,'Fixed_symptoms.json'];

            if isempty(app.ActivationProfileDict)
                [ConnectomeName,PathName]=uigetfile('*.json','Please Choose a Symptom-Tract Dictionary or Export It');
                app.ActivationProfileDict = fullfile(PathName,ConnectomeName);
            else
                [~,ConnectomeName,Exten] = fileparts(app.ActivationProfileDict);
            end

            % copy activation profile dict to the patient folder
            copyfile(app.ActivationProfileDict, app.stimfolder)

            % check how many tracts we have
            N_tracts = ea_get_N_pathways(app.ActivationProfileDict);

            % load NB_default_settings.mat
            % update some fields
            NB_default_settings = load([ea_getearoot,'cleartune/cleartunePAM/NB_default_settings.mat']);

            % load options for OSS-DBS (including default axon properties)
            options = ea_get_OSS_DBS_options(NB_default_settings.varargin{1, 2}, patientPath, N_tracts, ConnectomeName);
            options.stimSetMode = 1;

            % check the reconstruction file
            reconstruction_file = dir([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [reconst, ~, ~, side] = ea_get_reconstruction(reconstruction_file_path);      

            % the first two return arguments are already stored in StimSets_info.json
            [min_bound_per_contact, max_bound_per_contact, S] = ea_get_currents_per_contact(app.MinCylindricEditField_2.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value, reconst, side, 1);
            S.label = 'NBstim';

            % create a master dictionary that will guide ANN_module.py
            % in this specific case, we convert to A
            jsonDict.netblendict.min_bound_per_contact = min_bound_per_contact .* 0.001;
            jsonDict.netblendict.max_bound_per_contact = max_bound_per_contact .* 0.001;

            jsonDict.netblendict.FF_dictionary = app.ActivationProfileDict;
            jsonDict.netblendict.symptom_weights_file = fixed_symptom_file;

            % Acceptable error thresholds, convert from %
            jsonDict.netblendict.Err_threshold = app.ErrorthresholdEditField.Value / 100.0;
            jsonDict.netblendict.SE_err_threshold = app.SideeffecterrorthresholdEditField.Value / 100.0;

            % optimization parameters
            jsonDict.netblendict.num_iterations_ANN = app.NumberofIterationsEditField.Value;
            jsonDict.netblendict.optim_alg = app.OptAlgorithmDropDown.Value;
            jsonDict.netblendict.similarity_metric = app.DistancemetricDropDown.Value;

            % IMPORTANT: if StimSets is 0, but netblend is provided, then
            % Launcher thinks it is a simple optimization
            jsonDict.netblendict.Optimizer = 0;
            jsonDict.netblendict.StimSets = 1;

            netblend_settings_file = [app.stimfolder,filesep, 'netblend_dict_file.json'];
            jsonText = jsonencode(jsonDict);
            fid = fopen(netblend_settings_file, 'w');
            fprintf(fid, '%s', jsonText)
            fclose(fid);

            % run Launcher_OSS_lite.py via Lead-DBS with StimSets on
            % it will actually attempt to run both side
            % and we can run both sides if necessary
            ea_genvat_butenko(S,options)

            % now run training and test of ANN (outside of Docker for now)
            if length(side) == 2
                % run train_test_ANN()
                % here I use my customize version of tensorflow
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side(1))]);	% 0 is right side, 1 is the left side here
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side(2))]);	% 0 is right side, 1 is the left side here
            else
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side)]);	% 0 is right side, 1 is the left side here
            end

            % it also stores info about pathways that ANN learned (some could have been excluded)
            % os.environ['STIMDIR'] + '/NB_' + str(side) + '/ANN_abs_errors.json'

            % only if ANN training returns a success flag, otherwise throw
            % a warning
            app.OptimizewithANNButton.Enable = 1;
        end

        % Button pushed function: OptimizewithANNButton
        function OptimizewithANNButtonPushed(app, event)
            % check the reconstruction file
            reconstruction_file = dir([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [reconst, ~, ~, side] = ea_get_reconstruction(reconstruction_file_path);  

            if length(side) == 2
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/NB_outline.py ', ...
                        app.stimfolder, ' ', app.ActivationProfileDict, ' ', num2str(side(1))]);	% 0 is right side, 1 is the left side here
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/NB_outline.py ', ...
                        app.stimfolder, ' ', app.ActivationProfileDict, ' ', num2str(side(2))]);	% 0 is right side, 1 is the left side here
            else
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/NB_outline.py ', ...
                        app.stimfolder, ' ', app.ActivationProfileDict, ' ', num2str(side)]);	% 0 is right side, 1 is the left side here

                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Bilateral_results.py ', ...
                        app.stimfolder, ' ', 'weights']);	% 0 is right side, 1 is the left side here

                system(['/home/konstantin/anaconda3/bin/python', ' ', ea_getearoot, 'cleartune/cleartunePAM/Bilateral_results.py ', ...
                        app.stimfolder, ' ', 'improvement']);	% 0 is right side, 1 is the left side here
            end
        end

        % Value changed function: SideeffecterrorthresholdEditField
        function SideeffecterrorthresholdEditFieldValueChanged(app, event)
            value = app.SideeffecterrorthresholdEditField.Value;
        end

        % Value changed function: StartatcontactEditField
        function StartatcontactEditFieldValueChanged(app, event)
            value = app.StartatcontactEditField.Value;
            options = ea_setopts_local;
            options.native = 0;
            options.groupmode = 1;
            options.groupid = 'cleartune';
            options = ea_getptopts(app.patientlist{1}, options);
            [coords_mm]=ea_load_reconstruction(options);
            numContacts = size(coords_mm{1},1);
            if app.StartatcontactEditField.Value > numContacts
                warning("The electrode specified does not have this contact!");
                app.StartatcontactEditField.Value = numContacts;
            end
            function options=ea_setopts_local

                options.earoot=ea_getearoot;
                options.verbose=3;
                options.sides=1:2; % re-check this later..
                options.fiberthresh=1;
                options.writeoutstats=1;
                options.writeoutpm = 0;
                options.colormap=jet;
                options.d3.write=1;
                options.d3.prolong_electrode=2;
                options.d3.writeatlases=1;
                options.macaquemodus=0;

                % try
                %    options.atlasset=options.atlasset{get(handles.atlassetpopup,'Value')};
                % catch % too many entries..
                %    set(handles.atlassetpopup,'Value',1);
                %    options.atlasset=1;
                % end

                % options.labelatlas=get(handles.labelpopup,'String');
                % try
                %     options.labelatlas=options.labelatlas{get(handles.labelpopup,'Value')};
                % catch % too many entries..
                %     set(handles.labelpopup,'Value',1);
                %     options.labelatlas=1;
                % end
            end
        end

        % Value changed function: OptimizeusingDropDown
        function OptimizeusingDropDownValueChanged(app, event)
            value = app.OptimizeusingDropDown.Value;
            
        end

        % Button pushed function: VATButton
        function VATButtonPushed(app, event)

            % for now hardwired
            VAT_thresh = 200.0;  % V/m

            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);

            % for now, hardwired to run in native
            % excessive, because we already created it in GenerateTrainTestsetsButtonPushed
            app.stimfolder = fullfile(patientPath, filesep, 'stimulations/native/NBstim');
            if ~isfolder(app.stimfolder)
                mkdir(app.stimfolder)
            end

            % for now, same weights for both sides!
            ea_save_fixed_weights(app.stimfolder, app.Optimize_Weight_CheckBox, app.tractset.cleartunevars.weights, app.slider_name)
            % (Fixed_symptoms is patient-specific, unlike ActivationProfileDict)

            fixed_symptom_file = [app.stimfolder,filesep,'Fixed_symptoms.json'];

            if isempty(app.ActivationProfileDict)
                [ConnectomeName,PathName]=uigetfile('*.json','Please Choose a Symptom-Tract Dictionary or Export It');
                app.ActivationProfileDict = fullfile(PathName,ConnectomeName);
            else
                [~,ConnectomeName,Exten] = fileparts(app.ActivationProfileDict);
            end

            % Get paths of tracts
            connFolder = [ea_getconnectomebase, 'dMRI_MultiTract', filesep, ConnectomeName];

            % copy activation profile dict to the patient folder
            copyfile(app.ActivationProfileDict, app.stimfolder)

            % check how many tracts we have
            N_tracts = ea_get_N_pathways(app.ActivationProfileDict);

            % load NB_default_settings.mat
            % update some fields
            NB_default_settings = load([ea_getearoot,'cleartune/cleartunePAM/NB_default_settings.mat']);

            % load options for OSS-DBS (including default axon properties)
            options = ea_get_OSS_DBS_options(NB_default_settings.varargin{1, 2}, patientPath, N_tracts, ConnectomeName);
            options.stimSetMode = 1;

            % check the reconstruction file
            reconstruction_file = dir([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [reconst, ~, ~, side] = ea_get_reconstruction(reconstruction_file_path);      

            % the first two return arguments are already stored in StimSets_info.json
            [min_bound_per_contact, max_bound_per_contact, S] = ea_get_currents_per_contact(app.MinCylindricEditField_2.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value, reconst, side, 1);
            S.label = 'NBstim';

            % create a master dictionary that will guide ANN_module.py
            % in this specific case, we convert to A
            jsonDict.netblendict.min_bound_per_contact = min_bound_per_contact .* 0.001;
            jsonDict.netblendict.max_bound_per_contact = max_bound_per_contact .* 0.001;

            jsonDict.netblendict.FF_dictionary = app.ActivationProfileDict;
            jsonDict.netblendict.symptom_weights_file = fixed_symptom_file;

            % Acceptable error thresholds, convert from %
            jsonDict.netblendict.Err_threshold = app.ErrorthresholdEditField.Value / 100.0;
            jsonDict.netblendict.SE_err_threshold = app.SideeffecterrorthresholdEditField.Value / 100.0;

            % optimization parameters
            jsonDict.netblendict.num_iterations_ANN = app.NumberofIterationsEditField.Value;
            jsonDict.netblendict.optim_alg = app.OptAlgorithmDropDown.Value;
            jsonDict.netblendict.similarity_metric = app.DistancemetricDropDown.Value;

            % IMPORTANT: if StimSets is 0, but netblend is provided, then
            % Launcher thinks it is a simple optimization
            jsonDict.netblendict.Optimizer = 0;
            jsonDict.netblendict.StimSets = 1;

            netblend_settings_file = [app.stimfolder,filesep, 'netblend_dict_file.json'];
            jsonText = jsonencode(jsonDict);
            fid = fopen(netblend_settings_file, 'w');
            fprintf(fid, '%s', jsonText)
            fclose(fid);
            
            % this can be changed later
            if length(side) == 1
                if side == 0
                    stim_prot_file = [app.stimfolder,filesep,'Current_protocols_0.csv'];
                else
                    stim_prot_file = [app.stimfolder,filesep,'Current_protocols_1.csv'];
                end
            end
            
            % now run training and test of ANN (outside of Docker for now)
            if length(side) == 2

                % run VAT-based pathway recruitment for both sides
                ea_compute_pathways_recruitment(connFolder,[app.stimfolder,filesep,'Current_protocols_0.csv'],patientPath,app.stimfolder,side(1),VAT_thresh)
                ea_compute_pathways_recruitment(connFolder,[app.stimfolder,filesep,'Current_protocols_1.csv'],patientPath,app.stimfolder,side(2),VAT_thresh)

                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side(1))]);	% 0 is right side, 1 is the left side here
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side(2))]);	% 0 is right side, 1 is the left side here
            else
                ea_compute_pathways_recruitment(connFolder,stim_prot_file,patientPath,app.stimfolder,side,VAT_thresh)
                system(['/usr/bin/python3.8', ' ', ea_getearoot, 'cleartune/cleartunePAM/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side)]);	% 0 is right side, 1 is the left side here
            end


        end

        % Button pushed function: providewithamatfileButton
        function providewithamatfileButtonPushed(app, event)
           [scoreFile,path] = uigetfile(pwd,'Clinical Scores Dialog Box');
           app.providewithamatfileButton.Text = scoreFile;
           scoreStruct = load(fullfile(path,scoreFile));
           app.symptomWeightVar = scoreStruct.scores;
        end

        % Callback function: not associated with a component
        function ApplyapenaltyvalueofEditFieldValueChanged(app, event)
            value = app.ApplyapenaltyvalueofEditField.Value;
            app.PenaltyVal = app.ApplyapenaltyvalueofEditField.Value;
        end

        % Value changed function: ApplypenaltyusingDropDown
        function ApplypenaltyusingDropDownValueChanged(app, event)
            value = app.ApplypenaltyusingDropDown.Value;
            if strcmp(value,'Range of amplitudes')
                app.SweetspotamplitudeEditFieldLabel.Text = 'Apply penalty value of..';
            elseif strcmp(value,'Quadratic curve')
                app.SweetspotamplitudeEditFieldLabel.Text = 'Sweetspot amplitude';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.OptimizeStimulationvolumecalculationsMenu = uimenu(app.ToolsMenu);
            app.OptimizeStimulationvolumecalculationsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizeStimulationvolumecalculationsMenuSelected, true);
            app.OptimizeStimulationvolumecalculationsMenu.Text = 'Optimize Stimulation volume calculations';
            app.ChooseFiberfilteringModelButton = uibutton(app.UIFigure, 'push');
            app.ChooseFiberfilteringModelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFiberfilteringModelButtonPushed, true);
            app.ChooseFiberfilteringModelButton.Text = 'Choose Fiberfiltering Model';
            app.ChooseNetworkMappingModelButton = uibutton(app.UIFigure, 'push');
            app.ChooseNetworkMappingModelButton.Enable = 'off';
            app.ChooseNetworkMappingModelButton.Text = 'Choose Network Mapping Model';
            app.SymptomWeightsPanel = uipanel(app.UIFigure);
            app.SymptomWeightsPanel.Title = 'Symptom Weights';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SuggestStimulationParametersTab_2 = uitab(app.TabGroup);
            app.SuggestStimulationParametersTab_2.Title = 'Suggest Stimulation Parameters';
            app.SuggestStimulationParametersButton = uibutton(app.SuggestStimulationParametersTab_2, 'push');
            app.SuggestStimulationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SuggestStimulationParametersButtonPushed, true);
            app.SuggestStimulationParametersButton.Text = 'Suggest Stimulation Parameters';
            app.VisualizeResultsCheckBox = uicheckbox(app.SuggestStimulationParametersTab_2);
            app.VisualizeResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeResultsCheckBoxValueChanged, true);
            app.VisualizeResultsCheckBox.Text = 'Visualize Results';
            app.VisualizeResultsCheckBox.Value = true;
            app.AutoRefreshCheckBox = uicheckbox(app.SuggestStimulationParametersTab_2);
            app.AutoRefreshCheckBox.Text = 'Auto Refresh';
            app.OptimizeusingDropDownLabel = uilabel(app.SuggestStimulationParametersTab_2);
            app.OptimizeusingDropDownLabel.Text = 'Optimize using ...';
            app.OptimizeusingDropDown = uidropdown(app.SuggestStimulationParametersTab_2);
            app.OptimizeusingDropDown.Items = {'Surrogate Optimizer', 'Genetic Algorithm', 'Brute Force', 'Stim Fit (Roediger et al., 2022)'};
            app.OptimizeusingDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizeusingDropDownValueChanged, true);
            app.OptimizeusingDropDown.Value = 'Surrogate Optimizer';
            app.StimulationVolumeModelDropDownLabel = uilabel(app.SuggestStimulationParametersTab_2);
            app.StimulationVolumeModelDropDownLabel.Text = 'Stimulation Volume Model';
            app.StimulationVolumeModelDropDown = uidropdown(app.SuggestStimulationParametersTab_2);
            app.StimulationVolumeModelDropDown.Items = {'Simbio/FieldTrip (Horn 2017)', 'Fastfield'};
            app.StimulationVolumeModelDropDown.Value = 'Simbio/FieldTrip (Horn 2017)';
            app.StartatcontactEditFieldLabel = uilabel(app.SuggestStimulationParametersTab_2);
            app.StartatcontactEditFieldLabel.Text = 'Start at contact ';
            app.StartatcontactEditField = uieditfield(app.SuggestStimulationParametersTab_2, 'numeric');
            app.StartatcontactEditField.ValueChangedFcn = createCallbackFcn(app, @StartatcontactEditFieldValueChanged, true);
            app.StartatcontactEditField.Tooltip = {'This contact will be activated at a 100%. Case will be set to 100%'};
            app.StartatcontactEditField.Value = 2;
            app.ApplypenaltyusingDropDownLabel = uilabel(app.SuggestStimulationParametersTab_2);
            app.ApplypenaltyusingDropDownLabel.Text = 'Apply penalty using...';
            app.ApplypenaltyusingDropDown = uidropdown(app.SuggestStimulationParametersTab_2);
            app.ApplypenaltyusingDropDown.Items = {'None', 'Quadratic curve', 'Range of amplitudes'};
            app.ApplypenaltyusingDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplypenaltyusingDropDownValueChanged, true);
            app.ApplypenaltyusingDropDown.Value = 'Quadratic curve';
            app.SweetspotamplitudeEditFieldLabel = uilabel(app.SuggestStimulationParametersTab_2);
            app.SweetspotamplitudeEditFieldLabel.Text = 'Sweet spot amplitude';
            app.SweetspotamplitudeEditField = uieditfield(app.SuggestStimulationParametersTab_2, 'numeric');
            app.SweetspotamplitudeEditField.Value = 3;
            app.PredictOutcomesforExistingParametersTab = uitab(app.TabGroup);
            app.PredictOutcomesforExistingParametersTab.Title = 'Predict Outcomes for Existing Parameters';
            app.PredictOutcomesButton = uibutton(app.PredictOutcomesforExistingParametersTab, 'push');
            app.PredictOutcomesButton.ButtonPushedFcn = createCallbackFcn(app, @PredictOutcomesButtonPushed, true);
            app.PredictOutcomesButton.Text = 'Predict Outcomes';
            app.StimulationSettingDropDownLabel = uilabel(app.PredictOutcomesforExistingParametersTab);
            app.StimulationSettingDropDownLabel.Text = 'Stimulation Setting';
            app.StimulationSettingDropDown = uidropdown(app.PredictOutcomesforExistingParametersTab);
            app.PathwayTuneTab = uitab(app.TabGroup);
            app.PathwayTuneTab.Title = 'PathwayTune';
            app.PredictImprovementusingPAMButton = uibutton(app.PathwayTuneTab, 'push');
            app.PredictImprovementusingPAMButton.ButtonPushedFcn = createCallbackFcn(app, @PredictImprovementusingPAMButtonPushed, true);
            app.PredictImprovementusingPAMButton.Text = 'Predict Improvement using PAM';
            app.OptimizeStimulationusingPAMButton = uibutton(app.PathwayTuneTab, 'push');
            app.OptimizeStimulationusingPAMButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeStimulationusingPAMButtonPushed, true);
            app.OptimizeStimulationusingPAMButton.Tooltip = {'Optimize current protocol for selected weights'};
            app.OptimizeStimulationusingPAMButton.Text = 'Optimize Stimulation using PAM';
            app.OptAlgorithmDropDownLabel = uilabel(app.PathwayTuneTab);
            app.OptAlgorithmDropDownLabel.Text = 'Opt. Algorithm';
            app.OptAlgorithmDropDown = uidropdown(app.PathwayTuneTab);
            app.OptAlgorithmDropDown.Items = {'Dual Annealing', 'PSO'};
            app.OptAlgorithmDropDown.Value = 'Dual Annealing';
            app.ANNModelPanel = uipanel(app.PathwayTuneTab);
            app.ANNModelPanel.Title = 'ANN Model';
            app.PAMButton = uibutton(app.ANNModelPanel, 'push');
            app.PAMButton.ButtonPushedFcn = createCallbackFcn(app, @PAMButtonPushed, true);
            app.PAMButton.Enable = 'off';
            app.PAMButton.Text = 'PAM';
            app.VATButton = uibutton(app.ANNModelPanel, 'push');
            app.VATButton.ButtonPushedFcn = createCallbackFcn(app, @VATButtonPushed, true);
            app.VATButton.Enable = 'off';
            app.VATButton.Text = 'VAT';
            app.ErrorthresholdEditFieldLabel = uilabel(app.ANNModelPanel);
            app.ErrorthresholdEditFieldLabel.Text = 'Error threshold';
            app.ErrorthresholdEditField = uieditfield(app.ANNModelPanel, 'numeric');
            app.ErrorthresholdEditField.Value = 10;
            app.GenerateTrainTestsetsButton = uibutton(app.ANNModelPanel, 'push');
            app.GenerateTrainTestsetsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrainTestsetsButtonPushed, true);
            app.GenerateTrainTestsetsButton.Enable = 'off';
            app.GenerateTrainTestsetsButton.Tooltip = {'Choose at least two items to optimize to enable Weight Optimizer'};
            app.GenerateTrainTestsetsButton.Text = 'Generate Train/Test sets';
            app.SideeffecterrorthresholdEditFieldLabel = uilabel(app.ANNModelPanel);
            app.SideeffecterrorthresholdEditFieldLabel.Text = 'Side-effect error threshold';
            app.SideeffecterrorthresholdEditField = uieditfield(app.ANNModelPanel, 'numeric');
            app.SideeffecterrorthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SideeffecterrorthresholdEditFieldValueChanged, true);
            app.SideeffecterrorthresholdEditField.Value = 5;
            app.TrainANNusingLabel = uilabel(app.ANNModelPanel);
            app.TrainANNusingLabel.Text = 'Train ANN using';
            app.Label_2 = uilabel(app.ANNModelPanel);
            app.Label_2.Text = '%';
            app.Label = uilabel(app.ANNModelPanel);
            app.Label.Text = '%';
            app.ExportScoreGroupedPathwaysButton = uibutton(app.PathwayTuneTab, 'push');
            app.ExportScoreGroupedPathwaysButton.ButtonPushedFcn = createCallbackFcn(app, @ExportScoreGroupedPathwaysButtonPushed, true);
            app.ExportScoreGroupedPathwaysButton.Text = 'Export Score-Grouped Pathways ';
            app.OptimizewithANNButton = uibutton(app.PathwayTuneTab, 'push');
            app.OptimizewithANNButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizewithANNButtonPushed, true);
            app.OptimizewithANNButton.Enable = 'off';
            app.OptimizewithANNButton.Text = 'Optimize with ANN';
            app.NumberofIterationsEditFieldLabel = uilabel(app.PathwayTuneTab);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.PathwayTuneTab, 'numeric');
            app.NumberofIterationsEditField.Value = 1000;
            app.DistancemetricDropDownLabel = uilabel(app.PathwayTuneTab);
            app.DistancemetricDropDownLabel.Text = 'Distance metric';
            app.DistancemetricDropDown = uidropdown(app.PathwayTuneTab);
            app.DistancemetricDropDown.Items = {'Canberra', 'Euclidean', 'Cosine', 'Bray-Curtis', 'Manhattan'};
            app.DistancemetricDropDown.Value = 'Canberra';
            app.PatientSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.PatientSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatientSelectionButtonGroupSelectionChanged, true);
            app.PatientSelectionButtonGroup.Title = 'Patient Selection';
            app.SelectexternalPatientsButton = uiradiobutton(app.PatientSelectionButtonGroup);
            app.SelectexternalPatientsButton.Text = 'Select external Patients:';
            app.SelectexternalPatientsButton.Value = true;
            app.UsepatientsfrommodelfilesleaveoneoutButton = uiradiobutton(app.PatientSelectionButtonGroup);
            app.UsepatientsfrommodelfilesleaveoneoutButton.Text = 'Use patients from model files (leave-one-out)';
            app.ChoosePatientFoldersButton = uibutton(app.PatientSelectionButtonGroup, 'push');
            app.ChoosePatientFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePatientFoldersButtonPushed, true);
            app.ChoosePatientFoldersButton.Text = 'Choose Patient Folder(s)...';
            app.BusyLampLabel = uilabel(app.PatientSelectionButtonGroup);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.PatientSelectionButtonGroup);
            app.UsepatientsfrommodelfilesleavenothingoutButton = uiradiobutton(app.PatientSelectionButtonGroup);
            app.UsepatientsfrommodelfilesleavenothingoutButton.Text = 'Use patients from model files (leave-nothing-out)';
            app.ChooseSweetspotMappingModelButton = uibutton(app.UIFigure, 'push');
            app.ChooseSweetspotMappingModelButton.Enable = 'off';
            app.ChooseSweetspotMappingModelButton.Text = 'Choose Sweetspot Mapping Model';
            app.OptAmplitudesLabel = uilabel(app.UIFigure);
            app.OptAmplitudesLabel.Text = 'Opt. Amplitudes';
            app.ConstCurrDropdown = uidropdown(app.UIFigure);
            app.ConstCurrDropdown.Items = {'mA', 'V'};
            app.ConstCurrDropdown.ValueChangedFcn = createCallbackFcn(app, @ConstCurrDropdownValueChanged, true);
            app.ConstCurrDropdown.Value = 'mA';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'cleartunePAM', 'contact_types2.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cleartunePAM', 'CT_logo.png');
            app.MinSegmentedEditFieldLabel = uilabel(app.UIFigure);
            app.MinSegmentedEditFieldLabel.Text = 'Min. Segmented';
            app.MinSegmentedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinSegmentedEditField.Value = -2;
            app.MinCylindricEditField_2Label = uilabel(app.UIFigure);
            app.MinCylindricEditField_2Label.Text = 'Min. Cylindric';
            app.MinCylindricEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MinCylindricEditField_2.Value = -4;
            app.MaxCylindricEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCylindricEditFieldLabel.Text = 'Max. Cylindric';
            app.MaxCylindricEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCylindricEditField.Value = 4;
            app.MaxSegmentedEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSegmentedEditFieldLabel.Text = 'Max. Segmented';
            app.MaxSegmentedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxSegmentedEditField.Value = 2;
            app.providewithamatfileButton = uibutton(app.UIFigure, 'push');
            app.providewithamatfileButton.ButtonPushedFcn = createCallbackFcn(app, @providewithamatfileButtonPushed, true);
            app.providewithamatfileButton.Text = 'provide with a .mat file';
            app.ProvideweightsasamatfileLabel = uilabel(app.UIFigure);
            app.ProvideweightsasamatfileLabel.Text = 'Provide weights as a mat file...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_cleartune(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end