classdef clinical_score_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UPDRSTab                        matlab.ui.container.Tab
        CreateCustomTextArea            matlab.ui.control.TextArea
        CreateCustomClinicalScoresLabel  matlab.ui.control.Label
        GENERATEButton                  matlab.ui.control.Button
        LOADTOLEADGROUPButton           matlab.ui.control.Button
        ExportasExcelButton             matlab.ui.control.Button
        PostopColumnStopEditField_2     matlab.ui.control.EditField
        PostopColumnStopEditField_2Label  matlab.ui.control.Label
        PostopColumnStartEditField_2    matlab.ui.control.EditField
        PostopColumnStartEditField_2Label  matlab.ui.control.Label
        BaselineColumnStopEditField_2   matlab.ui.control.EditField
        BaselineColumnStopEditField_2Label  matlab.ui.control.Label
        BaselineColumnStartEditField_2  matlab.ui.control.EditField
        BaselineColumnStartEditField_9Label  matlab.ui.control.Label
        PostopColumnStopEditField       matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel  matlab.ui.control.Label
        PostopColumnStartEditField      matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel  matlab.ui.control.Label
        BaselineColumnStopEditField     matlab.ui.control.EditField
        BaselineColumnStopEditFieldLabel  matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        BaselineColumnStartEditField    matlab.ui.control.EditField
        BaselineColumnStartEditFieldLabel  matlab.ui.control.Label
        BASELINEEditField               matlab.ui.control.EditField
        LEADGROUPIDEditFieldLabel       matlab.ui.control.Label
        LEADGROUPIDEditField            matlab.ui.control.EditField
        POSTOPEditField                 matlab.ui.control.EditField
        CheckBox                        matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        LateralityButtonGroup           matlab.ui.container.ButtonGroup
        BilateralButton                 matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        ImportfromEXCELButton           matlab.ui.control.Button
        ImportfromEXCELButton_2         matlab.ui.control.Button
        ImportfromEXCELButton_3         matlab.ui.control.Button
        ImportfromEXCELButton_4         matlab.ui.control.Button
        BDITab                          matlab.ui.container.Tab
        PostopColumnStopEditField_3     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_2  matlab.ui.control.Label
        PostopColumnStartEditField_3    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_2  matlab.ui.control.Label
        BaselineColumnStopEditField_3   matlab.ui.control.EditField
        BaselineColumnStopEditFieldLabel_2  matlab.ui.control.Label
        BaselineColumnStartEditField_3  matlab.ui.control.EditField
        BaselineColumnStartEditFieldLabel_2  matlab.ui.control.Label
        BASELINEEditField_2             matlab.ui.control.EditField
        POSTOPEditField_2               matlab.ui.control.EditField
        CheckBox_4                      matlab.ui.control.CheckBox
        GENERATEButton_2                matlab.ui.control.Button
        ExportasExcelButton_2           matlab.ui.control.Button
        POSTOPEditField_4               matlab.ui.control.EditField
        CheckBox_6                      matlab.ui.control.CheckBox
        PostopColumnStopEditField_5     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_3  matlab.ui.control.Label
        PostopColumnStartEditField_5    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_4  matlab.ui.control.Label
        STIMTab                         matlab.ui.container.Tab
        ElectrodetypeDropDownLabel      matlab.ui.control.Label
        ElectrodetypeDropDown           matlab.ui.control.DropDown
        PostopColumnStopEditField_4     matlab.ui.control.EditField
        PostopColumnStartEditField_4    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_3  matlab.ui.control.Label
        POSTOPEditField_3               matlab.ui.control.EditField
        CheckBox_5                      matlab.ui.control.CheckBox
    end

  properties (Access = public)
        postop_checked_box
        medon_stimoff
        medon_stimon
        leadgroupid
        fname
        sheet_name
        baseline
        baseline_2
        postop
        postop_2
        baseline_checked_box
        postop2_checked_box
        baseline_start_bdi
        baseline_stop_bdi
        postop_start_bdi
        postop_stop_bdi
        postop_checked_bdi
        baseline_start_medoff
        baseline_stop_medoff
        baseline_start_medon
        baseline_stop_medon
        postop_start_medoff
        postop_stop_medoff
        postop_start_medon
        postop_stop_medon
        
        BASET
        POSTT
        BASET_medoff
        POSTT_medoff
        M
        cdata
        patients
        flag
        global_score
        brady
        rigidity
        axial
        tremor
        create_custom
        create_custom_bdi
        custom_factors
        GLOBAL_ABS_VALS
        GLOBAL_IMP_VALS
        GLOBAL_CLEANED_VALS
        BRADYKINESIA_ABS_VALS
        BRADYKINESIA_IMP_VALS
        BRADYKINESIA_CLEANED_VALS
        RIGIDITY_ABS_VALS
        RIGIDITY_IMP_VALS
        RIGIDITY_CLEANED_VALS
        AXIAL_ABS_VALS
        AXIAL_IMP_VALS
        AXIAL_CLEANED_VALS
        TREMOR_ABS_VALS
        TREMOR_IMP_VALS
        TREMOR_CLEANED_VALS
        CUSTOM_ABS_VALS
        CUSTOM_IMP_VALS
        CUSTOM_CLEANED_VALS
        somatotopy
        all_selected_nodes
        postop_start
        postop_stop
        
    end
    
    
    methods (Access = private)
        
        function [motor_flag,scores] = score_generator(app,flag,to_match,scores)
            if flag == 1
                app.postop_start = app.postop_start_medoff;
                app.postop_stop = app.postop_stop_medoff;
                postop = app.postop;
            elseif flag == 2
                app.postop_start = app.baseline_start_medon;
                app.postop_stop = app.baseline_stop_medon;
                postop = app.baseline_2;
            else
                app.postop_start = app.postop_start_medon;
                app.postop_stop = app.postop_stop_medon;
                postop = app.postop_2;
            end
            
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
               baseline_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
               baseline_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
            else
               baseline_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
               baseline_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
            end
            baseline_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            if ischar(app.postop_start) && ischar(app.postop_stop)
                postop_range = [app.postop_start ':' app.postop_stop];
                postop_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
                %full_range = [app.baseline_start_medoff ':' app.postop_stop]
            else
                postop_range = [1,app.postop_start,length(app.patients)+1,app.postop_stop];
                postop_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
                %full_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.postop_stop];
            end
            postop_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
            postop_tab_names = postop_tab.Properties.VariableNames;
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            if all(isnan(postop_mat(1,:)))
                postop_mat(1,:) = [];
            end
            
            disp("Writing clinical scores to patient directory...")
            app.BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
            app.POSTT =array2table(postop_mat,'VariableNames',postop_tab_names);
            
            %T = readtable(app.fname,'Sheet',app.sheet_name,'Range',full_range);
            %app.cdata.entries = [T.Properties.VariableNames];
            %app.BASET =array2table(baseline_mat,'VariableNames',app.cdata.entries(1:size(baseline_mat,2)));
            %app.POSTT =array2table(postop_mat,'VariableNames',app.cdata.entries(size(baseline_mat,2)+1:end));
            for pt=1:length(app.patients)
                if exist([app.M.M.patient.list{pt},'/clinical/','clinical_scores.mat'],'file')
                       load([app.M.M.patient.list{pt},'/clinical/','clinical_scores.mat'])
                end
                odir=[app.M.M.patient.list{pt},'/clinical/'];
                if ~exist(odir,'dir')
                    ea_mkdir(odir);
                end
               
                BASET_pt = app.BASET(pt,:);
                POSTT_pt = app.POSTT(pt,:);
                identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                
                if isnan(motor_identifier{1,1})
                   
                    motor_flag = 'Motor_UPDRS';
                    scores.Motor_UPDRS.(postop).Global.(app.baseline) = BASET_pt;
                    scores.Motor_UPDRS.(postop).Global.(postop) = POSTT_pt;
                    scores.Motor_UPDRS.(postop).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                    scores.Motor_UPDRS.(postop).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                    app.GLOBAL_ABS_VALS(pt,:) = scores.Motor_UPDRS.(postop).Global.abs_improvements;
                    app.GLOBAL_IMP_VALS(pt,:) = scores.Motor_UPDRS.(postop).Global.perc_improvements;
                    
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                    end
                        
                    brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    brady_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    brady_preop.Total = nansum(brady_preop{:,1:end},2);
                    brady_postop.Total = nansum(brady_postop{:,1:end},2);
                    scores.Motor_UPDRS.(postop).Bradykinesia.(app.baseline) = brady_preop;
                    scores.Motor_UPDRS.(postop).Bradykinesia.(postop) = brady_postop;
                    scores.Motor_UPDRS.(postop).Bradykinesia.abs_improvements = brady_preop.Total - brady_postop.Total;
                    scores.Motor_UPDRS.(postop).Bradykinesia.perc_improvements = ((brady_preop.Total-brady_postop.Total)./brady_preop.Total);
                    app.BRADYKINESIA_ABS_VALS(pt,:) = scores.Motor_UPDRS.(postop).Bradykinesia.abs_improvements;
                    app.BRADYKINESIA_IMP_VALS(pt,:) = scores.Motor_UPDRS.(postop).Bradykinesia.perc_improvements;
                    
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22'));
                    end
                    rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    rigidity_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                    rigidity_postop.Total = nansum(rigidity_postop{:,1:end},2);
                    scores.Motor_UPDRS.(postop).Rigidity.(app.baseline) = rigidity_preop;
                    scores.Motor_UPDRS.(postop).Rigidity.(postop) = rigidity_postop;
                    scores.Motor_UPDRS.(postop).Rigidity.abs_improvements = rigidity_preop.Total - rigidity_postop.Total;
                    scores.Motor_UPDRS.(postop).Rigidity.perc_improvements = ((rigidity_preop.Total-rigidity_postop.Total)./rigidity_preop.Total);
                    app.RIGIDITY_ABS_VALS(pt,:) = scores.Motor_UPDRS.(postop).Rigidity.abs_improvements;
                    app.RIGIDITY_IMP_VALS(pt,:) = scores.Motor_UPDRS.(postop).Rigidity.perc_improvements;
                    
                    
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30'));
                    hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30'));
                    axial_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    axial_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    axial_preop.Total = nansum(axial_preop{:,1:end},2);
                    axial_postop.Total = nansum(axial_postop{:,1:end},2);
                    scores.Motor_UPDRS.(postop).Axial.(app.baseline) = axial_preop;
                    scores.Motor_UPDRS.(postop).Axial.(postop) = axial_postop;
                    scores.Motor_UPDRS.(postop).Axial.abs_improvements = axial_preop.Total - axial_postop.Total;
                    scores.Motor_UPDRS.(postop).Axial.perc_improvements = ((axial_preop.Total-axial_postop.Total)./axial_preop.Total);
                    app.AXIAL_ABS_VALS(pt,:) = scores.Motor_UPDRS.(postop).Axial.abs_improvements;
                    app.AXIAL_IMP_VALS(pt,:) = scores.Motor_UPDRS.(postop).Axial.perc_improvements;
                    
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor'));
                       
                    end
                    
                    tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    tremor_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                    tremor_postop.Total = nansum(tremor_postop{:,1:end},2);
                    scores.Motor_UPDRS.(postop).Tremor.(app.baseline) = tremor_preop;
                    scores.Motor_UPDRS.(postop).Tremor.(postop) = tremor_postop;
                    scores.Motor_UPDRS.(postop).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                    scores.Motor_UPDRS.(postop).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                    app.TREMOR_ABS_VALS(pt,:) = scores.Motor_UPDRS.(postop).Tremor.abs_improvements;
                    app.TREMOR_IMP_VALS(pt,:) = scores.Motor_UPDRS.(postop).Tremor.perc_improvements;
                    
                    if ~strcmp(app.create_custom,'')
                        disp("Witing Custom scores...")
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,to_match));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames,to_match));
                        matched_str = app.BASET.Properties.VariableNames(hasMatch);
                        for i=1:length(matched_str)
                            disp(matched_str{i})
                        end
                        custom_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        custom_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        custom_preop.Total = nansum(custom_preop{:,1:end},2);
                        custom_postop.Total = nansum(custom_postop{:,1:end},2);
                        scores.Motor_UPDRS.(postop).Custom.(app.baseline) = custom_preop;
                        scores.Motor_UPDRS.(postop).Custom.(postop) = custom_postop;
                        scores.Motor_UPDRS.(postop).Custom.abs_improvements = custom_preop.Total - custom_postop.Total;
                        scores.Motor_UPDRS.(postop).Custom.perc_improvements = ((custom_preop.Total-custom_postop.Total)./custom_preop.Total);
                        app.CUSTOM_ABS_VALS(pt,:) = scores.Motor_UPDRS.(postop).Custom.abs_improvements;
                        app.CUSTOM_IMP_VALS(pt,:) = scores.Motor_UPDRS.(postop).Custom.perc_improvements;
                    end
                else %MDS-UPDRS
                    motor_flag = 'Motor_MDSUPDRS';
                    
                    scores.Motor_MDSUPDRS.(postop).Global.(app.baseline) = BASET_pt;
                    scores.Motor_MDSUPDRS.(postop).Global.(postop) = POSTT_pt;
                    scores.Motor_MDSUPDRS.(postop).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                    scores.Motor_MDSUPDRS.(postop).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                    app.GLOBAL_ABS_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Global.abs_improvements;
                    app.GLOBAL_IMP_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Global.perc_improvements;
                    
                    %disp("Writing Bradykinesia MDS-UPDRS sc
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31|MDStoetapping.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31|MDStoetapping.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31|MDStoetapping.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31|MDStoetapping.*Left'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31|MDStoetapping'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31|MDStoetapping.*Left'));
                    end
                    
                    brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    brady_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    brady_preop.Total = nansum(brady_preop{:,1:end},2);
                    brady_postop.Total = nansum(brady_postop{:,1:end},2);
                    scores.Motor_MDSUPDRS.(postop).Bradykinesia.(app.baseline) = brady_preop;
                    scores.Motor_MDSUPDRS.(postop).Bradykinesia.(postop) = brady_postop;
                    scores.Motor_MDSUPDRS.(postop).Bradykinesia.abs_improvements = brady_preop.Total - brady_postop.Total;
                    scores.Motor_MDSUPDRS.(postop).Bradykinesia.perc_improvements = ((brady_preop.Total-brady_postop.Total)./brady_preop.Total);
                    app.BRADYKINESIA_ABS_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Bradykinesia.abs_improvements;
                    app.BRADYKINESIA_IMP_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Bradykinesia.perc_improvements;
                    
                    %disp("Writing Rigidity UPDRS scores...")
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22.*Left'));                        
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22'));

                    end
                    rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    rigidity_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                    rigidity_postop.Total = nansum(rigidity_postop{:,1:end},2);
                    scores.Motor_MDSUPDRS.(postop).Rigidity.(app.baseline) = rigidity_preop;
                    scores.Motor_MDSUPDRS.(postop).Rigidity.(postop) = rigidity_postop;
                    scores.Motor_MDSUPDRS.(postop).Rigidity.abs_improvements = rigidity_preop.Total - rigidity_postop.Total;
                    scores.Motor_MDSUPDRS.(postop).Rigidity.perc_improvements = ((rigidity_preop.Total-rigidity_postop.Total)./rigidity_preop.Total);
                    app.RIGIDITY_ABS_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Rigidity.abs_improvements;
                    app.RIGIDITY_IMP_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Rigidity.perc_improvements;
                    
                    %disp("Writing Axial MDS-UPDRS scores...")
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30|MDSfog'));
                    hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30|MDSfog'));
                    axial_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    axial_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    axial_preop.Total = nansum(axial_preop{:,1:end},2);
                    axial_postop.Total = nansum(axial_postop{:,1:end},2);
                    scores.Motor_MDSUPDRS.(postop).Axial.(app.baseline) = axial_preop;
                    scores.Motor_MDSUPDRS.(postop).Axial.(postop) = axial_postop;
                    scores.Motor_MDSUPDRS.(postop).Axial.abs_improvements = axial_preop.Total - axial_postop.Total;
                    scores.Motor_MDSUPDRS.(postop).Axial.perc_improvements = ((axial_preop.Total-axial_postop.Total)./axial_preop.Total);
                    app.AXIAL_ABS_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Axial.abs_improvements;
                    app.AXIAL_IMP_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Axial.perc_improvements;
                    
                    %disp("Writing Tremor MDS scores...")
                     if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                     elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor'));
                    end
                    
                    tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    tremor_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                    tremor_postop.Total = nansum(tremor_postop{:,1:end},2);
                    scores.Motor_MDSUPDRS.(postop).Tremor.(app.baseline) = tremor_preop;
                    scores.Motor_MDSUPDRS.(postop).Tremor.(postop) = tremor_postop;
                    scores.Motor_MDSUPDRS.(postop).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                    scores.Motor_MDSUPDRS.(postop).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                    app.TREMOR_ABS_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Tremor.abs_improvements;
                    app.TREMOR_IMP_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Tremor.perc_improvements;
                    
                    if ~strcmp(app.create_custom,'')
                        disp("Witing Custom scores...")
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,to_match));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames,to_match));
                        matched_str = app.BASET.Properties.VariableNames(hasMatch);
                        for i=1:length(matched_str)
                            disp(matched_str{i})
                        end
                        custom_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        custom_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        custom_preop.Total = nansum(custom_preop{:,1:end},2);
                        custom_postop.Total = nansum(custom_postop{:,1:end},2);
                        scores.Motor_MDSUPDRS.(postop).Custom.(app.baseline) = custom_preop;
                        scores.Motor_MDSUPDRS.(postop).Custom.(postop) = custom_postop;
                        scores.Motor_MDSUPDRS.(postop).Custom.abs_improvements = custom_preop.Total - custom_postop.Total;
                        scores.Motor_MDSUPDRS.(postop).Custom.perc_improvements = ((custom_preop.Total-custom_postop.Total)./custom_preop.Total);
                        app.CUSTOM_ABS_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Custom.abs_improvements;
                        app.CUSTOM_IMP_VALS(pt,:) = scores.Motor_MDSUPDRS.(postop).Custom.perc_improvements;
                    end
                end
                
                save([odir,'clinical_scores.mat'],'scores'); 
            end
            app.GLOBAL_CLEANED_VALS = ea_resid(app.BASET{:,end},app.GLOBAL_ABS_VALS);
            app.BRADYKINESIA_CLEANED_VALS = ea_resid(app.BASET{:,end},app.BRADYKINESIA_ABS_VALS);
            app.RIGIDITY_CLEANED_VALS = ea_resid(app.BASET{:,end},app.RIGIDITY_ABS_VALS);
            app.AXIAL_CLEANED_VALS = ea_resid(app.BASET{:,end},app.AXIAL_ABS_VALS);
            app.TREMOR_CLEANED_VALS = ea_resid(app.BASET{:,end},app.TREMOR_ABS_VALS);
            if ~strcmp(app.create_custom,'')
                app.CUSTOM_CLEANED_VALS = ea_resid(app.BASET{:,end},app.CUSTOM_ABS_VALS);
            end
            for i = 1:length(app.patients)                
                    load([app.M.M.patient.list{i},'/clinical/','clinical_scores.mat']);
                    odir = [app.M.M.patient.list{i},'/clinical/'];
               if isfield(scores,'Motor_UPDRS')
                    scores.Motor_UPDRS.(postop).Global.cleaned_improvements = app.GLOBAL_CLEANED_VALS(i,:);
                    scores.Motor_UPDRS.(postop).Bradykinesia.cleaned_improvements = app.BRADYKINESIA_CLEANED_VALS(i,:);
                    scores.Motor_UPDRS.(postop).Rigidity.cleaned_improvements = app.RIGIDITY_CLEANED_VALS(i,:);
                    scores.Motor_UPDRS.(postop).Axial.cleaned_improvements = app.AXIAL_CLEANED_VALS(i,:);
                    scores.Motor_UPDRS.(postop).Tremor.cleaned_improvements = app.TREMOR_CLEANED_VALS(i,:);
                    if ~strcmp(app.create_custom,'')
                        scores.Motor_UPDRS.(postop).Custom.cleaned_improvements = app.CUSTOM_CLEANED_VALS(i,:);                       end
               else
                    scores.Motor_MDSUPDRS.(postop).Global.cleaned_improvements = app.GLOBAL_CLEANED_VALS(i,:);
                    scores.Motor_MDSUPDRS.(postop).Bradykinesia.cleaned_improvements = app.BRADYKINESIA_CLEANED_VALS(i,:);
                    scores.Motor_MDSUPDRS.(postop).Rigidity.cleaned_improvements = app.RIGIDITY_CLEANED_VALS(i,:);
                    scores.Motor_MDSUPDRS.(postop).Axial.cleaned_improvements = app.AXIAL_CLEANED_VALS(i,:);
                    scores.Motor_MDSUPDRS.(postop).Tremor.cleaned_improvements = app.TREMOR_CLEANED_VALS(i,:);
                    if ~strcmp(app.create_custom,'')
                        scores.Motor_MDSUPDRS.(postop).Custom.cleaned_improvements = app.CUSTOM_CLEANED_VALS(i,:);
                    end
                end
                save([odir,'clinical_scores.mat'],'scores'); 
            end
        to_disp = ['*' 'Scores saved successfully for all patients' '*'];
        disp(to_disp)  
        return
        end
       
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, excel_sheet, sheet_name)
            app.fname = excel_sheet;
            app.sheet_name = sheet_name;
            
            app.PostopColumnStartEditField.Enable = 'off';
            app.PostopColumnStopEditField.Enable = 'off';
            app.BaselineColumnStartEditField_2.Enable = 'off';
            app.BaselineColumnStopEditField_2.Enable = 'off';
            app.PostopColumnStartEditField_2.Enable = 'off';
            app.PostopColumnStopEditField_2.Enable = 'off';
            app.LOADTOLEADGROUPButton.Visible = 'off';
            
            app.ImportfromEXCELButton_2.Enable = 'off';
            app.ImportfromEXCELButton_3.Enable = 'off';
            app.ImportfromEXCELButton_4.Enable = 'off';
            
        end

        % Callback function
        function MedoffStimoffCheckBoxValueChanged(app, event)
            app.medoff_stimoff = app.MedoffStimoffCheckBox.Value;
            
        end

        % Value changed function: BaselineColumnStartEditField
        function BaselineColumnStartEditFieldValueChanged(app, event)
            app.baseline_start_medoff = app.BaselineColumnStartEditField.Value;
            
        end

        % Value changed function: BaselineColumnStopEditField
        function BaselineColumnStopEditFieldValueChanged(app, event)
             app.baseline_stop_medoff = app.BaselineColumnStopEditField.Value;
            
        end

        % Callback function
        function MedoffStimonCheckBoxValueChanged(app, event)
            app.medoff_stimon = app.MedoffStimonCheckBox.Value;
            if app.medoff_stimon
               app.PostopColumnStartEditField.Enable = 'on';
               app.PostopColumnStopEditField.Enable = 'on'; 
            else
               app.PostopColumnStartEditField.Enable = 'off';
               app.PostopColumnStopEditField.Enable = 'off';  
               
            end
            
        end

        % Value changed function: PostopColumnStartEditField
        function PostopColumnStartEditFieldValueChanged(app, event)
            app.postop_start_medoff = app.PostopColumnStartEditField.Value;
            
        end

        % Value changed function: PostopColumnStopEditField
        function PostopColumnStopEditFieldValueChanged(app, event)
            app.postop_stop_medoff = app.PostopColumnStopEditField.Value;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.baseline_checked_box = app.CheckBox_2.Value;
            if app.baseline_checked_box
                app.BaselineColumnStartEditField_2.Enable = 'on';
                app.BaselineColumnStopEditField_2.Enable = 'on';
                app.ImportfromEXCELButton_3.Enable = 'on';
            else
               app.BaselineColumnStartEditField_2.Enable = 'off';
               app.BaselineColumnStopEditField_2.Enable = 'off';
               app.ImportfromEXCELButton_3.Enable = 'off';
            end
        end

        % Value changed function: BaselineColumnStartEditField_2
        function BaselineColumnStartEditField_2ValueChanged(app, event)
            app.baseline_start_medon = app.BaselineColumnStartEditField_2.Value;
             
        end

        % Value changed function: BaselineColumnStopEditField_2
        function BaselineColumnStopEditField_2ValueChanged(app, event)
            app.baseline_stop_medon = app.BaselineColumnStopEditField_2.Value;
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            app.postop2_checked_box = app.CheckBox_3.Value;
            if app.postop2_checked_box
               app.PostopColumnStartEditField_2.Enable = 'on';
               app.PostopColumnStopEditField_2.Enable = 'on';
               app.ImportfromEXCELButton_4.Enable = 'on';
            else
               app.PostopColumnStartEditField_2.Enable = 'off';
               app.PostopColumnStopEditField_2.Enable = 'off'; 
               app.ImportfromEXCELButton_4.Enable = 'off';
            end
        end

        % Value changed function: PostopColumnStartEditField_2
        function PostopColumnStartEditField_2ValueChanged(app, event)
            app.postop_start_medon = app.PostopColumnStartEditField_2.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_2
        function PostopColumnStopEditField_2ValueChanged(app, event)
            app.postop_stop_medon = app.PostopColumnStopEditField_2.Value;
            
        end

        % Callback function
        function SCORESButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SCORESButtonGroup.SelectedObject;
            %if strcmp(selectedButton,'GLOBAL')
            %    disp("global")
            %end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            app.baseline = app.BASELINEEditField.Value;
            app.postop = app.POSTOPEditField.Value;
            app.baseline_2 = app.EditField.Value;
            app.postop_2 = app.EditField_2.Value;
            app.create_custom = app.CreateCustomTextArea.Value;
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            if ~app.baseline
                disp("Please provide atleast the baseline scores...")
            end
            
            app.M = load([pwd,'/LEAD_groupanalysis.mat']); % need to clarify this
            app.patients = app.M.M.patient.list;   
            app.M.M.guid = app.leadgroupid;
                       
            if ~strcmp(app.create_custom,'')
               factors = app.create_custom;
               if length(factors) == 1
                  to_match = [factors{1}];
               elseif length(factors) == 2
                  to_match = [factors{1} '.*' factors{2}];
               elseif length(factors) == 3
                  to_match = [factors{1} '.*' factors{2} '.*' factors{3}];
               elseif length(factors) == 4
                  to_match = [factors{1} '.*' factors{2} '.*' factors{3} '.*' factors{4}];
               else
                 to_match = [factors{1} '.*' factors{2} '.*' factors{3} '.*' factors{4} '.*' factors{5}];
               end
            end
            if app.BaselineColumnStartEditField.Enable && ~app.PostopColumnStartEditField.Enable && ~app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField_2.Enable
                if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                   baseline_range_medoff = [app.baseline_start_medoff ':' app.baseline_stop_medoff]; 
                   baseline_medoff_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range_medoff);
                   
                else
                    baseline_range_medoff = [1,length(app.patients)+1,app.baseline_start_medoff,app.baseline_stop_medoff];
                    baseline_medoff_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range_medoff);
                   
                end
                baseline_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
                baseline_tab_names = baseline_tab.Properties.VariableNames;
                if all(isnan(baseline_medoff_mat(1,:)))
                    baseline_medoff_mat(1,:) = [];
                end
                %full_range_medoff = baseline_range_medoff; %here the full range is only the baseline range
                %T = readtable(app.fname,'Sheet',app.sheet_name,'Range',full_range_medoff);
                %app.cdata.entries = [T.Properties.VariableNames];
                %%creating cdata
                app.BASET_medoff =array2table(baseline_medoff_mat,'VariableNames',baseline_tab_names);
                for pt=1:length(app.patients)
                    odir=[app.M.M.patient.list{pt},'/clinical/'];
                    if ~exist(odir,'dir')
                       ea_mkdir(odir);
                    end
                    
                    BASET_medoff_pt = app.BASET_medoff(pt,:);    
                    identifier = ~cellfun('isempty', regexpi(BASET_medoff_pt.Properties.VariableNames, 'MDS*'));
                    motor_identifier = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(identifier));
                
                    if isnan(motor_identifier{1,1}) % UPDRS
                        motor_flag = 'Motor_UPDRS';             
                        scores.Motor_UPDRS.(app.baseline).Global.(app.baseline) = BASET_medoff_pt;
                        
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                           
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                        end
                        
                        brady_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));
                        brady_preop_medoff.Total = nansum(brady_preop_medoff{:,1:end},2);
                        scores.Motor_UPDRS.(app.baseline).Bradykinesia.(app.baseline) = brady_preop_medoff;
                        
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22'));
                        end
                        rigidity_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));
                        rigidity_preop_medoff.Total = nansum(rigidity_preop_medoff{:,1:end},2);
                        scores.Motor_UPDRS.(app.baseline).Rigidity.(app.baseline) = rigidity_preop_medoff;
                        
                        hasMatch = ~cellfun('isempty', regexpi(BASET_medoff_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30'));
                        axial_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch));                       
                        axial_preop_medoff.Total = nansum(axial_preop_medoff{:,1:end},2);
                        scores.Motor_UPDRS.(app.baseline).Axial.(app.baseline) = axial_preop_medoff;
                        
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        end
                        tremor_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));                       
                        tremor_preop_medoff.Total = nansum(tremor_preop_medoff{:,1:end},2);
                        scores.Motor_UPDRS.(app.baseline).Tremor.(app.baseline) = tremor_preop_medoff;
                        if app.create_custom
                           disp("Witing Custom scores...")
                           hasMatch_preop = ~cellfun('isempty', regexpi(BASET_medoff_pt.Properties.VariableNames,to_match));
                           matched_str = app.BASET_medoff.Properties.VariableNames(hasMatch);
                           for i=1:length(matched_str)
                               disp(matched_str{i})
                           end   
                           custom_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));                           
                           custom_preop_medoff.Total = nansum(custom_preop_medoff{:,1:end},2);
                           scores.Motor_UPDRS.(app.baseline).Custom.(app.baseline) = custom_preop_medoff;
                        end
                    
                    else
                        motor_flag = 'Motor_MDSUPDRS';
                        scores.Motor_MDSUPDRS.(app.baseline).Global.(app.baseline) = BASET_medoff_pt;
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                        end
                        brady_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));
                        brady_preop_medoff.Total = nansum(brady_preop_medoff{:,1:end},2);
                        scores.Motor_MDSUPDRS.(app.baseline).Bradykinesia.(app.baseline) = brady_preop_medoff;
                        
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22'));
                        end
                        rigidity_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));
                        rigidity_preop_medoff.Total = nansum(rigidity_preop_medoff{:,1:end},2);
                        scores.Motor_MDSUPDRS.(app.baseline).Rigidity.(app.baseline) = rigidity_preop_medoff;
                        
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_medoff_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30|MDSfog'));
                        axial_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));
                        axial_preop_medoff.Total = nansum(axial_preop_medoff{:,1:end},2);
                        scores.Motor_MDSUPDRS.(app.baseline).Axial.(app.baseline) = axial_preop_medoff;
                        
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        end
                        tremor_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));
                        tremor_preop_medoff.Total = nansum(tremor_preop_medoff{:,1:end},2);
                        scores.Motor_MDSUPDRS.(app.baseline).Tremor.(app.baseline) = tremor_preop_medoff;
                        
                        if ~strcmp(app.create_custom,'')
                           disp("Witing Custom scores...")
                           hasMatch_preop = ~cellfun('isempty', regexpi(BASET_medoff_pt.Properties.VariableNames,to_match));
                           matched_str = app.BASET_medoff.Properties.VariableNames(hasMatch_preop);
                           for i=1:length(matched_str)
                               disp(matched_str{i})
                           end   
                           custom_preop_medoff = BASET_medoff_pt(:,BASET_medoff_pt.Properties.VariableNames(hasMatch_preop));                           
                           custom_preop_medoff.Total = nansum(custom_preop_medoff{:,1:end},2);
                           scores.Motor_MDSUPDRS.(app.baseline).Custom.(app.baseline) = custom_preop_medoff;
                        end
                    end
                    save([odir,'clinical_scores.mat'],'scores');
                    
                end
                to_disp = ['*' 'Scores saved successfully for all patient ' '*'];
                disp(to_disp);
            end
            if  app.PostopColumnStartEditField.Enable && ~app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField_2.Enable
                tic
                if strcmp(app.create_custom,'')
                   [motor_flag,scores] = score_generator(app,1,'','');
                   disp("Process Done **")
                   toc
                else
                   [motor_flag,scores] = score_generator(app,1,to_match,'');
                   disp("Process Done **")
                   toc
                end
                
            end
            if app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField.Enable && ~app.PostopColumnStartEditField_2.Enable
                tic
                if strcmp(app.create_custom,'')
                   [motor_flag,scores] = score_generator(app,2,'','');
                   disp("Process Done **")
                   toc
                else
                   [motor_flag,scores] = score_generator(app,2,to_match,'');
                   disp("Process Done **")
                   toc
                end
            end
                    
            if app.PostopColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField.Enable && ~app.BaselineColumnStartEditField_2.Enable
                tic
                if strcmp(app.create_custom,'')
                   [motor_flag,scores] = score_generator(app,3,'','');
                   disp("Process Done **")
                   toc
                else
                   [motor_flag,scores] = score_generator(app,3,to_match,'');
                   disp("Process Done **")
                   toc
                end
            end
            
            if app.PostopColumnStartEditField.Enable && app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField_2.Enable
               tic
               if strcmp(app.create_custom,'')
                   [~,scores] = score_generator(app,1,'','');
               else
                   [~,scores] = score_generator(app,1,to_match,'','');
               end
               if strcmp(app.create_custom,'')
                   [motor_flag,scores] = score_generator(app,2,'',scores);
               else
                   [motor_flag,scores] = score_generator(app,2,to_match,scores);
               end
               disp("Process Done **")
               toc
           
            end   
            
            if app.PostopColumnStartEditField.Enable && app.BaselineColumnStartEditField_2.Enable && app.PostopColumnStartEditField_2.Enable
               tic
               if strcmp(app.create_custom,'')
                   [~,scores] = score_generator(app,1,'','');
               else
                   [~,scores] = score_generator(app,1,to_match,'');
               end
               if strcmp(app.create_custom,'')
                   [~,scores] = score_generator(app,2,'',scores);
               else
                   [~,scores] = score_generator(app,2,to_match,scores);
               end
               if strcmp(app.create_custom,'')
                   [motor_flag,scores] = score_generator(app,3,'',scores); 
               else
                   [motor_flag,scores] = score_generator(app,3,to_match,scores); 
               end 
               disp("Process Done **")
               toc
            end   
            if strcmp(motor_flag,'Motor_UPDRS')
                app.M.M.postopid = fieldnames(scores.Motor_UPDRS);
            else
                app.M.M.postopid = fieldnames(scores.Motor_MDSUPDRS); 
            end
            M = app.M.M;
            save([app.M.M.root,'/LEAD_groupanalysis.mat'],'M');              
                
        end

        % Callback function
        function GLOBALCheckBoxValueChanged(app, event)
            app.global_score = app.GLOBALCheckBox.Value;
            
        end

        % Callback function
        function AXIALCheckBoxValueChanged(app, event)
            app.axial = app.AXIALCheckBox.Value;
            
        end

        % Callback function
        function RIGIDITYCheckBoxValueChanged(app, event)
            app.rigidity = app.RIGIDITYCheckBox.Value;
            
        end

        % Callback function
        function BRADYKINESIACheckBoxValueChanged(app, event)
            app.brady = app.BRADYKINESIACheckBox.Value;
            
        end

        % Callback function
        function TREMORCheckBoxValueChanged(app, event)
            app.tremor = app.TREMORCheckBox.Value;
            
        end

        % Button pushed function: LOADTOLEADGROUPButton
        function LOADTOLEADGROUPButtonPushed(app, event)
            
        end

        % Callback function
        function CREATECUSTOMSUBSCOREButtonPushed(app, event)
           
        end

        % Callback function
        function CreateCustomSubscoreEditFieldValueChanged(app, event)
            %app.create_custom = app.CreateCustomSubscoreEditField.Value;
        end

        % Value changed function: CreateCustomTextArea
        function CreateCustomTextAreaValueChanged(app, event)
            app.create_custom = app.CreateCustomTextArea.Value;
            
        end

        % Callback function
        function OKButtonPushed(app, event)
           
        end

        % Button pushed function: ExportasExcelButton
        function ExportasExcelButtonPushed(app, event)
            global_abs_table = array2table(app.GLOBAL_ABS_VALS,'VariableNames',{'GLOBAL ABSOLUTE VALUES'});
            global_imp_table = array2table(app.GLOBAL_IMP_VALS,'VariableNames',{'GLOBAL IMPROVEMENT VALUES'});
            global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS,'VariableNames',{'GLOBAL CLEANED VALUES'});
            bradykinesia_abs_table = array2table(app.BRADYKINESIA_ABS_VALS,'VariableNames',{'BRADYKINESIA ABSOLUTE VALUES'});
            bradykinesia_imp_table = array2table(app.BRADYKINESIA_IMP_VALS,'VariableNames',{'BRADYKINESIA IMPROVEMENT VALUES'});
            bradykinesia_cleaned_tables = array2table(app.BRADYKINESIA_CLEANED_VALS,'VariableNames',{'BRADYKINESIA CLEANED VALUES'});
            rigidity_abs_table = array2table(app.RIGIDITY_ABS_VALS,'VariableNames',{'RIGIDITY ABSOLUTE VALUES'});
            rigidity_imp_table = array2table(app.RIGIDITY_IMP_VALS,'VariableNames',{'RIGIDITY IMPROVEMENT VALUES'});
            rigidity_cleaned_tables = array2table(app.RIGIDITY_CLEANED_VALS,'VariableNames',{'RIGIDITY CLEANED VALUES'});
            axial_abs_table = array2table(app.AXIAL_ABS_VALS,'VariableNames',{'AXIAL ABSOLUTE VALUES'});
            axial_imp_table = array2table(app.AXIAL_IMP_VALS,'VariableNames',{'AXIAL IMPROVEMENT VALUES'});
            axial_cleaned_tables = array2table(app.AXIAL_CLEANED_VALS,'VariableNames',{'AXIAL CLEANED VALUES'});
            tremor_abs_table = array2table(app.TREMOR_ABS_VALS,'VariableNames',{'TREMOR ABSOLUTE VALUES'});
            tremor_imp_table = array2table(app.TREMOR_IMP_VALS,'VariableNames',{'TREMOR IMPROVEMENT VALUES'});
            tremor_cleaned_tables = array2table(app.TREMOR_CLEANED_VALS,'VariableNames',{'TREMOR CLEANED VALUES'});
            if ~strcmp(app.create_custom,'')
                custom_abs_table = array2table(app.CUSTOM_ABS_VALS,'VariableNames',{'CUSTOM ABSOLUTE VALUES'});
                custom_imp_table = array2table(app.CUSTOM_IMP_VALS,'VariableNames',{'CUSTOM IMPROVEMENT VALUES'});
                custom_cleaned_tables = array2table(app.CUSTOM_CLEANED_VALS,'VariableNames',{'CUSTOM CLEANED VALUES'});
            end
            full_table = [app.BASET,app.POSTT,global_abs_table,global_imp_table,global_cleaned_tables];
            if ~strcmp(app.create_custom,'')
                subscores_table = [bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables,custom_abs_table,custom_imp_table,custom_cleaned_tables];
            else
                subscores_table = [bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables];
            end
           
            writetable(full_table,uiputfile('cleaned_clinical_scores.xlsx'));
            writetable(subscores_table,uiputfile('cleaned_clinical_subscores.xlsx'));


        end

        % Value changed function: BASELINEEditField
        function BASELINEEditFieldValueChanged(app, event)
            app.baseline = app.BASELINEEditField.Value;
            app.BASELINEEditField.Tooltip = {'Please enter an alpha numeric value WITHOUT any spaces and extra characters.'};
            if app.baseline
                app.BaselineColumnStartEditField.Enable = 'on';
                app.BaselineColumnStopEditField.Enable = 'on';
            else
                app.BaselineColumnStartEditField.Enable = 'off';
                app.BaselineColumnStopEditField.Enable = 'off';
            end
        end

        % Value changed function: POSTOPEditField
        function POSTOPEditFieldValueChanged(app, event)
            app.postop = app.POSTOPEditField.Value;
            app.POSTOPEditField.Tooltip = {'Please enter an alpha numeric value WITHOUT any spaces and extra characters.'};
        end

        % Value changed function: LEADGROUPIDEditField
        function LEADGROUPIDEditFieldValueChanged(app, event)
            app.leadgroupid = app.LEADGROUPIDEditField.Value;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.postop_checked_box = app.CheckBox.Value;
            
            if app.postop_checked_box
               app.PostopColumnStartEditField.Enable = 'on';
               app.PostopColumnStopEditField.Enable = 'on'; 
               app.ImportfromEXCELButton_2.Enable = 'on';
            else
               app.PostopColumnStartEditField.Enable = 'off';
               app.PostopColumnStopEditField.Enable = 'off';  
               app.ImportfromEXCELButton_2.Enable = 'off';
               
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.baseline_2 = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.postop_2 = app.EditField_2.Value;
            
        end

        % Value changed function: BASELINEEditField_2
        function BASELINEEditField_2ValueChanged(app, event)
            app.baseline_bdi = app.BASELINEEditField_2.Value;
            
        end

        % Value changed function: BaselineColumnStartEditField_3
        function BaselineColumnStartEditField_3ValueChanged(app, event)
            app.baseline_start_bdi = app.BaselineColumnStartEditField_3.Value;
            
        end

        % Value changed function: BaselineColumnStopEditField_3
        function BaselineColumnStopEditField_3ValueChanged(app, event)
            app.baseline_stop_bdi = app.BaselineColumnStopEditField_3.Value;
            
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            app.postop_checked_bdi = app.CheckBox_4.Value;
            
        end

        % Callback function
        function CreateCustomTextArea_2ValueChanged(app, event)
            app.create_custom_bdi = app.CreateCustomTextArea_2.Value;
            
        end

        % Callback function
        function SomatotopyDropDownValueChanged(app, event)
            %app.somatotopy = app.SomatotopyDropDown.Value;
            
        end

        % Selection changed function: LateralityButtonGroup
        function LateralityButtonGroupSelectionChanged(app, event)
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ImportfromEXCELButton
        function ImportfromEXCELButtonPushed(app, event)
            
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            
            cbt = uitree(fig,"Multiselect",'on','Position',[150 100 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
      
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.baseline_start_medoff = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                %app.baseline_start_medoff =  app.all_selected_nodes(1).Text;
                %app.baseline_stop_medoff = app.all_selected_nodes(end).Text;
                app.baseline_stop_medoff = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
            
            
        end

        % Button pushed function: ImportfromEXCELButton_2
        function ImportfromEXCELButton_2Pushed(app, event)
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            
            cbt = uitree(fig,"Multiselect",'on','Position',[150 100 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.postop_start_medoff = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.postop_stop_medoff = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                %app.postop_start_medoff =  app.all_selected_nodes(1).Text;
                %app.postop_stop_medoff = app.all_selected_nodes(end).Text;
                close(fig)
            end
             
        end

        % Button pushed function: ImportfromEXCELButton_3
        function ImportfromEXCELButton_3Pushed(app, event)
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            
            cbt = uitree(fig,"Multiselect",'on','Position',[150 100 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.baseline_start_medon = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.baseline_stop_medon = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                %app.baseline_start_medon =  app.all_selected_nodes(1).Text;
                %app.baseline_stop_medon = app.all_selected_nodes(end).Text;
                close(fig)
            end
             
        end

        % Button pushed function: ImportfromEXCELButton_4
        function ImportfromEXCELButton_4Pushed(app, event)
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            
            cbt = uitree(fig,"Multiselect",'on','Position',[150 100 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.postop_start_medon = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.postop_stop_medoff = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UPDRSTab = uitab(app.TabGroup);
            app.UPDRSTab.Title = 'UPDRS';
            app.CreateCustomTextArea = uitextarea(app.UPDRSTab);
            app.CreateCustomTextArea.ValueChangedFcn = createCallbackFcn(app, @CreateCustomTextAreaValueChanged, true);
            app.CreateCustomClinicalScoresLabel = uilabel(app.UPDRSTab);
            app.CreateCustomClinicalScoresLabel.Text = 'Create Custom Clinical Scores';
            app.GENERATEButton = uibutton(app.UPDRSTab, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.LOADTOLEADGROUPButton = uibutton(app.UPDRSTab, 'push');
            app.LOADTOLEADGROUPButton.ButtonPushedFcn = createCallbackFcn(app, @LOADTOLEADGROUPButtonPushed, true);
            app.LOADTOLEADGROUPButton.Text = 'LOAD TO LEAD GROUP';
            app.ExportasExcelButton = uibutton(app.UPDRSTab, 'push');
            app.ExportasExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasExcelButtonPushed, true);
            app.ExportasExcelButton.Text = 'Export as Excel';
            app.PostopColumnStopEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStopEditField_2.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_2ValueChanged, true);
            app.PostopColumnStopEditField_2Label = uilabel(app.UPDRSTab);
            app.PostopColumnStopEditField_2Label.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_2ValueChanged, true);
            app.PostopColumnStartEditField_2Label = uilabel(app.UPDRSTab);
            app.PostopColumnStartEditField_2Label.Text = 'Postop Column Start';
            app.BaselineColumnStopEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStopEditField_2.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStopEditField_2ValueChanged, true);
            app.BaselineColumnStopEditField_2Label = uilabel(app.UPDRSTab);
            app.BaselineColumnStopEditField_2Label.Text = 'Postop Column Stop';
            app.BaselineColumnStartEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStartEditField_2ValueChanged, true);
            app.BaselineColumnStartEditField_9Label = uilabel(app.UPDRSTab);
            app.BaselineColumnStartEditField_9Label.Text = 'Postop Column Start';
            app.PostopColumnStopEditField = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStopEditField.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditFieldValueChanged, true);
            app.PostopColumnStopEditFieldLabel = uilabel(app.UPDRSTab);
            app.PostopColumnStopEditFieldLabel.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStartEditField.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditFieldValueChanged, true);
            app.PostopColumnStartEditFieldLabel = uilabel(app.UPDRSTab);
            app.PostopColumnStartEditFieldLabel.Text = 'Postop Column Start';
            app.BaselineColumnStopEditField = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStopEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStopEditFieldValueChanged, true);
            app.BaselineColumnStopEditFieldLabel = uilabel(app.UPDRSTab);
            app.BaselineColumnStopEditFieldLabel.Text = 'Baseline Column Stop';
            app.CheckBox_3 = uicheckbox(app.UPDRSTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_2 = uicheckbox(app.UPDRSTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.BaselineColumnStartEditField = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStartEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStartEditFieldValueChanged, true);
            app.BaselineColumnStartEditFieldLabel = uilabel(app.UPDRSTab);
            app.BaselineColumnStartEditFieldLabel.Text = 'Baseline Column Start';
            app.BASELINEEditField = uieditfield(app.UPDRSTab, 'text');
            app.BASELINEEditField.ValueChangedFcn = createCallbackFcn(app, @BASELINEEditFieldValueChanged, true);
            app.BASELINEEditField.Value = 'Baseline';
            app.LEADGROUPIDEditFieldLabel = uilabel(app.UPDRSTab);
            app.LEADGROUPIDEditFieldLabel.Text = 'LEAD-GROUP ID';
            app.LEADGROUPIDEditField = uieditfield(app.UPDRSTab, 'text');
            app.LEADGROUPIDEditField.ValueChangedFcn = createCallbackFcn(app, @LEADGROUPIDEditFieldValueChanged, true);
            app.LEADGROUPIDEditField.Value = 'analysis_one';
            app.POSTOPEditField = uieditfield(app.UPDRSTab, 'text');
            app.POSTOPEditField.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditFieldValueChanged, true);
            app.POSTOPEditField.Value = 'Postop';
            app.CheckBox = uicheckbox(app.UPDRSTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.EditField = uieditfield(app.UPDRSTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 'postop2';
            app.EditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 'Postop3';
            app.LateralityButtonGroup = uibuttongroup(app.UPDRSTab);
            app.LateralityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LateralityButtonGroupSelectionChanged, true);
            app.LateralityButtonGroup.Title = 'Laterality';
            app.BilateralButton = uiradiobutton(app.LateralityButtonGroup);
            app.BilateralButton.Text = 'Bilateral';
            app.BilateralButton.Value = true;
            app.RightButton = uiradiobutton(app.LateralityButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftButton = uiradiobutton(app.LateralityButtonGroup);
            app.LeftButton.Text = 'Left';
            app.ImportfromEXCELButton = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButtonPushed, true);
            app.ImportfromEXCELButton.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_2 = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_2Pushed, true);
            app.ImportfromEXCELButton_2.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_3 = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_3Pushed, true);
            app.ImportfromEXCELButton_3.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_4 = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_4Pushed, true);
            app.ImportfromEXCELButton_4.Text = 'Import from EXCEL';
            app.BDITab = uitab(app.TabGroup);
            app.BDITab.Title = 'BDI';
            app.PostopColumnStopEditField_3 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStopEditFieldLabel_2 = uilabel(app.BDITab);
            app.PostopColumnStopEditFieldLabel_2.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_3 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStartEditFieldLabel_2 = uilabel(app.BDITab);
            app.PostopColumnStartEditFieldLabel_2.Text = 'Postop Column Start';
            app.BaselineColumnStopEditField_3 = uieditfield(app.BDITab, 'text');
            app.BaselineColumnStopEditField_3.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStopEditField_3ValueChanged, true);
            app.BaselineColumnStopEditFieldLabel_2 = uilabel(app.BDITab);
            app.BaselineColumnStopEditFieldLabel_2.Text = 'Baseline Column Stop';
            app.BaselineColumnStartEditField_3 = uieditfield(app.BDITab, 'text');
            app.BaselineColumnStartEditField_3.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStartEditField_3ValueChanged, true);
            app.BaselineColumnStartEditFieldLabel_2 = uilabel(app.BDITab);
            app.BaselineColumnStartEditFieldLabel_2.Text = 'Baseline Column Start';
            app.BASELINEEditField_2 = uieditfield(app.BDITab, 'text');
            app.BASELINEEditField_2.ValueChangedFcn = createCallbackFcn(app, @BASELINEEditField_2ValueChanged, true);
            app.BASELINEEditField_2.Value = 'Baseline';
            app.POSTOPEditField_2 = uieditfield(app.BDITab, 'text');
            app.POSTOPEditField_2.Value = 'Postop';
            app.CheckBox_4 = uicheckbox(app.BDITab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.GENERATEButton_2 = uibutton(app.BDITab, 'push');
            app.GENERATEButton_2.Text = 'GENERATE';
            app.ExportasExcelButton_2 = uibutton(app.BDITab, 'push');
            app.ExportasExcelButton_2.Text = 'Export as Excel';
            app.POSTOPEditField_4 = uieditfield(app.BDITab, 'text');
            app.POSTOPEditField_4.Value = 'Custom';
            app.CheckBox_6 = uicheckbox(app.BDITab);
            app.CheckBox_6.Text = '';
            app.PostopColumnStopEditField_5 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStopEditFieldLabel_3 = uilabel(app.BDITab);
            app.PostopColumnStopEditFieldLabel_3.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_5 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStartEditFieldLabel_4 = uilabel(app.BDITab);
            app.PostopColumnStartEditFieldLabel_4.Text = 'Postop Column Start';
            app.STIMTab = uitab(app.TabGroup);
            app.STIMTab.Title = 'STIM';
            app.ElectrodetypeDropDownLabel = uilabel(app.STIMTab);
            app.ElectrodetypeDropDownLabel.Text = 'Electrode type';
            app.ElectrodetypeDropDown = uidropdown(app.STIMTab);
            app.ElectrodetypeDropDown.Items = {'Medtronic 3387', 'Medtronic 3389', 'Boston ', 'St.Jude'};
            app.ElectrodetypeDropDown.Value = 'Medtronic 3387';
            app.PostopColumnStopEditField_4 = uieditfield(app.STIMTab, 'text');
            app.PostopColumnStopEditField_4.Value = 'Left settings ';
            app.PostopColumnStartEditField_4 = uieditfield(app.STIMTab, 'text');
            app.PostopColumnStartEditField_4.Value = 'Right setting';
            app.PostopColumnStartEditFieldLabel_3 = uilabel(app.STIMTab);
            app.PostopColumnStartEditFieldLabel_3.Text = '';
            app.POSTOPEditField_3 = uieditfield(app.STIMTab, 'text');
            app.POSTOPEditField_3.Value = 'Postop';
            app.CheckBox_5 = uicheckbox(app.STIMTab);
            app.CheckBox_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clinical_score_generator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end