classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshInformationfromLeadGroupprojectMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        MultitractAnalysisPanel         matlab.ui.container.Panel
        VarianceExpSlider               matlab.ui.control.Slider
        VarianceExpSliderLabel          matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        PCsEditField                    matlab.ui.control.NumericEditField
        PCsEditFieldLabel               matlab.ui.control.Label
        SubscoresListBox                matlab.ui.control.ListBox
        ModelSetupOptionButtonGroup     matlab.ui.container.ButtonGroup
        SplitColorByPCAButton           matlab.ui.control.RadioButton
        SplitColorBySubscoreButton      matlab.ui.control.RadioButton
        SplitColorByGroupButton         matlab.ui.control.RadioButton
        SingleTractAnalysisButton       matlab.ui.control.RadioButton
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        MultitractWeightsButton         matlab.ui.control.Button
        ModeltypeDropDown               matlab.ui.control.DropDown
        FittoscoresCheckBox             matlab.ui.control.CheckBox
        BinsEditField                   matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        ModelNormalizationDropDown      matlab.ui.control.DropDown
        ModelNormalizationDropDownLabel  matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        MultTestStrategy                matlab.ui.control.DropDown
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantFibersonlyCheckBox  matlab.ui.control.CheckBox
        RightSliderNegative             matlab.ui.control.Slider
        RightSlider_2Label              matlab.ui.control.Label
        ShowAmountSliderNegative        matlab.ui.control.Slider
        ShowAmountSlider_2Label         matlab.ui.control.Label
        LeftSliderNegative              matlab.ui.control.Slider
        LeftSlider_2Label               matlab.ui.control.Label
        RightSliderPositive             matlab.ui.control.Slider
        RightSliderLabel                matlab.ui.control.Label
        LeftSliderPositive              matlab.ui.control.Slider
        LeftSliderLabel                 matlab.ui.control.Label
        ShowAmountSliderPositive        matlab.ui.control.Slider
        ShowAmountSliderLabel           matlab.ui.control.Label
        ExportasAtlasButton             matlab.ui.control.Button
        ShowNegativeFibersCheckBox      matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox      matlab.ui.control.CheckBox
        ColorButtonNegative             matlab.ui.control.Button
        ColorButtonPositive             matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        Corrtype                        matlab.ui.control.DropDown
        ModelSetupDropDown              matlab.ui.control.DropDown
        ModelSetupDropDownLabel         matlab.ui.control.Label
        Efieldmetric                    matlab.ui.control.DropDown
        CovariatesCheckBox              matlab.ui.control.CheckBox
        EfieldthresholdLabel            matlab.ui.control.Label
        EfieldthresholdSlider           matlab.ui.control.Slider
        ConnthresholdLabel              matlab.ui.control.Label
        ConnthresholdSlider             matlab.ui.control.Slider
        CovariatesListBox               matlab.ui.control.ListBox
        VariableofInterestDropDown      matlab.ui.control.DropDown
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        VmLabel                         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ConnectomeDropDown              matlab.ui.control.DropDown
        ConnectomeDropDownLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        tractset % associated ea_disctract object.
    end


    methods (Access = private)

        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.tractset.colorbar)
                if ~ismember(app.tractset.multitractmode,{'Split & Color By Group','Split & Color By Subscore'})
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                else
                    app.ColorBar.Visible = 'on';
                    cb = app.tractset.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                end
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end

        function results = enablerules(app)
            app.isbusy(1);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val='off';
            else
                val=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            try
                numsides=numel(app.tractset.results.(ea_conn2connid(app.tractset.connectome)).(ea_method2methodid(app.tractset)).fibsval);
            catch % default
                numsides=2;
            end
            % enable controls:
            app.ModelSetupDropDown.Enable = val;
            app.ModelSetupDropDownLabel.Enable = val;


            app.MirrorSidesCheckBox.Enable = val;
            app.VariableofInterestDropDown.Enable = val;

            selectedButton = app.ModelSetupOptionButtonGroup.SelectedObject;


            groupsassigned=(length(unique(app.tractset.M.patient.group))>1);
            if groupsassigned
                app.SplitColorByGroupButton.Enable = val;
            else
                app.SplitColorByGroupButton.Enable = 'off';
                app.SplitColorByGroupButton.Value = 0;
            end

            app.SplitColorBySubscoreButton.Enable = val;
            app.SingleTractAnalysisButton.Enable = val;
            app.SplitColorByPCAButton.Enable = val;
            app.ModelSetupOptionButtonGroup.Enable = val;

            app.ShowPositiveFibersCheckBox.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;
            app.ShowAmountSliderPositive.Enable = val;
            app.LeftSliderPositive.Enable = val;
            app.RightSliderPositive.Enable = val;
            app.ColorButtonPositive.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;

            app.ConnthresholdSlider.Enable = val;
            app.ConnthresholdLabel.Enable = val;
            app.ShowAmountSliderLabel.Enable = val;
            app.LeftSliderLabel.Enable = val;
            app.RightSliderLabel.Enable = val;
            app.VariableofInterestDropDownLabel.Enable = val;
            app.CovariatesCheckBox.Enable = val;

            if app.SplitColorByGroupButton.Value
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
                app.ExportasAtlasButton.Enable = 'off';
                cvEnable = 'off';
            else
                app.ColorBar.Visible = val;
                if strcmp(val, 'off')
                    cla(app.ColorBar);
                end
                app.ExportasAtlasButton.Enable = val;
                cvEnable = val;
            end
            %rules for multitract analysis
            %TODO make button group compatible for 2020a
            %  app.ModelSetupOptionButtonGroup.Enable = val;
            app.SubscoresListBox.Enable = val;

            app.MultitractWeightsButton.Enable = val;


            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ModelNormalizationDropDown.Enable = cvEnable;
            app.ModelNormalizationDropDownLabel.Enable = cvEnable;


            switch app.BasePredictiononDropDown.Value
                case {'Histogram','z-scored Histogram'}
                    app.BinsEditField.Enable = cvEnable;
                    app.BinsEditFieldLabel.Enable = cvEnable;
                otherwise
                    app.BinsEditField.Enable = 'off';
                    app.BinsEditFieldLabel.Enable = 'off';
            end

            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.tractset.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    app.TrainOnListBox.Items=app.tractset.M.patient.list;
                    app.PredictListBox.Items=app.tractset.M.patient.list;
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                    end
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                        app.PredictListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.tractset.setlabels;
                    app.PredictListBox.Items=app.tractset.setlabels;
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end

                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end

            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            app.ModeltypeDropDown.Enable = cvlEnable;

            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;

            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson'})
                app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                app.ConsiderSignificantFibersonlyCheckBox.Enable = val;
                app.levelEditField.Enable = val;
                app.levelEditFieldLabel.Enable = val;
                app.MultTestStrategy.Enable = val;
            end

            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson','Bend'})
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesCheckBox.Value = 0;
            end

            app.BasePredictiononDropDown.Enable = val;
            app.BasePredictiononDropDownLabel.Enable = val;


            app.FittoscoresCheckBox.Enable = cvEnable;

            if app.SplitColorByPCAButton.Value % when doing a PCA based prediction, we need to fit back tract estimates to responsevars.
                app.FittoscoresCheckBox.Value = 1;
                app.FittoscoresCheckBox.Enable = 'off';
                app.tractset.doactualprediction = 1; % redundancy just to make sure in fact this should not be needed.
            end

            switch app.FittoscoresCheckBox.Value
                case 1
                    app.ModeltypeDropDown.Enable = cvEnable;
                case 0
                    app.ModeltypeDropDown.Enable = 'off';
            end

            if app.CovariatesCheckBox.Value
                app.CovariatesListBox.Enable = val;
            else
                app.CovariatesListBox.Enable = 'off';
            end

            switch app.ModelSetupDropDown.Value
                case {'T-Tests / VTAs (Baldermann 2019)','T-Tests / OSS-DBS Pathway ActivationsT-Tests / OSS-DBS Pathway Activations'}
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.EfieldthresholdSlider.Enable = val;
                    app.EfieldthresholdLabel.Enable = val;
                    app.Efieldmetric.Enable = val;
                    app.Corrtype.Enable = val;
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'Dice Coeffs / VTAs (binary vars)'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    app.EfieldthresholdSlider.Enable = val;
                    app.EfieldthresholdLabel.Enable = val;
                    app.Efieldmetric.Enable = val;
                    app.Corrtype.Enable = val;
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'Plain Connections'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.CovariatesCheckBox.Value = 0;
                    app.CovariatesCheckBox.Enable = 'off';
                    app.CovariatesListBox.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                    app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                    app.levelEditField.Enable = 'off';
                    app.MultTestStrategy.Enable = 'off';
                    app.ShowNegativeFibersCheckBox.Value = 0;
                    app.ShowNegativeFibersCheckBox.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Sum of Scores','Mean of Scores','Peak of Scores','Peak 5% of Scores'};
                    if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                    app.PosthoccorrectforgroupCheckBox.Value = 0;
            end

            app.PatientsListBox.Enable=val;

            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.tractset.setlabels];

            switch app.ShowNegativeFibersCheckBox.Value
                case 0
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountSlider_2Label.Enable = 'off';

                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';

                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';

                case 1
                    app.ShowAmountSliderNegative.Enable = val;
                    app.ColorButtonNegative.Enable = val;
                    app.ShowAmountSlider_2Label.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.LeftSliderNegative.Enable = val;
                        app.RightSliderNegative.Enable = val;

                        app.LeftSlider_2Label.Enable = val;
                        app.RightSlider_2Label.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.LeftSliderNegative.Enable = 'off';
                        app.RightSliderNegative.Enable = 'off';

                        app.LeftSlider_2Label.Enable = 'off';
                        app.RightSlider_2Label.Enable = 'off';

                    end
            end

            switch app.ShowPositiveFibersCheckBox.Value
                case 0
                    app.ShowAmountSliderPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.LeftSliderPositive.Enable = 'off';
                    app.RightSliderPositive.Enable = 'off';

                    app.ShowAmountSliderLabel.Enable = 'off';
                    app.LeftSliderLabel.Enable = 'off';
                    app.RightSliderLabel.Enable = 'off';
                case 1
                    app.ShowAmountSliderPositive.Enable = val;
                    app.ColorButtonPositive.Enable = val;
                    app.ShowAmountSliderLabel.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.LeftSliderPositive.Enable = val;
                        app.RightSliderPositive.Enable = val;

                        app.LeftSliderLabel.Enable = val;
                        app.RightSliderLabel.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.LeftSliderPositive.Enable = 'off';
                        app.RightSliderPositive.Enable = 'off';
                        app.LeftSliderLabel.Enable = 'off';
                        app.RightSliderLabel.Enable = 'off';
                    end

            end

            % Logic of multitract panel radio buttons - enabling of pca panel is
            % handled in updatepca()
            if app.SingleTractAnalysisButton.Value
                app.ColorButtonPositive.Enable = val;
                app.ColorButtonNegative.Enable = val;
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = val;
                app.CovariatesListBox.Enable = val;
                app.MultitractWeightsButton.Enable = 'off';
            end
            if app.SplitColorByGroupButton.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = val;
                app.CovariatesListBox.Enable = val;
                app.MultitractWeightsButton.Enable = 'off';
            end
            if app.SplitColorBySubscoreButton.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
                app.SubscoresListBox.Enable = val;
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesListBox.Enable = 'off';
                app.MultitractWeightsButton.Enable = cvEnable;
            end
            if app.SplitColorByPCAButton.Value
                app.ColorButtonPositive.Enable = val;
                app.ColorButtonNegative.Enable = val;
                app.SubscoresListBox.Enable = val;
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesListBox.Enable = 'off';
                app.MultitractWeightsButton.Enable = 'off';
            end

            app.updatepca;

            % turn light back to green
            app.isbusy(0);
        end


        function results = tractset2UI(app)
            app.isbusy(1);

            app.ModelSetupDropDown.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';

            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.tractset.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.tractset.leadgroup;
            app.AnalysisIDEditField.Value = app.tractset.ID;
            switch app.tractset.statmetric
                case 1
                    app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,49];
                case 2
                    app.ModelSetupDropDown.Value = 'Correlations / E-fields (Irmen 2020)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [0,100];
                case 3
                    app.ModelSetupDropDown.Value = 'T-Tests / OSS-DBS Pathway Activations';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,49];
                case 4
                    app.ModelSetupDropDown.Value = 'Dice Coeffs / VTAs (binary vars)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,49];
                case 5
                    app.ModelSetupDropDown.Value = 'Reverse T-Tests / E-Fields (binary vars)';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [0,100];
                case 6
                    app.ModelSetupDropDown.Value = 'Plain Connections';
                    if isfield(app.tractset.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
                    end
                    app.ConnthresholdSlider.Limits = [1,100];
            end

            app.Corrtype.Value=app.tractset.corrtype;
            app.ConsiderSignificantFibersonlyCheckBox.Value = app.tractset.showsignificantonly;
            app.levelEditField.Value = (app.tractset.alphalevel);
            app.MultTestStrategy.Value = app.tractset.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if ~isempty(app.tractset.connectome) && ismember(app.tractset.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.tractset.connectome;
            end

            app.MirrorSidesCheckBox.Value = app.tractset.mirrorsides;

            app.PatientsListBox.Items=app.tractset.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.tractset.setlabels];
            for set=1:length(app.tractset.setlabels)
                if isequal(app.tractset.patientselection,find(app.tractset.setselections{set}))
                    app.SubcohortsDropDown.Value=app.tractset.setlabels{set};
                end
            end
            if isequal(app.tractset.patientselection,1:length(app.tractset.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end

            app.Efieldmetric.Value=app.tractset.efieldmetric;

            % setup calculation threshold (only used when calculating
            % fibers for a new connectome).
            if ~isempty(app.tractset.calcthreshold)
                app.ThresholdEditField.Value = app.tractset.calcthreshold;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                end
            else
                prefs=ea_prefs;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                    app.ThresholdEditField.Value=0;
                else
                    app.ThresholdEditField.Value=prefs.machine.vatsettings.horn_ethresh*1000; % set to a default value based on prefs.
                end
            end

            if ~isempty(app.tractset.efieldthreshold)
                app.setefieldsliders;
                if strcmp(app.Efieldmetric.Value,'Mean')
                    app.EfieldthresholdSlider.Value = app.tractset.efieldthreshold;
                else
                    app.EfieldthresholdSlider.Value = app.tractset.efieldthreshold/1000;
                end
            end

            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};

            app.ConnthresholdSlider.Value = app.tractset.connthreshold;

            switch app.tractset.statmetric
                case {1,4}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' %'],['and < ',sprintf('%.1f',100-app.ConnthresholdSlider.Value),' % of ',bubbles]};
                case {2,5}
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                case 6
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
            end

            app.PosthoccorrectforgroupCheckBox.Value = app.tractset.adjustforgroups;

            app.VariableofInterestDropDown.Items = app.tractset.M.clinical.labels;
            app.VariableofInterestDropDown.Value = app.tractset.responsevarlabel;

            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.SubscoresListBox.Items = app.tractset.M.clinical.labels;
            app.SubscoresListBox.Value = app.tractset.subscore.labels;

            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];

            app.CovariatesListBox.Value=app.tractset.covarlabels;

            if ~isempty(app.tractset.results)
                app.CovariatesCheckBox.Value=~isempty(app.tractset.covarlabels);
            end
            switch app.tractset.multitractmode
                case 'Single Tract Analysis'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 1;
                case 'Split & Color By Group'
                    app.SplitColorByGroupButton.Value = 1;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By PCA'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 1;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By Subscore'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 1;
                    app.SingleTractAnalysisButton.Value = 0;
            end


            app.PCsEditField.Value = app.tractset.numpcs;

            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.ShowPositiveFibersCheckBox.Value = app.tractset.posvisible;
            try
                if app.tractset.statmetric == 6
                    app.ShowPositiveFibersCheckBox.Text = ['Show Connected Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                else
                    app.ShowPositiveFibersCheckBox.Text = ['Show Positive Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                end
            end

            app.ShowNegativeFibersCheckBox.Value = app.tractset.negvisible;
            try
                app.ShowNegativeFibersCheckBox.Text = ['Show Negative Fibers (',num2str(sum(app.tractset.stats.neg.available)),')'];
            end

            % Amount sliders:
            app.ShowAmountSliderPositive.Value = mean(app.tractset.showposamount);
            app.ShowAmountSliderPositive.Tooltip = sprintf('%d%%', round(app.ShowAmountSliderPositive.Value));
            try
                app.ShowAmountSliderLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
            end

            app.ShowAmountSliderNegative.Value = mean(app.tractset.shownegamount);
            app.ShowAmountSliderNegative.Tooltip = sprintf('%d%%', round(app.ShowAmountSliderNegative.Value));
            try
                app.ShowAmountSlider_2Label.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
            end

            app.LeftSliderPositive.Value = app.tractset.showposamount(2);
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive.Value));
            try
                app.LeftSliderLabel.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
            end

            app.RightSliderPositive.Value = app.tractset.showposamount(1);
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(app.RightSliderPositive.Value));
            try
                app.RightSliderLabel.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
            end

            app.LeftSliderNegative.Value = app.tractset.shownegamount(2);
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative.Value));
            try
                app.LeftSlider_2Label.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
            end

            app.RightSliderNegative.Value = app.tractset.shownegamount(1);
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(app.RightSliderNegative.Value));
            try
                app.RightSlider_2Label.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
            end


            app.ModeltypeDropDown.Value = app.tractset.predictionmodel;


            app.LiveVisualizeCheckBox.Value = app.tractset.cvlivevisualize;

            app.BinsEditField.Value = app.tractset.numBins;
            app.ModelNormalizationDropDown.Value = app.tractset.modelNormalization;


            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.tractset.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.tractset.negcolor;


            % refresh enablerules
            app.enablerules;

            app.isbusy(0);
        end

        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end

        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            dorefresh=1;

            if app.SplitColorByPCAButton.Value && strcmp(app.PCAsettingsLabel.Enable,'off') % case in which PCA would fail and hence drawing would run into errors.
                dorefresh=0;
            end


            if (app.AutoRefreshCheckBox.Value || force) && dorefresh
                app.isbusy(1);
                drawnow;
                app.tractset.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.tractset2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end

        function Vms = getvms(app)
            switch app.tractset.efieldmetric
                case 'Mean'
                    Vms='V/mm';
                otherwise
                    Vms='V/m';
            end
        end

        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.tractset.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.tractset.covars = app.tractset.M.clinical.vars(is);
                    app.tractset.covarlabels = app.tractset.M.clinical.labels(is);
                else % none selected
                    app.tractset.covars = {};
                    app.tractset.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getstimamp},app.tractset.covars];
                    app.tractset.covarlabels = [{'Stimulation Amplitude'},app.tractset.covarlabels];
                end

                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getvtavolumes},app.tractset.covars];
                    app.tractset.covarlabels = [{'VTA Volume'},app.tractset.covarlabels];
                end

                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getefieldmagnitudes},app.tractset.covars];
                    app.tractset.covarlabels = [{'E-Field Magnitude Sum'},app.tractset.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getcohortregressor},app.tractset.covars];
                    app.tractset.covarlabels = [{'Group / Cohort regressor'},app.tractset.covarlabels];
                end
            else
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
            end
        end

        function setefieldsliders(app)
            value = app.tractset.efieldmetric;
            dimval=1000;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5]; % in V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[2.5,1000]; % in V/mm (!)
                    dimval=1;
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,5]; % in V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,5]; % in V/m
            end


            if app.tractset.efieldthreshold/dimval < app.EfieldthresholdSlider.Limits(1) % it is below min, set to min
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Limits(1)*dimval;
            end
            if app.tractset.efieldthreshold/dimval > app.EfieldthresholdSlider.Limits(2) % it is above max, set to max
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Limits(2)*dimval;
            end
        end


        function results = updatepca(app,whatchanged)
            generalEnableVal=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset))); % check if generally should enable

            if app.SplitColorByPCAButton.Value
                app.PCAsettingsLabel.Enable = generalEnableVal;
                app.VarianceExpSlider.Enable = generalEnableVal;
                app.VarianceExpSliderLabel.Enable = generalEnableVal;
                app.PCsEditField.Enable = generalEnableVal;
                app.PCsEditFieldLabel.Enable = generalEnableVal;
            else
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';

                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end

            % prep PCA:
            subvars=ea_nanzscore(cell2mat(app.tractset.subscore.vars'));
            try
                [coeff,score,latent,tsquared,explained]=pca(subvars,'algorithm','als');
            catch % PCA failed, likely no / too few scores selected
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';
                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end

            cumexplained=cumsum(explained);
            app.VarianceExpSlider.MajorTickLabelsMode='manual';
            app.VarianceExpSlider.MajorTicks=cumexplained;
            app.VarianceExpSlider.MinorTicksMode='manual';

            app.VarianceExpSlider.MajorTickLabels(ea_searchclosest(cumexplained,80)+1:end)={''}; % display var exp up until 80% on PCs to avoid crowding of tick labels.
            for pc=1:ea_searchclosest(cumexplained,80)
                app.VarianceExpSlider.MajorTickLabels{pc}=[sprintf('%.0f',cumexplained(pc)),' %'];
            end
            app.VarianceExpSlider.MinorTicks=[];
            if app.PCsEditField.Value > length(cumexplained)
                app.PCsEditField.Value = length(cumexplained);
            end
            if exist('whatchanged','var')
                switch whatchanged
                    case 'slider' % user has changed the slider
                        correctcomponent=ea_searchclosest(cumexplained,app.VarianceExpSlider.Value);
                        app.PCsEditField.Value=correctcomponent;
                    case 'edit' % user has edited the edit field
                        % nothing additional to do in this case.
                end
            end
            app.VarianceExpSlider.Value=cumexplained(app.PCsEditField.Value);
            app.tractset.numpcs=app.PCsEditField.Value; % also feed back to model
            app.VarianceExpSliderLabel.Text = [sprintf('%0.1f',cumexplained(app.tractset.numpcs)),'% Variance Explained'];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Fiber Filtering Explorer';

            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            app.ConnectomeDropDown.Items=mdl(sf==1);

            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';

            % Add Value to connthreshsliderlabel
            changingValue=app.EfieldthresholdSlider.Value;
            if ~exist('resultfig','var')
                resultfig = ea_mnifigure;
            end

            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('LEAD_groupanalysis.mat','Please Choose Lead-Group Analysis project...');
                lgpath = fullfile(PathName,FileName);
            end
            letsdraw=0; % flag to state whether to draw directly.
            lgpath = GetFullPath(lgpath);
            varinfo=who('-file',lgpath); % check if file is a LEAD_groupanalysis matfile or a stored disctract object with var name 'tractset'

            if ismember('tractset',varinfo)
                D = load(lgpath,'-mat');
                app.tractset = D.tractset; % load stored disctract object


                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.tractset.ID, ID)
                    app.tractset.ID = ID;
                end

                % Fix group folder in case it has been moved
                tractGroupFolder = strrep(app.tractset.leadgroup,'LEAD_groupanalysis.mat','');
                UIGroupFolder = strrep(lgpath,['fiberfiltering', filesep, app.tractset.ID, '.fibfilt'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.tractset.analysispath = strrep(app.tractset.analysispath, tractGroupFolder, UIGroupFolder);
                    app.tractset.leadgroup = strrep(app.tractset.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.tractset.M.root = strrep(app.tractset.M.root, tractGroupFolder, UIGroupFolder);
                    if isfield(app.tractset.M,'ui')
                        app.tractset.M.ui.groupdir = strrep(app.tractset.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                    end
                end

                app.tractset.resultfig = resultfig; % attach to figure
                if ~isempty(app.tractset.results)
                    if isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                        letsdraw=1;
                    end
                end
            else % init from M struct of LEAD group file
                app.tractset = ea_disctract;
                app.tractset.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
            end


            if isfield(app.tractset.M,'ui')
                if ismember(app.tractset.M.ui.connectomename, app.ConnectomeDropDown.Items)
                    app.ConnectomeDropDown.Value = app.tractset.M.ui.connectomename;
                end
            end
            app.setefieldsliders;
            if letsdraw
                app.draw; % show its results
            else
                tractset2UI(app);
            end
            enablerules(app);
            app.isbusy(0);

            hint={'Welcome to the Lead-DBS Fiber Filtering Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create discriminative tract models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');

        end

        % Callback function
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)


        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.tractset.statmetric=1;
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.tractset.statmetric=2;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'T-Tests / OSS-DBS Pathway Activations'
                    app.tractset.statmetric=3;
                case 'Dice Coeffs / VTAs (binary vars)'
                    app.tractset.statmetric=4;
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    app.tractset.statmetric=5;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Plain Connections'
                    app.tractset.statmetric=6;
                    if app.tractset.connthreshold < 1
                        app.tractset.connthreshold = 1; % reset to a suitable value
                    end
            end
            tractset2UI(app);
            enablerules(app);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
            app.draw;

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %app.tractset.app=app; % need to change
            app.tractset.leadgroup=app.LeadGroupPathLabel.Text;

            %% set up object properties:
            app.tractset.posvisible = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.negvisible = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;

            app.tractset.calcthreshold = app.ThresholdEditField.Value;

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};

            setcovars(app);

            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.tractset.statmetric=1;
                case 'Correlations / E-fields (Irmen 2020)'
                    app.tractset.statmetric=2;
                case 'T-Tests / OSS-DBS Pathway Activations'
                    app.tractset.statmetric=3;
                case 'Dice Coeffs / VTAs (binary vars)'
                    app.tractset.statmetric=4;
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    app.tractset.statmetric=5;
                case 'Plain Connections'
                    app.tractset.statmetric=6;
            end
            app.tractset.connectome = app.ConnectomeDropDown.Value;
            app.setefieldsliders;

            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.tractset.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;

            % calculate general setup
            app.tractset.calculate;

            % save dataset:
            app.tractset.save;

            enablerules(app);

            % draw results
            app.draw;
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            enablerules(app);
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.posvisible = value;
            enablerules(app);
            app.draw;

        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.negvisible = value;
            enablerules(app);
            app.draw;

        end

        % Value changing function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive.Value = changingValue;
            app.RightSliderPositive.Value = changingValue;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
        end

        % Value changing function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative.Value = changingValue;
            app.RightSliderNegative.Value = changingValue;
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];

        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'],'file')
                app.AnalysisIDEditField.Value = app.tractset.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end

            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],'file')
                movefile([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],...
                    [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt']);
            end

            ht = getappdata(app.tractset.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.tractset.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'], app.tractset.resultfig});
            end

            app.tractset.ID=value;
            app.tractset.save;

        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            app.tractset2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Value changed function: LeftSliderPositive
        function LeftSliderPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(app.LeftSliderPositive.Value));
            app.draw;
        end

        % Value changed function: RightSliderPositive
        function RightSliderPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(app.RightSliderPositive.Value));
            app.draw;
        end

        % Value changed function: LeftSliderNegative
        function LeftSliderNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(app.LeftSliderNegative.Value));
            app.draw;
        end

        % Value changed function: RightSliderNegative
        function RightSliderNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(app.RightSliderNegative.Value));
            app.draw;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            app.tractset.connectome = value;
            enablerules(app);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changed function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanged(app, event)
            value = app.ShowAmountSliderPositive.Value;
            app.LeftSliderPositive.Value = value;
            app.RightSliderPositive.Value = value;
            app.ShowAmountSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderPositive.Tooltip = sprintf('%d%%', round(value));
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Value changed function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanged(app, event)
            value = app.ShowAmountSliderNegative.Value;
            app.LeftSliderNegative.Value = value;
            app.RightSliderNegative.Value = value;
            app.ShowAmountSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.LeftSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.RightSliderNegative.Tooltip = sprintf('%d%%', round(value));
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.tractset.statmetric
                case {1,4}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case {2,5}
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
                case 3
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.tractset.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.customselection = [];
                    [I,Ihat,R0,R1,pperm]=app.tractset.lnopb;
                    I = I(:,1);
                    Ihat = Ihat{1};
                    cvs='lnopb';
                    sel=app.tractset.patientselection;
                    h1=ea_plothistperm([app.tractset.responsevarlabel,' [Permutation-Based Test]'],[R1;R0],{'Unpermuted prediction'},{1},1);
                    saveas(h1,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.responsevarlabel,'_',cvs,'_permtest.png']);
                case 'Leave-One-Patient-Out'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.loocv;
                    cvs='loocv';
                    sel=app.tractset.patientselection;
                case 'Leave-One-Cohort-Out'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.lococv;
                    cvs='lococv';
                    sel=app.tractset.patientselection;
                case 'k-fold (randomized)'
                    app.tractset.customselection = [];
                    [I,Ihat]=app.tractset.kfoldcv;
                    cvs='kfoldcv';
                    sel=app.tractset.patientselection;
                case 'Custom (Patients)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Custom (Cohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.tractset.M.patient.group, str2double(app.TrainOnListBox.Value)))';
                    test = find(ismember(app.tractset.M.patient.group,str2double(app.PredictListBox.Value)))';
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_cohs';
                    sel = test;
                case 'Custom (Subcohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    tsets = find(ismember(app.tractset.setlabels, app.TrainOnListBox.Value));
                    training=[];
                    for ts=1:length(tsets)
                        training = [training,find(app.tractset.setselections{tsets(ts)})];
                    end
                    training=unique(training);
                    tsets = find(ismember(app.tractset.setlabels, app.PredictListBox.Value));
                    test=[];
                    for ts=1:length(tsets)
                        test = [test,find(app.tractset.setselections{tsets(ts)})];
                    end
                    test=unique(test);
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);
                    cvs = 'custom_subcohs';
                    sel = test;
                case 'Custom (Sets)'
                    cvp.NumTestSets = 1;
                    % Generate cvpartition
                    rng(app.tractset.rngseed);
                    cvpKfold = cvpartition(length(app.tractset.M.patient.list), 'KFold', str2double(app.kEditField.Value));
                    % Calculate training and test indices based on the sets selected and the cvpartitions generated
                    trainSetInd = str2double(app.TrainOnListBox.Value');
                    testSetInd = str2double(app.PredictListBox.Value');
                    training = zeros(size(app.tractset.M.patient.list));
                    test = zeros(size(app.tractset.M.patient.list));
                    for i=1:length(trainSetInd)
                        training = training | cvpKfold.test(trainSetInd(i));
                    end
                    for i=1:length(testSetInd)
                        test = test | cvpKfold.test(testSetInd(i));
                    end
                    training = find(training);
                    test = find(test);
                    % Patient selected based on the training and test indices
                    app.tractset.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.tractset.customselection, training);
                    cvp.test{1} = ismember(app.tractset.customselection, test);
                    [I, Ihat]=app.tractset.crossval(cvp);

                    cvs = 'custom_sets';
                    sel = test;
            end
            % check if groups exist:
            if length(unique(app.tractset.M.patient.group))>1 && app.PosthoccorrectforgroupCheckBox.Value
                Ihat=ea_resid(ea_cohortregressor(app.tractset.M.patient.group(sel)),Ihat);
                suffix=' [Corrected for Cohort]';
            else
                suffix='';
            end

            groupID = app.tractset.M.patient.group(sel);
            groupColors = app.tractset.M.groups.color(unique(groupID),:);
            groupColors = rgb2hsv(groupColors);
            groupColors(:,2) = groupColors(:,2)+((1-groupColors(:,2))/2);
            groupColors=hsv2rgb(groupColors);
            empiricallabel=[ea_sub2space(app.tractset.responsevarlabel),' (Empirical)'];
            if app.tractset.doactualprediction
                fibscorelabel = [ea_sub2space(app.tractset.responsevarlabel),' (Predicted)'];
            else
                fibscorelabel=[];
                switch app.tractset.statmetric
                    case {2,3,5}
                        fibscorelabel=[fibscorelabel,'Weighted '];
                end
                switch lower(app.tractset.basepredictionon)
                    case 'mean of scores'
                        fibscorelabel=[fibscorelabel,'Mean of'];
                    case 'sum of scores'
                        fibscorelabel=[fibscorelabel,'Sum of'];
                    case 'peak of scores'
                        fibscorelabel=[fibscorelabel,'Peak of'];
                    case 'peak 5% of scores'
                        fibscorelabel=[fibscorelabel,'Peak 5% of'];
                    case 'profile of scores: spearman'
                        fibscorelabel=[fibscorelabel,'Spatial Spearman Correlation of'];
                    case 'profile of scores: pearson'
                        fibscorelabel=[fibscorelabel,'Spatial Pearson Correlation of'];
                    case 'profile of scores: bend'
                        fibscorelabel=[fibscorelabel,'Spatial Bend Correlation of'];
                end
                switch app.tractset.statmetric
                    case {1,3,5}
                        fibscorelabel=[fibscorelabel,' Fiber-T-Scores'];
                    case 2
                        fibscorelabel=[fibscorelabel,' Fiber-R-Scores'];
                    case 4
                        fibscorelabel=[fibscorelabel,' Fiber-DICE-Scores'];
                    case 6
                        fibscorelabel=[fibscorelabel,' Plain Connections'];
                end
                fibscorelabel=[fibscorelabel,suffix];
            end
            if iscell(I)

                for sc=1:length(I)
                    fibscorelabel = [ea_sub2space(app.tractset.subscore.labels{sc}),' (Predicted)'];
                    empiricallabel=[ea_sub2space(app.tractset.subscore.labels{sc}),' (Empirical)'];
                    h=ea_corrplot(I{sc},Ihat{sc},{['Disc. Fiber prediction ',upper(cvs)],empiricallabel,fibscorelabel},'permutation_spearman',groupID,[],[],groupColors);
                    saveas(h,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.subscore.labels{sc},'_',cvs,'_',num2str(app.tractset.numpcs),'_factor_pca.png']);
                end
                    
            else

                if exist('pperm', 'var')
                    h=ea_corrplot(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],empiricallabel,fibscorelabel},'permutation_spearman',groupID,[],pperm,groupColors);
                else
                    h=ea_corrplot(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],empiricallabel,fibscorelabel},'permutation_spearman',groupID,[],[],groupColors);
                end
                saveas(h,[fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'_',app.tractset.responsevarlabel,'_',cvs,'.png']);
            end
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            app.enablerules;
            app.tractset2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isbusy(1,'saving');
            app.tractset.save;
            for tract=1:numel(app.tractset.drawobject)
                delete(app.tractset.drawobject{tract});
            end
            app.isbusy(0);
            delete(app)
        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.setefieldsliders;
            app.tractset.efieldmetric=value;

            app.enablerules;
            app.tractset2UI;

            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end

            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.tractset.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.tractset.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:app.tractset.Nsets]');
                        app.PredictListBox.Items=sprintfc('%d',[1:app.tractset.Nsets]');
                    end
            end
        end

        % Value changed function: CovariatesCheckBox
        function CovariatesCheckBoxValueChanged(app, event)
            value = app.CovariatesCheckBox.Value;
            app.enablerules;
            if ~value
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end

        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.tractset.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshInformationfromLeadGroupprojectMenu
        function RefreshInformationfromLeadGroupprojectMenuSelected(app, event)
            app.tractset.refreshlg;
            app.tractset2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: 
        % ConsiderSignificantFibersonlyCheckBox
        function ConsiderSignificantFibersonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox.Value;
            app.tractset.showsignificantonly=value;
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            disctract = app.tractset.fiberdrawn;
            disctract.info.Connectome = app.tractset.connectome;
            if app.ShowPositiveFibersCheckBox.Value
                disctract.info.PosAmount = sum(app.tractset.stats.pos.shown);
                if numel(app.tractset.stats.pos.shown)==2
                    disctract.info.PosAmountRight = sum(app.tractset.stats.pos.shown(1));
                    disctract.info.PosAmountLeft = sum(app.tractset.stats.pos.shown(2));
                    disctract.info.PosPercentRight = app.RightSliderPositive.Value;
                    disctract.info.PosPercentLeft = app.LeftSliderPositive.Value;
                end
                disctract.info.PosPercent = app.ShowAmountSliderPositive.Value;

            end
            if app.ShowNegativeFibersCheckBox.Value
                disctract.info.NegAmount = sum(app.tractset.stats.neg.shown);
                if numel(app.tractset.stats.neg.shown)==2
                    disctract.info.NegAmountRight = sum(app.tractset.stats.neg.shown(1));
                    disctract.info.NegAmountLeft = sum(app.tractset.stats.neg.shown(2));
                    disctract.info.NegPercentRight = app.RightSliderNegative.Value;
                    disctract.info.NegPercentLeft = app.LeftSliderNegative.Value;
                end
                disctract.info.NegPercent = app.ShowAmountSliderNegative.Value;
            end
            atlasname = app.tractset.ID;
            tractname = 'Tract';
            negativecolor = app.tractset.negcolor;
            positivecolor = app.tractset.poscolor;
            ea_discfibers_exportatlas(disctract, atlasname, tractname, negativecolor, positivecolor);
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.tractset2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.tractset.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.tractset.multcompstrategy=value;
            app.draw;
        end

        % Value changed function: Corrtype
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.tractset.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.tractset.showsignificantonly=0;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.tractset.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.tractset.basepredictionon=lower(value);
            app.enablerules;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.tractset.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.enablerules;
        end

        % Menu selected function: 
        % SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.tractset.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.tractset.rngseed = str2double(rngseed{1});
            end
        end

        % Value changing function: RightSliderNegative
        function RightSliderNegativeValueChanging(app, event)
            changingValue = event.Value;

        end

        % Value changed function: ModelNormalizationDropDown
        function ModelNormalizationDropDownValueChanged(app, event)
            value = app.ModelNormalizationDropDown.Value;
            app.tractset.modelNormalization=value;
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            value = app.BinsEditField.Value;
            app.numBins=value;
        end

        % Selection changed function: ModelSetupOptionButtonGroup
        function ModelSetupOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelSetupOptionButtonGroup.SelectedObject;
            app.tractset.multitractmode=selectedButton.Text;
            if strcmp(selectedButton.Text,'Split & Color By Group')
                enablerules(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By Subscore')
                %removing the covariates box since it doesn't make sense
                %anymore
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.tractset.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{subscore,1}),app.tractset.M.clinical.labels);
                    app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
                end

                enablerules(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Single Tract Analysis')
                enablerules(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By PCA')
                app.tractset.doactualprediction = 1;
                app.FittoscoresCheckBox.Value = 1;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.tractset.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{subscore,1}),app.tractset.M.clinical.labels);
                    app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
                end

                enablerules(app);
                app.draw;
            end
            % save dataset:
        end

        % Value changed function: SubscoresListBox
        function SubscoresListBoxValueChanged(app, event)

            app.tractset.subscore.labels = app.SubscoresListBox.Value;
            app.tractset.subscore.vars = cell(length(app.tractset.subscore.labels),1);
            try % keep prior weights if we can
                app.tractset.subscore.weights=app.tractset.subscore.weights(1:length(app.tractset.subscore.labels));
            catch
                app.tractset.subscore.weights = zeros(length(app.tractset.subscore.labels),1);
            end

            for subscore = 1:length(app.SubscoresListBox.Value)
                [is,ix] = ismember(app.tractset.subscore.labels{subscore},app.tractset.M.clinical.labels);
                app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
            end
            app.enablerules;
            app.draw;

        end

        % Button pushed function: MultitractWeightsButton
        function MultitractWeightsButtonPushed(app, event)

            f = uifigure;
            f.Position(3) = 630;
            for i=1:length(app.tractset.subscore.vars)
                slider_name{i} = app.tractset.subscore.labels{i};
            end

            j = 390; %start position of the label
            k = 380; %start position of the slider
            l = 360;
            m = 362;

            for i=1:length(slider_name)
                slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);

                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                pos_col_button{i} = uibutton(f,'Text','Color','Position',[570,m,40,25],'BackgroundColor',app.tractset.subscore.colors(i,:));
                base_pred_lbl{i} = uilabel(f,'Text','Weight Using..','Position',[20,m,80,25]);
                base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.tractset.subscore.weights(i))
                    slider_val_name{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChange(slider_text_box,slider_val_name,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChanging(event,slider_text_box,slider_val_name,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChange_text(slider_text_box,slider_val_name,i);
                base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
                pos_col_button{i}.ButtonPushedFcn = @(pos_col_button,event) set_pos_fiber_color(app,pos_col_button,i);
            end


            function set_pos_fiber_color(app,pos_col_button,i)
                c=uisetcolor;
                pos_col_button.BackgroundColor=c;
                app.ColorButtonPositive.BackgroundColor=c;
                app.tractset.colorbar = c;
                %ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i}),app.tractset.M.clinical.labels);
                app.tractset.subscore.colors(i,:) = c;
                app.tractset.responsevar = app.tractset.subscore.vars{i};
                app.tractset.responsevarlabel = app.tractset.subscore.labels{i};
                app.draw;
            end

            function base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i)
                saved_labels = app.tractset.subscore.labels{i};
                saved_vars = app.tractset.subscore.vars{i};
                if strcmp(base_pred_dd{i}.Value,'None')
                    slider_text_box{i}.Enable = 'on';
                    slider_val_name{i}.Enable = 'on';
                    app.tractset.subscore.weightvars{i,1} = '';
                    app.tractset.subscore.weightvars{i,1} = [];
                    app.tractset.subscore.weights(i)=slider_val_name{i}.Value/100;
                else
                    slider_text_box{i}.Enable = 'off';
                    slider_val_name{i}.Enable = 'off';
                    index_of_new_subscore = cellfun(@(x)isequal(x,base_pred_dd{i}.Value),app.tractset.M.clinical.labels);
                    app.tractset.subscore.weightvars{i,1} = app.tractset.M.clinical.vars{index_of_new_subscore};
                    app.tractset.subscore.weightvarlabels{i,1} = base_pred_dd{i}.Value;
                    app.tractset.subscore.weights(i)=nan;
                end

            end

            function SliderChange_text(slider_text_box,slider_val_name,i)

                text_value = slider_text_box{i}.Value;
                slider_val_name{i}.Value = text_value;
                app.tractset.showposamount = [text_value,text_value];
                %draw the other slider
                app.tractset.responsevarlabel = app.tractset.subscore.labels{i};
                app.tractset.responsevar = app.tractset.subscore.vars{i};
                %to provide selective control of fiber numbers, lets switch
                %of split by subscore, but switch it back on after drawing.
                %n
                app.tractset.subscore.control_single_fiber = 1;
                app.draw;


                %this is the old code. Stashing it away for now but it will
                %be soon useful

                %other_indices = [1:i-1, i+1:length(slider_val_name)];
                %value_other_sliders = (100-sum)/length(other_indices);
                %for j=1:length(other_indices)
                %    k = other_indices(j);
                %    slider_val_name{k}.Value = value_other_sliders;
                %    slider_text_box{k}.Value = value_other_sliders;
                %end
                %reset to 1
                %j = 1;
                %for i=1:length(slider_val_name)
                %    app.tractset.subscore.weights(j) = slider_val_name{i}.Value/100;
                %    disp(['Weight Corresponding to ' slider_lab_name{i}.Text ' is:' num2str(app.tractset.subscore.weights(j))]);
                %    j = j+1;
                %end
                % app.tractset.subscore.mixfibers = 1;


            end


            function SliderChange(slider_text_box,slider_val_name,i)
                %change value using sliders
                remainingamount=1-(slider_val_name{i}.Value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end

                app.draw;
            end

            function SliderChanging(event,slider_text_box,slider_val_name,i)
                remainingamount=1-(event.Value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=event.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
            end


            %                 %the slider that moves takes the value of the slider
            %                 % if slider_text_box{i}.Value == 0
            %                 sum = slider_val_name{i}.Value;
            %                 slider_text_box{i}.Value = slider_val_name{i}.Value;
            %                 other_indices = [1:i-1, i+1:length(slider_val_name)];
            %                 value_other_sliders = (100-sum)/length(other_indices);
            %                 %
            %                 for j=1:length(other_indices)
            %                     k = other_indices(j);
            %                     slider_val_name{k}.Value = value_other_sliders;
            %                     slider_text_box{k}.Value = value_other_sliders;
            %                 end
            %                 %reset to 1
            %                 j = 1;
            %                 for i=1:length(slider_val_name)
            %                     app.tractset.subscore.weights(j) = slider_val_name{i}.Value/100;
            %                     disp(['Weight Corresponding to ' slider_lab_name{i}.Text ' is:' num2str(app.tractset.subscore.weights(j))]);
            %                     j = j+1;
            %                 end
            %                 %app.tractset.subscore.mixfibers = 1;
            %                 app.draw;


        end

        % Value changed function: AutoRefreshCheckBox
        function AutoRefreshCheckBoxValueChanged(app, event)
            value = app.AutoRefreshCheckBox.Value;

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.tractset.calcthreshold = value;


            hint={'Note that changing this value will not have an effect on values already calculated.'
                'You would need to repeat the calculation for each connectome of interest for changes to have an effect.'};
            uigetpref('LeadDBS','FiberFilteringShowCalcThreshHint','Fiber Filtering Explorer',hint,'Makes Sense');


        end

        % Value changed function: FittoscoresCheckBox
        function FittoscoresCheckBoxValueChanged(app, event)
            value = app.FittoscoresCheckBox.Value;
            app.tractset.doactualprediction = value;
            app.ModeltypeDropDown.Enable = ea_bool2onoff(value);

        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            app.tractset.predictionmodel = app.ModeltypeDropDown.Value;
        end

        % Value changed function: PCsEditField
        function PCsEditFieldValueChanged(app, event)
            value = app.PCsEditField.Value;
            app.tractset.numpcs=value;
            app.updatepca('edit');
            app.draw;
        end

        % Value changed function: VarianceExpSlider
        function VarianceExpSliderValueChanged(app, event)
            value = app.VarianceExpSlider.Value;
            app.updatepca('slider');
            app.draw;
        end

        % Value changing function: VarianceExpSlider
        function VarianceExpSliderValueChanging(app, event)
            changingValue = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshInformationfromLeadGroupprojectMenu = uimenu(app.ToolsMenu);
            app.RefreshInformationfromLeadGroupprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshInformationfromLeadGroupprojectMenuSelected, true);
            app.RefreshInformationfromLeadGroupprojectMenu.Text = 'Refresh Information from Lead-Group project';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditFieldLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ConnectomeSelectionPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 200;
            app.VmLabel = uilabel(app.ConnectomeSelectionPanel);
            app.VmLabel.Text = 'V/m';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.Limits = [0 49];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.EfieldthresholdSlider.Limits = [1 40];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 2.5;
            app.EfieldthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts are modulated > '; 'sum E-Field of  V/m'};
            app.CovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesCheckBoxValueChanged, true);
            app.CovariatesCheckBox.Enable = 'off';
            app.CovariatesCheckBox.Text = 'Covariates';
            app.Efieldmetric = uidropdown(app.InteractiveModelSetupPanel);
            app.Efieldmetric.Items = {'Sum', 'Mean', 'Peak', 'Peak 5%'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Value = 'Sum';
            app.ModelSetupDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDownLabel.Enable = 'off';
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019)', 'Correlations / E-fields (Irmen 2020)', 'T-Tests / OSS-DBS Pathway Activations', 'Dice Coeffs / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Enable = 'off';
            app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019)';
            app.Corrtype = uidropdown(app.InteractiveModelSetupPanel);
            app.Corrtype.Items = {'Spearman', 'Pearson', 'Bend', 'Skipped Spearman', 'Skipped Pearson'};
            app.Corrtype.ValueChangedFcn = createCallbackFcn(app, @CorrtypeValueChanged, true);
            app.Corrtype.Enable = 'off';
            app.Corrtype.Value = 'Spearman';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.ColorBar = uiaxes(app.VisualizationThresholdsPanel);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorButtonPositive = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.ColorButtonNegative = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Enable = 'off';
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Enable = 'off';
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.ExportasAtlasButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ShowAmountSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderLabel.Enable = 'off';
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanged, true);
            app.ShowAmountSliderPositive.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanging, true);
            app.ShowAmountSliderPositive.MinorTicks = [];
            app.ShowAmountSliderPositive.Enable = 'off';
            app.ShowAmountSliderPositive.Value = 25;
            app.LeftSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.LeftSliderLabel.Enable = 'off';
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.LeftSliderPositive.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositiveValueChanged, true);
            app.LeftSliderPositive.MinorTicks = [];
            app.LeftSliderPositive.Enable = 'off';
            app.LeftSliderPositive.Value = 25;
            app.RightSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.RightSliderLabel.Enable = 'off';
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.RightSliderPositive.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositiveValueChanged, true);
            app.RightSliderPositive.MinorTicks = [];
            app.RightSliderPositive.Enable = 'off';
            app.RightSliderPositive.Value = 25;
            app.LeftSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.LeftSlider_2Label.Enable = 'off';
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.LeftSliderNegative.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegativeValueChanged, true);
            app.LeftSliderNegative.MinorTicks = [];
            app.LeftSliderNegative.Enable = 'off';
            app.LeftSliderNegative.Value = 25;
            app.ShowAmountSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.ShowAmountSlider_2Label.Enable = 'off';
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanged, true);
            app.ShowAmountSliderNegative.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanging, true);
            app.ShowAmountSliderNegative.MinorTicks = [];
            app.ShowAmountSliderNegative.Enable = 'off';
            app.ShowAmountSliderNegative.Value = 25;
            app.RightSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.RightSlider_2Label.Enable = 'off';
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.RightSliderNegative.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegativeValueChanged, true);
            app.RightSliderNegative.ValueChangingFcn = createCallbackFcn(app, @RightSliderNegativeValueChanging, true);
            app.RightSliderNegative.MinorTicks = [];
            app.RightSliderNegative.Enable = 'off';
            app.RightSliderNegative.Value = 25;
            app.ConsiderSignificantFibersonlyCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ConsiderSignificantFibersonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantFibersonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
            app.ConsiderSignificantFibersonlyCheckBox.Text = 'Consider Significant Fibers only';
            app.RefreshViewButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.AutoRefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRefreshCheckBoxValueChanged, true);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.levelEditFieldLabel = uilabel(app.VisualizationThresholdsPanel);
            app.levelEditFieldLabel.Enable = 'off';
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.VisualizationThresholdsPanel, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Enable = 'off';
            app.levelEditField.Value = 0.05;
            app.MultTestStrategy = uidropdown(app.VisualizationThresholdsPanel);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Enable = 'off';
            app.MultTestStrategy.Value = 'FDR';
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Enable = 'off';
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores', 'Histogram', 'z-scored Histogram'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Enable = 'off';
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ModelNormalizationDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDownLabel.Enable = 'off';
            app.ModelNormalizationDropDownLabel.Text = 'Model Normalization';
            app.ModelNormalizationDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDown.Items = {'None', 'z-score', 'van Albada 2007'};
            app.ModelNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelNormalizationDropDownValueChanged, true);
            app.ModelNormalizationDropDown.Enable = 'off';
            app.ModelNormalizationDropDown.Value = 'None';
            app.BinsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Visible = 'off';
            app.BinsEditFieldLabel.Text = '#Bins';
            app.BinsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Visible = 'off';
            app.BinsEditField.Value = 15;
            app.FittoscoresCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.FittoscoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @FittoscoresCheckBoxValueChanged, true);
            app.FittoscoresCheckBox.Enable = 'off';
            app.FittoscoresCheckBox.Text = 'Fit to scores';
            app.ModeltypeDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModeltypeDropDown.Items = {'Constant', 'Linear', 'Quadratic'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Enable = 'off';
            app.ModeltypeDropDown.Value = 'Linear';
            app.MultitractWeightsButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.MultitractWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @MultitractWeightsButtonPushed, true);
            app.MultitractWeightsButton.Enable = 'off';
            app.MultitractWeightsButton.Text = 'Multitract Weights...';
            app.MultitractAnalysisPanel = uipanel(app.UIFigure);
            app.MultitractAnalysisPanel.Title = 'Multitract Analysis';
            app.ModelSetupOptionButtonGroup = uibuttongroup(app.MultitractAnalysisPanel);
            app.ModelSetupOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSetupOptionButtonGroupSelectionChanged, true);
            app.ModelSetupOptionButtonGroup.Enable = 'off';
            app.ModelSetupOptionButtonGroup.Title = 'Model Setup Option';
            app.SingleTractAnalysisButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SingleTractAnalysisButton.Enable = 'off';
            app.SingleTractAnalysisButton.Text = 'Single Tract Analysis';
            app.SingleTractAnalysisButton.Value = true;
            app.SplitColorByGroupButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByGroupButton.Enable = 'off';
            app.SplitColorByGroupButton.Text = 'Split & Color By Group';
            app.SplitColorBySubscoreButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorBySubscoreButton.Enable = 'off';
            app.SplitColorBySubscoreButton.Text = 'Split & Color By Subscore';
            app.SplitColorByPCAButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByPCAButton.Enable = 'off';
            app.SplitColorByPCAButton.Text = 'Split & Color By PCA';
            app.SubscoresListBox = uilistbox(app.MultitractAnalysisPanel);
            app.SubscoresListBox.Multiselect = 'on';
            app.SubscoresListBox.ValueChangedFcn = createCallbackFcn(app, @SubscoresListBoxValueChanged, true);
            app.SubscoresListBox.Enable = 'off';
            app.SubscoresListBox.Value = {'Item 1'};
            app.PCsEditFieldLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCsEditFieldLabel.Enable = 'off';
            app.PCsEditFieldLabel.Text = '# PCs';
            app.PCsEditField = uieditfield(app.MultitractAnalysisPanel, 'numeric');
            app.PCsEditField.RoundFractionalValues = 'on';
            app.PCsEditField.ValueChangedFcn = createCallbackFcn(app, @PCsEditFieldValueChanged, true);
            app.PCsEditField.Enable = 'off';
            app.PCsEditField.Value = 4;
            app.PCAsettingsLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCAsettingsLabel.Enable = 'off';
            app.PCAsettingsLabel.Text = 'PCA settings';
            app.VarianceExpSliderLabel = uilabel(app.MultitractAnalysisPanel);
            app.VarianceExpSliderLabel.Enable = 'off';
            app.VarianceExpSliderLabel.Text = 'Variance Exp';
            app.VarianceExpSlider = uislider(app.MultitractAnalysisPanel);
            app.VarianceExpSlider.MajorTicks = [0 20 40 60 80 100];
            app.VarianceExpSlider.ValueChangedFcn = createCallbackFcn(app, @VarianceExpSliderValueChanged, true);
            app.VarianceExpSlider.ValueChangingFcn = createCallbackFcn(app, @VarianceExpSliderValueChanging, true);
            app.VarianceExpSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.VarianceExpSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end