classdef ea_stimparams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        stimfig              matlab.ui.Figure
        addStimSet           matlab.ui.control.CheckBox
        estimateInTemplate   matlab.ui.control.CheckBox
        settings             matlab.ui.control.Button
        betawarning          matlab.ui.control.Label
        saveparams           matlab.ui.control.Button
        nextpt               matlab.ui.control.Button
        prevpt               matlab.ui.control.Button
        stimlabel            matlab.ui.control.DropDown
        stimlab              matlab.ui.control.Label
        uipanel3             matlab.ui.container.Panel
        usLabel_L            matlab.ui.control.Label
        pulseWidthLabel_L    matlab.ui.control.Label
        pulseWidthTextbox_L  matlab.ui.control.EditField
        Ls4va                matlab.ui.control.DropDown
        Ls4am                matlab.ui.control.EditField
        Ls3va                matlab.ui.control.DropDown
        Ls3am                matlab.ui.control.EditField
        Ls2va                matlab.ui.control.DropDown
        Ls2am                matlab.ui.control.EditField
        Ls1va                matlab.ui.control.DropDown
        text50               matlab.ui.control.Label
        Ls1am                matlab.ui.control.EditField
        caseLtxt             matlab.ui.control.Label
        LCu                  matlab.ui.control.EditField
        kohmtext4            matlab.ui.control.Label
        perctext4            matlab.ui.control.Label
        k15im                matlab.ui.control.EditField
        k14im                matlab.ui.control.EditField
        k13im                matlab.ui.control.EditField
        k12im                matlab.ui.control.EditField
        k15txt               matlab.ui.control.Label
        k15u                 matlab.ui.control.EditField
        k14txt               matlab.ui.control.Label
        k14u                 matlab.ui.control.EditField
        k13txt               matlab.ui.control.Label
        k13u                 matlab.ui.control.EditField
        k12txt               matlab.ui.control.Label
        k12u                 matlab.ui.control.EditField
        kohmtext3            matlab.ui.control.Label
        perctext3            matlab.ui.control.Label
        k11im                matlab.ui.control.EditField
        k10im                matlab.ui.control.EditField
        k9im                 matlab.ui.control.EditField
        k8im                 matlab.ui.control.EditField
        k11txt               matlab.ui.control.Label
        k11u                 matlab.ui.control.EditField
        k10txt               matlab.ui.control.Label
        k10u                 matlab.ui.control.EditField
        k9txt                matlab.ui.control.Label
        k9u                  matlab.ui.control.EditField
        k8txt                matlab.ui.control.Label
        k8u                  matlab.ui.control.EditField
        k12ax                matlab.ui.control.UIAxes
        k13ax                matlab.ui.control.UIAxes
        k14ax                matlab.ui.control.UIAxes
        k15ax                matlab.ui.control.UIAxes
        k11ax                matlab.ui.control.UIAxes
        k10ax                matlab.ui.control.UIAxes
        k9ax                 matlab.ui.control.UIAxes
        k8ax                 matlab.ui.control.UIAxes
        LCax                 matlab.ui.control.UIAxes
        uipanel2             matlab.ui.container.Panel
        usLabel_R            matlab.ui.control.Label
        pulseWidthLabel_R    matlab.ui.control.Label
        pulseWidthTextbox_R  matlab.ui.control.EditField
        Rs4va                matlab.ui.control.DropDown
        Rs4am                matlab.ui.control.EditField
        Rs3va                matlab.ui.control.DropDown
        Rs3am                matlab.ui.control.EditField
        Rs2va                matlab.ui.control.DropDown
        Rs2am                matlab.ui.control.EditField
        Rs1va                matlab.ui.control.DropDown
        text36               matlab.ui.control.Label
        Rs1am                matlab.ui.control.EditField
        caseRtxt             matlab.ui.control.Label
        RCu                  matlab.ui.control.EditField
        kohmtext2            matlab.ui.control.Label
        perctext2            matlab.ui.control.Label
        k7im                 matlab.ui.control.EditField
        k6im                 matlab.ui.control.EditField
        k5im                 matlab.ui.control.EditField
        k4im                 matlab.ui.control.EditField
        k7txt                matlab.ui.control.Label
        k7u                  matlab.ui.control.EditField
        k6txt                matlab.ui.control.Label
        k6u                  matlab.ui.control.EditField
        k5txt                matlab.ui.control.Label
        k5u                  matlab.ui.control.EditField
        k4txt                matlab.ui.control.Label
        k4u                  matlab.ui.control.EditField
        kohmtext1            matlab.ui.control.Label
        perctext1            matlab.ui.control.Label
        k3im                 matlab.ui.control.EditField
        k2im                 matlab.ui.control.EditField
        k1im                 matlab.ui.control.EditField
        k0im                 matlab.ui.control.EditField
        k3txt                matlab.ui.control.Label
        k3u                  matlab.ui.control.EditField
        k2txt                matlab.ui.control.Label
        k2u                  matlab.ui.control.EditField
        k1txt                matlab.ui.control.Label
        k1u                  matlab.ui.control.EditField
        k0txt                matlab.ui.control.Label
        k0u                  matlab.ui.control.EditField
        k4ax                 matlab.ui.control.UIAxes
        k5ax                 matlab.ui.control.UIAxes
        k6ax                 matlab.ui.control.UIAxes
        k7ax                 matlab.ui.control.UIAxes
        k3ax                 matlab.ui.control.UIAxes
        k2ax                 matlab.ui.control.UIAxes
        k1ax                 matlab.ui.control.UIAxes
        k0ax                 matlab.ui.control.UIAxes
        RCax                 matlab.ui.control.UIAxes
        text13               matlab.ui.control.Label
        stimulate            matlab.ui.control.Button
        modelselect          matlab.ui.control.DropDown
        headertxt            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ea_stimparams_OpeningFcn(app, varargin)
            % --- Executes just before ea_stimparams is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.stimfig, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ea_stimparams (see VARARGIN)
            
            % Change name
            set(handles.stimfig,'Name','Stimulation Parameters');
            
            % store input arguments in figure to make it available to subroutines.
            elstruct = varargin{1};
            resultfig = varargin{2};
            options = varargin{3};
            
            setappdata(handles.stimfig,'elstruct',elstruct);
            
            if options.native
                set(handles.estimateInTemplate,'Visible','off');
            end
            
            set(handles.estimateInTemplate,'Value',ea_getprefs('vatsettings.estimateInTemplate'));
            
            if strcmp(options.leadprod, 'group')
                groupmode=1;
                M=getappdata(resultfig,'M');
                try % priorly loaded stim params.
                    gS=M.S;
                    setappdata(handles.stimfig,'gS',gS);
                    gSv.vatmodel=M.vatmodel;
                    setappdata(handles.stimfig,'gSv',gSv);
                end
                set(handles.prevpt,'visible','on');
                set(handles.nextpt,'visible','on');
                set(handles.saveparams,'visible','on');
            
                set(handles.stimlabel,'visible','off');
                set(handles.stimlab,'visible','off');
                set(handles.stimulate,'visible','off');
                if length(elstruct) == 1
                    set(handles.prevpt,'enable','off');
                    set(handles.nextpt,'enable','off');
                end
            
                % UI adaption
                Yshift = 24;
                set(handles.stimfig, 'Position', handles.stimfig.Position - [0 0 0 Yshift]);
                set(handles.headertxt, 'Position', handles.headertxt.Position - [0 Yshift 0 0]);
                set(handles.settings, 'Position', handles.settings.Position - [0 Yshift+2 0 0]);
            else
                groupmode=0;
            
                set(handles.headertxt, 'String', ['Patient: ', elstruct(1).name]);
            
                set(handles.prevpt,'visible','off');
                set(handles.nextpt,'visible','off');
                set(handles.saveparams,'visible','off');
            
                set(handles.stimlabel,'visible','on');
                set(handles.stimlab,'visible','on');
                set(handles.stimulate,'visible','on');
            end
            
            setappdata(handles.stimfig,'groupmode',groupmode);
            setappdata(handles.stimfig,'actpt',1);
            
            setappdata(handles.stimfig,'elstruct',elstruct);
            setappdata(handles.stimfig,'resultfig',resultfig);
            setappdata(handles.stimfig,'options',options);
            
            stimparams=getappdata(resultfig,'stimparams'); % get info from resultfig.
            if isempty(stimparams)
                stimparams = struct();
            end
            setappdata(handles.stimfig,'stimparams',stimparams); % store stimulation settings from resultfig to stim (this) fig for subroutines.
            
            % setup modelselect popup
            if strcmp(options.leadprod, 'group')
                isdirected=0; % for now allow everything in lead group
            else
                e=load(fullfile(ea_getearoot,'templates','electrode_models',options.elspec.matfname));
                if isfield(e.electrode,'isdirected')
                    isdirected=e.electrode.isdirected;
                else
                    isdirected=0;
                end
            end
            
            % Hide OSS-DBS option in case non-dev env or elmodel not available
            if strcmp(options.leadprod, 'dbs')
                funcs = ea_regexpdir(ea_getearoot, 'ea_genvat_.*\.m$', 0);
                funcs = regexp(funcs, '(ea_genvat_.*)(?=\.m)', 'match', 'once');
                [names, supportDirected] = cellfun(@(x) eval([x, '(''prompt'');']), funcs, 'Uni', 0);
                if isdirected
                    funcs = funcs(cell2mat(supportDirected));
                    names = names(cell2mat(supportDirected));
                end
                if ~ismember(options.elmodel,ea_ossdbs_elmodel)
                    ossdbsInd = find(contains(names,'OSS-DBS'));
                    funcs(ossdbsInd) = [];
                    names(ossdbsInd) = [];
                end
            else % Call in lead 'group'
                funcs = getappdata(resultfig, 'genvatfunctions');
                names = getappdata(resultfig, 'vatfunctionnames');
            end
            
            setappdata(gcf, 'genvatfunctions', funcs);
            value = find(contains(names, handles.modelselect.String(handles.modelselect.Value)));
            set(handles.modelselect, 'String', names);
            set(handles.modelselect, 'Value', value);
            
            % if ~isempty(stimparams) % stimfigure has been used before..
            %     for side=1:2
            %         for el=1:4
            %             %keyboard
            %             set(eval(['handles.k',num2str(((side-1)*4)+el-1),'u']),'String', num2str(stimparams(side).U(el)));
            %             set(eval(['handles.k',num2str(((side-1)*4)+el-1),'im']),'String',num2str(stimparams(side).Im(el)));
            %         end
            %     end
            %
            %     set(handles.fiberthresh,'String',num2str(stimparams(1).fiberthresh))
            %     set(handles.showfibs,'Value',stimparams(1).showfibers);
            %     set(handles.showconns,'Value',stimparams(1).showconnectivities);
            % end
            
            pos=get(handles.stimfig,'position');
            set(handles.stimfig,'position',[51,51,pos(3),pos(4)]);
            
            ea_refreshguisp(handles,options);
            
            if ~strcmp(options.leadprod, 'group')
                label =handles.stimlabel.String{handles.stimlabel.Value};
                label(strfind(label, ' ')) = '';
                stimDir = fullfile(options.subj.stimDir, ea_nt(options), label);
                filePrefix = ['sub-', options.subj.subjId, '_sim-'];
                visualizeVAT = 1;
            
                stimParams = ea_regexpdir(stimDir, 'stimparameters\.mat$', 0);
                load(stimParams{1}, 'S');
                modelLabel = ea_simModel2Label(S.model);
            
                if visualizeVAT
                    if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = vatvolume;
                        if exist('vatgrad','var')
                            vatgradtemp(1) = vatgrad;
                        end
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = vatvolume;
                        if exist('vatgrad','var')
                            vatgradtemp(2) = vatgrad;
                            vatgrad = vatgradtemp;
                        end
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat'])
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = vatvolume;
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                        %For consistency, left is always on 2nd element of stimparams
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = vatvolume;
                    else
                        if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            stimparams(1,1).VAT.VAT = vatfv;
                            stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            stimparams(1,2).VAT.VAT = vatfv;
                            stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii'])
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            stimparams(1,1).VAT.VAT = vatfv;
                            stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            %For consistency, left is always on 2nd element of stimparams
                            stimparams(1,2).VAT.VAT = vatfv;
                            stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        else
                            visualizeVAT = 0;
                        end
                    end
            
                    if isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat']) ...
                            && isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                    elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'])
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                    elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                    end
            
                    if visualizeVAT
                        setappdata(handles.stimfig,'stimparams',stimparams);
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        if exist('vatgrad')
                            setappdata(resultfig,'vatgrad',vatgrad);
                        end
                        setappdata(resultfig,'stimparams',stimparams(1,:));
                        S=ea_loadstimulation(label,options);
                        setappdata(resultfig,'curS',S(1))
                        options.writeoutstats = 1;
                        ea_calc_vatstats(resultfig,options);
                    end
                end
            end
            
            % Choose default command line output for ea_stimparams
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: LCu
        function LCu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LCu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of LCu as text
            %        str2double(get(hObject,'String')) returns contents of LCu as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.case.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: Ls1am
        function Ls1am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls1am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Ls1am as text
            %        str2double(get(hObject,'String')) returns contents of Ls1am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=1;
            S.Ls1.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls1va
        function Ls1va_Callback(app, event)
            % --- Executes on selection change in Ls1va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls1va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Ls1va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Ls1va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=1;
            S.Ls1.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls2am
        function Ls2am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls2am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Ls2am as text
            %        str2double(get(hObject,'String')) returns contents of Ls2am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=2;
            S.Ls2.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls2va
        function Ls2va_Callback(app, event)
            % --- Executes on selection change in Ls2va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls2va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Ls2va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Ls2va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=2;
            S.Ls2.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls3am
        function Ls3am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls3am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Ls3am as text
            %        str2double(get(hObject,'String')) returns contents of Ls3am as a double
            
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=3;
            S.Ls3.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls3va
        function Ls3va_Callback(app, event)
            % --- Executes on selection change in Ls3va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls3va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Ls3va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Ls3va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=3;
            S.Ls3.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls4am
        function Ls4am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls4am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Ls4am as text
            %        str2double(get(hObject,'String')) returns contents of Ls4am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=4;
            S.Ls4.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls4va
        function Ls4va_Callback(app, event)
            % --- Executes on selection change in Ls4va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Ls4va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Ls4va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Ls4va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=4;
            S.Ls4.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: RCu
        function RCu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RCu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of RCu as text
            %        str2double(get(hObject,'String')) returns contents of RCu as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.case.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: Rs1am
        function Rs1am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs1am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Rs1am as text
            %        str2double(get(hObject,'String')) returns contents of Rs1am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=1;
            S.Rs1.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs1va
        function Rs1va_Callback(app, event)
            % --- Executes on selection change in Rs1va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs1va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Rs1va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Rs1va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=1;
            S.Rs1.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs2am
        function Rs2am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs2am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Rs2am as text
            %        str2double(get(hObject,'String')) returns contents of Rs2am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=2;
            S.Rs2.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs2va
        function Rs2va_Callback(app, event)
            % --- Executes on selection change in Rs2va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs2va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Rs2va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Rs2va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=2;
            S.Rs2.va=get(hObject,'Value');
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs3am
        function Rs3am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs3am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Rs3am as text
            %        str2double(get(hObject,'String')) returns contents of Rs3am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=3;
            S.Rs3.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs3va
        function Rs3va_Callback(app, event)
            % --- Executes on selection change in Rs3va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs3va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Rs3va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Rs3va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=3;
            S.Rs3.va=get(hObject,'Value');
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs4am
        function Rs4am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs4am (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Rs4am as text
            %        str2double(get(hObject,'String')) returns contents of Rs4am as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=4;
            S.Rs4.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs4va
        function Rs4va_Callback(app, event)
            % --- Executes on selection change in Rs4va.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Rs4va (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Rs4va contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Rs4va
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=4;
            S.Rs4.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: addStimSet
        function addStimSet_Callback(app, event)
            % --- Executes on button press in addStimSet.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to addStimSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of addStimSet
            if hObject.Value
                options = getappdata(handles.stimfig, 'options');
                stimLabel = getappdata(handles.stimfig, 'stimlabel');
            
                if strcmp(options.leadprod, 'dbs')
                    patdir = [options.root, options.patientname];
                    numContacts = options.elspec.numel;
                else % Special case when call from Lead Group
                    actpt = getappdata(handles.stimfig, 'actpt');
                    resultfig = getappdata(handles.stimfig, 'resultfig');
                    M = getappdata(resultfig, 'M');
                    patdir = M.patient.list{actpt};
                    numContacts = size(M.elstruct(actpt).coords_mm{1}, 1);
                end
            
                stimFolder = fullfile(patdir ,'stimulations', ea_nt(~handles.estimateInTemplate.Value), stimLabel);
                ea_mkdir(stimFolder);
                ea_addStimSet(numContacts, stimFolder, hObject);
            
                % Set stimSetMode flag for Lead Group
                if hObject.Value && strcmp(options.leadprod, 'group')
                    M.ui.stimSetMode = 1;
                    setappdata(resultfig, 'M');
                end
            end
        end

        % Value changed function: k0im
        function k0im_Callback(app, event)
            % --- Executes during object creation, after setting all properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k1im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k1im as text
            %        str2double(get(hObject,'String')) returns contents of k1im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k0.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k0u
        function k0u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k0u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k0u as text
            %        str2double(get(hObject,'String')) returns contents of k0u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k0.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k10im
        function k10im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k10im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k10im as text
            %        str2double(get(hObject,'String')) returns contents of k10im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k10.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k10u
        function k10u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k10u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k10u as text
            %        str2double(get(hObject,'String')) returns contents of k10u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k10.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k11im
        function k11im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k11im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k11im as text
            %        str2double(get(hObject,'String')) returns contents of k11im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k11.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k11u
        function k11u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k11u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k11u as text
            %        str2double(get(hObject,'String')) returns contents of k11u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k11.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k12im
        function k12im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k12im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k12im as text
            %        str2double(get(hObject,'String')) returns contents of k12im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k12.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k12u
        function k12u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k12u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k12u as text
            %        str2double(get(hObject,'String')) returns contents of k12u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k12.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k13im
        function k13im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k13im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k13im as text
            %        str2double(get(hObject,'String')) returns contents of k13im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k13.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k13u
        function k13u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k13u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k13u as text
            %        str2double(get(hObject,'String')) returns contents of k13u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k13.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k14im
        function k14im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k14im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k14im as text
            %        str2double(get(hObject,'String')) returns contents of k14im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k14.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k14u
        function k14u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k14u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k14u as text
            %        str2double(get(hObject,'String')) returns contents of k14u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k14.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k15im
        function k15im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k15im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k15im as text
            %        str2double(get(hObject,'String')) returns contents of k15im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k15.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k15u
        function k15u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k15u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k15u as text
            %        str2double(get(hObject,'String')) returns contents of k15u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k15.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k1im
        function k1im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k1im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k1im as text
            %        str2double(get(hObject,'String')) returns contents of k1im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k1.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k1u
        function k1u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k1u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k1u as text
            %        str2double(get(hObject,'String')) returns contents of k1u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k1.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k2im
        function k2im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k2im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k2im as text
            %        str2double(get(hObject,'String')) returns contents of k2im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k2.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k2u
        function k2u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k2u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k2u as text
            %        str2double(get(hObject,'String')) returns contents of k2u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k2.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k3im
        function k3im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3im as text
            %        str2double(get(hObject,'String')) returns contents of k3im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k3.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k3u
        function k3u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k3.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k4im
        function k4im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k4.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k4u
        function k4u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k4.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k5im
        function k5im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k5.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k5u
        function k5u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k5.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k6im
        function k6im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k6.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k6u
        function k6u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k6.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k7im
        function k7im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k7.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k7u
        function k7u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k3u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k3u as text
            %        str2double(get(hObject,'String')) returns contents of k3u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.k7.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k8im
        function k8im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k8im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k8im as text
            %        str2double(get(hObject,'String')) returns contents of k8im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k8.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k8u
        function k8u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k8u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k8u as text
            %        str2double(get(hObject,'String')) returns contents of k8u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k8.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k9im
        function k9im_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k9im (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k9im as text
            %        str2double(get(hObject,'String')) returns contents of k9im as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k9.imp=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k9u
        function k9u_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to k9u (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of k9u as text
            %        str2double(get(hObject,'String')) returns contents of k9u as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.k9.perc=',num2str(get(hObject,'String')),';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: modelselect
        function modelselect_Callback(app, event)
            % --- Executes on selection change in modelselect.
            if event.EventName == "Clicked"
                return;
            end

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to modelselect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns modelselect contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from modelselect
            
            % Set model
            S = getappdata(handles.stimfig,'S');
            models = get(handles.modelselect,'String');
            S.model = models{get(handles.modelselect,'Value')};
            setappdata(handles.stimfig,'S',S);
            
            % Handle special case for call from LeadGroup
            groupmode=getappdata(handles.stimfig,'groupmode');
            if groupmode
                choice = questdlg('Changing VAT model will delete stimulation parameters of all patients! Continue?', ...
                    'Warning', ...
                    'Yes, sure','No','No');
            
                gSv=getappdata(handles.stimfig,'gSv');
            
                switch choice
                    case 'No'
                        % Keep current model
                        currentModelInd = find(ismember(get(hObject,'String'),gSv.vatmodel),1);
                        set(hObject,'Value',currentModelInd);
                        return
                    case 'Yes, sure'
                        setappdata(handles.stimfig,'gS',[]);
            
                        % Set new model
                        models = get(hObject,'String');
                        gSv.vatmodel = models{get(hObject,'Value')};
                        setappdata(handles.stimfig,'gSv',gSv);
            
                        % Clear stimulation parameters
                        setappdata(handles.stimfig,'S',[]);
                end
            end
            
            % Refresh GUI
            options = getappdata(handles.stimfig,'options');
            ea_refreshguisp(handles,options);
            
            % Save stimulation parameters
            if ~groupmode
                S = getappdata(handles.stimfig,'S');
                ea_savestimulation(S,options);
            end
        end

        % Button pushed function: nextpt
        function nextpt_Callback(app, event)
            % --- Executes on button press in nextpt.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nextpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            S=getappdata(handles.stimfig,'S');
            gS=getappdata(handles.stimfig,'gS');
            
            gSv=getappdata(handles.stimfig,'gSv');
            
            actpt=getappdata(handles.stimfig,'actpt');
            elstruct=getappdata(handles.stimfig,'elstruct');
            options=getappdata(handles.stimfig,'options');
            
            %ensure active patient is non empty
            if isempty(actpt)
                actpt=1;
            end
            
            if isempty(gS)
                clear gS
            end
            
            S=ea_activecontacts(S);
            try
                gS(actpt)=S;
            catch
                S.sources=1:4;
                S.volume=[0,0];
                gS(actpt)=S;
            end
            setappdata(handles.stimfig,'gS',gS);
            
            if (actpt+1)>length(elstruct)
                setto=1;
            else
                setto=actpt+1;
            end
            try
                setappdata(handles.stimfig,'S',gS(setto));
            catch % S for this next pt not defined yet.
                setappdata(handles.stimfig,'S',[]);
            end
            setappdata(handles.stimfig,'actpt',setto);
            
            ea_refreshguisp(handles,options);
        end

        % Button pushed function: prevpt
        function prevpt_Callback(app, event)
            % --- Executes on button press in prevpt.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prevpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            S=getappdata(handles.stimfig,'S');
            gS=getappdata(handles.stimfig,'gS');
            actpt=getappdata(handles.stimfig,'actpt');
            elstruct=getappdata(handles.stimfig,'elstruct');
            options=getappdata(handles.stimfig,'options');
            
            %ensure active patient is non empty
            if isempty(actpt)
                actpt=1;
            end
            
            if isempty(gS)
                clear gS
            end
            
            S=ea_activecontacts(S);
            gS(actpt)=S;
            setappdata(handles.stimfig,'gS',gS);
            
            if (actpt-1)<1
                setto=length(elstruct);
            else
                setto=actpt-1;
            end
            
            try
                setappdata(handles.stimfig,'S',gS(setto));
            catch
                setappdata(handles.stimfig,'S',[]);
            end
            setappdata(handles.stimfig,'actpt',setto);
            
            ea_refreshguisp(handles,options);
        end

        % Value changed function: pulseWidthTextbox_L
        function pulseWidthTextbox_L_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pulseWidthTextbox_L (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of pulseWidthTextbox_L as text
            %        str2double(get(hObject,'String')) returns contents of pulseWidthTextbox_L as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Ls',num2str(S.active(2)),'.pulseWidth=',hObject.String,';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: pulseWidthTextbox_R
        function pulseWidthTextbox_R_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pulseWidthTextbox_R (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of pulseWidthTextbox_R as text
            %        str2double(get(hObject,'String')) returns contents of pulseWidthTextbox_R as a double
            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            eval(['S.Rs',num2str(S.active(1)),'.pulseWidth=',hObject.String,';']);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Button pushed function: saveparams
        function saveparams_Callback(app, event)
            % --- Executes on button press in saveparams.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveparams (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            S = getappdata(handles.stimfig, 'S');
            gS = getappdata(handles.stimfig, 'gS');
            actpt = getappdata(handles.stimfig, 'actpt');
            elstruct = getappdata(handles.stimfig, 'elstruct');
            options = getappdata(handles.stimfig, 'options');
            
            if isempty(gS)
                clear gS
            end
            
            S = ea_activecontacts(S);
            
            try
                gS(actpt)=S;
            catch
                S.sources=1:4;
                S.volume=[0,0];
                gS(actpt)=S;
            end
            setappdata(handles.stimfig, 'gS', gS);
            
            gSv = getappdata(handles.stimfig, 'gSv');
            lgfig = getappdata(handles.stimfig, 'resultfig');
            
            setappdata(lgfig, 'S', gS);
            setappdata(lgfig, 'vatmodel', gSv.vatmodel);
            setstimbutton = lgfig.findobj('Tag','setstimparamsbutton');
            if ~isempty(gS)
                set(setstimbutton,'BackgroundColor',[0.1;0.8;0.1]);
            else
                set(setstimbutton,'BackgroundColor',[0.93,0.93,0.93]);
            end
            
            ea_setprefs('vatsettings.estimateInTemplate',get(handles.estimateInTemplate,'Value'));
            
            close(handles.stimfig);
        end

        % Button pushed function: settings
        function settings_Callback(app, event)
            % --- Executes on button press in settings.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to settings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            models=get(handles.modelselect,'String');
            try
                model=models{get(handles.modelselect,'Value')};
            catch
                set(handles.modelselect,'Value',1);
                model=models{1};
            end
            
            switch model
                case 'SimBio/FieldTrip (see Horn 2017)'
                    ea_vatsettings_horn;
                case 'Dembek 2017'
                    ea_vatsettings_dembek;
                case 'Fastfield (Baniasadi 2020)'
                    ea_vatsettings_fastfield;
                case 'OSS-DBS (Butenko 2020)'
                    ea_vatsettings_butenko(handles);
            end
        end

        % Close request function: stimfig
        function stimfig_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close stimfig.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stimfig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            ea_setprefs('vatsettings.estimateInTemplate',get(handles.estimateInTemplate,'Value'));
            delete(hObject);
        end

        % Value changed function: stimlabel
        function stimlabel_Callback(app, event)
            if event.EventName == "Clicked"
                return;
            end

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stimlabel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of stimlabel as text
            %        str2double(get(hObject,'String')) returns contents of stimlabel as a double
            S = getappdata(handles.stimfig,'S');
            options = getappdata(handles.stimfig,'options');
            sel = get(handles.stimlabel,'String');
            sel = sel{get(handles.stimlabel,'Value')};
            if startsWith(sel,' => ') % command, not entry
                switch sel(5:end)
                    case 'New stimulation'
                        resultfig = getappdata(handles.stimfig,'resultfig');
            
                        PL = getappdata(resultfig, 'PL');
                        for group = 1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
            
                        ea_savestimulation(S,options);
                        S = []; % this will create the prompt to generate a new S.
                        options.gen_newstim = 1;
                        setappdata(handles.stimfig,'options',options);
                        setappdata(handles.stimfig,'S',S);
                        ea_refreshguisp(handles,options);
                        S = getappdata(handles.stimfig,'S');
                        ea_savestimulation(S,options);
                        options.gen_newstim = 0; % reset new stim flag
                        setappdata(handles.stimfig,'options',options);
                    case 'Rename stimulation'
                        stimlabel = getappdata(handles.stimfig,'stimlabel');
            
                        [~,ix] = ismember(stimlabel,get(handles.stimlabel,'String'));
                        set(handles.stimlabel,'Value',ix);
                        stimc = inputdlg('Please enter a label for this stimulation','Stimulation Label',1,{stimlabel});
                        if isfolder([options.subj.stimDir,filesep,ea_nt(0),stimlabel])
                            movefile([options.subj.stimDir,filesep,ea_nt(0),stimlabel],[options.subj.stimDir,filesep,ea_nt(0),stimc{1}]);
                        end
                        if isfolder([options.subj.stimDir,filesep,ea_nt(1),stimlabel])
                            movefile([options.subj.stimDir,filesep,ea_nt(1),stimlabel],[options.subj.stimDir,filesep,ea_nt(1),stimc{1}]);
                        end
                        slabelc = get(handles.stimlabel,'String');
                        slabelc{ix} = stimc{1};
                        set(handles.stimlabel,'String',slabelc);
                        S.label = stimc{1};
                        setappdata(handles.stimfig,'S',S);
                        setappdata(handles.stimfig,'stimlabel',S.label);
                        ea_refreshguisp(handles,options);
                        ea_savestimulation(S,options);
                    case 'Delete stimulation'
                        answ = questdlg(['Are you sure you wish to delete the stimulation parameters for ',...
                            S.label,'?'],'Delete stimulation parameters','Sure','No','No');
                        if strcmp(answ,'No')
                            set(handles.stimlabel,'Value',1);
                        else % truly delete Stimulation parameters
                            resultfig = getappdata(handles.stimfig,'resultfig');
                            PL = getappdata(resultfig, 'PL');
                            for group = 1:length(PL)
                                ea_deletePL(PL(group));
                            end
                            clear PL
                            ea_delete([options.subj.stimDir,filesep,ea_nt(0),S.label]);
                            ea_delete([options.subj.stimDir,filesep,ea_nt(1),S.label]);
                            S = []; % this will create the prompt to generate a new S.
                            setappdata(handles.stimfig,'S',S);
                            set(handles.stimlabel,'Value',1);
                            setappdata(handles.stimfig,'stimlabel','');
                            options.gen_newstim = 0;
                            setappdata(handles.stimfig,'S',S);
                            ea_refreshguisp(handles,options);
                            S = getappdata(handles.stimfig,'S');
                            ea_savestimulation(S,options);
                        end
                end
            else
                label = handles.stimlabel.String{handles.stimlabel.Value};
                label(strfind(label,' ')) = '';
                S = ea_loadstimulation(label,options);
                S.label = label;
                setappdata(handles.stimfig,'S',S);
                setappdata(handles.stimfig,'stimlabel',S.label);
                setappdata(handles.stimfig,'S',S);
                ea_refreshguisp(handles,options);
            
                %% stuff by Till for visualizing VATs by selecting them from the stimlabel list
                % tries to load .mat-files which are now created by ea_genvat_horn.m
                % and contain the VAT as well as the quiver. In case no .mat-files are
                % available the vat_xxx.nii is loaded and visualized
            
                stimDir = fullfile(options.subj.stimDir, ea_nt(options), label);
                filePrefix = ['sub-', options.subj.subjId, '_sim-'];
            
                stimParams = ea_regexpdir(stimDir, 'stimparameters\.mat$', 0);
                load(stimParams{1}, 'S');
                modelLabel = ea_simModel2Label(S.model);
            
                visualizeVAT = 1;
                stimparams = struct();
                if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                    stimparams(1,1).VAT.VAT = vatfv;
                    stimparams(1,1).volume = vatvolume;
                    if exist('vatgrad','var')
                        vatgradtemp(1) = vatgrad;
                    end
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                    stimparams(1,2).VAT.VAT = vatfv;
                    stimparams(1,2).volume = vatvolume;
                    if exist('vatgrad','var')
                        vatgradtemp(2) = vatgrad;
                        vatgrad = vatgradtemp;
                    end
                elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat'])
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                    stimparams(1,1).VAT.VAT = vatfv;
                    stimparams(1,1).volume = vatvolume;
                elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                    stimparams(1,2).VAT.VAT = vatfv;
                    stimparams(1,2).volume = vatvolume;
                else
                    if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii'])
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                    else
                        visualizeVAT = 0;
                    end
                end
            
                visualizeFiberActivation = 1;
                if isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat']) ...
                        && isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'])
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                else
                    visualizeFiberActivation = 0;
                end
            
                if visualizeVAT
                    setappdata(handles.stimfig,'stimparams',stimparams);
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    if exist('vatgrad')
                        setappdata(resultfig,'vatgrad',vatgrad);
                    end
                    setappdata(resultfig,'stimparams',stimparams(1,:));
                    setappdata(resultfig,'curS',S(1))
                    options.writeoutstats = 1;
                    ea_calc_vatstats(resultfig,options);
                end
            
                if ~visualizeVAT && ~visualizeFiberActivation
                    fprintf('\n');
                    warning('off', 'backtrace');
                    warning('Nothing to be visualized, please rerun stimulation!!');
                    warning('on', 'backtrace');
                    fprintf('\n');
                end
            end
        end

        % Button pushed function: stimulate
        function stimulate_Callback(app, event)
            % --- Executes on button press in stimulate.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stimulate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ea_busyaction('on',handles.stimfig,'stim');
            elstruct = getappdata(handles.stimfig,'elstruct');
            resultfig = getappdata(handles.stimfig,'resultfig');
            options = getappdata(handles.stimfig,'options');
            % refresh prefs:
            options.prefs = ea_prefs;
            setappdata(resultfig,'options',options);
            setappdata(handles.stimfig,'options',options);
            S = getappdata(handles.stimfig,'S');
            S = ea_activecontacts(S);
            
            options = getappdata(resultfig,'options'); % selected atlas could have refreshed.
            options.orignative = options.native;
            
            if handles.estimateInTemplate.Visible % only allowed for specific VTA functions
                switch handles.estimateInTemplate.Value
                    case 0
                        options.native = 1;
                    case 1
                        options.native = 0;
                end
            end
            
            if ~isfield(options.subj, 'norm') && options.native
                ea_cprintf('CmdWinWarnings', 'Calculating VTA in template space since patient folder %s is incomplete.\n', options.subj.subjId);
                options.native = 0;
            end
            
            ea_savestimulation(S,options);
            setappdata(handles.stimfig,'S',S);
            
            if isfield(elstruct,'group') % group analysis, more than one electrode set
                % this should not happen, in this case the stim button is
                % hidden.
                keyboard
            end
            
            % assign correct .m-file to function.
            genvatfunctions = getappdata(handles.stimfig,'genvatfunctions');
            ea_genvat = eval(['@',genvatfunctions{get(handles.modelselect,'Value')}]);
            stimname = S.label;
            
            for el = 1:length(elstruct)
                % Load stim coordinates
                if options.native % Reload native space coordinates
                    coords = ea_load_reconstruction(options);
                else
                    coords = elstruct(el).coords_mm;
                end
            
                if strcmp(S.model, 'OSS-DBS (Butenko 2020)') % For OSS-DBS, side iteration is within the genvat function
                    % Set stimSetMode flag to options
                    % (avoid additional parameter or setting appdata, to make it scriptable)
                    if handles.addStimSet.Value
                        options.stimSetMode = 1;
                    else
                        options.stimSetMode = 0;
                    end
                    if options.prefs.machine.vatsettings.butenko_calcPAM
                        feval(ea_genvat,getappdata(handles.stimfig,'S'),options,handles.stimfig);
                        ea_busyaction('off',handles.stimfig,'stim');
                        return;
                    else
                        [~, stimparams] = feval(ea_genvat,getappdata(handles.stimfig,'S'),options,handles.stimfig);
                        flix = 1;
                    end
                else
                    stimparams = struct();
                    for iside = 1:length(options.sides)
                        side = options.sides(iside);
                        [vatfv, vatvolume] = feval(ea_genvat,coords,getappdata(handles.stimfig,'S'),side,options,stimname,handles.stimfig);
                        stimparams(1,side).VAT(el).VAT = vatfv;
                        stimparams(1,side).volume = vatvolume;
                        flix = 1;
                    end
                end
            end
            
            options.native = options.orignative;
            PL = getappdata(resultfig, 'PL');
            for group = 1:length(PL)
                ea_deletePL(PL(group));
            end
            clear PL
            
            for group = flix
                setappdata(resultfig,'stimparams',stimparams(group,:));
                setappdata(resultfig,'curS',S(group));
            
                if ~exist('hmchanged','var')
                    hmchanged = 1;
                end
                ea_calc_vatstats(resultfig,options,hmchanged);
            
                try % TODO: fix dir
                    copyfile([options.root,options.patientname,filesep,'ea_pm.nii'],[options.root,options.patientname,filesep,'ea_pm_group_',num2str(group),'.nii']);
                end
            
                try
                    PL(group) = getappdata(resultfig,'PL');
                catch
                    keyboard
                end
            end
            setappdata(resultfig,'PL',PL);
            
            ea_busyaction('off',handles.stimfig,'stim');
        end

        % Window key release function: stimfig
        function stimfigWindowKeyRelease(app, event)
            if ~isempty(event.Source.CurrentObject)
                key = event.Key;
                sourceTag = event.Source.CurrentObject.Tag;
                if key == "return" && ismember(sourceTag, {'Rs1am','Rs2am','Rs3am','Rs4am','Ls1am','Ls2am','Ls3am','Ls4am'})
                    [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
                    S=getappdata(handles.stimfig,'S');
                    options=getappdata(handles.stimfig,'options');
                    if sourceTag(1) == "R"
                        S.active(1)=str2double(sourceTag(3));
                    elseif sourceTag(1) == "L"
                        S.active(2)=str2double(sourceTag(3));
                    end
                    S.(sourceTag(1:3)).amp=str2double(event.Source.CurrentObject.Value);
                    
                    setappdata(handles.stimfig,'S',S);
                    ea_refreshguisp(handles,options);
                end
            end
        end

        % Window button up function: stimfig
        function stimfigWindowButtonUp(app, event)
            if ~isempty(event.Source.CurrentObject)
                sourceTag = event.Source.CurrentObject.Tag;
                if event.Source.SelectionType == "normal" && ismember(sourceTag, {'Rs1am','Rs2am','Rs3am','Rs4am','Ls1am','Ls2am','Ls3am','Ls4am'})
                    [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
                    S=getappdata(handles.stimfig,'S');
                    options=getappdata(handles.stimfig,'options');
                    if sourceTag(1) == "R"
                        S.active(1)=str2double(sourceTag(3));
                    elseif sourceTag(1) == "L"
                        S.active(2)=str2double(sourceTag(3));
                    end
                    S.(sourceTag(1:3)).amp=str2double(event.Source.CurrentObject.Value);
                    
                    setappdata(handles.stimfig,'S',S);
                    ea_refreshguisp(handles,options);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.stimfig = uifigure('Visible', 'off');
            colormap(app.stimfig, 'parula');
            app.stimfig.Name = 'ea_stimparams';
            app.stimfig.CloseRequestFcn = createCallbackFcn(app, @stimfig_CloseRequestFcn, true);
            app.stimfig.WindowButtonUpFcn = createCallbackFcn(app, @stimfigWindowButtonUp, true);
            app.stimfig.WindowKeyReleaseFcn = createCallbackFcn(app, @stimfigWindowKeyRelease, true);
            app.stimfig.Tag = 'stimfig';
            app.headertxt = uilabel(app.stimfig);
            app.headertxt.Tag = 'headertxt';
            app.headertxt.Text = 'Patient:';
            app.modelselect = uidropdown(app.stimfig);
            app.modelselect.Items = {'Maedler 2012', 'Kuncel 2008', 'SimBio/FieldTrip (see Horn 2017)'};
            app.modelselect.ValueChangedFcn = createCallbackFcn(app, @modelselect_Callback, true);
            app.modelselect.Tag = 'modelselect';
            app.modelselect.Value = 'SimBio/FieldTrip (see Horn 2017)';
            app.stimulate = uibutton(app.stimfig, 'push');
            app.stimulate.ButtonPushedFcn = createCallbackFcn(app, @stimulate_Callback, true);
            app.stimulate.Tag = 'stimulate';
            app.stimulate.Text = 'Visualize';
            app.text13 = uilabel(app.stimfig);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Model:';
            app.uipanel2 = uipanel(app.stimfig);
            app.uipanel2.Title = 'Right Hemisphere';
            app.uipanel2.Tag = 'uipanel2';
            app.RCax = uiaxes(app.uipanel2);
            app.RCax.Tag = 'RCax';
            app.k0ax = uiaxes(app.uipanel2);
            app.k0ax.Tag = 'k0ax';
            app.k1ax = uiaxes(app.uipanel2);
            app.k1ax.Tag = 'k1ax';
            app.k2ax = uiaxes(app.uipanel2);
            app.k2ax.Tag = 'k2ax';
            app.k3ax = uiaxes(app.uipanel2);
            app.k3ax.Tag = 'k3ax';
            app.k7ax = uiaxes(app.uipanel2);
            app.k7ax.Tag = 'k7ax';
            app.k6ax = uiaxes(app.uipanel2);
            app.k6ax.Tag = 'k6ax';
            app.k5ax = uiaxes(app.uipanel2);
            app.k5ax.Tag = 'k5ax';
            app.k4ax = uiaxes(app.uipanel2);
            app.k4ax.Tag = 'k4ax';
            app.k0u = uieditfield(app.uipanel2, 'text');
            app.k0u.ValueChangedFcn = createCallbackFcn(app, @k0u_Callback, true);
            app.k0u.Tag = 'k0u';
            app.k0u.Value = '0';
            app.k0txt = uilabel(app.uipanel2);
            app.k0txt.Tag = 'k0txt';
            app.k0txt.Text = 'K0';
            app.k1u = uieditfield(app.uipanel2, 'text');
            app.k1u.ValueChangedFcn = createCallbackFcn(app, @k1u_Callback, true);
            app.k1u.Tag = 'k1u';
            app.k1u.Value = '0';
            app.k1txt = uilabel(app.uipanel2);
            app.k1txt.Tag = 'k1txt';
            app.k1txt.Text = 'K1';
            app.k2u = uieditfield(app.uipanel2, 'text');
            app.k2u.ValueChangedFcn = createCallbackFcn(app, @k2u_Callback, true);
            app.k2u.Tag = 'k2u';
            app.k2u.Value = '0';
            app.k2txt = uilabel(app.uipanel2);
            app.k2txt.Tag = 'k2txt';
            app.k2txt.Text = 'K2';
            app.k3u = uieditfield(app.uipanel2, 'text');
            app.k3u.ValueChangedFcn = createCallbackFcn(app, @k3u_Callback, true);
            app.k3u.Tag = 'k3u';
            app.k3u.Value = '0';
            app.k3txt = uilabel(app.uipanel2);
            app.k3txt.Tag = 'k3txt';
            app.k3txt.Text = 'K3';
            app.k0im = uieditfield(app.uipanel2, 'text');
            app.k0im.ValueChangedFcn = createCallbackFcn(app, @k0im_Callback, true);
            app.k0im.Tag = 'k0im';
            app.k0im.Value = '1';
            app.k1im = uieditfield(app.uipanel2, 'text');
            app.k1im.ValueChangedFcn = createCallbackFcn(app, @k1im_Callback, true);
            app.k1im.Tag = 'k1im';
            app.k1im.Value = '1';
            app.k2im = uieditfield(app.uipanel2, 'text');
            app.k2im.ValueChangedFcn = createCallbackFcn(app, @k2im_Callback, true);
            app.k2im.Tag = 'k2im';
            app.k2im.Value = '1';
            app.k3im = uieditfield(app.uipanel2, 'text');
            app.k3im.ValueChangedFcn = createCallbackFcn(app, @k3im_Callback, true);
            app.k3im.Tag = 'k3im';
            app.k3im.Value = '1';
            app.perctext1 = uilabel(app.uipanel2);
            app.perctext1.Tag = 'perctext1';
            app.perctext1.Text = '%';
            app.kohmtext1 = uilabel(app.uipanel2);
            app.kohmtext1.Tag = 'kohmtext1';
            app.kohmtext1.Text = 'kΩ';
            app.k4u = uieditfield(app.uipanel2, 'text');
            app.k4u.ValueChangedFcn = createCallbackFcn(app, @k4u_Callback, true);
            app.k4u.Tag = 'k4u';
            app.k4u.Value = '0';
            app.k4txt = uilabel(app.uipanel2);
            app.k4txt.Tag = 'k4txt';
            app.k4txt.Text = 'K4';
            app.k5u = uieditfield(app.uipanel2, 'text');
            app.k5u.ValueChangedFcn = createCallbackFcn(app, @k5u_Callback, true);
            app.k5u.Tag = 'k5u';
            app.k5u.Value = '0';
            app.k5txt = uilabel(app.uipanel2);
            app.k5txt.Tag = 'k5txt';
            app.k5txt.Text = 'K5';
            app.k6u = uieditfield(app.uipanel2, 'text');
            app.k6u.ValueChangedFcn = createCallbackFcn(app, @k6u_Callback, true);
            app.k6u.Tag = 'k6u';
            app.k6u.Value = '0';
            app.k6txt = uilabel(app.uipanel2);
            app.k6txt.Tag = 'k6txt';
            app.k6txt.Text = 'K6';
            app.k7u = uieditfield(app.uipanel2, 'text');
            app.k7u.ValueChangedFcn = createCallbackFcn(app, @k7u_Callback, true);
            app.k7u.Tag = 'k7u';
            app.k7u.Value = '0';
            app.k7txt = uilabel(app.uipanel2);
            app.k7txt.Tag = 'k7txt';
            app.k7txt.Text = 'K7';
            app.k4im = uieditfield(app.uipanel2, 'text');
            app.k4im.ValueChangedFcn = createCallbackFcn(app, @k4im_Callback, true);
            app.k4im.Tag = 'k4im';
            app.k4im.Value = '1';
            app.k5im = uieditfield(app.uipanel2, 'text');
            app.k5im.ValueChangedFcn = createCallbackFcn(app, @k5im_Callback, true);
            app.k5im.Tag = 'k5im';
            app.k5im.Value = '1';
            app.k6im = uieditfield(app.uipanel2, 'text');
            app.k6im.ValueChangedFcn = createCallbackFcn(app, @k6im_Callback, true);
            app.k6im.Tag = 'k6im';
            app.k6im.Value = '1';
            app.k7im = uieditfield(app.uipanel2, 'text');
            app.k7im.ValueChangedFcn = createCallbackFcn(app, @k7im_Callback, true);
            app.k7im.Tag = 'k7im';
            app.k7im.Value = '1';
            app.perctext2 = uilabel(app.uipanel2);
            app.perctext2.Tag = 'perctext2';
            app.perctext2.Text = '%';
            app.kohmtext2 = uilabel(app.uipanel2);
            app.kohmtext2.Tag = 'kohmtext2';
            app.kohmtext2.Text = 'kΩ';
            app.RCu = uieditfield(app.uipanel2, 'text');
            app.RCu.ValueChangedFcn = createCallbackFcn(app, @RCu_Callback, true);
            app.RCu.Tag = 'RCu';
            app.RCu.Value = '100';
            app.caseRtxt = uilabel(app.uipanel2);
            app.caseRtxt.Tag = 'caseRtxt';
            app.caseRtxt.Text = 'Case';
            app.Rs1am = uieditfield(app.uipanel2, 'text');
            app.Rs1am.ValueChangedFcn = createCallbackFcn(app, @Rs1am_Callback, true);
            app.Rs1am.Tag = 'Rs1am';
            app.Rs1am.Value = '0';
            app.text36 = uilabel(app.uipanel2);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Sources';
            app.Rs1va = uidropdown(app.uipanel2);
            app.Rs1va.Items = {'V', 'mA'};
            app.Rs1va.ValueChangedFcn = createCallbackFcn(app, @Rs1va_Callback, true);
            app.Rs1va.Tag = 'Rs1va';
            app.Rs1va.Value = 'V';
            app.Rs2am = uieditfield(app.uipanel2, 'text');
            app.Rs2am.ValueChangedFcn = createCallbackFcn(app, @Rs2am_Callback, true);
            app.Rs2am.Tag = 'Rs2am';
            app.Rs2am.Value = '0';
            app.Rs2va = uidropdown(app.uipanel2);
            app.Rs2va.Items = {'V', 'mA'};
            app.Rs2va.ValueChangedFcn = createCallbackFcn(app, @Rs2va_Callback, true);
            app.Rs2va.Tag = 'Rs2va';
            app.Rs2va.Value = 'V';
            app.Rs3am = uieditfield(app.uipanel2, 'text');
            app.Rs3am.ValueChangedFcn = createCallbackFcn(app, @Rs3am_Callback, true);
            app.Rs3am.Tag = 'Rs3am';
            app.Rs3am.Value = '0';
            app.Rs3va = uidropdown(app.uipanel2);
            app.Rs3va.Items = {'V', 'mA'};
            app.Rs3va.ValueChangedFcn = createCallbackFcn(app, @Rs3va_Callback, true);
            app.Rs3va.Tag = 'Rs3va';
            app.Rs3va.Value = 'V';
            app.Rs4am = uieditfield(app.uipanel2, 'text');
            app.Rs4am.ValueChangedFcn = createCallbackFcn(app, @Rs4am_Callback, true);
            app.Rs4am.Tag = 'Rs4am';
            app.Rs4am.Value = '0';
            app.Rs4va = uidropdown(app.uipanel2);
            app.Rs4va.Items = {'V', 'mA'};
            app.Rs4va.ValueChangedFcn = createCallbackFcn(app, @Rs4va_Callback, true);
            app.Rs4va.Tag = 'Rs4va';
            app.Rs4va.Value = 'V';
            app.pulseWidthTextbox_R = uieditfield(app.uipanel2, 'text');
            app.pulseWidthTextbox_R.ValueChangedFcn = createCallbackFcn(app, @pulseWidthTextbox_R_Callback, true);
            app.pulseWidthTextbox_R.Tag = 'pulseWidthTextbox_R';
            app.pulseWidthTextbox_R.Visible = 'off';
            app.pulseWidthTextbox_R.Value = '60';
            app.pulseWidthLabel_R = uilabel(app.uipanel2);
            app.pulseWidthLabel_R.Tag = 'pulseWidthLabel_R';
            app.pulseWidthLabel_R.Visible = 'off';
            app.pulseWidthLabel_R.Text = 'Pulse width';
            app.usLabel_R = uilabel(app.uipanel2);
            app.usLabel_R.Tag = 'usLabel_R';
            app.usLabel_R.Visible = 'off';
            app.usLabel_R.Text = 'μs';
            app.uipanel3 = uipanel(app.stimfig);
            app.uipanel3.Title = 'Left Hemisphere';
            app.uipanel3.Tag = 'uipanel3';
            app.LCax = uiaxes(app.uipanel3);
            app.LCax.Tag = 'LCax';
            app.k8ax = uiaxes(app.uipanel3);
            app.k8ax.Tag = 'k8ax';
            app.k9ax = uiaxes(app.uipanel3);
            app.k9ax.Tag = 'k9ax';
            app.k10ax = uiaxes(app.uipanel3);
            app.k10ax.Tag = 'k10ax';
            app.k11ax = uiaxes(app.uipanel3);
            app.k11ax.Tag = 'k11ax';
            app.k15ax = uiaxes(app.uipanel3);
            app.k15ax.Tag = 'k15ax';
            app.k14ax = uiaxes(app.uipanel3);
            app.k14ax.Tag = 'k14ax';
            app.k13ax = uiaxes(app.uipanel3);
            app.k13ax.Tag = 'k13ax';
            app.k12ax = uiaxes(app.uipanel3);
            app.k12ax.Tag = 'k12ax';
            app.k8u = uieditfield(app.uipanel3, 'text');
            app.k8u.ValueChangedFcn = createCallbackFcn(app, @k8u_Callback, true);
            app.k8u.Tag = 'k8u';
            app.k8u.Value = '0';
            app.k8txt = uilabel(app.uipanel3);
            app.k8txt.Tag = 'k8txt';
            app.k8txt.Text = 'K8';
            app.k9u = uieditfield(app.uipanel3, 'text');
            app.k9u.ValueChangedFcn = createCallbackFcn(app, @k9u_Callback, true);
            app.k9u.Tag = 'k9u';
            app.k9u.Value = '0';
            app.k9txt = uilabel(app.uipanel3);
            app.k9txt.Tag = 'k9txt';
            app.k9txt.Text = 'K9';
            app.k10u = uieditfield(app.uipanel3, 'text');
            app.k10u.ValueChangedFcn = createCallbackFcn(app, @k10u_Callback, true);
            app.k10u.Tag = 'k10u';
            app.k10u.Value = '0';
            app.k10txt = uilabel(app.uipanel3);
            app.k10txt.Tag = 'k10txt';
            app.k10txt.Text = 'K10';
            app.k11u = uieditfield(app.uipanel3, 'text');
            app.k11u.ValueChangedFcn = createCallbackFcn(app, @k11u_Callback, true);
            app.k11u.Tag = 'k11u';
            app.k11u.Value = '0';
            app.k11txt = uilabel(app.uipanel3);
            app.k11txt.Tag = 'k11txt';
            app.k11txt.Text = 'K11';
            app.k8im = uieditfield(app.uipanel3, 'text');
            app.k8im.ValueChangedFcn = createCallbackFcn(app, @k8im_Callback, true);
            app.k8im.Tag = 'k8im';
            app.k8im.Value = '1';
            app.k9im = uieditfield(app.uipanel3, 'text');
            app.k9im.ValueChangedFcn = createCallbackFcn(app, @k9im_Callback, true);
            app.k9im.Tag = 'k9im';
            app.k9im.Value = '1';
            app.k10im = uieditfield(app.uipanel3, 'text');
            app.k10im.ValueChangedFcn = createCallbackFcn(app, @k10im_Callback, true);
            app.k10im.Tag = 'k10im';
            app.k10im.Value = '1';
            app.k11im = uieditfield(app.uipanel3, 'text');
            app.k11im.ValueChangedFcn = createCallbackFcn(app, @k11im_Callback, true);
            app.k11im.Tag = 'k11im';
            app.k11im.Value = '1';
            app.perctext3 = uilabel(app.uipanel3);
            app.perctext3.Tag = 'perctext3';
            app.perctext3.Text = '%';
            app.kohmtext3 = uilabel(app.uipanel3);
            app.kohmtext3.Tag = 'kohmtext3';
            app.kohmtext3.Text = 'kΩ';
            app.k12u = uieditfield(app.uipanel3, 'text');
            app.k12u.ValueChangedFcn = createCallbackFcn(app, @k12u_Callback, true);
            app.k12u.Tag = 'k12u';
            app.k12u.Value = '0';
            app.k12txt = uilabel(app.uipanel3);
            app.k12txt.Tag = 'k12txt';
            app.k12txt.Text = 'K12';
            app.k13u = uieditfield(app.uipanel3, 'text');
            app.k13u.ValueChangedFcn = createCallbackFcn(app, @k13u_Callback, true);
            app.k13u.Tag = 'k13u';
            app.k13u.Value = '0';
            app.k13txt = uilabel(app.uipanel3);
            app.k13txt.Tag = 'k13txt';
            app.k13txt.Text = 'K13';
            app.k14u = uieditfield(app.uipanel3, 'text');
            app.k14u.ValueChangedFcn = createCallbackFcn(app, @k14u_Callback, true);
            app.k14u.Tag = 'k14u';
            app.k14u.Value = '0';
            app.k14txt = uilabel(app.uipanel3);
            app.k14txt.Tag = 'k14txt';
            app.k14txt.Text = 'K14';
            app.k15u = uieditfield(app.uipanel3, 'text');
            app.k15u.ValueChangedFcn = createCallbackFcn(app, @k15u_Callback, true);
            app.k15u.Tag = 'k15u';
            app.k15u.Value = '0';
            app.k15txt = uilabel(app.uipanel3);
            app.k15txt.Tag = 'k15txt';
            app.k15txt.Text = 'K15';
            app.k12im = uieditfield(app.uipanel3, 'text');
            app.k12im.ValueChangedFcn = createCallbackFcn(app, @k12im_Callback, true);
            app.k12im.Tag = 'k12im';
            app.k12im.Value = '1';
            app.k13im = uieditfield(app.uipanel3, 'text');
            app.k13im.ValueChangedFcn = createCallbackFcn(app, @k13im_Callback, true);
            app.k13im.Tag = 'k13im';
            app.k13im.Value = '1';
            app.k14im = uieditfield(app.uipanel3, 'text');
            app.k14im.ValueChangedFcn = createCallbackFcn(app, @k14im_Callback, true);
            app.k14im.Tag = 'k14im';
            app.k14im.Value = '1';
            app.k15im = uieditfield(app.uipanel3, 'text');
            app.k15im.ValueChangedFcn = createCallbackFcn(app, @k15im_Callback, true);
            app.k15im.Tag = 'k15im';
            app.k15im.Value = '1';
            app.perctext4 = uilabel(app.uipanel3);
            app.perctext4.Tag = 'perctext4';
            app.perctext4.Text = '%';
            app.kohmtext4 = uilabel(app.uipanel3);
            app.kohmtext4.Tag = 'kohmtext4';
            app.kohmtext4.Text = 'kΩ';
            app.LCu = uieditfield(app.uipanel3, 'text');
            app.LCu.ValueChangedFcn = createCallbackFcn(app, @LCu_Callback, true);
            app.LCu.Tag = 'LCu';
            app.LCu.Value = '100';
            app.caseLtxt = uilabel(app.uipanel3);
            app.caseLtxt.Tag = 'caseLtxt';
            app.caseLtxt.Text = 'Case';
            app.Ls1am = uieditfield(app.uipanel3, 'text');
            app.Ls1am.ValueChangedFcn = createCallbackFcn(app, @Ls1am_Callback, true);
            app.Ls1am.Tag = 'Ls1am';
            app.Ls1am.Value = '0';
            app.text50 = uilabel(app.uipanel3);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Sources';
            app.Ls1va = uidropdown(app.uipanel3);
            app.Ls1va.Items = {'V', 'mA'};
            app.Ls1va.ValueChangedFcn = createCallbackFcn(app, @Ls1va_Callback, true);
            app.Ls1va.Tag = 'Ls1va';
            app.Ls1va.Value = 'V';
            app.Ls2am = uieditfield(app.uipanel3, 'text');
            app.Ls2am.ValueChangedFcn = createCallbackFcn(app, @Ls2am_Callback, true);
            app.Ls2am.Tag = 'Ls2am';
            app.Ls2am.Value = '0';
            app.Ls2va = uidropdown(app.uipanel3);
            app.Ls2va.Items = {'V', 'mA'};
            app.Ls2va.ValueChangedFcn = createCallbackFcn(app, @Ls2va_Callback, true);
            app.Ls2va.Tag = 'Ls2va';
            app.Ls2va.Value = 'V';
            app.Ls3am = uieditfield(app.uipanel3, 'text');
            app.Ls3am.ValueChangedFcn = createCallbackFcn(app, @Ls3am_Callback, true);
            app.Ls3am.Tag = 'Ls3am';
            app.Ls3am.Value = '0';
            app.Ls3va = uidropdown(app.uipanel3);
            app.Ls3va.Items = {'V', 'mA'};
            app.Ls3va.ValueChangedFcn = createCallbackFcn(app, @Ls3va_Callback, true);
            app.Ls3va.Tag = 'Ls3va';
            app.Ls3va.Value = 'V';
            app.Ls4am = uieditfield(app.uipanel3, 'text');
            app.Ls4am.ValueChangedFcn = createCallbackFcn(app, @Ls4am_Callback, true);
            app.Ls4am.Tag = 'Ls4am';
            app.Ls4am.Value = '0';
            app.Ls4va = uidropdown(app.uipanel3);
            app.Ls4va.Items = {'V', 'mA'};
            app.Ls4va.ValueChangedFcn = createCallbackFcn(app, @Ls4va_Callback, true);
            app.Ls4va.Tag = 'Ls4va';
            app.Ls4va.Value = 'V';
            app.pulseWidthTextbox_L = uieditfield(app.uipanel3, 'text');
            app.pulseWidthTextbox_L.ValueChangedFcn = createCallbackFcn(app, @pulseWidthTextbox_L_Callback, true);
            app.pulseWidthTextbox_L.Tag = 'pulseWidthTextbox_L';
            app.pulseWidthTextbox_L.Visible = 'off';
            app.pulseWidthTextbox_L.Value = '60';
            app.pulseWidthLabel_L = uilabel(app.uipanel3);
            app.pulseWidthLabel_L.Tag = 'pulseWidthLabel_L';
            app.pulseWidthLabel_L.Visible = 'off';
            app.pulseWidthLabel_L.Text = 'Pulse width';
            app.usLabel_L = uilabel(app.uipanel3);
            app.usLabel_L.Tag = 'usLabel_L';
            app.usLabel_L.Visible = 'off';
            app.usLabel_L.Text = 'μs';
            app.stimlab = uilabel(app.stimfig);
            app.stimlab.Tag = 'stimlab';
            app.stimlab.Text = 'Stimulation:';
            app.stimlabel = uidropdown(app.stimfig);
            app.stimlabel.Items = {};
            app.stimlabel.ValueChangedFcn = createCallbackFcn(app, @stimlabel_Callback, true);
            app.stimlabel.Tag = 'stimlabel';
            app.stimlabel.Value = {};
            app.prevpt = uibutton(app.stimfig, 'push');
            app.prevpt.ButtonPushedFcn = createCallbackFcn(app, @prevpt_Callback, true);
            app.prevpt.Tag = 'prevpt';
            app.prevpt.Text = {'Previous'; 'patient'};
            app.nextpt = uibutton(app.stimfig, 'push');
            app.nextpt.ButtonPushedFcn = createCallbackFcn(app, @nextpt_Callback, true);
            app.nextpt.Tag = 'nextpt';
            app.nextpt.Text = {'Next'; 'patient'};
            app.saveparams = uibutton(app.stimfig, 'push');
            app.saveparams.ButtonPushedFcn = createCallbackFcn(app, @saveparams_Callback, true);
            app.saveparams.Tag = 'saveparams';
            app.saveparams.Text = 'Save';
            app.betawarning = uilabel(app.stimfig);
            app.betawarning.Tag = 'betawarning';
            app.betawarning.Text = 'Beta';
            app.settings = uibutton(app.stimfig, 'push');
            app.settings.ButtonPushedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Tag = 'settings';
            app.settings.Text = 'Settings';
            app.estimateInTemplate = uicheckbox(app.stimfig);
            app.estimateInTemplate.Tag = 'estimateInTemplate';
            app.estimateInTemplate.Tooltip = 'You may choose to directly solve the VTA in template space which may potentially be less accurate';
            app.estimateInTemplate.Text = 'Estimate in template space';
            app.addStimSet = uicheckbox(app.stimfig);
            app.addStimSet.ValueChangedFcn = createCallbackFcn(app, @addStimSet_Callback, true);
            app.addStimSet.Tag = 'addStimSet';
            app.addStimSet.Tooltip = 'Add a set of current stimulation amplitudes in Current_protocols_[0|1].csv';
            app.addStimSet.Text = 'Add StimSet';
            % Show the figure after all components are created
            app.stimfig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_stimparams(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.stimfig)

                % Execute the startup function
                runStartupFcn(app, @(app)ea_stimparams_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.stimfig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.stimfig)
        end
    end
end