classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshinformationfromLeadGroupanalysisMenu  matlab.ui.container.Menu
        RecreatevisualizationfigureMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        OptimizeParametersontrainingsetMenu  matlab.ui.container.Menu
        CrossvalidationsMenu            matlab.ui.container.Menu
        SimilaritiesDissimilaritiesMenu  matlab.ui.container.Menu
        CombinedMenu                    matlab.ui.container.Menu
        LoadPriorOptimization           matlab.ui.container.Menu
        LoadPriorOptimizationAndResume  matlab.ui.container.Menu
        LoadSettingsMenu                matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SingleTractAnalysisTab          matlab.ui.container.Tab
        ThresholdEditField_2            matlab.ui.control.NumericEditField
        ThresholdEditField_2Label       matlab.ui.control.Label
        WeighttractsbyROIButton         matlab.ui.control.Button
        RefineFiberColor                matlab.ui.control.Button
        ExportModelButton               matlab.ui.control.Button
        PlotDistributionButton          matlab.ui.control.Button
        ThresholdingDropDown            matlab.ui.control.DropDown
        ThresholdingDropDownLabel       matlab.ui.control.Label
        ShowAmountEditFieldNegative     matlab.ui.control.NumericEditField
        ShowAmountEditFieldPositive     matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative  matlab.ui.control.NumericEditField
        MultTestStrategy                matlab.ui.control.DropDown
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantFibersonlyCheckBox  matlab.ui.control.CheckBox
        ExportasAtlasButton             matlab.ui.control.Button
        ShowNegativeFibersCheckBox      matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox      matlab.ui.control.CheckBox
        ColorButtonNegative             matlab.ui.control.Button
        ShowAmountLabelPositive         matlab.ui.control.Label
        ShowAmountLeftLabelPositive     matlab.ui.control.Label
        ShowAmountRightLabelPositive    matlab.ui.control.Label
        ShowAmountLeftLabelNegative     matlab.ui.control.Label
        ShowAmountLabelNegative         matlab.ui.control.Label
        ShowAmountRightLabelNegative    matlab.ui.control.Label
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        ColorButtonPositive             matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        Subscore_1Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_2   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_2   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_2  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_2  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_2  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_2  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_2           matlab.ui.control.CheckBox
        RefreshViewButton_2             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_2    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_2    matlab.ui.control.CheckBox
        ColorButtonNegative_2           matlab.ui.control.Button
        ShowAmountLabelPositive_2       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_2   matlab.ui.control.Label
        ShowAmountRightLabelPositive_2  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_2   matlab.ui.control.Label
        ShowAmountLabelNegative_2       matlab.ui.control.Label
        ShowAmountRightLabelNegative_2  matlab.ui.control.Label
        ColorButtonPositive_2           matlab.ui.control.Button
        Subscore_2Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_3   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_3   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_3  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_3  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_3  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_3  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_3           matlab.ui.control.CheckBox
        RefreshViewButton_3             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_3    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_3    matlab.ui.control.CheckBox
        ColorButtonNegative_3           matlab.ui.control.Button
        ShowAmountLabelPositive_3       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_3   matlab.ui.control.Label
        ShowAmountRightLabelPositive_3  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_3   matlab.ui.control.Label
        ShowAmountLabelNegative_3       matlab.ui.control.Label
        ShowAmountRightLabelNegative_3  matlab.ui.control.Label
        ColorButtonPositive_3           matlab.ui.control.Button
        Subscore_3Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_4   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_4   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_4  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_4  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_4  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_4  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_4           matlab.ui.control.CheckBox
        RefreshViewButton_4             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_4    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_4    matlab.ui.control.CheckBox
        ColorButtonNegative_4           matlab.ui.control.Button
        ShowAmountLabelPositive_4       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_4   matlab.ui.control.Label
        ShowAmountRightLabelPositive_4  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_4   matlab.ui.control.Label
        ShowAmountLabelNegative_4       matlab.ui.control.Label
        ShowAmountRightLabelNegative_4  matlab.ui.control.Label
        ColorButtonPositive_4           matlab.ui.control.Button
        Subscore_4Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_5   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_5   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_5  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_5  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_5  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_5  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_5           matlab.ui.control.CheckBox
        RefreshViewButton_5             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_5    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_5    matlab.ui.control.CheckBox
        ColorButtonNegative_5           matlab.ui.control.Button
        ShowAmountLabelPositive_5       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_5   matlab.ui.control.Label
        ShowAmountRightLabelPositive_5  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_5   matlab.ui.control.Label
        ShowAmountLabelNegative_5       matlab.ui.control.Label
        ShowAmountRightLabelNegative_5  matlab.ui.control.Label
        ColorButtonPositive_5           matlab.ui.control.Button
        Subscore_5Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_6   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_6   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_6  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_6  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_6  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_6  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_6           matlab.ui.control.CheckBox
        RefreshViewButton_6             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_6    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_6    matlab.ui.control.CheckBox
        ColorButtonNegative_6           matlab.ui.control.Button
        ShowAmountLabelPositive_6       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_6   matlab.ui.control.Label
        ShowAmountRightLabelPositive_6  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_6   matlab.ui.control.Label
        ShowAmountLabelNegative_6       matlab.ui.control.Label
        ShowAmountRightLabelNegative_6  matlab.ui.control.Label
        ColorButtonPositive_6           matlab.ui.control.Button
        Subscore_6Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_7   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_7   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_7  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_7  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_7  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_7  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_7           matlab.ui.control.CheckBox
        RefreshViewButton_7             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_7    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_7    matlab.ui.control.CheckBox
        ColorButtonNegative_7           matlab.ui.control.Button
        ShowAmountLabelPositive_7       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_7   matlab.ui.control.Label
        ShowAmountRightLabelPositive_7  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_7   matlab.ui.control.Label
        ShowAmountLabelNegative_7       matlab.ui.control.Label
        ShowAmountRightLabelNegative_7  matlab.ui.control.Label
        ColorButtonPositive_7           matlab.ui.control.Button
        Subscore_7Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_8   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_8   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_8  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_8  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_8  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_8  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_8           matlab.ui.control.CheckBox
        RefreshViewButton_8             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_8    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_8    matlab.ui.control.CheckBox
        ColorButtonNegative_8           matlab.ui.control.Button
        ShowAmountLabelPositive_8       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_8   matlab.ui.control.Label
        ShowAmountRightLabelPositive_8  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_8   matlab.ui.control.Label
        ShowAmountLabelNegative_8       matlab.ui.control.Label
        ShowAmountRightLabelNegative_8  matlab.ui.control.Label
        ColorButtonPositive_8           matlab.ui.control.Button
        Subscore_8Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_9   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_9   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_9  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_9  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_9  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_9  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_9           matlab.ui.control.CheckBox
        RefreshViewButton_9             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_9    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_9    matlab.ui.control.CheckBox
        ColorButtonNegative_9           matlab.ui.control.Button
        ShowAmountLabelPositive_9       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_9   matlab.ui.control.Label
        ShowAmountRightLabelPositive_9  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_9   matlab.ui.control.Label
        ShowAmountLabelNegative_9       matlab.ui.control.Label
        ShowAmountRightLabelNegative_9  matlab.ui.control.Label
        ColorButtonPositive_9           matlab.ui.control.Button
        Subscore_9Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_10  matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_10  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_10  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_10  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_10  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_10  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_10          matlab.ui.control.CheckBox
        RefreshViewButton_10            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_10   matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_10   matlab.ui.control.CheckBox
        ColorButtonNegative_10          matlab.ui.control.Button
        ShowAmountLabelPositive_10      matlab.ui.control.Label
        ShowAmountLeftLabelPositive_10  matlab.ui.control.Label
        ShowAmountRightLabelPositive_10  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_10  matlab.ui.control.Label
        ShowAmountLabelNegative_10      matlab.ui.control.Label
        ShowAmountRightLabelNegative_10  matlab.ui.control.Label
        ColorButtonPositive_10          matlab.ui.control.Button
        Subscore_10Tab                  matlab.ui.container.Tab
        ShowAmountEditFieldPositive_11  matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_11  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_11  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_11  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_11  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_11  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_11          matlab.ui.control.CheckBox
        RefreshViewButton_11            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_11   matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_11   matlab.ui.control.CheckBox
        ColorButtonNegative_11          matlab.ui.control.Button
        ShowAmountLabelPositive_11      matlab.ui.control.Label
        ShowAmountLeftLabelPositive_11  matlab.ui.control.Label
        ShowAmountRightLabelPositive_11  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_11  matlab.ui.control.Label
        ShowAmountLabelNegative_11      matlab.ui.control.Label
        ShowAmountRightLabelNegative_11  matlab.ui.control.Label
        ColorButtonPositive_11          matlab.ui.control.Button
        MultitractAnalysisPanel         matlab.ui.container.Panel
        InspectPCAButton                matlab.ui.control.Button
        VarianceExpSlider               matlab.ui.control.Slider
        VarianceExpSliderLabel          matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        PCsEditField                    matlab.ui.control.NumericEditField
        PCsEditFieldLabel               matlab.ui.control.Label
        SubscoresListBox                matlab.ui.control.ListBox
        ModelSetupOptionButtonGroup     matlab.ui.container.ButtonGroup
        SplitColorByPCAButton           matlab.ui.control.RadioButton
        SplitColorBySubscoreButton      matlab.ui.control.RadioButton
        SplitColorByGroupButton         matlab.ui.control.RadioButton
        SingleTractAnalysisButton       matlab.ui.control.RadioButton
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        OpeninCurrentuneButton          matlab.ui.control.Button
        ImportedModelName               matlab.ui.control.EditField
        SubcohortsDropDown_2            matlab.ui.control.DropDown
        SubcohortsDropDown_2Label       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.StateButton
        NestedCheckBox                  matlab.ui.control.CheckBox
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        OpeninCleartuneButton           matlab.ui.control.Button
        MultitractWeightsButton         matlab.ui.control.Button
        ModeltypeDropDown               matlab.ui.control.DropDown
        FittoscoresCheckBox             matlab.ui.control.CheckBox
        BinsEditField                   matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        ModelNormalizationDropDown      matlab.ui.control.DropDown
        ModelNormalizationDropDownLabel  matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        EfieldmetricLabel               matlab.ui.control.Label
        StatFamilyLabel                 matlab.ui.control.Label
        StatTestLabel                   matlab.ui.control.Label
        StatH0Label                     matlab.ui.control.Label
        StimulationModelLabel           matlab.ui.control.Label
        StatFamilyDropDown              matlab.ui.control.DropDown
        StatTestDropDown                matlab.ui.control.DropDown
        StatH0DropDown                  matlab.ui.control.DropDown
        StimulationModel                matlab.ui.control.DropDown
        FibersSwitch                    matlab.ui.control.Switch
        ConnectedFibersLabel            matlab.ui.control.Label
        ROISwitch                       matlab.ui.control.Switch
        ROISwitchLabel                  matlab.ui.control.Label
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        Efieldmetric                    matlab.ui.control.DropDown
        CovariatesCheckBox              matlab.ui.control.CheckBox
        EfieldthresholdLabel            matlab.ui.control.Label
        EfieldthresholdSlider           matlab.ui.control.Slider
        ConnthresholdLabel              matlab.ui.control.Label
        ConnthresholdSlider             matlab.ui.control.Slider
        CovariatesListBox               matlab.ui.control.ListBox
        VariableofInterestDropDown      matlab.ui.control.DropDown
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        ConnectivityModelButtonGroup    matlab.ui.container.ButtonGroup
        PAMButton                       matlab.ui.control.RadioButton
        StimulationVolumeButton         matlab.ui.control.RadioButton
        VmLabel                         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ConnectomeDropDown              matlab.ui.control.DropDown
        ConnectomeDropDownLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        tractset % associated ea_disctract object.
     
    end


    methods (Access = private)

        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.tractset.colorbar)
                if strcmp(app.TabGroup.SelectedTab.Title, 'Single Tract Analysis')
                    app.ColorBar.Visible = 'on';
                    cb = app.tractset.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                else
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                end
                app.ColorBar.Visible = 'on';
                cb = app.tractset.colorbar;
                ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end


        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end

        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            dorefresh=1;

            if app.SplitColorByPCAButton.Value && strcmp(app.PCAsettingsLabel.Enable,'off') % case in which PCA would fail and hence drawing would run into errors.
                dorefresh=0;
            end


            if (app.AutoRefreshCheckBox.Value || force) && dorefresh
                app.isbusy(1);
                drawnow;
                app.tractset.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.ea_discfibers_tractset2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end

        function Vms = getvms(app)
            switch app.tractset.efieldmetric
                case {'Mean'}
                    Vms='V/m';
                otherwise
                    Vms='V/mm';
            end
        end

        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.tractset.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.tractset.covars = app.tractset.M.clinical.vars(is);
                    app.tractset.covarlabels = app.tractset.M.clinical.labels(is);
                else % none selected
                    app.tractset.covars = {};
                    app.tractset.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getstimamp},app.tractset.covars];
                    app.tractset.covarlabels = [{'Stimulation Amplitude'},app.tractset.covarlabels];
                end

                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getvtavolumes},app.tractset.covars];
                    app.tractset.covarlabels = [{'VTA Volume'},app.tractset.covarlabels];
                end

                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getefieldmagnitudes},app.tractset.covars];
                    app.tractset.covarlabels = [{'E-Field Magnitude Sum'},app.tractset.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.tractset.covars = [{app.tractset.getcohortregressor},app.tractset.covars];
                    app.tractset.covarlabels = [{'Group / Cohort regressor'},app.tractset.covarlabels];
                end
            else
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
            end
        end

        function setefieldsliders(app)
            %% Set slider limits
            switch app.tractset.statsettings.stimulationmodel
                case 'Sigmoid Field'
                    if app.tractset.statsettings.efieldthreshold > 1
                        app.tractset.statsettings.efieldthreshold = ea_SigmoidFromEfield(app.tractset.statsettings.efieldthreshold);
                    end
                    app.EfieldthresholdSlider.Limits=[round(ea_SigmoidFromEfield(app.tractset.calcthreshold),2),1]; % probability activation
                case 'Electric Field'
                    app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                    if app.tractset.statsettings.efieldthreshold < 1
                        app.tractset.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.tractset.statsettings.efieldthreshold);
                    end
                case 'VTA'
                    app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                    if app.tractset.statsettings.efieldthreshold < 1
                        app.tractset.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.tractset.statsettings.efieldthreshold);
                    end
            end
            %% Check if threshold is outside the limits
            if app.tractset.statsettings.efieldthreshold < app.EfieldthresholdSlider.Limits(1)
                app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(1);
            elseif app.tractset.statsettings.efieldthreshold > app.EfieldthresholdSlider.Limits(2)
                app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(2);
            end
            % app.EfieldthresholdSlider.Value=app.tractset.statsettings.efieldthreshold;
            % app.Efieldthreshold.Value=num2str(app.tractset.statsettings.efieldthreshold);
            % %% Set labels
            % switch app.StimulationModel.Value
            %     case 'Electric Field'
            %         app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
            %     case 'Sigmoid Field'
            %         app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.2f',app.EfieldthresholdSlider.Value)]};
            %     case 'VTA'
            %         app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
            % end


            value = app.tractset.efieldmetric;
            dimval=1000;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5]; % in V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[2.5,1000]; % in V/mm (!)
                    dimval=1;
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,5]; % in V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,5]; % in V/m
            end

            if app.tractset.SigmoidTransform == 1
                app.EfieldthresholdSlider.Limits=[0.0,1.0]; % probability activation
            end

            if app.tractset.efieldthreshold/dimval < app.EfieldthresholdSlider.Limits(1) % it is below min, set to min
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Limits(1)*dimval;
            end
            if app.tractset.efieldthreshold/dimval > app.EfieldthresholdSlider.Limits(2) % it is above max, set to max
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Limits(2)*dimval;
            end
        end
        function result = ea_isnan(app,input_array,flag)
            if size(input_array,2) > 1
                if strcmp(flag,'any')
                    op = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    op = all(isnan(input_array));
                end
                if length(find(op)) > 1
                   result = 1;
                else
                   result = 0;
                
                end
            else
                if strcmp(flag,'any')
                    result = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    result = all(isnan(input_array));
                end
            end
        end
        function results = updatepca(app,whatchanged)
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                generalEnableVal='off';
            else
                generalEnableVal=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end


            if app.SplitColorByPCAButton.Value
                app.PCAsettingsLabel.Enable = generalEnableVal;
                app.VarianceExpSlider.Enable = generalEnableVal;
                app.VarianceExpSliderLabel.Enable = generalEnableVal;
                app.PCsEditField.Enable = generalEnableVal;
                app.PCsEditFieldLabel.Enable = generalEnableVal;
            else
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';

                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end

            % prep PCA:
            for i=1:length(app.tractset.subscore.vars)
                selected_subscores{i} = app.tractset.subscore.vars{i}(app.tractset.patientselection);
            end
            subvars=ea_nanzscore(cell2mat(selected_subscores));
            if size(subvars,2) <= 2
                ea_warndlg("You may not have enough subscores & this might result in errors. Please consider selecting more subscores.")
            end

            %subvars=ea_nanzscore(cell2mat(app.tractset.subscore.vars'));
            %try
            [coeff,score,latent,tsquared,explained]=pca(subvars,'Rows','complete');
                %[coeff,score,latent,tsquared,explained]=pca(subvars,'rows','pairwise');
%             catch % PCA failed, likely no / too few scores selected
%                 app.PCAsettingsLabel.Enable = 'off';
%                 app.VarianceExpSlider.Enable = 'off';
%                 app.VarianceExpSliderLabel.Enable = 'off';
%                 app.PCsEditField.Enable = 'off';
%                 app.PCsEditFieldLabel.Enable = 'off';
%                 return
%            end
            
            if ea_isnan(app,explained,'any') || isempty(explained) %PCA function did not fail, but the values are empty
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';
                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end
            cumexplained=cumsum(explained);
            app.VarianceExpSlider.MajorTickLabelsMode='manual';
            app.VarianceExpSlider.MajorTicks=cumexplained;
            app.VarianceExpSlider.MinorTicksMode='manual';

            app.VarianceExpSlider.MajorTickLabels(dsearchn(cumexplained,80)+1:end)={''}; % display var exp up until 80% on PCs to avoid crowding of tick labels.
            for pc=1:dsearchn(cumexplained,80)
                app.VarianceExpSlider.MajorTickLabels{pc}=num2str(round(cumexplained(pc)));
            end
            app.VarianceExpSlider.MinorTicks=[];
            if app.PCsEditField.Value > length(cumexplained)
                app.PCsEditField.Value = length(cumexplained);
            end
            if exist('whatchanged','var')
                switch whatchanged
                    case 'slider' % user has changed the slider
                        correctcomponent=dsearchn(cumexplained,app.VarianceExpSlider.Value);
                        app.PCsEditField.Value=correctcomponent;
                    case 'edit' % user has edited the edit field
                        % nothing additional to do in this case.
                end
            end
            app.VarianceExpSlider.Value=cumexplained(app.PCsEditField.Value);
            app.tractset.numpcs=app.PCsEditField.Value; % also feed back to model
            app.VarianceExpSliderLabel.Text = [sprintf('%0.1f',cumexplained(app.tractset.numpcs)),'% Variance Explained'];

        end


        function results = tabupdate(app)
            n_group = unique(app.tractset.M.patient.group);
            if  app.SplitColorBySubscoreButton.Value
                app.SingleTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                iter = length(app.SubscoresListBox.Value);
            elseif app.SplitColorByGroupButton.Value
                app.SingleTractAnalysisTab.Title = 'Group:Combined Visualization';
                iter = length(n_group);
            elseif app.SplitColorByPCAButton.Value
                app.SingleTractAnalysisTab.Title = 'PCA:Combined Visualization';
                iter = app.PCsEditField.Value;
            else
                app.SingleTractAnalysisTab.Parent = app.TabGroup;
                app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
                app.ColorBar.Visible = 'on';

                return
            end

            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonPositive.Enable = 'off';
            for i=1:10
                tab_title = (['Subscore_' num2str(i) 'Tab']);
                negcolor_btn_title = (['ColorButtonNegative_' num2str(i+1)]);
                poscolor_btn_title = (['ColorButtonPositive_' num2str(i+1)]);
                showposfib_cb = ['ShowPositiveFibersCheckBox_' num2str(i+1)];
                shownegfib_cb = ['ShowNegativeFibersCheckBox_' num2str(i+1)];
                showposamt_editfield = ['ShowAmountEditFieldPositive_' num2str(i+1)];
                showposamt_label = ['ShowAmountLabelPositive_' num2str(i+1)];
                shownegamt_editfield = ['ShowAmountEditFieldNegative_' num2str(i+1)];
                shownegamt_label = ['ShowAmountLabelNegative_' num2str(i+1)];

                showposamt_l_editfield = ['ShowAmountLeftEditFieldPositive_' num2str(i+1)];
                showposamt_l_label = ['ShowAmountLeftLabelPositive_' num2str(i+1)];
                showposamt_r_editfield = ['ShowAmountRightEditFieldPositive_' num2str(i+1)];
                showposamt_r_label = ['ShowAmountRightLabelPositive_' num2str(i+1)];

                shownegamt_l_editfield = ['ShowAmountLeftEditFieldNegative_' num2str(i+1)];
                shownegamt_l_label = ['ShowAmountLeftLabelNegative_' num2str(i+1)];
                shownegamt_r_editfield = ['ShowAmountRightEditFieldNegative_' num2str(i+1)];
                shownegamt_r_label = ['ShowAmountRightLabelNegative_' num2str(i+1)];

                if i <= iter
                    % find the tab that has the title
                    app.(tab_title).Parent = app.TabGroup;
                    if app.SplitColorBySubscoreButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.subscore.colors{1,1}(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.subscore.colors{1,2}(i,:);
                    elseif app.SplitColorByPCAButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.subscore.pcacolors(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.subscore.pcacolors(i,:);
                    elseif app.SplitColorByGroupButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.M.groups.color(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.M.groups.color(i,:);
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposfib_cb).Value = 1;
                        app.tractset.subscore.posvisible(i) = 1;
                        app.(showposfib_cb).Enable = 'on';
                        app.(poscolor_btn_title).Enable = 'on';
                    else
                        app.(showposfib_cb).Value = 0;
                        app.tractset.subscore.posvisible(i) = 0;
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegfib_cb).Value = 1;
                        app.tractset.subscore.negvisible(i) = 1;
                        app.(shownegfib_cb).Enable = 'on';
                        app.(negcolor_btn_title).Enable = 'on';
                    else
                        app.(shownegfib_cb).Value = 0;
                        app.tractset.subscore.negvisible(i) = 0;
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposamt_editfield).Value = app.ShowAmountEditFieldPositive.Value;
                        app.(showposamt_editfield).Enable = 'on';
                        app.(showposamt_label).Enable = 'on';
                    else
                        app.(showposamt_editfield).Value = app.ShowAmountEditFieldPositive.Value;
                        app.(showposamt_editfield).Enable = 'off';
                        app.(showposamt_label).Enable = 'off';
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegamt_editfield).Value = app.ShowAmountEditFieldNegative.Value;
                        app.(shownegamt_editfield).Enable = 'on';
                        app.(shownegamt_label).Enable = 'on';
                    else
                        app.(shownegamt_editfield).Value = app.ShowAmountEditFieldNegative.Value;
                        app.(shownegamt_editfield).Enable = 'off';
                        app.(shownegamt_label).Enable = 'off';
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposamt_l_editfield).Value = app.ShowAmountLeftEditFieldPositive.Value;
                        app.tractset.subscore.vis.showposamount(i,2) = app.ShowAmountLeftEditFieldPositive.Value;  
                        app.(showposamt_l_editfield).Enable = 'on';
                        app.(showposamt_l_label).Enable = 'on';
                    else
                        app.(showposamt_l_editfield).Value = app.ShowAmountLeftEditFieldPositive.Value;
                        app.(showposamt_l_editfield).Enable = 'off';
                        app.(showposamt_l_label).Enable = 'off';
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposamt_r_editfield).Value = app.ShowAmountRightEditFieldPositive.Value;
                        app.tractset.subscore.vis.showposamount(i,1) = app.ShowAmountRightEditFieldPositive.Value;  
                        app.(showposamt_r_editfield).Enable = 'on';
                        app.(showposamt_r_label).Enable = 'on';
                    else
                        app.(showposamt_r_editfield).Value = app.ShowAmountRightEditFieldPositive.Value;
                        app.(showposamt_r_editfield).Enable = 'off';
                        app.(showposamt_r_label).Enable = 'off';
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegamt_l_editfield).Value = app.ShowAmountLeftEditFieldNegative.Value;
                        %update the new value into the model
                        app.tractset.subscore.vis.shownegamount(i,2) = app.ShowAmountLeftEditFieldNegative.Value;  
                        app.(shownegamt_l_editfield).Enable = 'on';
                        app.(shownegamt_l_label).Enable = 'on';
                    else
                        app.(shownegamt_l_editfield).Value = app.ShowAmountLeftEditFieldNegative.Value;
                        app.(shownegamt_l_editfield).Enable = 'off';
                        app.(shownegamt_l_label).Enable = 'off';
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegamt_r_editfield).Value = app.ShowAmountRightEditFieldNegative.Value;
                        app.tractset.subscore.vis.shownegamount(i,1) = app.ShowAmountRightEditFieldNegative.Value;  
                        app.(shownegamt_r_editfield).Enable = 'on';
                        app.(shownegamt_r_label).Enable = 'on';
                    else
                        app.(shownegamt_r_editfield).Value = app.ShowAmountRightEditFieldNegative.Value;
                        app.(shownegamt_r_editfield).Enable = 'off';
                        app.(shownegamt_r_label).Enable = 'off';
                    end

                else
                    app.(tab_title).Parent = [];
                end
            end
        end
        function results = tabfill(app)
            
            app.ShowPositiveFibersCheckBox.Value = app.tractset.posvisible;
            app.ShowNegativeFibersCheckBox.Value = app.tractset.negvisible;
            app.ShowAmountEditFieldPositive.Value = mean(app.tractset.showposamount);
            app.ShowAmountEditFieldNegative.Value = mean(app.tractset.shownegamount);
            app.ShowAmountLeftEditFieldPositive.Value = app.tractset.showposamount(2);
            app.ShowAmountRightEditFieldPositive.Value = app.tractset.showposamount(1);
            app.ShowAmountLeftEditFieldNegative.Value = app.tractset.shownegamount(2);
            app.ShowAmountRightEditFieldNegative.Value = app.tractset.shownegamount(1);
            
            num_tabs = length(app.tractset.subscore.labels);
            for indx=1:num_tabs
                checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
                pos_label = ['ShowAmountLabelPositive_' num2str(indx+1)];
                left_pos_label = ['ShowAmountLeftLabelPositive_' num2str(indx+1)];
                right_pos_label = ['ShowAmountRightLabelPositive_' num2str(indx+1)];
                app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.tractset.subscore.vis.pos_available(indx,:))),')'];
                app.(pos_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.pos_shown(indx,:))),')']};
                app.(left_pos_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,2)),')']};
                app.(right_pos_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,1)),')']};
                checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
                neg_label = ['ShowAmountLabelNegative_' num2str(indx+1)];
                left_label = ['ShowAmountLeftLabelNegative_' num2str(indx+1)];
                right_label = ['ShowAmountRightLabelNegative_' num2str(indx+1)];
                app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.tractset.subscore.vis.neg_available(indx,:))),')'];
                app.(neg_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.neg_shown(indx,:))),')']};
                app.(left_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,2)),')']};
                app.(right_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,1)),')']};
            end
        end

        
        function results = ea_discfibers_tractset2UI(app,flag)
            app.isbusy(1);


            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';

            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.tractset.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.tractset.leadgroup;
            app.AnalysisIDEditField.Value = app.tractset.ID;


            app.tractset=ea_discfibers_compat_statmetrics2statsettings(app.tractset); % legacy check, convert statmetric to statsettings

            % set stimstats GUI
            app.pullstatsettings;
            if ~isempty(app.tractset.statsettings.efieldthreshold)
                app.setefieldsliders;
                try
                    app.EfieldthresholdSlider.Value = app.tractset.statsettings.efieldthreshold;
                end
            end

            % switch app.tractset.statmetric
            %     case {'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)'}
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [1,49];
            %     case {'One-Sample Tests / VTAs / PAM (OSS-DBS)'}
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [5,100];
            %     case 'Correlations / E-fields (Irmen 2020)'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [0,100];
            %     case 'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [0,100];   
            %     case 'Odds Ratios / EF-Sigmoid (Jergas 2023)'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [0,100];     
            %     case 'Proportion Test (Chi-Square) / VTAs (binary vars)'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [1,49];
            %     case 'Binomial Tests / VTAs (binary vars)'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [1,49];
            %     case 'Reverse T-Tests / E-Fields (binary vars)'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [0,100];
            %     case 'Plain Connections'
            %         if isfield(app.tractset.M,'pseudoM') % custom scripting input
            %             app.CovariatesListBox.Items=['Group / Cohort regressor',app.tractset.M.clinical.labels];
            %         else % regular case
            %             app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            %         end
            %         app.ConnthresholdSlider.Limits = [1,100];
            % end

            app.ConsiderSignificantFibersonlyCheckBox.Value = app.tractset.showsignificantonly;
            app.levelEditField.Value = (app.tractset.alphalevel);
            app.MultTestStrategy.Value = app.tractset.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if ~isempty(app.tractset.connectome) && ismember(app.tractset.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.tractset.connectome;
            end

            app.MirrorSidesCheckBox.Value = app.tractset.mirrorsides;

            app.PatientsListBox.Items=app.tractset.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.tractset.setlabels];
            for set=1:length(app.tractset.setlabels)
                if isequal(app.tractset.patientselection,find(app.tractset.setselections{set}))
                    app.SubcohortsDropDown.Value=app.tractset.setlabels{set};
                end
            end
            if isequal(app.tractset.patientselection,1:length(app.tractset.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end

            app.Efieldmetric.Value=app.tractset.statsettings.efieldmetric;

            if app.SplitColorBySubscoreButton.Value && isempty(app.tractset.subscore.labels)
                ea_warndlg('No subscores found. Return to "Single Tract Analysis"!');
                app.tractset.multitractmode = 'Single Tract Analysis';
            end
            
            if app.tractset.roivisible
                app.ROISwitch.Value='Show';
            else
                app.ROISwitch.Value='Hide';
            end
            if app.tractset.connfibvisible
                app.FibersSwitch.Value='Show';
            else
                app.FibersSwitch.Value='Hide';
            end

            if isfield(app.tractset.M,'pseudoM')
                app.ROISwitchLabel.Text='ROI';
            else
                app.ROISwitchLabel.Text='VTAs';
            end
            % setup calculation threshold (only used when calculating
            % fibers for a new connectome).
            if ~isempty(app.tractset.calcthreshold)
                app.ThresholdEditField.Value = app.tractset.calcthreshold;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                end
            else
                prefs=ea_prefs;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                    app.ThresholdEditField.Value=0;
                else
                    app.ThresholdEditField.Value=prefs.machine.vatsettings.horn_ethresh*1000; % set to a default value based on prefs.
                end
            end

            if ~isempty(app.tractset.statsettings.efieldthreshold)
                app.setefieldsliders;
                if strcmp(app.Efieldmetric.Value,'Mean')
                    try app.EfieldthresholdSlider.Value = app.tractset.statsettings.efieldthreshold; end
                else
                    try app.EfieldthresholdSlider.Value = app.tractset.statsettings.efieldthreshold/1000; end
                end
            end

            switch app.tractset.statsettings.stimulationmodel
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' probability'] ['of their activation > ',sprintf('%.1f',app.EfieldthresholdSlider.Value)]};
            end

            app.ConnthresholdSlider.Value = app.tractset.statsettings.connthreshold;

            switch app.tractset.statsettings.stimulationmodel
                case 'VTA'
                    switch app.tractset.statsettings.statfamily
                        case '1-Sample Tests'
                            bubbles='VTAs';
                            app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                        case '2-Sample Tests'
                            bubbles='VTAs';
                            app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' %'],['and < ',sprintf('%.1f',100-app.ConnthresholdSlider.Value),' % of ',bubbles]};
                    end
                case 'Electric Field'
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                case 'Descriptive'
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
            end

            app.PosthoccorrectforgroupCheckBox.Value = app.tractset.adjustforgroups;

            app.VariableofInterestDropDown.Items = app.tractset.M.clinical.labels;
            if ismember(app.tractset.responsevarlabel,app.tractset.M.clinical.labels)
                app.VariableofInterestDropDown.Value = app.tractset.responsevarlabel;
            else
                app.VariableofInterestDropDown.Value = app.tractset.M.clinical.labels{1};
            end
            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.SubscoresListBox.Items = app.tractset.M.clinical.labels;
            if ~isempty(app.tractset.subscore.labels)
                app.SubscoresListBox.Value = app.tractset.subscore.labels;
            end
            [is,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            if is
                if ~isempty(ix)
                    app.CovariatesListBox.Items(ix)=[];
                end
            end

            app.CovariatesListBox.Value=app.tractset.covarlabels;

            if ~isempty(app.tractset.results)
                app.CovariatesCheckBox.Value=~isempty(app.tractset.covarlabels);
            end
            switch app.tractset.multitractmode
                case 'Single Tract Analysis'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 1;
                case 'Split & Color By Group'
                    app.SplitColorByGroupButton.Value = 1;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By PCA'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 1;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By Subscore'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 1;
                    app.SingleTractAnalysisButton.Value = 0;
            end

            app.PCsEditField.Value = app.tractset.numpcs;

            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.ShowPositiveFibersCheckBox.Value = app.tractset.posvisible;
            try
                if strcmp(app.tractset.statsettings.stattest,'N-Map')
                    app.ShowPositiveFibersCheckBox.Text = ['Show Connected Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                else
                    app.ShowPositiveFibersCheckBox.Text = ['Show Positive Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                end
            end

            app.ShowNegativeFibersCheckBox.Value = app.tractset.negvisible;
            try
                app.ShowNegativeFibersCheckBox.Text = ['Show Negative Fibers (',num2str(sum(app.tractset.stats.neg.available)),')'];
            end

            app.ThresholdingDropDown.Value = app.tractset.threshstrategy;
            % Amount EditField:
            switch app.tractset.threshstrategy
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = sum(app.tractset.showposamount);
                    try
                        app.ShowAmountEditFieldLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
                    end

                    app.ShowAmountEditFieldNegative.Value = sum(app.tractset.shownegamount);
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.tractset.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.tractset.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.tractset.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.tractset.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
                    end

                    if size(app.tractset.subscore.labels,2) > 1
                        keyboard
                    end 
                case {'Histogram (CDF)', 'Fixed Fiber Value'} % edit fields
                    if app.tractset.showposamount(1) == app.tractset.showposamount(2)
                        app.ShowAmountEditFieldPositive.Value = app.tractset.showposamount(1);
                        app.ShowAmountEditFieldPositive.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldPositive.Value = 0;
                        app.ShowAmountEditFieldPositive.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountEditFieldLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
                    end

                    if app.tractset.shownegamount(1) == app.tractset.shownegamount(2)
                        app.ShowAmountEditFieldNegative.Value = app.tractset.shownegamount(1);
                        app.ShowAmountEditFieldNegative.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldNegative.Value = 0;
                        app.ShowAmountEditFieldNegative.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.tractset.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.tractset.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.tractset.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.tractset.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
                    end

                    if size(app.tractset.subscore.labels,2) > 1
                        keyboard
                    end 
                otherwise
                    if app.tractset.showposamount(1) == app.tractset.showposamount(2)
                        app.ShowAmountEditFieldPositive.Value = app.tractset.showposamount(1);
                        app.ShowAmountEditFieldPositive.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldPositive.Value = 0;
                        app.ShowAmountEditFieldPositive.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelPositive.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
                    end

                    if app.tractset.shownegamount(1) == app.tractset.shownegamount(2)
                        app.ShowAmountEditFieldNegative.Value = app.tractset.shownegamount(1);
                        app.ShowAmountEditFieldNegative.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldNegative.Value = 0;
                        app.ShowAmountEditFieldNegative.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.tractset.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.tractset.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.tractset.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.tractset.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
                    end

                    if size(app.tractset.subscore.labels,2) > 1
                        keyboard
                    end
            end

            % Labels for subscores
            if strcmp(app.tractset.multitractmode,'Split & Color By Subscore')
                %find the index of the current subscore you are working
                %with
                selected_indx = cellfun(@(x)isequal(x,app.TabGroup.SelectedTab.Title),app.tractset.subscore.labels);
                if ~isempty(find(selected_indx)) %will be empty when single tract analysis is chosen
                    indx = find(selected_indx);
                end
            elseif strcmp(app.tractset.multitractmode,'Split & Color By PCA') || strcmp(app.tractset.multitractmode,'Split & Color By Group') %usually,in PCAs and Groups
                % there is no reason to use the isequal cell function, because
                % they are already ordered (and will be when they are
                % called in the ea_distract)
                selected_indx = strsplit(app.TabGroup.SelectedTab.Title,'_');
                if length(selected_indx) > 1
                    indx = str2double(selected_indx{2});
                end
            end

            if exist('indx','var')
                checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
                pos_label = ['ShowAmountLabelPositive_' num2str(indx+1)];
                left_pos_label = ['ShowAmountLeftLabelPositive_' num2str(indx+1)];
                right_pos_label = ['ShowAmountRightLabelPositive_' num2str(indx+1)];
                app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.tractset.subscore.vis.pos_available(indx,:))),')'];
                app.(pos_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.pos_shown(indx,:))),')']};
                app.(left_pos_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,2)),')']};
                app.(right_pos_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,1)),')']};
                checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
                neg_label = ['ShowAmountLabelNegative_' num2str(indx+1)];
                left_label = ['ShowAmountLeftLabelNegative_' num2str(indx+1)];
                right_label = ['ShowAmountRightLabelNegative_' num2str(indx+1)];
                app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.tractset.subscore.vis.neg_available(indx,:))),')'];
                app.(neg_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.neg_shown(indx,:))),')']};
                app.(left_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,2)),')']};
                app.(right_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,1)),')']};
            end

            app.ModeltypeDropDown.Value = app.tractset.predictionmodel;

            app.LiveVisualizeCheckBox.Value = app.tractset.cvlivevisualize;

            app.BinsEditField.Value = app.tractset.numBins;
            app.ModelNormalizationDropDown.Value = app.tractset.modelNormalization;

            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.tractset.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.tractset.negcolor;


            % Base prediction on:
            try
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if app.tractset.nestedLOO
                app.NestedCheckBox.Value = 1;
            end
            app.IterationsEditField.Value = app.tractset.kIter;
            app.ea_discfibers_ui_enablerules;

            % refresh enablerules
            app.isbusy(0);
        
        end
        

        function results = ea_discfibers_ui_enablerules(app,flag)
             app.isbusy(1);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val='off';
            else
                val=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            try
                numsides=numel(app.tractset.results.(ea_conn2connid(app.tractset.connectome)).(ea_method2methodid(app.tractset)).fibsval);
            catch % default
                numsides=2;
            end


            %% Stimulation Setup GUI.
            app.StimulationModel.Enable = val;
            app.StimulationModelLabel.Enable = val;
            app.Efieldmetric.Enable = val;
            app.EfieldmetricLabel.Enable = val;
            app.StatFamilyDropDown.Enable = val;
            app.StatFamilyLabel.Enable = val;
            app.StatTestDropDown.Enable = val;
            app.StatTestLabel.Enable = val;
            switch app.StatFamilyDropDown.Value
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                    app.StatH0Label.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
                    app.StatH0Label.Enable = false;
            end


            app.MirrorSidesCheckBox.Enable = val;
            app.VariableofInterestDropDown.Enable = val;

            groupsassigned=(length(unique(app.tractset.M.patient.group))>1);
            if groupsassigned
                app.SplitColorByGroupButton.Enable = val;
            else
                app.SplitColorByGroupButton.Enable = 'off';
                app.SplitColorByGroupButton.Value = 0;
            end

            app.SplitColorBySubscoreButton.Enable = val;
            app.SingleTractAnalysisButton.Enable = val;
            app.SplitColorByPCAButton.Enable = val;


            app.ShowPositiveFibersCheckBox.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;

            app.ColorButtonPositive.Enable = val;
            %%%%%%%%todoapp.ShowNegativeFibersCheckBox.Enable = val;

            app.ConnthresholdSlider.Enable = val;
            app.ConnthresholdLabel.Enable = val;

            app.VariableofInterestDropDownLabel.Enable = val;
            app.CovariatesCheckBox.Enable = val;
            app.ColorBar.Visible = val;
            if strcmp(val, 'off')
                cla(app.ColorBar);
            end
            app.ExportasAtlasButton.Enable = val;
            cvEnable = val;
%             if app.SplitColorByGroupButton.Value
%                 app.ColorBar.Visible = 'off';
%                 cla(app.ColorBar);
%                 app.ExportasAtlasButton.Enable = 'off';
%                 cvEnable = 'off';
%             else
                
            % end

            app.SubscoresListBox.Enable = val;
            app.MultitractWeightsButton.Enable = val;
            app.OpeninCleartuneButton.Enable = val;


            app.ROISwitch.Enable=val;
            app.ROISwitchLabel.Enable=val;
            app.FibersSwitch.Enable=val;
            app.ConnectedFibersLabel.Enable=val;

            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ModelNormalizationDropDown.Enable = cvEnable;
            app.ModelNormalizationDropDownLabel.Enable = cvEnable;


            switch app.BasePredictiononDropDown.Value
                case {'Histogram','z-scored Histogram'}
                    app.BinsEditField.Enable = cvEnable;
                    app.BinsEditFieldLabel.Enable = cvEnable;
                otherwise
                    app.BinsEditField.Enable = 'off';
                    app.BinsEditFieldLabel.Enable = 'off';
            end

            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.tractset.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    [~, patNames] = fileparts(app.tractset.M.patient.list);
                    app.TrainOnListBox.Items = patNames;
                    app.PredictListBox.Items = patNames;
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                    end
                case 'Import Model'
                    if ~strcmp(app.tractset.ExternalModelFile, 'None')
                        [~, ExternalModelFileName] = fileparts(app.tractset.ExternalModelFile);
                        app.ImportedModelName.Value = ExternalModelFileName;
                    end

                    [~, patNames] = fileparts(app.tractset.M.patient.list);
                    app.PredictListBox.Items = patNames;                    
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(1:str2double(app.kEditField.Value))');
                        app.PredictListBox.Items=sprintfc('%d',(1:str2double(app.kEditField.Value))');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.tractset.setlabels;
                    app.PredictListBox.Items=app.tractset.setlabels;
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Import Model'
                    cvlEnable=cvEnable;
                    if isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end

                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end

            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            app.ModeltypeDropDown.Enable = cvlEnable;

            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;

            if ismember(app.StatTestDropDown.Value,{'Skipped Spearman','Skipped Pearson','N-Map','Mean-Map'})
                app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                app.ConsiderSignificantFibersonlyCheckBox.Enable = val;
                app.levelEditField.Enable = val;
                app.levelEditFieldLabel.Enable = val;
                app.MultTestStrategy.Enable = val;
            end

            if ismember(app.StatTestDropDown.Value,{'N-Map','Wilcoxon Rank-Sum Test','Wilcoxon Signed-Rank Test'})
                app.ShowNegativeFibersCheckBox.Enable = 'off';
                app.ShowNegativeFibersCheckBox.Value = 0;
            else
                app.ShowNegativeFibersCheckBox.Enable = val;
            end


            app.BasePredictiononDropDown.Enable = val;
            app.BasePredictiononDropDownLabel.Enable = val;
            %app.ModelSetupOptionButtonGroup.Enable = val;


            app.FittoscoresCheckBox.Enable = cvEnable;

            if app.SplitColorByPCAButton.Value % when doing a PCA based prediction, we need to fit back tract estimates to responsevars.
                app.FittoscoresCheckBox.Value = 1;
                app.FittoscoresCheckBox.Enable = 'off';
                app.tractset.doactualprediction = 1; % redundancy just to make sure in fact this should not be needed.
            end

            switch app.FittoscoresCheckBox.Value
                case 1
                    app.ModeltypeDropDown.Enable = cvEnable;
                case 0
                    app.ModeltypeDropDown.Enable = 'off';
            end

            if app.CovariatesCheckBox.Value
                app.CovariatesListBox.Enable = val;
            else
                app.CovariatesListBox.Enable = 'off';
            end


            % switch app.ModelSetupDropDown.Value
            %     case {'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)','T-Tests / PAM (OSS-DBS)'}
            %         app.EfieldthresholdSlider.Enable = 'off';
            %         app.EfieldthresholdLabel.Enable = 'off';
            %         app.Efieldmetric.Enable = 'off';
            %         app.Corrtype.Enable = 'off';
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
            %         if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
            %             app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
            %         end
            %     case 'One-Sample Tests / VTAs / PAM (OSS-DBS)'
            %         app.EfieldthresholdSlider.Enable = 'off';
            %         app.EfieldthresholdLabel.Enable = 'off';
            %         app.Efieldmetric.Enable = 'off';
            % 
            %         app.Corrtype.Enable = 'on';
            %         app.Corrtype.Items = {'T-Tests'
            %             'Wicoxon Signed Rank Tests'};
            %         app.tractset.corrtype=app.Corrtype.Value;
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
            %         if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
            %             app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
            %         end
            %     case 'Correlations / E-fields (Irmen 2020)'
            %         app.EfieldthresholdSlider.Enable = val;
            %         app.EfieldthresholdLabel.Enable = val;
            %         app.Efieldmetric.Enable = val;
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.Corrtype.Enable = val;
            %         app.Corrtype.Items = {'Spearman'
            %             'Pearson'
            %             'Bend'
            %             'Skipped Spearman'
            %             'Skipped Pearson'};
            %         app.tractset.corrtype=app.Corrtype.Value;
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
            %     case 'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)'
            %         app.EfieldthresholdSlider.Enable = val;
            %         app.EfieldthresholdLabel.Enable = val;
            %         app.Efieldmetric.Enable = val;
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.Corrtype.Visible = 'off';
            %         app.Corrtype.Enable = 'off';
            % 
            %         app.Corrtype.Items = {'Spearman'
            %             'Pearson'
            %             'Bend'
            %             'Skipped Spearman'
            %             'Skipped Pearson'};
            %         app.tractset.corrtype=app.Corrtype.Value;
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
            % 
            %     case 'Proportion Test (Chi-Square) / VTAs (binary vars)'
            %         app.EfieldthresholdSlider.Enable = 'off';
            %         app.EfieldthresholdLabel.Enable = 'off';
            %         app.Efieldmetric.Enable = 'off';
            %         app.Corrtype.Enable = 'off';
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
            %         if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
            %             app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
            %         end
            %     case 'Binomial Tests / VTAs (binary vars)'
            %         app.EfieldthresholdSlider.Enable = 'off';
            %         app.EfieldthresholdLabel.Enable = 'off';
            %         app.Efieldmetric.Enable = 'off';
            %         app.Corrtype.Enable = 'off';
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
            %         if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
            %             app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
            %         end
            %     case 'Odds Ratios / EF-Sigmoid (Jergas 2023)'
            %         app.EfieldthresholdSlider.Enable = val;
            %         app.EfieldthresholdLabel.Enable = val;
            %         app.Efieldmetric.Enable = val;
            % 
            %         app.Corrtype.Enable = 'off';
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
            %         if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
            %             app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
            %         end
            %     case 'Reverse T-Tests / E-Fields (binary vars)'
            %         app.EfieldthresholdSlider.Enable = val;
            %         app.EfieldthresholdLabel.Enable = val;
            %         app.Efieldmetric.Enable = val;
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'on';
            %         app.Corrtype.Enable = val;
            %         app.Corrtype.Items = {'Spearman'
            %             'Pearson'
            %             'Bend'
            %             'Skipped Spearman'
            %             'Skipped Pearson'};
            %         app.tractset.corrtype=app.Corrtype.Value;
            %         app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
            %     case 'Plain Connections'
            %         app.EfieldthresholdSlider.Enable = 'off';
            %         app.EfieldthresholdLabel.Enable = 'off';
            %         app.Efieldmetric.Enable = 'off';
            %         app.CovariatesCheckBox.Value = 0;
            %         app.CovariatesCheckBox.Enable = 'off';
            %         app.CovariatesListBox.Enable = 'off';
            %         app.Corrtype.Enable = 'off';
            %         app.ConsiderSignificantFibersonlyCheckBox.Value = 0;
            %         app.ConsiderSignificantFibersonlyCheckBox.Enable = 'off';
            %         app.levelEditField.Enable = 'off';
            %         app.MultTestStrategy.Enable = 'off';
            %         app.ShowNegativeFibersCheckBox.Value = 0;
            %         app.ShowNegativeFibersCheckBox.Enable = 'off';
            %         app.BasePredictiononDropDown.Items={'Sum of Scores','Mean of Scores','Peak of Scores','Peak 5% of Scores'};
            %         if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
            %             app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
            %         end
            %         app.PosthoccorrectforgroupCheckBox.Value = 0;
            % end
            if ~ismember(app.StatTestDropDown.Value,app.StatTestDropDown.Items)
                app.StatTestDropDown.Value=app.StatTestDropDown.Items{1};
            end


            switch app.StrategyDropDown.Value
                case 'Import Model'
                    app.tractset.useExternalModel = true;
                    app.Efieldmetric.Enable = 'off';
                    app.PredictListBoxLabel.Position(1) = 149;
                    app.PredictListBox.Position(1) = 200;
                    app.PredictListBox.Position(3) = 251;
                    app.TrainOnListBox.Visible = 'off';  % this might need to be placed above
                    app.ImportedModelName.Visible = 'on';

                otherwise
                    app.tractset.useExternalModel = false;
                    app.PredictListBoxLabel.Position(1) = 245;
                    app.PredictListBox.Position(1) = 297;
                    app.PredictListBox.Position(3) = 156;
                    app.TrainOnListBox.Visible = 'on';
                    app.ImportedModelName.Visible = 'off';
            end

            app.PatientsListBox.Enable=val;

            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.tractset.setlabels];

            app.SubcohortsDropDown_2.Items=[app.tractset.setlabels];

            % Thresholding Method:
            app.ThresholdingDropDown.Enable = val;
            app.ThresholdingDropDownLabel.Enable = val;

            switch app.ShowNegativeFibersCheckBox.Value
                case 0
                    app.ShowAmountEditFieldNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountLabelNegative.Enable = 'off';

                    app.ShowAmountLeftEditFieldNegative.Enable = 'off';
                    app.ShowAmountRightEditFieldNegative.Enable = 'off';

                    app.ShowAmountLeftLabelNegative.Enable = 'off';
                    app.ShowAmountRightLabelNegative.Enable = 'off';
                case 1
                    app.ShowAmountEditFieldNegative.Enable = val;
                    app.ColorButtonNegative.Enable = val;
                    app.ShowAmountLabelNegative.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.ShowAmountLeftEditFieldNegative.Enable = val;
                        app.ShowAmountRightEditFieldNegative.Enable = val;

                        app.ShowAmountLeftLabelNegative.Enable = val;
                        app.ShowAmountRightLabelNegative.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.ShowAmountLeftEditFieldNegative.Enable = 'off';
                        app.ShowAmountRightEditFieldNegative.Enable = 'off';

                        app.ShowAmountLeftLabelNegative.Enable = 'off';
                        app.ShowAmountRightLabelNegative.Enable = 'off';
                    end
            end

            switch app.ShowPositiveFibersCheckBox.Value
                case 0
                    app.ShowAmountEditFieldPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.ShowAmountLabelPositive.Enable = 'off';

                    app.ShowAmountLeftEditFieldPositive.Enable = 'off';
                    app.ShowAmountRightEditFieldPositive.Enable = 'off';

                    app.ShowAmountLeftLabelPositive.Enable = 'off';
                    app.ShowAmountRightLabelPositive.Enable = 'off';
                case 1
                    app.ShowAmountEditFieldPositive.Enable = val;
                    app.ColorButtonPositive.Enable = val;
                    app.ShowAmountLabelPositive.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.ShowAmountLeftEditFieldPositive.Enable = val;
                        app.ShowAmountRightEditFieldPositive.Enable = val;

                        app.ShowAmountLeftLabelPositive.Enable = val;
                        app.ShowAmountRightLabelPositive.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.ShowAmountLeftEditFieldPositive.Enable = 'off';
                        app.ShowAmountRightEditFieldPositive.Enable = 'off';

                        app.ShowAmountLeftLabelPositive.Enable = 'off';
                        app.ShowAmountRightLabelPositive.Enable = 'off';
                    end

            end

            % Logic of multitract panel radio buttons - enabling of pca panel is
            % handled in updatepca()
            if app.SingleTractAnalysisButton.Value
                app.ColorButtonPositive.Enable = val;
                app.ColorButtonNegative.Enable = val;
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = val;
                app.CovariatesListBox.Enable = val;
                app.MultitractWeightsButton.Enable = 'off';
                app.OpeninCurrentuneButton.Enable = 'off';
                %app.SingleTractAnalysisTab.Parent = app.TabGroup;
                app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
            end
            if app.SplitColorByGroupButton.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = val;
                app.CovariatesListBox.Enable = val;
                app.MultitractWeightsButton.Enable = 'off';
                app.OpeninCurrentuneButton.Enable = 'off';
                n_groups = unique(app.tractset.M.patient.group);
                app.SingleTractAnalysisTab.Title = 'Group Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(n_groups)
                        app.(tab_title).Parent = app.TabGroup;
                        new_tabtitle = ['Group' num2str(i)];
                        if strcmp(app.(tab_title).Title,new_tabtitle)
                            app.(tab_title).Title = ['dummy' num2str(randi(2^52))];
                            app.(tab_title).Title = ['Group' num2str(i)];
                        else
                            app.(tab_title).Title = ['Group' num2str(i)];
                        end
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            if app.SplitColorBySubscoreButton.Value
                app.ColorButtonPositive.Enable = 'off';
                app.ColorButtonNegative.Enable = 'off';
                app.SubscoresListBox.Enable = val;
                app.CovariatesCheckBox.Enable = 'on';
                app.CovariatesListBox.Enable = 'on';
                app.MultitractWeightsButton.Enable = cvEnable;
                app.OpeninCleartuneButton.Enable = cvEnable;
                app.OpeninCurrentuneButton.Enable = cvEnable;
                app.SingleTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(app.tractset.subscore.labels)
                        app.(tab_title).Parent = app.TabGroup;
                        if strcmp(app.(tab_title).Title,app.tractset.subscore.labels{i})
                            %if you want to change the name to a name that
                            %is already present in MATLAB'S memory, it
                            %switches to the default name for this tab
                            %"Subscore_n"
                            app.(tab_title).Title = ['dummy' num2str(randi(2^52))];
                            app.(tab_title).Title = app.tractset.subscore.labels{i};
                        else
                            app.(tab_title).Title = app.tractset.subscore.labels{i};
                        end
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            if app.SplitColorByPCAButton.Value
                app.NestedCheckBox.Visible = 'off';
                app.ColorButtonPositive.Enable = val;
                app.ColorButtonNegative.Enable = val;
                app.SubscoresListBox.Enable = val;
                app.CovariatesCheckBox.Enable = 'on';
                app.CovariatesListBox.Enable = 'on';
                app.MultitractWeightsButton.Enable = 'off';
                app.OpeninCurrentuneButton.Enable = 'off';
                app.SingleTractAnalysisTab.Title = 'PCA Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= app.tractset.numpcs
                        app.(tab_title).Parent = app.TabGroup;
                        new_tabtitle = ['PC' num2str(i)];
                        if strcmp(app.(tab_title).Title,new_tabtitle)
                            app.(tab_title).Title = ['dummy' num2str(randi(2^52))];
                            app.(tab_title).Title = ['PC' num2str(i)];
                        else
                            app.(tab_title).Title = ['PC' num2str(i)];
                        end
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            %end
            app.updatepca;
            % turn light back to green
            app.isbusy(0);
        end
        
        function results = enforcestatrules(app)
            warning('on','all')
            app.StimulationModel.Value=app.tractset.statsettings.stimulationmodel;
            %% Enforce Rules Based on the Model
            stattests=ea_explorer_statlist;
            testidx=cellfun(@ismember,stattests.compatibility,repmat({app.tractset.statsettings.stimulationmodel},size(stattests.compatibility)),'UniformOutput',false);
            testidx=cellfun(@any,testidx);
            tmptests =stattests(testidx,:);
            if ~any(app.tractset.statsettings.statfamily==tmptests.type)
                %set family to default
                app.tractset.statsettings.statfamily = tmptests.type(1);
                app.StatFamilyDropDown.Items = unique(tmptests.type);
                app.StatFamilyDropDown.Value= char(tmptests.type(1));
                % set tests to default
                % app.tractset.statsettings.statest=tmptests.name(1);
                % app.StatTestDropDown.Items=tmptests.name;
                % app.StatTestDropDown.Value=tmptests.name(1);
                warning('off','backtrace')
                warning('Test Family reset according to Stimulation Model!')
                warning('on','backtrace')
            else % set family in GUI
                app.StatFamilyDropDown.Items = unique(tmptests.type);
                app.StatFamilyDropDown.Value=char(app.tractset.statsettings.statfamily);
            end
            %% Enforce Rules Based on Test Family
            tmptests=tmptests(app.tractset.statsettings.statfamily==tmptests.type,:);
            if ~any(app.tractset.statsettings.stattest==tmptests.name)
                app.tractset.statsettings.statest=tmptests.name(1);
                app.StatTestDropDown.Items=tmptests.name;
                app.StatTestDropDown.Value=char(tmptests.name(1));
                warning('off','backtrace')
                warning('Statistical Test set to Default for this Test Family!')
                warning('on','backtrace')
            else
                app.StatTestDropDown.Items=tmptests.name;
                app.StatTestDropDown.Value=char(app.tractset.statsettings.stattest);
            end
            %%
            app.StatH0DropDown.Value=app.tractset.statsettings.H0;
            switch app.tractset.statsettings.statfamily
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
            end
            drawnow
            app.drawcb
        end
        
        function results = pushstatsettings(app)
            app.tractset.statsettings.stimulationmodel = app.StimulationModel.Value;
            app.tractset.statsettings.efieldmetric = app.Efieldmetric.Value; % if statmetric == ;Correlations / E-fields (Irmen 2020)p, efieldmetric can calculate sum, mean or peak along tracts
            app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.tractset.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.statsettings.statfamily = app.StatFamilyDropDown.Value; % the
            app.tractset.statsettings.stattest = app.StatTestDropDown.Value;
            app.tractset.statsettings.H0 = app.StatH0DropDown.Value;
      end
        
        
        function results = pullstatsettings(app,mystatsettings)
            if exist('mystatsettings','var')
                app.tractset.statsettings = mystatsettings;
            end
            app.enforcestatrules;
            drawnow
        end
        
        function results = pushthresholdingsettings(app)
            app.tractset.thresholding.showsignificantonly = app.ShowonlysignificantCheckBox.Value;
            app.tractset.thresholding.alphalevel = app.levelEditField.Value;
            app.tractset.thresholding.multcompstrategy = app.MultTestStrategy.Value;
            app.tractset.thresholding.threshstrategy = app.ThresholdingDropDown.Value;
            app.tractset.thresholding.posvisible = app.ShowPositiveCheckBox.Value;
            app.tractset.thresholding.negvisible = app.ShowNegativeCheckBox.Value;
            app.tractset.thresholding.showposamount = [app.ShowAmountEditFieldPositive.Value,app.ShowAmountEditFieldPositive.Value];
            app.tractset.thresholding.shownegamount = [app.ShowAmountEditFieldNegative.Value,app.ShowAmountEditFieldNegative.Value];
            app.tractset.thresholding.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.thresholding.negcolor = app.ColorButtonNegative.BackgroundColor;            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('*.mat','Please Choose Lead-Group Analysis project...');
                if isnumeric(FileName) && FileName == 0
                    ea_cprintf('CmdWinErrors', 'No group analysis selected!\n');
                    delete(app);
                    return;
                else
                    lgpath = fullfile(PathName,FileName);
                end
            elseif ~isfile(lgpath)
                ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
                delete(app);
                return;
            end
            lgpath = GetFullPath(lgpath);

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Opening application');
            d.Value = 0;
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Fiber Filtering Explorer';
            
            app.IterationsEditField.Visible = 'off';
            app.IterationsEditFieldLabel.Visible = 'off';
            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
            %app.ConnectomeDropDown.Items={mdl(sf==1)};

            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            d.Value = 0.33;
            % Add Value to connthreshsliderlabel
            changingValue=app.EfieldthresholdSlider.Value;

            letsdraw=0; % flag to state whether to draw directly.

            varinfo=who('-file',lgpath); % check if file is a group analysis matfile or a stored disctract object with var name 'tractset'

            if ismember('tractset',varinfo)
                D = load(lgpath,'-mat');
                app.tractset = D.tractset; % load stored disctract object
                app.tractset.analysispath=lgpath;
                if isprop(app.tractset,'nestedLOO')
                    app.NestedCheckBox.Value = app.tractset.nestedLOO;
                end
                app.NestedCheckBox.Visible = 'off';
                app.tractset.activateby={};

                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.tractset.ID, ID)
                    app.tractset.ID = ID;
                end

                % do not initialize with Import Model
                if strcmp(app.StrategyDropDown.Value, 'Import Model')
                    app.StrategyDropDown.Value = 'Custom (Patients)';
                end

                % Fix group folder in case it has been moved
                tractGroupFolder = regexprep(app.tractset.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['fiberfiltering', filesep, app.tractset.ID, '.fibfilt'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.tractset.analysispath = strrep(app.tractset.analysispath, tractGroupFolder, UIGroupFolder);
                    app.tractset.leadgroup = strrep(app.tractset.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.tractset.M.root = strrep(app.tractset.M.root, tractGroupFolder, UIGroupFolder);
                    if isfield(app.tractset.M,'ui')
                        app.tractset.M.ui.groupdir = strrep(app.tractset.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                    end
                end
                d.Value = 0.50;
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                app.tractset.resultfig = resultfig; % attach to figure
                if ~isempty(app.tractset.results)
                    if isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                        letsdraw=1;
                    end
                end
                %initialize for legacy support
                if ~isstruct(app.tractset.subscore)
                    app.tractset.subscore.vars = {};
                    app.tractset.subscore.labels = {};
                    app.tractset.subscore.pcavars = {};
                    app.tractset.subscore.weights = [];
                    app.tractset.subscore.colors{1,1} = ea_color_wes('all');
                    app.tractset.subscore.colors{1,2} = flip(ea_color_wes('all'));
                    app.tractset.subscore.vis.showposamount = repmat([25,25],10,1); %total of 10 subscores - will delete when we know the total number of subscores
                    app.tractset.subscore.vis.shownegamount = repmat([25,25],10,1);
                    app.tractset.subscore.vis.pos_shown = repmat([25,25],10,1);
                    app.tractset.subscore.vis.neg_shown = repmat([25,25],10,1);
                    app.tractset.subscore.negvisible = zeros(10,1);
                    app.tractset.subscore.posvisible = ones(10,1);
                    app.tractset.subscore.splitbysubscore = 0;
                    app.tractset.subscore.special_case = 0;
                    
                end
                if ~isprop(app.tractset,'multi_pathways')
                    app.tractset.multi_pathways = 0;
                end
                if ~isprop(app.tractset,'switch_connectivity')  
                  app.tractset.switch_connectivity = 0;
                end

                if ~isprop(app.tractset, 'twoSampleWeighted')
                    app.tractset.twoSampleWeighted = 1;
                end

                if ~isprop(app.tractset, 'SigmoidTransform')
                    app.tractset.SigmoidTransform = 0;
                end
                
                if ~isprop(app.tractset,'roiintersectdata')
                    app.tractset.roiintersectdata = {};
                end
                if ~isprop(app.tractset,'roithresh')
                    app.tractset.roithresh = 200;
                end
                % Currentune
                if ~isstruct(app.tractset.currentune)

                    app.tractset.currentune.patient = '';
                    app.tractset.currentune.stimfolder = '';
                    app.tractset.currentune.ActivationProfileDict = '';
                    app.tractset.currentune.CheckedWeights = {};
                    app.tractset.currentune.min_conc = -4.0;
                    app.tractset.currentune.max_conc = -4.0;
                    app.tractset.currentune.min_segm = 4.0;
                    app.tractset.currentune.max_segm = 4.0;
                end
                %Cleartune
                if ~isprop(app.tractset,'CleartuneOptim')
                    app.tractset.CleartuneOptim = 0;
                end
                if ~isstruct(app.tractset.cleartunevars)
                    app.tractset.cleartunevars.weights = {};
                    app.tractset.cleartunevars.weightvars = {};
                    app.tractset.cleartunevars.vars = {};
                end

                %  if old fibfilt is used, assume it was computed with VATs
                if ~isempty(app.tractset.results)
                    if ~isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'VAT_Ttest') && ~isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),'PAM_Ttest')
                        app.tractset.results.(ea_conn2connid(app.tractset.connectome)).('VAT_Ttest') = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).('ttests');
                        app.tractset.results.(ea_conn2connid(app.tractset.connectome)) = rmfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'ttests');
                    end
    
                    if ~isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'connFiberInd_VAT') && ~isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'connFiberInd_PAM')
                        if iscell(app.tractset.connFiberInd) && ~isempty(app.tractset.connFiberInd)
                            waitfor(msgbox("Connectivity indices were imported from the old version fibfilt."));
                            app.tractset.results.(ea_conn2connid(app.tractset.connectome)).connFiberInd_VAT = app.tractset.connFiberInd;
                            %app.tractset = rmfield(app.tractset, 'connFiberInd');
                        else
                            waitfor(msgbox(sprintf("Connectivity indices were not found!\nRecalcute or use with truncated functionality.")));
                        end
                    end

                    if isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'connFiberInd')
                        app.tractset.results.(ea_conn2connid(app.tractset.connectome)).connFiberInd_VAT = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).connFiberInd;
                        app.tractset.results.(ea_conn2connid(app.tractset.connectome)) = rmfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)), 'connFiberInd');
                    end
                end
                
            else % init from M struct of LEAD group file
                app.tractset = ea_disctract;
                app.NestedCheckBox.Visible = 'off';
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                try
                    app.tractset.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
                catch ME
                    close(resultfig);
                    app.UIFigureCloseRequest;
                    rethrow(ME);
                end
            end
            if isfield(app.tractset.M,'ui')
                if ismember(app.tractset.M.ui.connectomename, app.ConnectomeDropDown.Items)
                    app.ConnectomeDropDown.Value = app.tractset.M.ui.connectomename;
                end
            end
            d.Value = 0.75;
            
            if letsdraw
                app.draw; % show its results
            else
                app.ea_discfibers_tractset2UI;
            end
            app.setefieldsliders;
            app.ea_discfibers_ui_enablerules;
            if strcmp(app.tractset.multitractmode,'Split & Color By Subscore') && ~isempty(app.tractset.subscore.labels)
                app.tabfill;
            end
            app.isbusy(0);
            d.Value = 1.00;
            close(d);
            hint={'Welcome to the Lead-DBS Fiber Filtering Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create discriminative tract models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');

        end

        % Callback function
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)


        end

        % Callback function
        function ModelSetupDropDownValueChanged(app, event)
            app.tractset.statmetric=app.ModelSetupDropDown.Value;
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)'
                    app.ConnectivityModelButtonGroup.Children(1).Enable = 'on';
                    if strcmp(app.ConnectivityModelButtonGroup.SelectedObject.Text, 'Stimulation Volume')
                        app.tractset.connectivity_type = 1;
                        if app.tractset.connthreshold > 40
                            app.tractset.connthreshold = 20; % reset to a suitable value
                        end
                    else
                       app.tractset.connectivity_type = 2;
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.ConnectivityModelButtonGroup.SelectedObject = app.ConnectivityModelButtonGroup.Children(2);
                   % app.ConnectivityModelButtonGroup.Children(1).Enable = 'off';
                    app.tractset.connectivity_type = 1;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)'
                    app.ConnectivityModelButtonGroup.SelectedObject = app.ConnectivityModelButtonGroup.Children(2);
                   % app.ConnectivityModelButtonGroup.Children(1).Enable = 'off';
                    app.tractset.connectivity_type = 1;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Proportion Test (Chi-Square) / VTAs (binary vars)'
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Binomial Tests / VTAs (binary vars)'
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Odds Ratios / EF-Sigmoid (Jergas 2023)'
                    if app.tractset.connthreshold > 40
                        app.tractset.connthreshold = 20; % reset to a suitable value
                    end
                case 'Plain Connections'
                    if app.tractset.connthreshold < 1
                        app.tractset.connthreshold = 1; % reset to a suitable value
                    end
            end

            % check if the model changed from PAM to VAT or vice versa:
            % error thrown here if we remove PAM (OSS-DBS) as an option
            % from the menu
            
            %if (strcmp(app.ModelSetupDropDown.Value, 'T-Tests / PAM (OSS-DBS)') && strcmp('T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', event.PreviousValue)) || (strcmp(app.ModelSetupDropDown.Value, 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)') && strcmp('T-Tests / PAM (OSS-DBS)', event.PreviousValue))
            %    app.tractset.switch_connectivity = 1;
            %else
            %    app.tractset.switch_connectivity = 0;
            %end

            app.ea_discfibers_tractset2UI;
            app.ea_discfibers_ui_enablerules;
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.tractset.SigmoidTransform == 1
                app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' probability'] ['of their activation > ',sprintf('%.2f',changingValue)]};
            else
                app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.tractset.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
            end
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.tractset.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
            app.draw;

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %app.tractset.app=app; % need to change
            app.tractset.leadgroup=app.LeadGroupPathLabel.Text;

            %% set up object properties:
            app.tractset.posvisible = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.negvisible = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.tractset.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;

            app.tractset.calcthreshold = app.ThresholdEditField.Value;

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};

            setcovars(app);

            app.tractset.statmetric=app.ModelSetupDropDown.Value;
            app.tractset.connectome = app.ConnectomeDropDown.Value;
            app.setefieldsliders;

            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.tractset.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;

            % calculate general setup
            app.tractset.calculate;

            % save dataset:
            app.tractset.save;

            app.ea_discfibers_ui_enablerules;

            % draw results
            app.draw;
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            app.ea_discfibers_ui_enablerules;
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.posvisible = value;
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                %component_changed = 'ShowPositiveFibersCheckBox';
                tabupdate(app);
            end
            app.ea_discfibers_ui_enablerules;
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.negvisible = value;
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                tabupdate(app);
                %component_changed = 'ShowNegativeFibersCheckBox';
                %tabupdate(app,component_changed);
            end
            app.ea_discfibers_ui_enablerules;
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'],'file')
                app.AnalysisIDEditField.Value = app.tractset.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end

            if exist([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],'file')
                movefile([fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,app.tractset.ID,'.fibfilt'],...
                    [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt']);
            end

            ht = getappdata(app.tractset.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.tractset.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.tractset.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'], app.tractset.resultfig});
            end

            app.tractset.ID=value;
            app.tractset.save;

        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            app.ea_discfibers_tractset2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.tractset.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            %if the selected connectomes have multiple .mat files inside
            %it, then it is a multi tract file and
            %app.tractset.multi_pathways = 1
            if exist(fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dmri_multitract folder
                if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
                   warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
                   app.tractset.multi_pathways = 0;
                else 
                    %if it only exists in the multitract pathway, then
                    %check once more for the multiple .mat files and 
                    %set app.tractset.multi_pathways = 1;
                    mt_connectome = fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value);
                    mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
                    mt_files = {mt_connectome_dir.name};
                    if length(mt_files) > 1
                        app.tractset.multi_pathways = 1;
                    else
                        app.tractset.multi_pathways = 0;
                    end
                end
            else
                app.tractset.multi_pathways = 0;
            end
            app.tractset.connectome = value;
            app.ea_discfibers_ui_enablerules;
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.tractset.statmetric
                case {'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)','Proportion Test (Chi-Square) / VTAs (binary vars)','Binomial Tests / VTAs (binary vars)'}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case {'Correlations / E-fields (Irmen 2020)','Reverse T-Tests / E-Fields (binary vars)', 'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)', 'Odds Ratios / EF-Sigmoid (Jergas 2023)'}
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
                case {'One-Sample Tests / VTAs / PAM (OSS-DBS)','Plain Connections'}
                   bubbles='VTAs';
                   app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.tractset.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            app.tractset.CleartuneOptim = 0;
            customconfig.trainonitems=app.TrainOnListBox.Items;
            customconfig.trainonvalues=app.TrainOnListBox.Value;
            customconfig.predictonitems=app.PredictListBox.Items;
            customconfig.predictonvalues=app.PredictListBox.Value;
           
            ea_disctract_crossval(1,app.tractset,...
                app.StrategyDropDown.Value,...
                app.IterationsEditField.Value,...
                app.PosthoccorrectforgroupCheckBox.Value,...
                customconfig);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            if strcmp(app.StrategyDropDown.Value,'Leave-One-Patient-Out') || strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.NestedCheckBox.Visible = 'on';
            else
                app.NestedCheckBox.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value,'Import Model') 
                app.SubcohortsDropDown_2.Visible = 'on';
                app.SubcohortsDropDown_2Label.Visible = 'on';
            else
                app.SubcohortsDropDown_2.Visible = 'off';
                app.SubcohortsDropDown_2Label.Visible = 'off';
            end


            if strcmp(app.StrategyDropDown.Value,'Import Model')
                app.LoadModelButton.Visible = 'on'; 
            else
                app.LoadModelButton.Visible = 'off'; 
            end
                
            if strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.IterationsEditFieldLabel.Visible = 'on';
                app.IterationsEditField.Visible = 'on';
                app.IterationsEditFieldLabel.Enable = 'on';
                app.IterationsEditField.Enable = 'on';
                app.IterationsEditField.Value = 1;
            else
                app.IterationsEditFieldLabel.Visible = 'off';
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Enable = 'off';
                app.IterationsEditField.Enable = 'off';
            end
            app.ea_discfibers_ui_enablerules;

            % redraw if swtiching to and from Imported Model
            if strcmp(app.StrategyDropDown.Value,'Import Model') || strcmp(event.PreviousValue, 'Import Model')
                app.draw
            end

            app.ea_discfibers_tractset2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('event', 'var')
                answer = questdlg("Save Changes?",'Close Discriminative Fiber Toolbox','Yes','No','Yes');
            else
                answer = 'Yes';
            end

            if strcmp(answer,'Yes') && ~isempty(app.tractset)
                app.isbusy(1,'saving');
                app.tractset.save;
            end

            if isprop(app.tractset, 'drawobject') && iscell(app.tractset.drawobject)
                for tract=1:numel(app.tractset.drawobject)
                    try
                        delete(app.tractset.drawobject{tract});
                    end
                end
            end

            if isprop(app.tractset, 'resultfig') && isvalid(app.tractset.resultfig) && startsWith(app.tractset.resultfig.Name, 'No Patient Selected')
                close(app.tractset.resultfig);
            end

            app.isbusy(0);
            delete(app);
        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
% new from till:
            value = event.Value;
            app.Efieldmetric.Value = value;
            app.tractset.statsettings.efieldmetric=value;

            app.setefieldsliders;
            app.ea_explorer_ui_enablerules;
            app.ea_explorer_tractset2UI;


            % old code:
            value = app.Efieldmetric.Value;
            app.setefieldsliders;
            app.tractset.efieldmetric=value;

            app.ea_discfibers_ui_enablerules;
            app.ea_discfibers_tractset2UI;

            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_method2methodid(app.tractset)));
            end

            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.tractset.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.tractset.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(1:app.tractset.Nsets)');
                        app.PredictListBox.Items=sprintfc('%d',(1:app.tractset.Nsets)');
                    end
            end
        end

        % Value changed function: CovariatesCheckBox
        function CovariatesCheckBoxValueChanged(app, event)
            value = app.CovariatesCheckBox.Value;
            app.ea_discfibers_ui_enablerules;
            if ~value
                app.tractset.covars = {};
                app.tractset.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end

        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.tractset.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshinformationfromLeadGroupanalysisMenu
        function RefreshinformationfromLeadGroupanalysisMenuSelected(app, event)
            app.tractset.refreshlg;
            app.ea_discfibers_tractset2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: ConsiderSignificantFibersonlyCheckBox
        function ConsiderSignificantFibersonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox.Value;
            app.tractset.showsignificantonly=value;
            if app.SplitColorBySubscoreButton.Value
                app.tabupdate;
            end
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            disctract = app.tractset.fiberdrawn;
            disctract.info.Connectome = app.tractset.connectome;
            if app.ShowPositiveFibersCheckBox.Value
                disctract.info.PosAmount = sum(app.tractset.stats.pos.shown);
                if numel(app.tractset.stats.pos.shown)==2
                    disctract.info.PosAmountRight = sum(app.tractset.stats.pos.shown(1));
                    disctract.info.PosAmountLeft = sum(app.tractset.stats.pos.shown(2));
                    disctract.info.PosPercentRight = app.ShowAmountRightEditFieldPositive.Value;
                    disctract.info.PosPercentLeft = app.ShowAmountLeftEditFieldPositive.Value;
                end
                disctract.info.PosPercent = app.ShowAmountEditFieldPositive.Value;

            end
            if app.ShowNegativeFibersCheckBox.Value
                disctract.info.NegAmount = sum(app.tractset.stats.neg.shown);
                if numel(app.tractset.stats.neg.shown)==2
                    disctract.info.NegAmountRight = sum(app.tractset.stats.neg.shown(1));
                    disctract.info.NegAmountLeft = sum(app.tractset.stats.neg.shown(2));
                    disctract.info.NegPercentRight = app.ShowAmountRightEditFieldNegative.Value;
                    disctract.info.NegPercentLeft = app.ShowAmountLeftEditFieldNegative.Value;
                end
                disctract.info.NegPercent = app.ShowAmountEditFieldNegative.Value;
            end
            atlasname = app.tractset.ID;
            tractname = 'Tract';
            negativecolor = app.tractset.negcolor;
            positivecolor = app.tractset.poscolor;
            ea_discfibers_exportatlas(disctract, atlasname, tractname, negativecolor, positivecolor);
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.ea_discfibers_tractset2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.ea_discfibers_ui_enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.ea_discfibers_ui_enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.tractset.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.tractset.multcompstrategy=value;
            app.draw;
        end

        % Callback function
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.tractset.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.tractset.showsignificantonly=0;
            end
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.tractset.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.tractset.basepredictionon=value;
            app.ea_discfibers_ui_enablerules;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.tractset.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.ea_discfibers_ui_enablerules;
        end

        % Menu selected function: SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.tractset.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.tractset.rngseed = str2double(rngseed{1});
            end
        end

        % Value changed function: ModelNormalizationDropDown
        function ModelNormalizationDropDownValueChanged(app, event)
            value = app.ModelNormalizationDropDown.Value;
            app.tractset.modelNormalization=value;
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            value = app.BinsEditField.Value;
            app.numBins=value;
        end

        % Selection changed function: ModelSetupOptionButtonGroup
        function ModelSetupOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelSetupOptionButtonGroup.SelectedObject;
            app.tractset.multitractmode=selectedButton.Text;
            if strcmp(selectedButton.Text,'Split & Color By Group')
                app.tractset.subscore.splitbysubscore = 0;
                app.tractset.doactualprediction = 0;
                app.ea_discfibers_ui_enablerules;
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By Subscore')
                %removing the covariates box since it doesn't make sense
                %anymore
                app.tractset.subscore.splitbysubscore = 1;
                app.tractset.doactualprediction = 0;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.tractset.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{subscore,1}),app.tractset.M.clinical.labels);
                    app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
                end
                app.ea_discfibers_ui_enablerules;
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Single Tract Analysis')
                app.tractset.subscore.splitbysubscore = 0;
                app.tractset.doactualprediction = 0;
                app.ea_discfibers_ui_enablerules;
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By PCA')
                app.tractset.subscore.splitbysubscore = 0;
                app.tractset.doactualprediction = 1;
                app.FittoscoresCheckBox.Value = 1;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.tractset.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{subscore,1}),app.tractset.M.clinical.labels);
                    app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
                end
                app.ea_discfibers_ui_enablerules;
                tabupdate(app);
                app.draw;
            end
            % save dataset:
        end

        % Value changed function: SubscoresListBox
        function SubscoresListBoxValueChanged(app, event)

            app.tractset.subscore.labels = (app.SubscoresListBox.Value)';
            app.tractset.subscore.vars = cell(length(app.tractset.subscore.labels),1);
            try % keep prior weights if we can
                app.tractset.subscore.weights=app.tractset.subscore.weights(1:length(app.tractset.subscore.labels));
            catch
                app.tractset.subscore.weights = zeros(length(app.tractset.subscore.labels),1);
            end

            for subscore = 1:length(app.SubscoresListBox.Value)
                [is,ix] = ismember(app.tractset.subscore.labels{subscore,1},app.tractset.M.clinical.labels);
                app.tractset.subscore.vars{subscore,1} = app.tractset.M.clinical.vars{ix};
            end

            app.ea_discfibers_ui_enablerules;
            app.draw;

        end

        % Button pushed function: MultitractWeightsButton
        function MultitractWeightsButtonPushed(app, event)

            f = uifigure('Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 630;
            f.Position(4) = 800;
            for i=1:length(app.tractset.subscore.vars)
                slider_name{i} = app.tractset.subscore.labels{i};
            end


            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 700;
            m = 682;

            for i=1:length(slider_name)
                slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text','Weight Using..','Position',[20,m,80,25]);
                base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.tractset.subscore.weights(i))
                    slider_val_name{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChange(slider_text_box,slider_val_name,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChanging(event,slider_text_box,slider_val_name,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChange_text(slider_text_box,slider_val_name,i);
                base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


        function base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i)
            saved_labels = app.tractset.subscore.labels{i};
            saved_vars = app.tractset.subscore.vars{i};
            if strcmp(base_pred_dd{i}.Value,'None')
                slider_text_box{i}.Enable = 'on';
                slider_val_name{i}.Enable = 'on';
                app.tractset.subscore.weightvars{i,1} = '';
                app.tractset.subscore.weightvars{i,1} = [];
                app.tractset.subscore.weights(i)=slider_val_name{i}.Value/100;
            else
                slider_text_box{i}.Enable = 'off';
                slider_val_name{i}.Enable = 'off';
                index_of_new_subscore = cellfun(@(x)isequal(x,base_pred_dd{i}.Value),app.tractset.M.clinical.labels);
                app.tractset.subscore.weightvars{i,1} = app.tractset.M.clinical.vars{index_of_new_subscore};
                app.tractset.subscore.weightvarlabels{i,1} = base_pred_dd{i}.Value;
                app.tractset.subscore.weights(i)=nan;
            end

        end


        function SliderChange_text(slider_text_box,slider_val_name,i)

            text_value = slider_text_box{i}.Value;
            remainingamount=1-(text_value/100);
            otherentries=1:length(slider_val_name);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=slider_val_name{i}.Value/100;

            for slider=1:length(slider_val_name)
                if ~isnan(weights(slider))
                    slider_text_box{slider}.Value=weights(slider)*100;
                    app.tractset.subscore.weights(slider) = [weights(slider)];
                    slider_val_name{slider}.Value = weights(slider)*100;
                end
            end
            app.draw;
        end


        function SliderChange(slider_text_box,slider_val_name,i)
            %change value using sliders
            remainingamount=1-(slider_val_name{i}.Value/100);
            otherentries=1:length(slider_val_name);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=slider_val_name{i}.Value/100;

            for slider=1:length(slider_val_name)
                if ~isnan(weights(slider))
                    slider_val_name{slider}.Value=weights(slider)*100;
                    app.tractset.subscore.weights(slider) = [weights(slider)];
                    slider_text_box{slider}.Value = weights(slider)*100;
                end
            end

            app.draw;
        end

        function SliderChanging(event,slider_text_box,slider_val_name,i)
            remainingamount=1-(event.Value/100);
            otherentries=1:length(slider_val_name);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=event.Value/100;

            for slider=1:length(slider_val_name)
                if ~isnan(weights(slider))
                    slider_val_name{slider}.Value=weights(slider)*100;
                    app.tractset.subscore.weights(slider) = [weights(slider)];
                    slider_text_box{slider}.Value = weights(slider)*100;
                end
            end
        end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.tractset.calcthreshold = value;


            hint={'Note that changing this value will not have an effect on values already calculated.'
                'You would need to repeat the calculation for each connectome of interest for changes to have an effect.'};
            uigetpref('LeadDBS','FiberFilteringShowCalcThreshHint','Fiber Filtering Explorer',hint,'Makes Sense');


        end

        % Value changed function: FittoscoresCheckBox
        function FittoscoresCheckBoxValueChanged(app, event)
            value = app.FittoscoresCheckBox.Value;
            app.tractset.doactualprediction = value;
            app.ModeltypeDropDown.Enable = ea_bool2onoff(value);

        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            app.tractset.predictionmodel = app.ModeltypeDropDown.Value;
        end

        % Value changed function: PCsEditField
        function PCsEditFieldValueChanged(app, event)
            value = app.PCsEditField.Value;
            if value > length(app.tractset.subscore.vars)
                app.PCsEditField.Value = length(app.tractset.subscore.vars);
                msgbox("You cannot set more PCs than the number of variables");
            else
                app.tractset.numpcs=value;
                app.updatepca('edit');
                app.ea_discfibers_ui_enablerules;
                app.draw;
            end
        end

        % Value changed function: VarianceExpSlider
        function VarianceExpSliderValueChanged(app, event)
            value = app.VarianceExpSlider.Value;
            app.updatepca('slider');
            app.draw;
        end

        % Value changing function: VarianceExpSlider
        function VarianceExpSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: AutoRefreshCheckBox
        function AutoRefreshCheckBoxValueChanged(app, event)
            value = app.AutoRefreshCheckBox.Value;

        end

        % Callback function
        function ConsiderSignificantFibersonlyCheckBox_2ValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox_2.Value;
            app.tractset.subscore.showsignificantonly{1}=value;
            app.draw;
        end

        % Callback function
        function levelEditField_2ValueChanged(app, event)
            value = app.levelEditField_2.Value;
            app.tractset.subscore.alphalevel(1)=(value);
            app.draw;
        end

        % Callback function
        function MultTestStrategy_2ValueChanged(app, event)
            value = app.MultTestStrategy_2.Value;
            app.tractset.subscore.multcompstrategy{1}=value;
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_2
        function ShowNegativeFibersCheckBox_2ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_2.Value;
            app.tractset.subscore.negvisible(1) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_2.Enable = 'off';
                %                 app.ShowAmountLabelNegative_2.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_2.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_2.Enable = 'off';
                %                 app.ColorButtonNegative_2.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_2.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_2.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_2.Enable = 'on';
                app.ShowAmountLabelNegative_2.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_2.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_2.Enable = 'on';
                app.ColorButtonNegative_2.Enable = 'on';
                app.ShowAmountLeftLabelNegative_2.Enable = 'on';
                app.ShowAmountRightLabelNegative_2.Enable = 'on';
                app.draw;
            end


        end

        % Value changed function: ShowPositiveFibersCheckBox_2
        function ShowPositiveFibersCheckBox_2ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_2.Value;
            app.tractset.subscore.posvisible(1) = value;

            if ~value
                % app.ShowAmountEditFieldPositive_2.Enable = 'off';
                % app.ShowAmountLabelPositive_2.Enable = 'off';
                % app.ShowAmountLeftEditFieldPositive_2.Enable = 'off';
                % app.ShowAmountRightEditFieldPositive_2.Enable = 'off';
                % app.ColorButtonPositive_2.Enable = 'off';
                % app.ShowAmountLeftLabelPositive_2.Enable = 'off';
                % app.ShowAmountRightLabelPositive_2.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_2.Enable = 'on';
                app.ShowAmountLabelPositive_2.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_2.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_2.Enable = 'on';
                app.ColorButtonPositive_2.Enable = 'on';
                app.ShowAmountLeftLabelPositive_2.Enable = 'on';
                app.ShowAmountRightLabelPositive_2.Enable = 'on';
                app.draw;
            end

        end

        % Button down function: ColorBar
        function ColorBarButtonDown(app, event)
            
        end

        % Button pushed function: ColorButtonNegative_2
        function ColorButtonNegative_2Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_2.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_2
        function ColorButtonPositive_2Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_2.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            end
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_3
        function ShowNegativeFibersCheckBox_3ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_3.Value;
            app.tractset.subscore.negvisible(2) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_3.Enable = 'off';
                %                 app.ShowAmountLabelNegative_3.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_3.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_3.Enable = 'off';
                %                 app.ColorButtonNegative_3.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_3.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_3.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_3.Enable = 'on';
                app.ShowAmountLabelNegative_3.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_3.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_3.Enable = 'on';
                app.ColorButtonNegative_3.Enable = 'on';
                app.ShowAmountLeftLabelNegative_3.Enable = 'on';
                app.ShowAmountRightLabelNegative_3.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_3
        function ShowPositiveFibersCheckBox_3ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_3.Value;
            app.tractset.subscore.posvisible(2) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_3.Enable = 'off';
                %                 app.ShowAmountLabelPositive_3.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_3.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_3.Enable = 'off';
                %                 app.ColorButtonPositive_3.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_3.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_3.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_3.Enable = 'on';
                app.ShowAmountLabelPositive_3.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_3.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_3.Enable = 'on';
                app.ColorButtonPositive_3.Enable = 'on';
                app.ShowAmountLeftLabelPositive_3.Enable = 'on';
                app.ShowAmountRightLabelPositive_3.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowPositiveFibersCheckBox_4
        function ShowPositiveFibersCheckBox_4ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_4.Value;
            app.tractset.subscore.posvisible(3) = value;
            if ~value
                %                 app.ShowAmountEditFieldPositive_4.Enable = 'off';
                %                 app.ShowAmountLabelPositive_4.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_4.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_4.Enable = 'off';
                %                 app.ColorButtonPositive_4.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_4.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_4.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_4.Enable = 'on';
                app.ShowAmountLabelPositive_4.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_4.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_4.Enable = 'on';
                app.ColorButtonPositive_4.Enable = 'on';
                app.ShowAmountLeftLabelPositive_4.Enable = 'on';
                app.ShowAmountRightLabelPositive_4.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowNegativeFibersCheckBox_4
        function ShowNegativeFibersCheckBox_4ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_4.Value;
            app.tractset.subscore.negvisible(3) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_4.Enable = 'off';
                %                 app.ShowAmountLabelNegative_4.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_4.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_4.Enable = 'off';
                %                 app.ColorButtonNegative_4.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_4.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_4.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_4.Enable = 'on';
                app.ShowAmountLabelNegative_4.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_4.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_4.Enable = 'on';
                app.ColorButtonNegative_4.Enable = 'on';
                app.ShowAmountLeftLabelNegative_4.Enable = 'on';
                app.ShowAmountRightLabelNegative_4.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_5
        function ShowPositiveFibersCheckBox_5ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_5.Value;

            app.tractset.subscore.posvisible(4) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_5.Enable = 'off';
                %                 app.ShowAmountLabelPositive_5.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_5.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_5.Enable = 'off';
                %                 app.ColorButtonPositive_5.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_5.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_5.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_5.Enable = 'on';
                app.ShowAmountLabelPositive_5.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_5.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_5.Enable = 'on';
                app.ColorButtonPositive_5.Enable = 'on';
                app.ShowAmountLeftLabelPositive_5.Enable = 'on';
                app.ShowAmountRightLabelPositive_5.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_5
        function ShowNegativeFibersCheckBox_5ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_5.Value;
            app.tractset.subscore.negvisible(4) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_5.Enable = 'off';
                %                 app.ShowAmountLabelNegative_5.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_5.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_5.Enable = 'off';
                %                 app.ColorButtonNegative_5.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_5.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_5.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_5.Enable = 'on';
                app.ShowAmountLabelNegative_5.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_5.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_5.Enable = 'on';
                app.ColorButtonNegative_5.Enable = 'on';
                app.ShowAmountLeftLabelNegative_5.Enable = 'on';
                app.ShowAmountRightLabelNegative_5.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_6
        function ShowPositiveFibersCheckBox_6ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_6.Value;

            app.tractset.subscore.posvisible(5) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_6.Enable = 'off';
                %                 app.ShowAmountLabelPositive_6.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_6.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_6.Enable = 'off';
                %                 app.ColorButtonPositive_6.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_6.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_6.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_6.Enable = 'on';
                app.ShowAmountLabelPositive_6.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_6.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_6.Enable = 'on';
                app.ColorButtonPositive_6.Enable = 'on';
                app.ShowAmountLeftLabelPositive_6.Enable = 'on';
                app.ShowAmountRightLabelPositive_6.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_6
        function ShowNegativeFibersCheckBox_6ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_6.Value;
            app.tractset.subscore.negvisible(5) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_6.Enable = 'off';
                %                 app.ShowAmountLabelNegative_6.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_6.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_6.Enable = 'off';
                %                 app.ColorButtonNegative_6.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_6.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_6.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_6.Enable = 'on';
                app.ShowAmountLabelNegative_6.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_6.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_6.Enable = 'on';
                app.ColorButtonNegative_6.Enable = 'on';
                app.ShowAmountLeftLabelNegative_6.Enable = 'on';
                app.ShowAmountRightLabelNegative_6.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_7
        function ShowPositiveFibersCheckBox_7ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_7.Value;
            app.tractset.subscore.posvisible(6) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_7.Enable = 'off';
                %                 app.ShowAmountLabelPositive_7.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_7.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_7.Enable = 'off';
                %                 app.ColorButtonPositive_7.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_7.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_7.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_7.Enable = 'on';
                app.ShowAmountLabelPositive_7.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_7.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_7.Enable = 'on';
                app.ColorButtonPositive_7.Enable = 'on';
                app.ShowAmountLeftLabelPositive_7.Enable = 'on';
                app.ShowAmountRightLabelPositive_7.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_7
        function ShowNegativeFibersCheckBox_7ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_7.Value;
            app.tractset.subscore.negvisible(6) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_7.Enable = 'off';
                %                 app.ShowAmountLabelNegative_7.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_7.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_7.Enable = 'off';
                %                 app.ColorButtonNegative_7.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_7.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_7.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_7.Enable = 'on';
                app.ShowAmountLabelNegative_7.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_7.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_7.Enable = 'on';
                app.ColorButtonNegative_7.Enable = 'on';
                app.ShowAmountLeftLabelNegative_7.Enable = 'on';
                app.ShowAmountRightLabelNegative_7.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_8
        function ShowPositiveFibersCheckBox_8ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_8.Value;
            app.tractset.subscore.posvisible(7) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_8.Enable = 'off';
                %                 app.ShowAmountLabelPositive_8.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_8.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_8.Enable = 'off';
                %                 app.ColorButtonPositive_8.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_8.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_8.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_8.Enable = 'on';
                app.ShowAmountLabelPositive_8.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_8.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_8.Enable = 'on';
                app.ColorButtonPositive_8.Enable = 'on';
                app.ShowAmountLeftLabelPositive_8.Enable = 'on';
                app.ShowAmountRightLabelPositive_8.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_8
        function ShowNegativeFibersCheckBox_8ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_8.Value;
            app.tractset.subscore.negvisible(7) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_8.Enable = 'off';
                %                 app.ShowAmountLabelNegative_8.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_8.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_8.Enable = 'off';
                %                 app.ColorButtonNegative_8.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_8.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_8.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_8.Enable = 'on';
                app.ShowAmountLabelNegative_8.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_8.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_8.Enable = 'on';
                app.ColorButtonNegative_8.Enable = 'on';
                app.ShowAmountLeftLabelNegative_8.Enable = 'on';
                app.ShowAmountRightLabelNegative_8.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_9
        function ShowPositiveFibersCheckBox_9ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_9.Value;
            app.tractset.subscore.posvisible(8) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_9.Enable = 'off';
                %                 app.ShowAmountLabelPositive_9.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_9.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_9.Enable = 'off';
                %                 app.ColorButtonPositive_9.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_9.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_9.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_9.Enable = 'on';
                app.ShowAmountLabelPositive_9.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_9.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_9.Enable = 'on';
                app.ColorButtonPositive_9.Enable = 'on';
                app.ShowAmountLeftLabelPositive_9.Enable = 'on';
                app.ShowAmountRightLabelPositive_9.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_9
        function ShowNegativeFibersCheckBox_9ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_9.Value;
            app.tractset.subscore.negvisible(8) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_9.Enable = 'off';
                %                 app.ShowAmountLabelNegative_9.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_9.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_9.Enable = 'off';
                %                 app.ColorButtonNegative_9.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_9.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_9.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_9.Enable = 'on';
                app.ShowAmountLabelNegative_9.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_9.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_9.Enable = 'on';
                app.ColorButtonNegative_9.Enable = 'on';
                app.ShowAmountLeftLabelNegative_9.Enable = 'on';
                app.ShowAmountRightLabelNegative_9.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_10
        function ShowPositiveFibersCheckBox_10ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_10.Value;

            app.tractset.subscore.posvisible(9) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_10.Enable = 'off';
                %                 app.ShowAmountLabelPositive_10.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_10.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_10.Enable = 'off';
                %                 app.ColorButtonPositive_10.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_10.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_10.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_10.Enable = 'on';
                app.ShowAmountLabelPositive_10.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_10.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_10.Enable = 'on';
                app.ColorButtonPositive_10.Enable = 'on';
                app.ShowAmountLeftLabelPositive_10.Enable = 'on';
                app.ShowAmountRightLabelPositive_10.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_10
        function ShowNegativeFibersCheckBox_10ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_10.Value;
            app.tractset.subscore.negvisible(9) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_10.Enable = 'off';
                %                 app.ShowAmountLabelNegative_10.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_10.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_10.Enable = 'off';
                %                 app.ColorButtonNegative_10.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_10.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_10.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_10.Enable = 'on';
                app.ShowAmountLabelNegative_10.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_10.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_10.Enable = 'on';
                app.ColorButtonNegative_10.Enable = 'on';
                app.ShowAmountLeftLabelNegative_10.Enable = 'on';
                app.ShowAmountRightLabelNegative_10.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_11
        function ShowPositiveFibersCheckBox_11ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_11.Value;

            app.tractset.subscore.posvisible(10) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_11.Enable = 'off';
                %                 app.ShowAmountLabelPositive_11.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_11.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_11.Enable = 'off';
                %                 app.ColorButtonPositive_11.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_11.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_11.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_11.Enable = 'on';
                app.ShowAmountLabelPositive_11.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_11.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_11.Enable = 'on';
                app.ColorButtonPositive_11.Enable = 'on';
                app.ShowAmountLeftLabelPositive_11.Enable = 'on';
                app.ShowAmountRightLabelPositive_11.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_11
        function ShowNegativeFibersCheckBox_11ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_11.Value;
            app.tractset.subscore.negvisible(10) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_11.Enable = 'off';
                %                 app.ShowAmountLabelNegative_11.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_11.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_11.Enable = 'off';
                %                 app.ColorButtonNegative_11.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_11.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_11.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_11.Enable = 'on';
                app.ShowAmountLabelNegative_11.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_11.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_11.Enable = 'on';
                app.ColorButtonNegative_11.Enable = 'on';
                app.ShowAmountLeftLabelNegative_11.Enable = 'on';
                app.ShowAmountRightLabelNegative_11.Enable = 'on';
                app.draw;
            end
        end

        % Button pushed function: ColorButtonPositive_3
        function ColorButtonPositive_3Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_3.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_3
        function ColorButtonNegative_3Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_3.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_4
        function ColorButtonPositive_4Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_4.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_5
        function ColorButtonPositive_5Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_5.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_6
        function ColorButtonPositive_6Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_6.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_7
        function ColorButtonPositive_7Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_7.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_8
        function ColorButtonPositive_8Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_8.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_9
        function ColorButtonPositive_9Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_9.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_10
        function ColorButtonPositive_10Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_10.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_11
        function ColorButtonPositive_11Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_11.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,2}(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_11
        function ColorButtonNegative_11Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_11.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_10
        function ColorButtonNegative_10Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_10.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_9
        function ColorButtonNegative_9Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_9.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_8
        function ColorButtonNegative_8Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_8.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_7
        function ColorButtonNegative_7Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_7.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_6
        function ColorButtonNegative_6Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_6.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_5
        function ColorButtonNegative_5Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_5.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_4
        function ColorButtonNegative_4Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_4.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.colors{1,1}(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.tractset.subscore.pcacolors(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.tractset.M.groups.color(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: OpeninCleartuneButton
        function OpeninCleartuneButtonPushed(app, event)
            config.fibfiltmodelpath=fullfile(fileparts(app.tractset.leadgroup),'fiberfiltering',[app.tractset.ID,'.fibfilt']);
            config.tractset=app.tractset;
            config.resultfig=app.tractset.resultfig;
            ea_cleartune(config)
        end

        % Callback function
        function ConnectomeDropDownOpening(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Drop down opening function: ConnectomeDropDown
        function ConnectomeDropDownOpening2(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Value changed function: NestedCheckBox
        function NestedCheckBoxValueChanged(app, event)
            value = app.NestedCheckBox.Value;
            if app.NestedCheckBox.Value
                app.tractset.nestedLOO = true;
            else
                app.tractset.nestedLOO = false;
            end
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.tractset.kIter = app.IterationsEditField.Value;
        end

        % Selection changed function: ConnectivityModelButtonGroup
        function ConnectivityModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConnectivityModelButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Stimulation Volume')
                %app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Proportion Test (Chi-Square) / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                app.ModelSetupDropDown.Items = {'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)', 'Odds Ratios / EF-Sigmoid (Jergas 2023)', 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'One-Sample Tests / VTAs / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Proportion Test (Chi-Square) / VTAs (binary vars)', 'Binomial Tests / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                app.tractset.switch_connectivity = 1;
                app.tractset.connectivity_type = 1;
                app.tractset.statmetric = 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
            elseif strcmp(selectedButton.Text,'PAM')
                app.ModelSetupDropDown.Items = {'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'One-Sample Tests / VTAs / PAM (OSS-DBS)','Proportion Test (Chi-Square) / VTAs (binary vars)', 'Binomial Tests / VTAs (binary vars)', 'Plain Connections'};
                %if strcmp(app.ModelSetupDropDown.Value,'Correlations / E-fields (Irmen 2020)')
                %    app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                %end
                app.tractset.connectivity_type = 2;
                app.tractset.statmetric = 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                app.EfieldthresholdSlider.Enable = 'off';
                app.EfieldthresholdLabel.Enable = 'off';
                app.Efieldmetric.Enable = 'off';
                app.tractset.switch_connectivity = 1;
                app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                    app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                end
            end

            % change to connectivity type 
            %1 = vta, 2= pam
        end

        % Value changed function: ShowAmountEditFieldNegative
        function ShowAmountEditFieldNegativeValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative.Value = value/2;
                    app.ShowAmountRightEditFieldNegative.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative.Value = value;
                    app.ShowAmountRightEditFieldNegative.Value = value;
            end

            app.tractset.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;  
        end

        % Value changed function: ShowAmountEditFieldPositive
        function ShowAmountEditFieldPositiveValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive.Value = value/2;
                    app.ShowAmountRightEditFieldPositive.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive.Value = value;
                    app.ShowAmountRightEditFieldPositive.Value = value;
            end

            app.tractset.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative
        function ShowAmountLeftEditFieldNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountRightEditFieldNegative
        function ShowAmountRightEditFieldNegativeValueChanged(app, event)
            app.tractset.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive
        function ShowAmountLeftEditFieldPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountRightEditFieldPositive
        function ShowAmountRightEditFieldPositiveValueChanged(app, event)
            app.tractset.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.tractset.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.tractset.subscore.special_case = 0;
        end

        % Value changed function: ThresholdingDropDown
        function ThresholdingDropDownValueChanged(app, event)
            value = app.ThresholdingDropDown.Value;
            app.tractset.threshstrategy=value;
            app.ShowAmountEditFieldPositive.Enable = 'on';
            app.ShowAmountEditFieldNegative.Enable = 'on';
            switch value
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountRightEditFieldPositive.Value = 0;
                    app.ShowAmountLeftEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                    app.ShowAmountRightEditFieldNegative.Value = 0;
                    app.ShowAmountLeftEditFieldNegative.Value = 0;
                    app.PlotDistributionButton.Visible = 'off';
                case 'Histogram (CDF)'
                    app.ShowAmountEditFieldPositive.Value = 0.01;
                    app.ShowAmountRightEditFieldPositive.Value = 0.01;
                    app.ShowAmountLeftEditFieldPositive.Value = 0.01;
                    app.ShowAmountEditFieldNegative.Value = 0.01;
                    app.ShowAmountRightEditFieldNegative.Value = 0.01;
                    app.ShowAmountLeftEditFieldNegative.Value = 0.01;
                    app.PlotDistributionButton.Visible = 'on';
                case 'Fixed Fiber Value'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                    app.ShowAmountRightEditFieldPositive.Value = 0;
                    app.ShowAmountLeftEditFieldPositive.Value = 0;
                    app.ShowAmountRightEditFieldNegative.Value = 0;
                    app.ShowAmountLeftEditFieldNegative.Value = 0;
                    app.PlotDistributionButton.Visible = 'on';
                otherwise
                    app.ShowAmountEditFieldPositive.Value = 25;
                    app.ShowAmountEditFieldNegative.Value = 25;
                    app.ShowAmountRightEditFieldPositive.Value = 25;
                    app.ShowAmountLeftEditFieldPositive.Value = 25;
                    app.ShowAmountRightEditFieldNegative.Value = 25;
                    app.ShowAmountLeftEditFieldNegative.Value = 25;
                    app.PlotDistributionButton.Visible = 'off';
            end
            app.tractset.showposamount=[app.ShowAmountRightEditFieldPositive.Value,app.ShowAmountLeftEditFieldPositive.Value];
            app.tractset.shownegamount=[app.ShowAmountRightEditFieldNegative.Value,app.ShowAmountLeftEditFieldNegative.Value];
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Menu selected function: RecreatevisualizationfigureMenu
        function RecreatevisualizationfigureMenuSelected(app, event)
            if isvalid(app.tractset.resultfig)
                close(app.tractset.resultfig);
            end
            app.tractset.resultfig = ea_mnifigure;
            app.draw(1);
        end

        % Value changed function: ShowAmountEditFieldPositive_2
        function ShowAmountEditFieldPositive_2ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_2.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_2.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_2.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_2.Value = value;
                    app.ShowAmountRightEditFieldPositive_2.Value = value;
            end

            app.tractset.subscore.vis.showposamount(1,:) = [app.ShowAmountRightEditFieldPositive_2.Value, app.ShowAmountLeftEditFieldPositive_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_2
        function ShowAmountEditFieldNegative_2ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_2.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_2.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_2.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_2.Value = value;
                    app.ShowAmountRightEditFieldNegative_2.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(1,:) = [app.ShowAmountRightEditFieldNegative_2.Value, app.ShowAmountLeftEditFieldNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_3
        function ShowAmountEditFieldPositive_3ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_3.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_3.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_3.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_3.Value = value;
                    app.ShowAmountRightEditFieldPositive_3.Value = value;
            end

            app.tractset.subscore.vis.showposamount(2,:) = [app.ShowAmountRightEditFieldPositive_3.Value, app.ShowAmountLeftEditFieldPositive_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_3
        function ShowAmountEditFieldNegative_3ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_3.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_3.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_3.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_3.Value = value;
                    app.ShowAmountRightEditFieldNegative_3.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(2,:) = [app.ShowAmountRightEditFieldNegative_3.Value, app.ShowAmountLeftEditFieldNegative_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_4
        function ShowAmountEditFieldPositive_4ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_4.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_4.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_4.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_4.Value = value;
                    app.ShowAmountRightEditFieldPositive_4.Value = value;
            end

            app.tractset.subscore.vis.showposamount(3,:) = [app.ShowAmountRightEditFieldPositive_4.Value, app.ShowAmountLeftEditFieldPositive_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_4
        function ShowAmountEditFieldNegative_4ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_4.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_4.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_4.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_4.Value = value;
                    app.ShowAmountRightEditFieldNegative_4.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(3,:) = [app.ShowAmountRightEditFieldNegative_4.Value, app.ShowAmountLeftEditFieldNegative_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_5
        function ShowAmountEditFieldPositive_5ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_5.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_5.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_5.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_5.Value = value;
                    app.ShowAmountRightEditFieldPositive_5.Value = value;
            end

            app.tractset.subscore.vis.showposamount(4,:) = [app.ShowAmountRightEditFieldPositive_5.Value, app.ShowAmountLeftEditFieldPositive_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_5
        function ShowAmountEditFieldNegative_5ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_5.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_5.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_5.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_5.Value = value;
                    app.ShowAmountRightEditFieldNegative_5.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(4,:) = [app.ShowAmountRightEditFieldNegative_5.Value, app.ShowAmountLeftEditFieldNegative_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_6
        function ShowAmountEditFieldPositive_6ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_6.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_6.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_6.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_6.Value = value;
                    app.ShowAmountRightEditFieldPositive_6.Value = value;
            end

            app.tractset.subscore.vis.showposamount(5,:) = [app.ShowAmountRightEditFieldPositive_6.Value, app.ShowAmountLeftEditFieldPositive_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_6
        function ShowAmountEditFieldNegative_6ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_6.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_6.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_6.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_6.Value = value;
                    app.ShowAmountRightEditFieldNegative_6.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(5,:) = [app.ShowAmountRightEditFieldNegative_6.Value, app.ShowAmountLeftEditFieldNegative_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_7
        function ShowAmountEditFieldPositive_7ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_7.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_7.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_7.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_7.Value = value;
                    app.ShowAmountRightEditFieldPositive_7.Value = value;
            end

            app.tractset.subscore.vis.showposamount(6,:) = [app.ShowAmountRightEditFieldPositive_7.Value, app.ShowAmountLeftEditFieldPositive_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_7
        function ShowAmountEditFieldNegative_7ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_7.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_7.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_7.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_7.Value = value;
                    app.ShowAmountRightEditFieldNegative_7.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(6,:) = [app.ShowAmountRightEditFieldNegative_7.Value, app.ShowAmountLeftEditFieldNegative_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_8
        function ShowAmountEditFieldPositive_8ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_8.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_8.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_8.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_8.Value = value;
                    app.ShowAmountRightEditFieldPositive_8.Value = value;
            end

            app.tractset.subscore.vis.showposamount(7,:) = [app.ShowAmountRightEditFieldPositive_8.Value, app.ShowAmountLeftEditFieldPositive_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_8
        function ShowAmountEditFieldNegative_8ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_8.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_8.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_8.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_8.Value = value;
                    app.ShowAmountRightEditFieldNegative_8.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(7,:) = [app.ShowAmountRightEditFieldNegative_8.Value, app.ShowAmountLeftEditFieldNegative_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_9
        function ShowAmountEditFieldPositive_9ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_9.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_9.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_9.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_9.Value = value;
                    app.ShowAmountRightEditFieldPositive_9.Value = value;
            end

            app.tractset.subscore.vis.showposamount(8,:) = [app.ShowAmountRightEditFieldPositive_9.Value, app.ShowAmountLeftEditFieldPositive_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_9
        function ShowAmountEditFieldNegative_9ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_9.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_9.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_9.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_9.Value = value;
                    app.ShowAmountRightEditFieldNegative_9.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(8,:) = [app.ShowAmountRightEditFieldNegative_9.Value, app.ShowAmountLeftEditFieldNegative_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_10
        function ShowAmountEditFieldPositive_10ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_10.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_10.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_10.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_10.Value = value;
                    app.ShowAmountRightEditFieldPositive_10.Value = value;
            end

            app.tractset.subscore.vis.showposamount(9,:) = [app.ShowAmountRightEditFieldPositive_10.Value, app.ShowAmountLeftEditFieldPositive_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_10
        function ShowAmountEditFieldNegative_10ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_10.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_10.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_10.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_10.Value = value;
                    app.ShowAmountRightEditFieldNegative_10.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(9,:) = [app.ShowAmountRightEditFieldNegative_10.Value, app.ShowAmountLeftEditFieldNegative_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_11
        function ShowAmountEditFieldPositive_11ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_11.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_11.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_11.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_11.Value = value;
                    app.ShowAmountRightEditFieldPositive_11.Value = value;
            end

            app.tractset.subscore.vis.showposamount(10,:) = [app.ShowAmountRightEditFieldPositive_11.Value, app.ShowAmountLeftEditFieldPositive_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_11
        function ShowAmountEditFieldNegative_11ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_11.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_11.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_11.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_11.Value = value;
                    app.ShowAmountRightEditFieldNegative_11.Value = value;
            end

            app.tractset.subscore.vis.shownegamount(10,:) = [app.ShowAmountRightEditFieldNegative_11.Value, app.ShowAmountLeftEditFieldNegative_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_2
        function ShowAmountLeftEditFieldPositive_2ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(1,:) = [app.ShowAmountRightEditFieldPositive_2.Value, app.ShowAmountLeftEditFieldPositive_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_2
        function ShowAmountRightEditFieldPositive_2ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(1,:) = [app.ShowAmountRightEditFieldPositive_2.Value, app.ShowAmountLeftEditFieldPositive_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_2
        function ShowAmountLeftEditFieldNegative_2ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(1,:) = [app.ShowAmountRightEditFieldNegative_2.Value, app.ShowAmountLeftEditFieldNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_2
        function ShowAmountRightEditFieldNegative_2ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(1,:) = [app.ShowAmountRightEditFieldNegative_2.Value, app.ShowAmountLeftEditFieldNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_3
        function ShowAmountLeftEditFieldPositive_3ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(2,:) = [app.ShowAmountRightEditFieldPositive_3.Value, app.ShowAmountLeftEditFieldPositive_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_3
        function ShowAmountRightEditFieldPositive_3ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(2,:) = [app.ShowAmountRightEditFieldPositive_3.Value, app.ShowAmountLeftEditFieldPositive_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_3
        function ShowAmountLeftEditFieldNegative_3ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(2,:) = [app.ShowAmountRightEditFieldNegative_3.Value, app.ShowAmountLeftEditFieldNegative_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_3
        function ShowAmountRightEditFieldNegative_3ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(2,:) = [app.ShowAmountRightEditFieldNegative_3.Value, app.ShowAmountLeftEditFieldNegative_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_4
        function ShowAmountLeftEditFieldPositive_4ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(3,:) = [app.ShowAmountRightEditFieldPositive_4.Value, app.ShowAmountLeftEditFieldPositive_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_4
        function ShowAmountRightEditFieldPositive_4ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(3,:) = [app.ShowAmountRightEditFieldPositive_4.Value, app.ShowAmountLeftEditFieldPositive_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_4
        function ShowAmountLeftEditFieldNegative_4ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(3,:) = [app.ShowAmountRightEditFieldNegative_4.Value, app.ShowAmountLeftEditFieldNegative_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_4
        function ShowAmountRightEditFieldNegative_4ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(3,:) = [app.ShowAmountRightEditFieldNegative_4.Value, app.ShowAmountLeftEditFieldNegative_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_5
        function ShowAmountLeftEditFieldPositive_5ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(4,:) = [app.ShowAmountRightEditFieldPositive_5.Value, app.ShowAmountLeftEditFieldPositive_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_5
        function ShowAmountRightEditFieldPositive_5ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(4,:) = [app.ShowAmountRightEditFieldPositive_5.Value, app.ShowAmountLeftEditFieldPositive_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_5
        function ShowAmountLeftEditFieldNegative_5ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(4,:) = [app.ShowAmountRightEditFieldNegative_5.Value, app.ShowAmountLeftEditFieldNegative_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_5
        function ShowAmountRightEditFieldNegative_5ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(4,:) = [app.ShowAmountRightEditFieldNegative_5.Value, app.ShowAmountLeftEditFieldNegative_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_6
        function ShowAmountLeftEditFieldPositive_6ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(5,:) = [app.ShowAmountRightEditFieldPositive_6.Value, app.ShowAmountLeftEditFieldPositive_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_6
        function ShowAmountRightEditFieldPositive_6ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(5,:) = [app.ShowAmountRightEditFieldPositive_6.Value, app.ShowAmountLeftEditFieldPositive_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_6
        function ShowAmountLeftEditFieldNegative_6ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(5,:) = [app.ShowAmountRightEditFieldNegative_6.Value, app.ShowAmountLeftEditFieldNegative_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_6
        function ShowAmountRightEditFieldNegative_6ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(5,:) = [app.ShowAmountRightEditFieldNegative_6.Value, app.ShowAmountLeftEditFieldNegative_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_7
        function ShowAmountLeftEditFieldPositive_7ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(6,:) = [app.ShowAmountRightEditFieldPositive_7.Value, app.ShowAmountLeftEditFieldPositive_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_7
        function ShowAmountRightEditFieldPositive_7ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(6,:) = [app.ShowAmountRightEditFieldPositive_7.Value, app.ShowAmountLeftEditFieldPositive_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_7
        function ShowAmountLeftEditFieldNegative_7ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(6,:) = [app.ShowAmountRightEditFieldNegative_7.Value, app.ShowAmountLeftEditFieldNegative_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_7
        function ShowAmountRightEditFieldNegative_7ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(6,:) = [app.ShowAmountRightEditFieldNegative_7.Value, app.ShowAmountLeftEditFieldNegative_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_8
        function ShowAmountLeftEditFieldPositive_8ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(7,:) = [app.ShowAmountRightEditFieldPositive_8.Value, app.ShowAmountLeftEditFieldPositive_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_8
        function ShowAmountRightEditFieldPositive_8ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(7,:) = [app.ShowAmountRightEditFieldPositive_8.Value, app.ShowAmountLeftEditFieldPositive_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_8
        function ShowAmountLeftEditFieldNegative_8ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(7,:) = [app.ShowAmountRightEditFieldNegative_8.Value, app.ShowAmountLeftEditFieldNegative_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_8
        function ShowAmountRightEditFieldNegative_8ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(7,:) = [app.ShowAmountRightEditFieldNegative_8.Value, app.ShowAmountLeftEditFieldNegative_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_9
        function ShowAmountLeftEditFieldPositive_9ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(8,:) = [app.ShowAmountRightEditFieldPositive_9.Value, app.ShowAmountLeftEditFieldPositive_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_9
        function ShowAmountRightEditFieldPositive_9ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(8,:) = [app.ShowAmountRightEditFieldPositive_9.Value, app.ShowAmountLeftEditFieldPositive_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_9
        function ShowAmountLeftEditFieldNegative_9ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(8,:) = [app.ShowAmountRightEditFieldNegative_9.Value, app.ShowAmountLeftEditFieldNegative_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_9
        function ShowAmountRightEditFieldNegative_9ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(8,:) = [app.ShowAmountRightEditFieldNegative_9.Value, app.ShowAmountLeftEditFieldNegative_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_10
        function ShowAmountLeftEditFieldPositive_10ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(9,:) = [app.ShowAmountRightEditFieldPositive_10.Value, app.ShowAmountLeftEditFieldPositive_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_10
        function ShowAmountRightEditFieldPositive_10ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(9,:) = [app.ShowAmountRightEditFieldPositive_10.Value, app.ShowAmountLeftEditFieldPositive_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_10
        function ShowAmountLeftEditFieldNegative_10ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(9,:) = [app.ShowAmountRightEditFieldNegative_10.Value, app.ShowAmountLeftEditFieldNegative_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_10
        function ShowAmountRightEditFieldNegative_10ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(9,:) = [app.ShowAmountRightEditFieldNegative_10.Value, app.ShowAmountLeftEditFieldNegative_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_11
        function ShowAmountLeftEditFieldPositive_11ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(10,:) = [app.ShowAmountRightEditFieldPositive_11.Value, app.ShowAmountLeftEditFieldPositive_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_11
        function ShowAmountRightEditFieldPositive_11ValueChanged(app, event)
            app.tractset.subscore.vis.showposamount(10,:) = [app.ShowAmountRightEditFieldPositive_11.Value, app.ShowAmountLeftEditFieldPositive_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_11
        function ShowAmountLeftEditFieldNegative_11ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(10,:) = [app.ShowAmountRightEditFieldNegative_11.Value, app.ShowAmountLeftEditFieldNegative_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_11
        function ShowAmountRightEditFieldNegative_11ValueChanged(app, event)
            app.tractset.subscore.vis.shownegamount(10,:) = [app.ShowAmountRightEditFieldNegative_11.Value, app.ShowAmountLeftEditFieldNegative_11.Value];
            app.draw;
        end

        % Button pushed function: PlotDistributionButton
        function PlotDistributionButtonPushed(app, event)
            switch app.tractset.multitractmode
                case 'Single Tract Analysis'
                    posvisible = app.tractset.posvisible;
                    negvisible = app.tractset.negvisible;
                    showposamount = app.tractset.showposamount;
                    shownegamount = app.tractset.shownegamount;
                    threshstrategy = app.tractset.threshstrategy;
        
                    app.tractset.posvisible = 1;
                    app.tractset.negvisible = 1;
                    app.tractset.showposamount = [100, 100];
                    app.tractset.shownegamount = [100, 100];
                    app.tractset.threshstrategy = 'Percentage Relative to Peak';
                    
                    selection = app.tractset.patientselection; 
        
                    vals = ea_discfibers_calcstats(app.tractset, selection);
        
                    app.tractset.posvisible = posvisible;
                    app.tractset.negvisible = negvisible;
                    app.tractset.showposamount = showposamount;
                    app.tractset.shownegamount = shownegamount;
                    app.tractset.threshstrategy = threshstrategy;
        
                    vals = [vals{1}; vals{2}];
                    posval = vals(vals>0);
                    negval = vals(vals<0);
        
                    if isempty(posval) && isempty(negval)
                        ea_cprintf('CmdWinWarnings', 'No valid fiber found!\n');
                        return;
                    elseif ~isempty(posval) && ~isempty(negval)
                        rowNum = 2;
                        indOffset = 2;
                    elseif ~isempty(posval)
                        rowNum = 1;
                        indOffset = 2;
                    else
                        rowNum = 1;
                        indOffset = 0;
                    end
        
                    figure('NumberTitle', 'off', 'Name', 'Fiber Value Distribution', 'MenuBar', 'none');
                    lim = [0.5, 1];
        
                    if ~isempty(posval)
                        subplot(rowNum, 2, 1);
                        histogram(posval, 'BinWidth', 0.01);
                        title('Histogram - Positive Values');
            
                        subplot(rowNum, 2, 2);
                        [fx, x] = ecdf(posval);
                        plot(x, fx);
                        ylim(lim);
        
                        for sample = [0.9, 0.95, 0.99]
                            % Positive vals, vertical line and text
                            line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7]);
                            switch app.tractset.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.25, num2str(x(find(fx>=sample,1)), '%.2f'), 'Rotation', 90, 'Color', [0.7, 0.7, 0.7]);
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.15, ['TH. ', num2str(x(find(fx>=sample,1)), '%.2f')], 'Rotation', 90, 'Color', '#0072BD');
                            end
                            % Positive vals, horizontal line and text
                            line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7])
                            switch app.tractset.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1))*0.25, fx(find(fx>=sample,1)), ['TH. ', num2str(1-fx(find(fx>=sample,1)), '%.2f')], 'Color', '#0072BD');
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1))/2, fx(find(fx>=sample,1)), num2str(1-fx(find(fx>=sample,1)), '%.2f'), 'Color', [0.7, 0.7, 0.7]);
                            end
                        end
        
                        title('CDF - Positive Values');
                    end
        
                    if ~isempty(negval)
                        subplot(rowNum, 2, 1+indOffset);
                        histogram(-negval, 'BinWidth', 0.01);
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('Histogram - Negative Values');
            
                        subplot(rowNum, 2, 2+indOffset);
                        [fx, x] = ecdf(-negval);
                        plot(x, fx);
                        ylim(lim);
        
                        for sample = [0.9, 0.95, 0.99]
                            % Negative vals, vertical line and text
                            line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7])
                            switch app.tractset.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.25, num2str(-x(find(fx>=sample,1)), '%.2f'), 'Rotation', 90, 'Color', [0.7, 0.7, 0.7]);
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.15, ['TH. ', num2str(-x(find(fx>=sample,1)), '%.2f')], 'Rotation', 90, 'Color', '#0072BD');
                            end
                            % Negative vals, horizontal line and text
                            line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7])
                            switch app.tractset.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1))*0.25, fx(find(fx>=sample,1)), ['TH. ', num2str(1-fx(find(fx>=sample,1)), '%.2f')], 'Color', '#0072BD');
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1))/2, fx(find(fx>=sample,1)), num2str(1-fx(find(fx>=sample,1)), '%.2f'), 'Color', [0.7, 0.7, 0.7]);
                            end
                        end
        
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('CDF - Negative Values');
                    end
                
                case {'Split & Color By PCA', 'Split & Color By Subscore'}
                    posvisible = app.tractset.subscore.posvisible;
                    negvisible = app.tractset.subscore.negvisible;
                    showposamount = app.tractset.subscore.vis.showposamount;
                    shownegamount = app.tractset.subscore.vis.shownegamount;
                    threshstrategy = app.tractset.threshstrategy;
                    
                    
                    app.tractset.subscore.posvisible = ones(size(app.tractset.subscore.posvisible));
                    app.tractset.subscore.negvisible = ones(size(app.tractset.subscore.negvisible));
                    app.tractset.subscore.vis.showposamount = 100*ones(size(app.tractset.subscore.vis.showposamount));
                    app.tractset.subscore.vis.shownegamount = 100*ones(size(app.tractset.subscore.vis.shownegamount));
                    app.tractset.threshstrategy = 'Percentage Relative to Peak';
                    
                    selection = app.tractset.patientselection; 
                    
                    vals = ea_discfibers_calcstats(app.tractset, selection);
                    
                    app.tractset.subscore.posvisible = posvisible;
                    app.tractset.subscore.negvisible = negvisible;
                    app.tractset.subscore.vis.showposamount = showposamount;
                    app.tractset.subscore.vis.shownegamount = shownegamount;
                    app.tractset.threshstrategy = threshstrategy;
                    
                    switch app.tractset.multitractmode
                        case 'Split & Color By PCA'
                            vis_colors = app.tractset.subscore.pcacolors; 
                            n_tabs = app.tractset.numpcs; 
                        case 'Split & Color By Subscore'
                            vis_colors = app.tractset.subscore.colors{1,2};  
                            n_tabs = size(app.tractset.subscore.vars, 1);
                    end 
                    
                    for ti = 1:n_tabs
                        tempvals{ti} = [vals{ti, 1}; vals{ti, 2}]; 
                        posval{ti} = tempvals{ti}(tempvals{ti}>0); 
                        negval{ti} = tempvals{ti}(tempvals{ti}<0); 
                    end
                    
                    if sum(cellfun(@numel, posval))==0 && sum(cellfun(@numel, negval))==0
                        ea_cprintf('CmdWinWarnings', 'No valid fiber found!\n');
                        return;
                    elseif sum(cellfun(@numel, posval))>0 && sum(cellfun(@numel, negval))>0
                        rowNum = 2;
                        indOffset = 2;
                    elseif sum(cellfun(@numel, posval))>0
                        rowNum = 1;
                        indOffset = 2;
                    else
                        rowNum = 1;
                        indOffset = 0;
                    end
                    
                    figure('NumberTitle', 'off', 'Name', 'Fiber Value Distribution', 'MenuBar', 'none');
                                lim = [0.5, 1];
                    
                    if sum(cellfun(@numel, posval))>0
                        subplot(rowNum, 2, 1); hold on
                        for ti = find(cellfun(@numel, posval)>0)
                            histogram(posval{ti}, 'BinWidth', 0.01, 'FaceColor', vis_colors(ti, :), 'FaceAlpha', 0.4)
                        end
                        title('Histogram - Positive Values');
                    
                        subplot(rowNum, 2, 2), hold on
                        for ti = find(cellfun(@numel, posval)>0)
                            [fx, x] = ecdf(posval{ti});
                            plot(x, fx, 'color', vis_colors(ti, :), 'linew', 2);
                            ylim(lim);
                        
                            for sample = [0.9, 0.95, 0.99]
                                % Positive vals, vertical line and text
                                line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color',  vis_colors(ti, :));
                                % Positive vals, horizontal line and text
                                line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color', [.7, .7, .7]);
                            end
                        end
                        title('CDF - Positive Values');
                    end
                    
                    if sum(cellfun(@numel, negval))>0
                        subplot(rowNum, 2, 1+indOffset); hold on
                        for ti = find(cellfun(@numel, negval)>0)
                            histogram(-negval{ti}, 'BinWidth', 0.01, 'FaceColor', vis_colors(ti, :), 'FaceAlpha', 0.4)
                        end
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('Histogram - Negative Values');
                    
                        subplot(rowNum, 2, 2+indOffset), hold on    
                        for ti = find(cellfun(@numel, negval)>0)
                            [fx, x] = ecdf(-negval{ti});
                            plot(x, fx, 'color', vis_colors(ti, :), 'linew', 2);
                            ylim(lim);
                    
                            for sample = [0.9, 0.95, 0.99]
                                % Negative vals, vertical line and text
                                line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color', vis_colors(ti, :))
                                % Negative vals, horizontal line and text
                                line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color', [0.7, 0.7, 0.7])
                            end
                        end
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('CDF - Negative Values');
                    end

                otherwise
                    fprintf(['This has not been implemented yet for ' app.tractset.multitractmode '.\n'])
            end
        end

        % Button pushed function: RefreshViewButton_2
        function RefreshViewButton_2Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_3
        function RefreshViewButton_3Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_4
        function RefreshViewButton_4Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_5
        function RefreshViewButton_5Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_6
        function RefreshViewButton_6Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_7
        function RefreshViewButton_7Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_8
        function RefreshViewButton_8Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_9
        function RefreshViewButton_9Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_10
        function RefreshViewButton_10Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_11
        function RefreshViewButton_11Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: InspectPCAButton
        function InspectPCAButtonPushed(app, event)
            for i=1:length(app.tractset.subscore.vars)
                selected_subscores{i} = app.tractset.subscore.vars{i}(app.tractset.patientselection);
            end
            data = cell2mat(selected_subscores);
            var_names = app.tractset.subscore.labels;
            op_dir = fullfile(app.tractset.M.root,'fiberfiltering','pcaresults');
            if sum(isnan(data(:)))
                ea_warndlg(['Your data contains NaNs. The data from patients that ' ...
                    'have any NaN value will be ignored in PCA calculation. If this '...
                    'is not what you want, consider handling these values before using PCA.']);
            end
            ea_get_PCA_graphs(data,var_names,op_dir)
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            [FileName,PathName]=uiputfile('*.mat','File to save the Fiber Model...');
            ExternalModelFile = fullfile(PathName,FileName);
            ea_save_fibscore_model(app.tractset, ExternalModelFile)
        end

        % Value changed function: LoadModelButton
        function LoadModelButtonValueChanged(app, event)
            [FileName,PathName]=uigetfile('*.mat','Please Choose a Fiber Model for the selected connectome...');
            if strcmp(string(FileName),'0')
                waitfor(msgbox('The Fiber Model was not chosen!'));
                return;
            end

            [~, ExternalModelFileName] = fileparts(app.tractset.ExternalModelFile);
            app.TrainOnListBox.Items = string(ExternalModelFileName); % this does not update on-the-fly
            app.tractset.ExternalModelFile = fullfile(PathName,FileName);
            app.draw(1)
        end

        % Value changed function: SubcohortsDropDown_2
        function SubcohortsDropDown_2ValueChanged(app, event)
            value = app.SubcohortsDropDown_2.Value;
            [~,whichset]=ismember(value,app.tractset.setlabels);
            app.PredictListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});


            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;


%             tsets = find(ismember(app.tractset.setlabels, app.TrainOnListBox.Value));
%             training=[];
%             for ts=1:length(tsets)
%                 training = [training,find(app.tractset.setselections{tsets(ts)})];
%             end
%             training=unique(training);
%             tsets = find(ismember(app.tractset.setlabels, app.PredictListBox.Value));
%             test=[];
%             for ts=1:length(tsets)
%                 test = [test,find(app.tractset.setselections{tsets(ts)})];
%             end
%             test=unique(test);
%             % Patient selected based on the training and test indices
%             app.tractset.customselection = unique([training, test]);
%             % Construct cvp struct
%             cvp.training{1} = ismember(app.tractset.customselection, training);
%             cvp.test{1} = ismember(app.tractset.customselection, test);


%             app.tractset.customselection = unique([training, test]);
%             % Construct cvp struct
%             cvp.training{1} = ismember(app.tractset.customselection, training);
%             cvp.test{1} = ismember(app.tractset.customselection, test);

            %app.draw;
            % always reset to "Choose..."
            app.ea_discfibers_ui_enablerules;


        end

        % Value changed function: ROISwitch
        function ROISwitchValueChanged(app, event)
            app.tractset.roivisible = strcmp(app.ROISwitch.Value,'Show');
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Value changed function: FibersSwitch
        function FibersSwitchValueChanged(app, event)
            app.tractset.connfibvisible = strcmp(app.FibersSwitch.Value,'Show');
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Menu selected function: OptimizeParametersontrainingsetMenu
        function OptimizeParametersontrainingsetMenuSelected(app, event)
            
        end

        % Menu selected function: CrossvalidationsMenu
        function CrossvalidationsMenuSelected(app, event)
            ea_discfibers_optimize(app.tractset,app,'cv');
            app.ea_discfibers_tractset2UI;
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Menu selected function: SimilaritiesDissimilaritiesMenu
        function SimilaritiesDissimilaritiesMenuSelected(app, event)
            ea_discfibers_optimize(app.tractset,app,'sim');
            app.ea_discfibers_tractset2UI;
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Menu selected function: CombinedMenu
        function CombinedMenuSelected(app, event)
            ea_discfibers_optimize(app.tractset,app,'comb');
            app.ea_discfibers_tractset2UI;
            app.ea_discfibers_ui_enablerules;
            app.draw;
        end

        % Menu selected function: LoadPriorOptimization
        function LoadPriorOptimizationMenuSelected(app, event)
            optFile = uigetfile('*.mat', 'Choose Prior Optmization Status File', fileparts(app.tractset.leadgroup));
            if ischar(optFile)
                ea_discfibers_optimize(app.tractset, app, 'comb', optFile, 'stop');
                app.ea_discfibers_tractset2UI;
                app.ea_discfibers_ui_enablerules;
                app.draw;
            end
        end

        % Menu selected function: LoadPriorOptimizationAndResume
        function LoadPriorOptimizationAndResumeMenuSelected(app, event)
            optFile = uigetfile('*.mat', 'Choose Prior Optmization Status File', fileparts(app.tractset.leadgroup));
            if ischar(optFile)
                ea_discfibers_optimize(app.tractset, app, 'comb', optFile, 'resume');
                app.ea_discfibers_tractset2UI;
                app.ea_discfibers_ui_enablerules;
                app.draw;
            end
        end

        % Button pushed function: OpeninCurrentuneButton
        function OpeninCurrentuneButtonPushed(app, event)
            f = uifigure('Name','Currentune','Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 1030;
            f.Position(4) = 800;
            for i=1:length(app.tractset.subscore.vars)
                slider_name{i} = app.tractset.subscore.labels{i};
            end
           
            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 690;
            m = 682;

            frame = uipanel(f,'Title', 'Weights', 'FontSize',12, 'Position', [10, m - 10, 550,20]);

            % Current Optimizer
            frame2 = uipanel(f,'Title', 'Current Optimizer', 'FontSize',12, 'Position', [580, 547, 280,200]);
            
            ConcLbl = uilabel(f,'Text','Current Limits for Cylindrical Contacts, mA','Position',[590,697,300,25],'FontWeight','bold');
            ConcLblMin = uilabel(f,'Text','Min:','Position',[590,667,40,25]);
            ConcMin = uieditfield(f,'numeric','Value',-4.0, 'Position',[630,667,40,25], "ValueChangedFcn",@(ConcMin,event) ConcMinChanged(ConcMin));
            ConcMin.Limits = [-10.0, 10.0];  % m
            ConcLblMax = uilabel(f,'Text','Max:','Position',[700,667,40,25]);
            ConcMax = uieditfield(f,'numeric','Value',4.0, 'Position',[740,667,40,25], "ValueChangedFcn",@(ConcMax,event) ConcMaxChanged(ConcMax));
            ConcMax.Limits = [-10.0, 10.0];  % mA
            
            SegmLbl = uilabel(f,'Text','Current Limits for Segment Contacts, mA','Position',[590,627,300,25],'FontWeight','bold');
            SegmLblMin = uilabel(f,'Text','Min:','Position',[590,597,40,25]);
            SegmMin = uieditfield(f,'numeric','Value',-2.0, 'Position',[630,597,40,25], "ValueChangedFcn",@(SegmMin,event) SegmMinChanged(SegmMin));
            SegmMin.Limits = [-5.0, 5.0];  % mA
            SegmLblMax = uilabel(f,'Text','Max:','Position',[700,597,40,25]);
            SegmMax = uieditfield(f,'numeric','Value',2.0, 'Position',[740,597,40,25], "ValueChangedFcn",@(SegmMax,event) SegmMaxChanged(SegmMax));
            SegmMax.Limits = [-5.0, 5.0];  % mA


            % create a button for Export and Launch
            ExportSymptomPathwayBTN = uibutton(f,'Text','Export Item-grouped Pathways', 'Position', [10,m+80,200,25], 'ButtonPushedFcn', @(ExportSymptomPathwayBTN,event) ExportSymptomPathwayButtonPushed(ExportSymptomPathwayBTN));
            Launch_NetBlend = uibutton(f,'Text','Launch Network Blender', 'Position', [800,500,200,25], 'ButtonPushedFcn', @(Launch_NetBlend,event) Launch_NetBlendButtonPushed(Launch_NetBlend,slider_name));

            for i=1:length(slider_name)
                %slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text',slider_name{i},'Position',[20,m,180,25],'FontWeight','bold');

                fixed_weights{i} = uicheckbox(f,'Text', 'fixed', 'Position',[200,m,80,25]);

                %base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.tractset.subscore.weights(i))
                    slider_val_name{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                %fixed_weights{i}.Value = app.tractset.subscore.CheckedWeights{i};
                    
                    %base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChangeCurrentune(slider_text_box,slider_val_name,fixed_weights,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChangingCurrentune(event,slider_text_box,slider_val_name,fixed_weights,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChangeCurrentune_text(slider_text_box,slider_val_name,fixed_weights,i);
                fixed_weights{i}.ValueChangedFcn = @(app,event) FixedWeightcBoxChanged(fixed_weights,slider_val_name,Launch_NetBlend,i); 
                
                %base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


            PatientID = uieditfield(f,'text','Value','John Doe', 'Position',[190,m+5,150,25], "Editable","off", "ValueChangedFcn",@(PatientID,event) PatientIDChanged(PatientID));
            ChoosePatient = uibutton(f,'Text','Choose a patient', 'Position', [10,m+5,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) ChoosePatientButtonPushed(ChoosePatient,PatientID));
            PredictImprovement = uibutton(f,'Text','Predict Improvement', 'Position', [380,m+5,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) PredictImprovementButtonPushed(PredictImprovement,fixed_weights,slider_name));
            CurrentOptimizer = uibutton(f,'Text','Optimize Currents', 'Position', [380,m+5-40,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) CurrentOptimizerButtonPushed(CurrentOptimizer,fixed_weights,slider_name));

            GenerateTrainTest = uibutton(f,'Text','Generate Train/Test sets for ANN', 'Position', [580,500,200,25], 'ButtonPushedFcn', @(GenerateTrainTest,event) GenerateTrainTestButtonPushed(GenerateTrainTest,PatientID));

            lower_lim = m - 10 + 60;

            %m = 682;

            % adjust the frame size
            frame.Position(2) = lower_lim;
            frame.Position(4) = 682 + 65 - lower_lim;

            function GenerateTrainTestButtonPushed(GenerateTrainTest, PatientID)
                % we will choose number of samples depending on the
                % electrode model within Network Blending

                % add a sanity check for current limits
                app.tractset.currentune.min_conc = ConcMin.Value;
                app.tractset.currentune.max_conc = ConcMax.Value;
                app.tractset.currentune.min_segm = SegmMin.Value;
                app.tractset.currentune.max_segm = SegmMax.Value;

                if strcmp(PatientID.Value, 'John Doe')
                    ea_warndlg("Choose a patient first.")
                    return
                end

                % for now, hardwired to run in native
                app.tractset.currentune.stimfolder = fullfile(app.tractset.currentune.patient, 'stimulations/native/NB_stim/');
                mkdir(app.tractset.currentune.stimfolder)

                % we need to pass the reconstruction file
                reconstruction_file = dir([app.tractset.currentune.patient,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);

                try
                    reconst = load([app.tractset.currentune.patient,filesep,'reconstruction',filesep,reconstruction_file.name]);
                catch
                    ea_warndlg('The reconstruction file was not found')
                    return
                end
                
                if length(reconst.reco.props) == 2
                    %sides = 2;  % both sides implanted
                    el_model_right = strrep(reconst.reco.props(1).elmodel, ' ','_');
                    el_model_left = strrep(reconst.reco.props(2).elmodel, ' ','_');
                elseif reconst.reco.mni.markers(1).head(1) > 0.0
                    %sides = 0; % right side
                    el_model_right = strrep(reconst.reco.props(1).elmodel, ' ','_');
                    el_model_left = -1;
                else
                    %sides = 1; % left side
                    el_model_right = -1;
                    el_model_left = strrep(reconst.reco.props(2).elmodel, ' ','_');
                end

                % call .py script
                system(['python', ' ', ea_getearoot, 'currentune/TrainTest_Generator.py ', ...
                    app.tractset.currentune.stimfolder(1:end-1), ' ', el_model_right, ' ', el_model_left, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
            end

            function ChoosePatientButtonPushed(ChoosePatient,PatientID)
                [selpath]=uigetdir('*.mat','Please Choose a Patient Folder');
                if strcmp(string(selpath),'0')
                    waitfor(msgbox('No patient folder was not chosen!'));
                    return;
                else
                    app.tractset.currentune.patient = selpath;
                    [folderpath,foldername,~] = fileparts(app.tractset.currentune.patient);
                    PatientID.Value = foldername;
                end
            end

            function ConcMinChanged(ConcMin)
                app.tractset.currentune.min_conc = ConcMin.Value;
            end

            function ConcMaxChanged(ConcMax)
                app.tractset.currentune.max_conc = ConcMax.Value;
            end

            function SegmMinChanged(SegmMin)
                app.tractset.currentune.min_segm = SegmMin.Value;
            end

            function SegmMaxChanged(SegmMax)
                app.tractset.currentune.max_segm = SegmMax.Value;
            end

            function PredictImprovementButtonPushed(PredictImprovement, fixed_weights, slider_name)
                % add a sanity check for current limits
                app.tractset.currentune.min_conc = ConcMin.Value;
                app.tractset.currentune.max_conc = ConcMax.Value;
                app.tractset.currentune.min_segm = SegmMin.Value;
                app.tractset.currentune.max_segm = SegmMax.Value;

                % for now, hardwired to run in native
                app.tractset.currentune.stimfolder = fullfile(app.tractset.currentune.patient, 'stimulations/native/NB_stim/');
                mkdir(app.tractset.currentune.stimfolder)

                % create a json with fixed symptoms and weights      
                fixed_weight_flag = 0;
                for i = 1:length(fixed_weights) 
                    if fixed_weights{i}.Value == 1
                        jsonDict.fixed_symptom_weights.(genvarname(slider_name{i})) = app.tractset.subscore.weights(i);
                        fixed_weight_flag = fixed_weight_flag + 1;
                    end
                end

                ea_warndlg("Compute PAM for the exported connectome and press OK")

                if fixed_weight_flag == 0
                    ea_warndlg("No symptoms were fixed, please choose at least one")
                    return
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
                else
                    % save where the FF file is
                    %[filepath,~,~] = fileparts(app.tractset.leadgroup);

                    json_file = [app.tractset.currentune.stimfolder,'Fixed_symptoms.json'];
    
                    jsonText = jsonencode(jsonDict);
                    fid = fopen(json_file, 'w');
                    fprintf(fid, '%s', jsonText)
                    fclose(fid);
    
                    % we can also run OSS-DBS directly from here, but not
                    % for now

                    % IMPORTANT: for now we predict only the right side (0)
                    system(['python', ' ', ea_getearoot, 'currentune/Improvement4Protocol.py ', ...
                            app.tractset.currentune.stimfolder(1:end-1), ' ', num2str(0), ' ', app.tractset.currentune.ActivationProfileDict, ' ', json_file]);	% 0 is right side, 1 is the left side here
                end

            end

            function Launch_NetBlendButtonPushed(Launch_NetBlend, fixed_weights, slider_name)

                % add a sanity check for current limits
                app.tractset.currentune.min_conc = ConcMin.Value;
                app.tractset.currentune.max_conc = ConcMax.Value;
                app.tractset.currentune.min_segm = SegmMin.Value;
                app.tractset.currentune.max_segm = SegmMax.Value;
                
                % create a json with fixed symptoms and weights      
                fixed_weight_flag = 0;
                for i = 1:length(fixed_weights) 
                    if fixed_weights{i}.Value == 1
                        jsonDict.fixed_symptom_weights.(genvarname(slider_name{i})) = app.tractset.subscore.weights(i);
                        fixed_weight_flag = fixed_weight_flag + 1;
                    end
                end

                % in this case, throw a warning and run a current
                % optimization only
                if fixed_weight_flag == length(fixed_weights)
                    ea_warndlg("All weights were fixed, only currents will be optimized")
                elseif fixed_weight_flag == 0
                    ea_warndlg("No symptoms were fixed")
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
                else
                    % save where the FF file is
                    [filepath,~,~] = fileparts(app.tractset.leadgroup);
                    json_file = [filepath,filesep,'Fixed_symptoms.json'];
    
                    jsonText = jsonencode(jsonDict);
                    fid = fopen(json_file, 'w');
                    fprintf(fid, '%s', jsonText)
                    fclose(fid);
    
                    % call NetBlend
                    % pass the connectome and the location of json and ea_getearoot
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                            ea_getearoot, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm), ' ', json_file]);	% 0 is right side, 1 is the left side here
     
                end

                % add selection of a stimfolder, some parameters will be
                % taken from there

                % retrieve new weights for non-fixed. Disable their adjustment?

            end 

            function FixedWeightcBoxChanged(fixed_weights,slider_val_name,Launch_NetBlend,i)
                %app.tractset.subscore.CheckedWeights{i} = fixed_weights{i}.Value;

                % initialize
                if all(isnan(app.tractset.subscore.weights))
                    app.tractset.subscore.weights(:) = 1 / size(app.tractset.subscore.weights,1);
                end


                N_fixed = 0;
                inx_nonfixed = [];
                for k = 1:length(fixed_weights) 
                    app.tractset.currentune.CheckedWeights{k} = fixed_weights{k}.Value;
                    slider_val_name{k}.Enable = 1; % reinitialize
                    Launch_NetBlend.Enable = 1;
                    if fixed_weights{k}.Value == 1
                       N_fixed = N_fixed + 1;
                    else
                       inx_nonfixed = [inx_nonfixed,k]; 
                    end
                end

                % if all fixed, disable sliders
                if N_fixed == length(fixed_weights) 
                    for k = 1:length(fixed_weights) 
                        slider_val_name{k}.Enable = 0; % no weights to optimize
                    end
                    Launch_NetBlend.Enable = 0; 
                % if all but one fixed, disable only that slider
                elseif N_fixed == length(fixed_weights) - 1
                    slider_val_name{inx_nonfixed}.Enable = 0; % is only one value in this case
                    Launch_NetBlend.Enable = 0; % no weights to optimize
                end
            end
        
            function ExportSymptomPathwayButtonPushed(ExportSymptomPathwayBTN)
                [FileName,PathName]=uiputfile('*.json','File to save the symptom-tract dictionary...');
                app.tractset.currentune.ActivationProfileDict = fullfile(PathName,FileName);
                ea_export_symptoms_tracts(app.tractset,app.tractset.currentune.ActivationProfileDict);
            end  

            function SliderChangeCurrentune(slider_text_box,slider_val_name,fixed_weights,i)
                %change value using sliders
                %remainingamount=1-(slider_val_name{i}.Value/100);

                % change limits to the remainingamount for non-fixed
                % symptoms
                remainingamount = 1;
                idx_bool = zeros(length(fixed_weights),1);
                for k = 1:length(fixed_weights) 
                    if k == i || fixed_weights{k}.Value == 1
                        idx_bool(k) = 1;
                        remainingamount = remainingamount - app.tractset.subscore.weights(k);
                    end
                end
                idx_bool = logical(idx_bool);


                % enforce limits on adjusted if all other fixed
                if all(idx_bool) 
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    slider_val_name{k}.Limits(2) = ceil(1 - ea_nansum(app.tractset.subscore.weights(nonadjusted)));
                else
                    for k = 1:length(idx_bool)
                        if ~idx_bool(k)
                            slider_val_name{k}.Limits(2) = ceil(remainingamount * 100); 
                        end
                    end
                end

                otherentries=1:length(slider_val_name);
                %otherentries(i)=[];
                otherentries(idx_bool) = [];

                weights=app.tractset.subscore.weights;
%                 if ~all(idx_bool)
%                     weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
% 
%                     nonadjusted = 1:length(slider_val_name);
%                     nonadjusted(i)=[];
%                     weights(i)= remainingamount - ea_nansum(weights(nonadjusted));
%                 end
%                 %weights(i)=slider_val_name{i}.Value/100;

                if ~all(idx_bool)
                    % some weights are free for auto-adjustment
                    weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                    weights(i)=event.Value/100;
                else
                    % special case if all but one fixed
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    weights(i)= remainingamount - floor(ea_nansum(weights(nonadjusted)));
                end                
    
                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
    
                app.draw;
            end
    
            function SliderChangingCurrentune(event,slider_text_box,slider_val_name,fixed_weights,i)


                %remainingamount=1-(event.Value/100);


                % change limits to the remainingamount for non-fixed
                % symptoms
                remainingamount = 1;
                idx_bool = zeros(length(fixed_weights),1);
                for k = 1:length(fixed_weights) 
                    if k == i || fixed_weights{k}.Value == 1
                        idx_bool(k) = 1;
                        remainingamount = remainingamount - app.tractset.subscore.weights(k);
                    end
                end
                idx_bool = logical(idx_bool);

                % enforce limits on adjusted if all other fixed
                if all(idx_bool) 
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    slider_val_name{k}.Limits(2) = ceil(1 - ea_nansum(app.tractset.subscore.weights(nonadjusted)));
                else
                    for k = 1:length(idx_bool)
                        if ~idx_bool(k)
                            slider_val_name{k}.Limits(2) = ceil(remainingamount * 100); 
                        end
                    end
                end

                otherentries=1:length(slider_val_name);
                %otherentries(i)=[];
                otherentries(idx_bool) = [];
                weights=app.tractset.subscore.weights;
               
                if ~all(idx_bool)
                    % some weights are free for auto-adjustment
                    weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                    weights(i)=event.Value/100;
                else
                    % special case if all but one fixed
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    weights(i)= remainingamount - floor(ea_nansum(weights(nonadjusted)));
                end
                %weights(i)=event.Value/100;


                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
            end

            
            function SliderChangeCurrentune_text(slider_text_box,slider_val_name,i)
    
                text_value = slider_text_box{i}.Value;
                remainingamount=1-(text_value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;
    
                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_text_box{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_val_name{slider}.Value = weights(slider)*100;
                    end
                end
                app.draw;
            end


        end

        % Button pushed function: RefineFiberColor
        function RefineFiberColorButtonPushed(app, event)
            ea_discfibers_refinefibercolor(app.tractset.drawobject, app.tractset.drawvals);
        end

        % Menu selected function: LoadSettingsMenu
        function LoadSettingsMenuSelected(app, event)
            
            % Load the settings
            [file,path] = uigetfile({"*.mat";'.'},...
                "Select settings .mat File");
            loaded_tractset = load(fullfile(path, file));

            % Update app settings
            app.tractset = ea_update_settings(app.tractset,loaded_tractset);
            
            % Update settings on GUI
            app.ea_discfibers_tractset2UI

        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            
            %Let the user choose the file to save at
            [file,path,~] = uiputfile({'*.mat';'*.*'},"Save as");
            
            % Save the settings
            ea_save_settings(app.tractset,fullfile(path,file))
            
        end

        % Callback function
        function SampleSwitchValueChanged(app, event)
            value = app.SampleSwitch.Value;
            app.tractset.twoSampleWeighted = value;
            app.draw;
        end

        % Callback function
        function EFSigmoidCheckBoxValueChanged(app, event)
            value = app.EFSigmoidCheckBox.Value;
            app.tractset.SigmoidTransform = value;
            app.draw;        
        end

        % Button pushed function: WeighttractsbyROIButton
        function WeighttractsbyROIButtonPushed(app, event)
            [roiFilename,PathName]=uigetfile('*.nii','Please Choose Nifti file...');
            if strcmp(string(roiFilename),'0')
                waitfor(msgbox('The Fiber Model was not chosen!'));
                return;
            end
            app.WeighttractsbyROIButton.Text = 'Loaded';
            app.tractset.roiintersectdata = {fullfile(PathName,roiFilename)};
            app.tractset.roithresh = app.ThresholdEditField_2.Value;
            app.tractset.activateby = 'roiintersect';
            app.draw;
        end

        % Value changed function: ThresholdEditField_2
        function ThresholdEditField_2ValueChanged(app, event)
            app.tractset.roithresh = app.ThresholdEditField_2.Value;
            app.tractset.activateby = 'roiintersect';
            app.draw;
        end

        % Value changed function: StimulationModel
        function StimulationModelValueChanged(app, event)
            app.StimulationModel.Value = event.Value;
            app.tractset.statsettings.stimulationmodel=event.Value;
            switch event.Value
                case 'Electric Field'
                    app.EfieldmetricLabel.Visible=true;
                    app.Efieldmetric.Visible=true;
                    app.Efieldmetric.Enable=true;
                    app.tractset.statsettings.efieldmetric=app.Efieldmetric.Value;
                case 'Sigmoid Field'
                    app.EfieldmetricLabel.Visible=false;
                    app.Efieldmetric.Visible=false;
                    app.Efieldmetric.Value='Peak';
                case 'VTA'
                    app.EfieldmetricLabel.Visible=false;
                    app.Efieldmetric.Visible=false;
                    app.Efieldmetric.Value='Peak';
            end
            app.enforcestatrules;
            app.setefieldsliders;
            app.ea_discfibers_ui_enablerules;
            app.pushstatsettings;
            app.draw;
        end

        % Value changed function: StatFamilyDropDown
        function StatFamilyDropDownValueChanged(app, event)
            app.StatFamilyDropDown.Value = event.Value;
            app.tractset.statsettings.statfamily = event.Value;
            app.enforcestatrules;
            %% Inform StatTestItems
            % app.StatTestDropDown.Items = app.tractset.stattests.name(app.tractset.stattests.type==event.Value);
            % app.StatTestDropDown.Value = app.StatTestDropDown.Items(1);
            % switch event.Value
            %     case '1-Sample Tests'
            %         app.StatH0DropDown.Enable = true;
            %     otherwise
            %         app.StatH0DropDown.Enable = false;
            % end
            % % app.ea_explorer_tractset2UI;
            % app.ea_explorer_ui_enablerules;
            % if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
            %     val=0;
            % else
            %     val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            % end
            % if val % already calculated
            %
            % end
            app.pushstatsettings;            
            app.draw;
        end

        % Value changed function: StatTestDropDown
        function StatTestDropDownValueChanged(app, event)
            app.tractset.statsettings.stattest=event.Value;
            app.pushstatsettings;
            app.draw;

        end

        % Value changed function: StatH0DropDown
        function StatH0DropDownValueChanged(app, event)
            app.StatH0DropDown.Value=event.Value;
            app.tractset.statsettings.H0 = event.Value;
            app.pushstatsettings;
            app.draw;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshinformationfromLeadGroupanalysisMenu = uimenu(app.ToolsMenu);
            app.RefreshinformationfromLeadGroupanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshinformationfromLeadGroupanalysisMenuSelected, true);
            app.RefreshinformationfromLeadGroupanalysisMenu.Text = 'Refresh information from Lead-Group analysis';
            app.RecreatevisualizationfigureMenu = uimenu(app.ToolsMenu);
            app.RecreatevisualizationfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @RecreatevisualizationfigureMenuSelected, true);
            app.RecreatevisualizationfigureMenu.Text = 'Recreate visualization figure';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.OptimizeParametersontrainingsetMenu = uimenu(app.ToolsMenu);
            app.OptimizeParametersontrainingsetMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizeParametersontrainingsetMenuSelected, true);
            app.OptimizeParametersontrainingsetMenu.Text = 'Optimize Parameters (on training set)';
            app.CrossvalidationsMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.CrossvalidationsMenu.MenuSelectedFcn = createCallbackFcn(app, @CrossvalidationsMenuSelected, true);
            app.CrossvalidationsMenu.Text = 'Crossvalidations';
            app.SimilaritiesDissimilaritiesMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.SimilaritiesDissimilaritiesMenu.MenuSelectedFcn = createCallbackFcn(app, @SimilaritiesDissimilaritiesMenuSelected, true);
            app.SimilaritiesDissimilaritiesMenu.Text = 'Similarities/Dissimilarities';
            app.CombinedMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.CombinedMenu.MenuSelectedFcn = createCallbackFcn(app, @CombinedMenuSelected, true);
            app.CombinedMenu.Text = 'Combined';
            app.LoadPriorOptimization = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.LoadPriorOptimization.MenuSelectedFcn = createCallbackFcn(app, @LoadPriorOptimizationMenuSelected, true);
            app.LoadPriorOptimization.Text = 'Load Pior Optimization';
            app.LoadPriorOptimizationAndResume = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.LoadPriorOptimizationAndResume.MenuSelectedFcn = createCallbackFcn(app, @LoadPriorOptimizationAndResumeMenuSelected, true);
            app.LoadPriorOptimizationAndResume.Text = 'Load Pior Optimization and Resume';
            app.LoadSettingsMenu = uimenu(app.ToolsMenu);
            app.LoadSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsMenuSelected, true);
            app.LoadSettingsMenu.Text = 'Load Settings';
            app.SaveSettingsMenu = uimenu(app.ToolsMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ConnectomeDropDownOpening2, true);
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditFieldLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ConnectomeSelectionPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 200;
            app.VmLabel = uilabel(app.ConnectomeSelectionPanel);
            app.VmLabel.Text = 'V/m';
            app.ConnectivityModelButtonGroup = uibuttongroup(app.ConnectomeSelectionPanel);
            app.ConnectivityModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConnectivityModelButtonGroupSelectionChanged, true);
            app.ConnectivityModelButtonGroup.Title = 'Connectivity Model';
            app.StimulationVolumeButton = uiradiobutton(app.ConnectivityModelButtonGroup);
            app.StimulationVolumeButton.Text = 'Stimulation Volume';
            app.StimulationVolumeButton.Value = true;
            app.PAMButton = uiradiobutton(app.ConnectivityModelButtonGroup);
            app.PAMButton.Text = 'PAM';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.Limits = [0 49];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.EfieldthresholdSlider.Limits = [1 40];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 2.5;
            app.EfieldthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts are modulated > '; 'sum E-Field of  V/m'};
            app.CovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesCheckBoxValueChanged, true);
            app.CovariatesCheckBox.Enable = 'off';
            app.CovariatesCheckBox.Text = 'Covariates';
            app.Efieldmetric = uidropdown(app.InteractiveModelSetupPanel);
            app.Efieldmetric.Items = {'Sum', 'Mean', 'Peak', 'Peak 5%'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Value = 'Sum';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.ROISwitchLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ROISwitchLabel.Enable = 'off';
            app.ROISwitchLabel.Text = 'ROI';
            app.ROISwitch = uiswitch(app.InteractiveModelSetupPanel, 'slider');
            app.ROISwitch.Items = {'Hide', 'Show'};
            app.ROISwitch.ValueChangedFcn = createCallbackFcn(app, @ROISwitchValueChanged, true);
            app.ROISwitch.Enable = 'off';
            app.ROISwitch.Value = 'Hide';
            app.ConnectedFibersLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnectedFibersLabel.Enable = 'off';
            app.ConnectedFibersLabel.Text = 'Fibers';
            app.FibersSwitch = uiswitch(app.InteractiveModelSetupPanel, 'slider');
            app.FibersSwitch.Items = {'Hide', 'Show'};
            app.FibersSwitch.ValueChangedFcn = createCallbackFcn(app, @FibersSwitchValueChanged, true);
            app.FibersSwitch.Enable = 'off';
            app.FibersSwitch.Value = 'Hide';
            app.StimulationModel = uidropdown(app.InteractiveModelSetupPanel);
            app.StimulationModel.Items = {'Electric Field', 'Sigmoid Field', 'VTA'};
            app.StimulationModel.ValueChangedFcn = createCallbackFcn(app, @StimulationModelValueChanged, true);
            app.StimulationModel.Enable = 'off';
            app.StimulationModel.Value = 'Electric Field';
            app.StatH0DropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatH0DropDown.Items = {'Average', 'Zero'};
            app.StatH0DropDown.ValueChangedFcn = createCallbackFcn(app, @StatH0DropDownValueChanged, true);
            app.StatH0DropDown.Enable = 'off';
            app.StatH0DropDown.Value = 'Average';
            app.StatTestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatTestDropDown.Items = {'Spearman', 'Pearson'};
            app.StatTestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatTestDropDownValueChanged, true);
            app.StatTestDropDown.Enable = 'off';
            app.StatTestDropDown.Value = 'Spearman';
            app.StatFamilyDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatFamilyDropDown.Items = {'Correlations', '1-Sample Tests', '2-Sample Tests'};
            app.StatFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @StatFamilyDropDownValueChanged, true);
            app.StatFamilyDropDown.Enable = 'off';
            app.StatFamilyDropDown.Value = 'Correlations';
            app.StimulationModelLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StimulationModelLabel.Enable = 'off';
            app.StimulationModelLabel.Text = 'Stimulation Model';
            app.StatH0Label = uilabel(app.InteractiveModelSetupPanel);
            app.StatH0Label.Enable = 'off';
            app.StatH0Label.Text = 'Against';
            app.StatTestLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatTestLabel.Enable = 'off';
            app.StatTestLabel.Text = 'Statistical Test';
            app.StatFamilyLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatFamilyLabel.Enable = 'off';
            app.StatFamilyLabel.Text = 'Test Family';
            app.EfieldmetricLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldmetricLabel.Enable = 'off';
            app.EfieldmetricLabel.Text = 'Metric';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)', 'Import Model'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Enable = 'off';
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores', 'Histogram', 'z-scored Histogram'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Enable = 'off';
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ModelNormalizationDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDownLabel.Enable = 'off';
            app.ModelNormalizationDropDownLabel.Text = 'Model Normalization';
            app.ModelNormalizationDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDown.Items = {'None', 'z-score', 'van Albada 2007'};
            app.ModelNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelNormalizationDropDownValueChanged, true);
            app.ModelNormalizationDropDown.Enable = 'off';
            app.ModelNormalizationDropDown.Value = 'None';
            app.BinsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Visible = 'off';
            app.BinsEditFieldLabel.Text = '#Bins';
            app.BinsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Visible = 'off';
            app.BinsEditField.Value = 15;
            app.FittoscoresCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.FittoscoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @FittoscoresCheckBoxValueChanged, true);
            app.FittoscoresCheckBox.Enable = 'off';
            app.FittoscoresCheckBox.Text = 'Fit to scores';
            app.ModeltypeDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModeltypeDropDown.Items = {'Constant', 'Linear', 'Quadratic'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Enable = 'off';
            app.ModeltypeDropDown.Value = 'Linear';
            app.MultitractWeightsButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.MultitractWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @MultitractWeightsButtonPushed, true);
            app.MultitractWeightsButton.Enable = 'off';
            app.MultitractWeightsButton.Text = 'Multitract Weights...';
            app.OpeninCleartuneButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.OpeninCleartuneButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninCleartuneButtonPushed, true);
            app.OpeninCleartuneButton.Enable = 'off';
            app.OpeninCleartuneButton.Text = 'Open in Cleartune';
            app.IterationsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.IterationsEditFieldLabel.Enable = 'off';
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Enable = 'off';
            app.NestedCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.NestedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NestedCheckBoxValueChanged, true);
            app.NestedCheckBox.Text = 'Nested';
            app.LoadModelButton = uibutton(app.CrossvalidationPredictionPanel, 'state');
            app.LoadModelButton.ValueChangedFcn = createCallbackFcn(app, @LoadModelButtonValueChanged, true);
            app.LoadModelButton.Visible = 'off';
            app.LoadModelButton.Text = 'Load Model';
            app.SubcohortsDropDown_2Label = uilabel(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2Label.Visible = 'off';
            app.SubcohortsDropDown_2Label.Text = 'Subcohorts';
            app.SubcohortsDropDown_2 = uidropdown(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDown_2ValueChanged, true);
            app.SubcohortsDropDown_2.Visible = 'off';
            app.ImportedModelName = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.ImportedModelName.Enable = 'off';
            app.ImportedModelName.Visible = 'off';
            app.OpeninCurrentuneButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.OpeninCurrentuneButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninCurrentuneButtonPushed, true);
            app.OpeninCurrentuneButton.Enable = 'off';
            app.OpeninCurrentuneButton.Visible = 'off';
            app.OpeninCurrentuneButton.Text = 'Open in Currentune';
            app.MultitractAnalysisPanel = uipanel(app.UIFigure);
            app.MultitractAnalysisPanel.Title = 'Multitract Analysis';
            app.ModelSetupOptionButtonGroup = uibuttongroup(app.MultitractAnalysisPanel);
            app.ModelSetupOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSetupOptionButtonGroupSelectionChanged, true);
            app.ModelSetupOptionButtonGroup.Title = 'Model Setup Option';
            app.SingleTractAnalysisButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SingleTractAnalysisButton.Enable = 'off';
            app.SingleTractAnalysisButton.Text = 'Single Tract Analysis';
            app.SingleTractAnalysisButton.Value = true;
            app.SplitColorByGroupButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByGroupButton.Enable = 'off';
            app.SplitColorByGroupButton.Text = 'Split & Color By Group';
            app.SplitColorBySubscoreButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorBySubscoreButton.Enable = 'off';
            app.SplitColorBySubscoreButton.Text = 'Split & Color By Subscore';
            app.SplitColorByPCAButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByPCAButton.Enable = 'off';
            app.SplitColorByPCAButton.Text = 'Split & Color By PCA';
            app.SubscoresListBox = uilistbox(app.MultitractAnalysisPanel);
            app.SubscoresListBox.Multiselect = 'on';
            app.SubscoresListBox.ValueChangedFcn = createCallbackFcn(app, @SubscoresListBoxValueChanged, true);
            app.SubscoresListBox.Enable = 'off';
            app.SubscoresListBox.Value = {'Item 1'};
            app.PCsEditFieldLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCsEditFieldLabel.Enable = 'off';
            app.PCsEditFieldLabel.Text = '# PCs';
            app.PCsEditField = uieditfield(app.MultitractAnalysisPanel, 'numeric');
            app.PCsEditField.RoundFractionalValues = 'on';
            app.PCsEditField.ValueChangedFcn = createCallbackFcn(app, @PCsEditFieldValueChanged, true);
            app.PCsEditField.Enable = 'off';
            app.PCsEditField.Value = 4;
            app.PCAsettingsLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCAsettingsLabel.Enable = 'off';
            app.PCAsettingsLabel.Text = 'PCA settings';
            app.VarianceExpSliderLabel = uilabel(app.MultitractAnalysisPanel);
            app.VarianceExpSliderLabel.Enable = 'off';
            app.VarianceExpSliderLabel.Text = 'Variance Exp';
            app.VarianceExpSlider = uislider(app.MultitractAnalysisPanel);
            app.VarianceExpSlider.MajorTicks = [0 20 40 60 80 100];
            app.VarianceExpSlider.ValueChangedFcn = createCallbackFcn(app, @VarianceExpSliderValueChanged, true);
            app.VarianceExpSlider.ValueChangingFcn = createCallbackFcn(app, @VarianceExpSliderValueChanging, true);
            app.VarianceExpSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.VarianceExpSlider.Enable = 'off';
            app.InspectPCAButton = uibutton(app.MultitractAnalysisPanel, 'push');
            app.InspectPCAButton.ButtonPushedFcn = createCallbackFcn(app, @InspectPCAButtonPushed, true);
            app.InspectPCAButton.Text = 'Inspect PCA';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTractAnalysisTab = uitab(app.TabGroup);
            app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
            app.ColorBar = uiaxes(app.SingleTractAnalysisTab);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorBar.ButtonDownFcn = createCallbackFcn(app, @ColorBarButtonDown, true);
            app.ColorButtonPositive = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.levelEditFieldLabel = uilabel(app.SingleTractAnalysisTab);
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Value = 0.05;
            app.ShowAmountRightLabelNegative = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountRightLabelNegative.Enable = 'off';
            app.ShowAmountRightLabelNegative.Text = 'Right';
            app.ShowAmountLabelNegative = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLabelNegative.Enable = 'off';
            app.ShowAmountLabelNegative.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLeftLabelNegative.Enable = 'off';
            app.ShowAmountLeftLabelNegative.Text = 'Left';
            app.ShowAmountRightLabelPositive = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountRightLabelPositive.Text = 'Right';
            app.ShowAmountLeftLabelPositive = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLeftLabelPositive.Text = 'Left';
            app.ShowAmountLabelPositive = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLabelPositive.Text = 'Show Amount';
            app.ColorButtonNegative = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.ExportasAtlasButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ConsiderSignificantFibersonlyCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ConsiderSignificantFibersonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantFibersonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantFibersonlyCheckBox.Text = 'Consider Significant Fibers only';
            app.RefreshViewButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.AutoRefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRefreshCheckBoxValueChanged, true);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.MultTestStrategy = uidropdown(app.SingleTractAnalysisTab);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Value = 'FDR';
            app.ShowAmountLeftEditFieldNegative = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountLeftEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegativeValueChanged, true);
            app.ShowAmountLeftEditFieldNegative.Enable = 'off';
            app.ShowAmountRightEditFieldNegative = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountRightEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegativeValueChanged, true);
            app.ShowAmountRightEditFieldNegative.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountLeftEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositiveValueChanged, true);
            app.ShowAmountRightEditFieldPositive = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountRightEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositiveValueChanged, true);
            app.ShowAmountEditFieldPositive = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositiveValueChanged, true);
            app.ShowAmountEditFieldNegative = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegativeValueChanged, true);
            app.ShowAmountEditFieldNegative.Enable = 'off';
            app.ThresholdingDropDownLabel = uilabel(app.SingleTractAnalysisTab);
            app.ThresholdingDropDownLabel.Text = 'Thresholding';
            app.ThresholdingDropDown = uidropdown(app.SingleTractAnalysisTab);
            app.ThresholdingDropDown.Items = {'Percentage Relative to Peak', 'Percentage Relative to Amount', 'Fixed Amount', 'Histogram (CDF)', 'Fixed Fiber Value'};
            app.ThresholdingDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdingDropDownValueChanged, true);
            app.ThresholdingDropDown.Value = 'Percentage Relative to Peak';
            app.PlotDistributionButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.PlotDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistributionButtonPushed, true);
            app.PlotDistributionButton.Text = 'Plot Distribution';
            app.ExportModelButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Text = 'Export Model';
            app.RefineFiberColor = uibutton(app.SingleTractAnalysisTab, 'push');
            app.RefineFiberColor.ButtonPushedFcn = createCallbackFcn(app, @RefineFiberColorButtonPushed, true);
            app.RefineFiberColor.Text = 'Refine FIber Color';
            app.WeighttractsbyROIButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.WeighttractsbyROIButton.ButtonPushedFcn = createCallbackFcn(app, @WeighttractsbyROIButtonPushed, true);
            app.WeighttractsbyROIButton.Text = 'Weight tracts by ROI';
            app.ThresholdEditField_2Label = uilabel(app.SingleTractAnalysisTab);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField_2 = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ThresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_2ValueChanged, true);
            app.ThresholdEditField_2.Value = 200;
            app.Subscore_1Tab = uitab(app.TabGroup);
            app.Subscore_1Tab.Title = 'Subscore_1';
            app.ColorButtonPositive_2 = uibutton(app.Subscore_1Tab, 'push');
            app.ColorButtonPositive_2.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_2Pushed, true);
            app.ColorButtonPositive_2.Text = 'Color';
            app.ShowAmountRightLabelNegative_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountRightLabelNegative_2.Enable = 'off';
            app.ShowAmountRightLabelNegative_2.Text = 'Right';
            app.ShowAmountLabelNegative_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLabelNegative_2.Enable = 'off';
            app.ShowAmountLabelNegative_2.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLeftLabelNegative_2.Enable = 'off';
            app.ShowAmountLeftLabelNegative_2.Text = 'Left';
            app.ShowAmountRightLabelPositive_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountRightLabelPositive_2.Text = 'Right';
            app.ShowAmountLeftLabelPositive_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLeftLabelPositive_2.Text = 'Left';
            app.ShowAmountLabelPositive_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLabelPositive_2.Text = 'Show Amount';
            app.ColorButtonNegative_2 = uibutton(app.Subscore_1Tab, 'push');
            app.ColorButtonNegative_2.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_2Pushed, true);
            app.ColorButtonNegative_2.Text = 'Color';
            app.ShowPositiveFibersCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.ShowPositiveFibersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_2ValueChanged, true);
            app.ShowPositiveFibersCheckBox_2.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_2.Value = true;
            app.ShowNegativeFibersCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.ShowNegativeFibersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_2ValueChanged, true);
            app.ShowNegativeFibersCheckBox_2.Text = 'Show Negative Fibers';
            app.RefreshViewButton_2 = uibutton(app.Subscore_1Tab, 'push');
            app.RefreshViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_2Pushed, true);
            app.RefreshViewButton_2.Text = 'Refresh View';
            app.AutoRefreshCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.AutoRefreshCheckBox_2.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_2.Value = true;
            app.ShowAmountLeftEditFieldNegative_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_2ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_2.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_2ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_2.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_2ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_2ValueChanged, true);
            app.ShowAmountEditFieldNegative_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountEditFieldNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_2ValueChanged, true);
            app.ShowAmountEditFieldNegative_2.Enable = 'off';
            app.ShowAmountEditFieldPositive_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountEditFieldPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_2ValueChanged, true);
            app.Subscore_2Tab = uitab(app.TabGroup);
            app.Subscore_2Tab.Title = 'Subscore_2';
            app.ColorButtonPositive_3 = uibutton(app.Subscore_2Tab, 'push');
            app.ColorButtonPositive_3.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_3Pushed, true);
            app.ColorButtonPositive_3.Text = 'Color';
            app.ShowAmountRightLabelNegative_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountRightLabelNegative_3.Enable = 'off';
            app.ShowAmountRightLabelNegative_3.Text = 'Right';
            app.ShowAmountLabelNegative_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLabelNegative_3.Enable = 'off';
            app.ShowAmountLabelNegative_3.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLeftLabelNegative_3.Enable = 'off';
            app.ShowAmountLeftLabelNegative_3.Text = 'Left';
            app.ShowAmountRightLabelPositive_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountRightLabelPositive_3.Text = 'Right';
            app.ShowAmountLeftLabelPositive_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLeftLabelPositive_3.Text = 'Left';
            app.ShowAmountLabelPositive_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLabelPositive_3.Text = 'Show Amount';
            app.ColorButtonNegative_3 = uibutton(app.Subscore_2Tab, 'push');
            app.ColorButtonNegative_3.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_3Pushed, true);
            app.ColorButtonNegative_3.Text = 'Color';
            app.ShowPositiveFibersCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.ShowPositiveFibersCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_3ValueChanged, true);
            app.ShowPositiveFibersCheckBox_3.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_3.Value = true;
            app.ShowNegativeFibersCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.ShowNegativeFibersCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_3ValueChanged, true);
            app.ShowNegativeFibersCheckBox_3.Text = 'Show Negative Fibers';
            app.RefreshViewButton_3 = uibutton(app.Subscore_2Tab, 'push');
            app.RefreshViewButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_3Pushed, true);
            app.RefreshViewButton_3.Text = 'Refresh View';
            app.AutoRefreshCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.AutoRefreshCheckBox_3.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_3.Value = true;
            app.ShowAmountLeftEditFieldNegative_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_3ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_3.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_3ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_3.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_3ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_3ValueChanged, true);
            app.ShowAmountEditFieldNegative_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountEditFieldNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_3ValueChanged, true);
            app.ShowAmountEditFieldNegative_3.Enable = 'off';
            app.ShowAmountEditFieldPositive_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountEditFieldPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_3ValueChanged, true);
            app.Subscore_3Tab = uitab(app.TabGroup);
            app.Subscore_3Tab.Title = 'Subscore_3';
            app.ColorButtonPositive_4 = uibutton(app.Subscore_3Tab, 'push');
            app.ColorButtonPositive_4.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_4Pushed, true);
            app.ColorButtonPositive_4.Text = 'Color';
            app.ShowAmountRightLabelNegative_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountRightLabelNegative_4.Enable = 'off';
            app.ShowAmountRightLabelNegative_4.Text = 'Right';
            app.ShowAmountLabelNegative_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLabelNegative_4.Enable = 'off';
            app.ShowAmountLabelNegative_4.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLeftLabelNegative_4.Enable = 'off';
            app.ShowAmountLeftLabelNegative_4.Text = 'Left';
            app.ShowAmountRightLabelPositive_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountRightLabelPositive_4.Text = 'Right';
            app.ShowAmountLeftLabelPositive_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLeftLabelPositive_4.Text = 'Left';
            app.ShowAmountLabelPositive_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLabelPositive_4.Text = 'Show Amount';
            app.ColorButtonNegative_4 = uibutton(app.Subscore_3Tab, 'push');
            app.ColorButtonNegative_4.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_4Pushed, true);
            app.ColorButtonNegative_4.Text = 'Color';
            app.ShowPositiveFibersCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.ShowPositiveFibersCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_4ValueChanged, true);
            app.ShowPositiveFibersCheckBox_4.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_4.Value = true;
            app.ShowNegativeFibersCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.ShowNegativeFibersCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_4ValueChanged, true);
            app.ShowNegativeFibersCheckBox_4.Text = 'Show Negative Fibers';
            app.RefreshViewButton_4 = uibutton(app.Subscore_3Tab, 'push');
            app.RefreshViewButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_4Pushed, true);
            app.RefreshViewButton_4.Text = 'Refresh View';
            app.AutoRefreshCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.AutoRefreshCheckBox_4.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_4.Value = true;
            app.ShowAmountLeftEditFieldNegative_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_4ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_4.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_4ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_4.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_4ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_4ValueChanged, true);
            app.ShowAmountEditFieldNegative_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountEditFieldNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_4ValueChanged, true);
            app.ShowAmountEditFieldNegative_4.Enable = 'off';
            app.ShowAmountEditFieldPositive_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountEditFieldPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_4ValueChanged, true);
            app.Subscore_4Tab = uitab(app.TabGroup);
            app.Subscore_4Tab.Title = 'Subscore_4';
            app.ColorButtonPositive_5 = uibutton(app.Subscore_4Tab, 'push');
            app.ColorButtonPositive_5.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_5Pushed, true);
            app.ColorButtonPositive_5.Text = 'Color';
            app.ShowAmountRightLabelNegative_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountRightLabelNegative_5.Enable = 'off';
            app.ShowAmountRightLabelNegative_5.Text = 'Right';
            app.ShowAmountLabelNegative_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLabelNegative_5.Enable = 'off';
            app.ShowAmountLabelNegative_5.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLeftLabelNegative_5.Enable = 'off';
            app.ShowAmountLeftLabelNegative_5.Text = 'Left';
            app.ShowAmountRightLabelPositive_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountRightLabelPositive_5.Text = 'Right';
            app.ShowAmountLeftLabelPositive_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLeftLabelPositive_5.Text = 'Left';
            app.ShowAmountLabelPositive_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLabelPositive_5.Text = 'Show Amount';
            app.ColorButtonNegative_5 = uibutton(app.Subscore_4Tab, 'push');
            app.ColorButtonNegative_5.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_5Pushed, true);
            app.ColorButtonNegative_5.Text = 'Color';
            app.ShowPositiveFibersCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.ShowPositiveFibersCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_5ValueChanged, true);
            app.ShowPositiveFibersCheckBox_5.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_5.Value = true;
            app.ShowNegativeFibersCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.ShowNegativeFibersCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_5ValueChanged, true);
            app.ShowNegativeFibersCheckBox_5.Text = 'Show Negative Fibers';
            app.RefreshViewButton_5 = uibutton(app.Subscore_4Tab, 'push');
            app.RefreshViewButton_5.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_5Pushed, true);
            app.RefreshViewButton_5.Text = 'Refresh View';
            app.AutoRefreshCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.AutoRefreshCheckBox_5.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_5.Value = true;
            app.ShowAmountLeftEditFieldNegative_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_5ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_5.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_5ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_5.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_5ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_5ValueChanged, true);
            app.ShowAmountEditFieldNegative_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountEditFieldNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_5ValueChanged, true);
            app.ShowAmountEditFieldNegative_5.Enable = 'off';
            app.ShowAmountEditFieldPositive_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountEditFieldPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_5ValueChanged, true);
            app.Subscore_5Tab = uitab(app.TabGroup);
            app.Subscore_5Tab.Title = 'Subscore_5';
            app.ColorButtonPositive_6 = uibutton(app.Subscore_5Tab, 'push');
            app.ColorButtonPositive_6.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_6Pushed, true);
            app.ColorButtonPositive_6.Text = 'Color';
            app.ShowAmountRightLabelNegative_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountRightLabelNegative_6.Enable = 'off';
            app.ShowAmountRightLabelNegative_6.Text = 'Right';
            app.ShowAmountLabelNegative_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLabelNegative_6.Enable = 'off';
            app.ShowAmountLabelNegative_6.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLeftLabelNegative_6.Enable = 'off';
            app.ShowAmountLeftLabelNegative_6.Text = 'Left';
            app.ShowAmountRightLabelPositive_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountRightLabelPositive_6.Text = 'Right';
            app.ShowAmountLeftLabelPositive_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLeftLabelPositive_6.Text = 'Left';
            app.ShowAmountLabelPositive_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLabelPositive_6.Text = 'Show Amount';
            app.ColorButtonNegative_6 = uibutton(app.Subscore_5Tab, 'push');
            app.ColorButtonNegative_6.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_6Pushed, true);
            app.ColorButtonNegative_6.Text = 'Color';
            app.ShowPositiveFibersCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.ShowPositiveFibersCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_6ValueChanged, true);
            app.ShowPositiveFibersCheckBox_6.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_6.Value = true;
            app.ShowNegativeFibersCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.ShowNegativeFibersCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_6ValueChanged, true);
            app.ShowNegativeFibersCheckBox_6.Text = 'Show Negative Fibers';
            app.RefreshViewButton_6 = uibutton(app.Subscore_5Tab, 'push');
            app.RefreshViewButton_6.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_6Pushed, true);
            app.RefreshViewButton_6.Text = 'Refresh View';
            app.AutoRefreshCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.AutoRefreshCheckBox_6.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_6.Value = true;
            app.ShowAmountLeftEditFieldNegative_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_6ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_6.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_6ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_6.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_6ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_6ValueChanged, true);
            app.ShowAmountEditFieldNegative_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountEditFieldNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_6ValueChanged, true);
            app.ShowAmountEditFieldNegative_6.Enable = 'off';
            app.ShowAmountEditFieldPositive_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountEditFieldPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_6ValueChanged, true);
            app.Subscore_6Tab = uitab(app.TabGroup);
            app.Subscore_6Tab.Title = 'Subscore_6';
            app.ColorButtonPositive_7 = uibutton(app.Subscore_6Tab, 'push');
            app.ColorButtonPositive_7.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_7Pushed, true);
            app.ColorButtonPositive_7.Text = 'Color';
            app.ShowAmountRightLabelNegative_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountRightLabelNegative_7.Enable = 'off';
            app.ShowAmountRightLabelNegative_7.Text = 'Right';
            app.ShowAmountLabelNegative_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLabelNegative_7.Enable = 'off';
            app.ShowAmountLabelNegative_7.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLeftLabelNegative_7.Enable = 'off';
            app.ShowAmountLeftLabelNegative_7.Text = 'Left';
            app.ShowAmountRightLabelPositive_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountRightLabelPositive_7.Text = 'Right';
            app.ShowAmountLeftLabelPositive_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLeftLabelPositive_7.Text = 'Left';
            app.ShowAmountLabelPositive_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLabelPositive_7.Text = 'Show Amount';
            app.ColorButtonNegative_7 = uibutton(app.Subscore_6Tab, 'push');
            app.ColorButtonNegative_7.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_7Pushed, true);
            app.ColorButtonNegative_7.Text = 'Color';
            app.ShowPositiveFibersCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.ShowPositiveFibersCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_7ValueChanged, true);
            app.ShowPositiveFibersCheckBox_7.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_7.Value = true;
            app.ShowNegativeFibersCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.ShowNegativeFibersCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_7ValueChanged, true);
            app.ShowNegativeFibersCheckBox_7.Text = 'Show Negative Fibers';
            app.RefreshViewButton_7 = uibutton(app.Subscore_6Tab, 'push');
            app.RefreshViewButton_7.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_7Pushed, true);
            app.RefreshViewButton_7.Text = 'Refresh View';
            app.AutoRefreshCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.AutoRefreshCheckBox_7.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_7.Value = true;
            app.ShowAmountLeftEditFieldNegative_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_7ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_7.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_7ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_7.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_7ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_7ValueChanged, true);
            app.ShowAmountEditFieldNegative_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountEditFieldNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_7ValueChanged, true);
            app.ShowAmountEditFieldNegative_7.Enable = 'off';
            app.ShowAmountEditFieldPositive_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountEditFieldPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_7ValueChanged, true);
            app.Subscore_7Tab = uitab(app.TabGroup);
            app.Subscore_7Tab.Title = 'Subscore_7';
            app.ColorButtonPositive_8 = uibutton(app.Subscore_7Tab, 'push');
            app.ColorButtonPositive_8.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_8Pushed, true);
            app.ColorButtonPositive_8.Text = 'Color';
            app.ShowAmountRightLabelNegative_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountRightLabelNegative_8.Enable = 'off';
            app.ShowAmountRightLabelNegative_8.Text = 'Right';
            app.ShowAmountLabelNegative_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLabelNegative_8.Enable = 'off';
            app.ShowAmountLabelNegative_8.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLeftLabelNegative_8.Enable = 'off';
            app.ShowAmountLeftLabelNegative_8.Text = 'Left';
            app.ShowAmountRightLabelPositive_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountRightLabelPositive_8.Text = 'Right';
            app.ShowAmountLeftLabelPositive_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLeftLabelPositive_8.Text = 'Left';
            app.ShowAmountLabelPositive_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLabelPositive_8.Text = 'Show Amount';
            app.ColorButtonNegative_8 = uibutton(app.Subscore_7Tab, 'push');
            app.ColorButtonNegative_8.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_8Pushed, true);
            app.ColorButtonNegative_8.Text = 'Color';
            app.ShowPositiveFibersCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.ShowPositiveFibersCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_8ValueChanged, true);
            app.ShowPositiveFibersCheckBox_8.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_8.Value = true;
            app.ShowNegativeFibersCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.ShowNegativeFibersCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_8ValueChanged, true);
            app.ShowNegativeFibersCheckBox_8.Text = 'Show Negative Fibers';
            app.RefreshViewButton_8 = uibutton(app.Subscore_7Tab, 'push');
            app.RefreshViewButton_8.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_8Pushed, true);
            app.RefreshViewButton_8.Text = 'Refresh View';
            app.AutoRefreshCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.AutoRefreshCheckBox_8.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_8.Value = true;
            app.ShowAmountLeftEditFieldNegative_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_8ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_8.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_8ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_8.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_8ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_8ValueChanged, true);
            app.ShowAmountEditFieldNegative_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountEditFieldNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_8ValueChanged, true);
            app.ShowAmountEditFieldNegative_8.Enable = 'off';
            app.ShowAmountEditFieldPositive_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountEditFieldPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_8ValueChanged, true);
            app.Subscore_8Tab = uitab(app.TabGroup);
            app.Subscore_8Tab.Title = 'Subscore_8';
            app.ColorButtonPositive_9 = uibutton(app.Subscore_8Tab, 'push');
            app.ColorButtonPositive_9.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_9Pushed, true);
            app.ColorButtonPositive_9.Text = 'Color';
            app.ShowAmountRightLabelNegative_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountRightLabelNegative_9.Enable = 'off';
            app.ShowAmountRightLabelNegative_9.Text = 'Right';
            app.ShowAmountLabelNegative_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLabelNegative_9.Enable = 'off';
            app.ShowAmountLabelNegative_9.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLeftLabelNegative_9.Enable = 'off';
            app.ShowAmountLeftLabelNegative_9.Text = 'Left';
            app.ShowAmountRightLabelPositive_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountRightLabelPositive_9.Text = 'Right';
            app.ShowAmountLeftLabelPositive_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLeftLabelPositive_9.Text = 'Left';
            app.ShowAmountLabelPositive_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLabelPositive_9.Text = 'Show Amount';
            app.ColorButtonNegative_9 = uibutton(app.Subscore_8Tab, 'push');
            app.ColorButtonNegative_9.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_9Pushed, true);
            app.ColorButtonNegative_9.Text = 'Color';
            app.ShowPositiveFibersCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.ShowPositiveFibersCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_9ValueChanged, true);
            app.ShowPositiveFibersCheckBox_9.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_9.Value = true;
            app.ShowNegativeFibersCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.ShowNegativeFibersCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_9ValueChanged, true);
            app.ShowNegativeFibersCheckBox_9.Text = 'Show Negative Fibers';
            app.RefreshViewButton_9 = uibutton(app.Subscore_8Tab, 'push');
            app.RefreshViewButton_9.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_9Pushed, true);
            app.RefreshViewButton_9.Text = 'Refresh View';
            app.AutoRefreshCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.AutoRefreshCheckBox_9.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_9.Value = true;
            app.ShowAmountLeftEditFieldNegative_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_9ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_9.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_9ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_9.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_9ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_9ValueChanged, true);
            app.ShowAmountEditFieldNegative_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountEditFieldNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_9ValueChanged, true);
            app.ShowAmountEditFieldNegative_9.Enable = 'off';
            app.ShowAmountEditFieldPositive_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountEditFieldPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_9ValueChanged, true);
            app.Subscore_9Tab = uitab(app.TabGroup);
            app.Subscore_9Tab.Title = 'Subscore_9';
            app.ColorButtonPositive_10 = uibutton(app.Subscore_9Tab, 'push');
            app.ColorButtonPositive_10.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_10Pushed, true);
            app.ColorButtonPositive_10.Text = 'Color';
            app.ShowAmountRightLabelNegative_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountRightLabelNegative_10.Enable = 'off';
            app.ShowAmountRightLabelNegative_10.Text = 'Right';
            app.ShowAmountLabelNegative_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLabelNegative_10.Enable = 'off';
            app.ShowAmountLabelNegative_10.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLeftLabelNegative_10.Enable = 'off';
            app.ShowAmountLeftLabelNegative_10.Text = 'Left';

            app.ShowAmountRightLabelPositive_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountRightLabelPositive_10.Text = 'Right';
            app.ShowAmountLeftLabelPositive_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLeftLabelPositive_10.Text = 'Left';
            app.ShowAmountLabelPositive_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLabelPositive_10.Text = 'Show Amount';
            app.ColorButtonNegative_10 = uibutton(app.Subscore_9Tab, 'push');
            app.ColorButtonNegative_10.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_10Pushed, true);
            app.ColorButtonNegative_10.Text = 'Color';
            app.ShowPositiveFibersCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.ShowPositiveFibersCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_10ValueChanged, true);
            app.ShowPositiveFibersCheckBox_10.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_10.Value = true;
            app.ShowNegativeFibersCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.ShowNegativeFibersCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_10ValueChanged, true);
            app.ShowNegativeFibersCheckBox_10.Text = 'Show Negative Fibers';
            app.RefreshViewButton_10 = uibutton(app.Subscore_9Tab, 'push');
            app.RefreshViewButton_10.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_10Pushed, true);
            app.RefreshViewButton_10.Text = 'Refresh View';
            app.AutoRefreshCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.AutoRefreshCheckBox_10.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_10.Value = true;
            app.ShowAmountLeftEditFieldNegative_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_10ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_10.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_10ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_10.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_10ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_10ValueChanged, true);
            app.ShowAmountEditFieldNegative_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountEditFieldNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_10ValueChanged, true);
            app.ShowAmountEditFieldNegative_10.Enable = 'off';
            app.ShowAmountEditFieldPositive_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountEditFieldPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_10ValueChanged, true);
            app.Subscore_10Tab = uitab(app.TabGroup);
            app.Subscore_10Tab.Title = 'Subscore_10';
            app.ColorButtonPositive_11 = uibutton(app.Subscore_10Tab, 'push');
            app.ColorButtonPositive_11.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_11Pushed, true);
            app.ColorButtonPositive_11.Text = 'Color';
            app.ShowAmountRightLabelNegative_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountRightLabelNegative_11.Enable = 'off';
            app.ShowAmountRightLabelNegative_11.Text = 'Right';
            app.ShowAmountLabelNegative_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLabelNegative_11.Enable = 'off';
            app.ShowAmountLabelNegative_11.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLeftLabelNegative_11.Enable = 'off';
            app.ShowAmountLeftLabelNegative_11.Text = 'Left';
            app.ShowAmountRightLabelPositive_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountRightLabelPositive_11.Text = 'Right';
            app.ShowAmountLeftLabelPositive_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLeftLabelPositive_11.Text = 'Left';
            app.ShowAmountLabelPositive_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLabelPositive_11.Text = 'Show Amount';
            app.ColorButtonNegative_11 = uibutton(app.Subscore_10Tab, 'push');
            app.ColorButtonNegative_11.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_11Pushed, true);
            app.ColorButtonNegative_11.Text = 'Color';
            app.ShowPositiveFibersCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.ShowPositiveFibersCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_11ValueChanged, true);
            app.ShowPositiveFibersCheckBox_11.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_11.Value = true;
            app.ShowNegativeFibersCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.ShowNegativeFibersCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_11ValueChanged, true);
            app.ShowNegativeFibersCheckBox_11.Text = 'Show Negative Fibers';
            app.RefreshViewButton_11 = uibutton(app.Subscore_10Tab, 'push');
            app.RefreshViewButton_11.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_11Pushed, true);
            app.RefreshViewButton_11.Text = 'Refresh View';
            app.AutoRefreshCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.AutoRefreshCheckBox_11.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_11.Value = true;
            app.ShowAmountLeftEditFieldNegative_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_11ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_11.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_11ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_11.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_11ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_11ValueChanged, true);
            app.ShowAmountEditFieldNegative_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountEditFieldNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_11ValueChanged, true);
            app.ShowAmountEditFieldNegative_11.Enable = 'off';
            app.ShowAmountEditFieldPositive_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountEditFieldPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_11ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end