classdef ea_unifiedmappingexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshinformationfromLeadGroupanalysisMenu  matlab.ui.container.Menu
        RecreatevisualizationfigureMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        OptimizeParametersontrainingsetMenu  matlab.ui.container.Menu
        CrossvalidationsMenu            matlab.ui.container.Menu
        SimilaritiesDissimilaritiesMenu  matlab.ui.container.Menu
        CombinedMenu                    matlab.ui.container.Menu
        LoadPriorOptimization           matlab.ui.container.Menu
        LoadPriorOptimizationAndResume  matlab.ui.container.Menu
        LoadSettingsMenu                matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        OpenanalysisButton              matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SaveasButton                    matlab.ui.control.Button
        CalculateButton_Networkmapping  matlab.ui.control.Button
        CalculateButton_Fiberfiltering  matlab.ui.control.Button
        CalculateButton_Sweetspot       matlab.ui.control.Button
        NetworkmappingSwitch            matlab.ui.control.Switch
        NetworkmappingSwitchLabel       matlab.ui.control.Label
        FiberfilteringSwitch            matlab.ui.control.Switch
        FiberfilteringSwitchLabel       matlab.ui.control.Label
        SweetspotSwitch                 matlab.ui.control.Switch
        SweetspotSwitchLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SingleVoxelTractAnalysisTab     matlab.ui.container.Tab
        RHCheckBox                      matlab.ui.control.CheckBox
        LHCheckBox                      matlab.ui.control.CheckBox
        ExportDropDown                  matlab.ui.control.DropDown
        ExportDropDownLabel             matlab.ui.control.Label
        NetMapModelDropDown             matlab.ui.control.DropDown
        NMModelLabel                    matlab.ui.control.Label
        NetMapVisualizeDropDown         matlab.ui.control.DropDown
        NMVisualizeDropDownLabel        matlab.ui.control.Label
        ThresholdEditField_2            matlab.ui.control.NumericEditField
        ThresholdEditField_2Label       matlab.ui.control.Label
        WeighttractsbyROIButton         matlab.ui.control.Button
        RefineFiberColor                matlab.ui.control.Button
        ExportModelButton               matlab.ui.control.Button
        PlotDistributionButton          matlab.ui.control.Button
        ThresholdingDropDown            matlab.ui.control.DropDown
        ThresholdingDropDownLabel       matlab.ui.control.Label
        ShowAmountEditFieldNegative     matlab.ui.control.NumericEditField
        ShowAmountEditFieldPositive     matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative  matlab.ui.control.NumericEditField
        MultTestStrategy                matlab.ui.control.DropDown
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantValuesonlyCheckBox  matlab.ui.control.CheckBox
        ExportasAtlasButton             matlab.ui.control.Button
        ShowNegativeCheckBox            matlab.ui.control.CheckBox
        ShowPositiveCheckBox            matlab.ui.control.CheckBox
        ColorButtonNegative             matlab.ui.control.Button
        ShowAmountLabelPositive         matlab.ui.control.Label
        ShowAmountLeftLabelPositive     matlab.ui.control.Label
        ShowAmountRightLabelPositive    matlab.ui.control.Label
        ShowAmountLeftLabelNegative     matlab.ui.control.Label
        ShowAmountLabelNegative         matlab.ui.control.Label
        ShowAmountRightLabelNegative    matlab.ui.control.Label
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        ColorButtonPositive             matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        Subscore_1Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_2   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_2   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_2  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_2  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_2  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_2  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_2           matlab.ui.control.CheckBox
        RefreshViewButton_2             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_2    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_2    matlab.ui.control.CheckBox
        ColorButtonNegative_2           matlab.ui.control.Button
        ShowAmountLabelPositive_2       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_2   matlab.ui.control.Label
        ShowAmountRightLabelPositive_2  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_2   matlab.ui.control.Label
        ShowAmountLabelNegative_2       matlab.ui.control.Label
        ShowAmountRightLabelNegative_2  matlab.ui.control.Label
        ColorButtonPositive_2           matlab.ui.control.Button
        Subscore_2Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_3   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_3   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_3  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_3  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_3  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_3  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_3           matlab.ui.control.CheckBox
        RefreshViewButton_3             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_3    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_3    matlab.ui.control.CheckBox
        ColorButtonNegative_3           matlab.ui.control.Button
        ShowAmountLabelPositive_3       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_3   matlab.ui.control.Label
        ShowAmountRightLabelPositive_3  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_3   matlab.ui.control.Label
        ShowAmountLabelNegative_3       matlab.ui.control.Label
        ShowAmountRightLabelNegative_3  matlab.ui.control.Label
        ColorButtonPositive_3           matlab.ui.control.Button
        Subscore_3Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_4   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_4   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_4  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_4  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_4  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_4  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_4           matlab.ui.control.CheckBox
        RefreshViewButton_4             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_4    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_4    matlab.ui.control.CheckBox
        ColorButtonNegative_4           matlab.ui.control.Button
        ShowAmountLabelPositive_4       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_4   matlab.ui.control.Label
        ShowAmountRightLabelPositive_4  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_4   matlab.ui.control.Label
        ShowAmountLabelNegative_4       matlab.ui.control.Label
        ShowAmountRightLabelNegative_4  matlab.ui.control.Label
        ColorButtonPositive_4           matlab.ui.control.Button
        Subscore_4Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_5   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_5   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_5  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_5  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_5  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_5  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_5           matlab.ui.control.CheckBox
        RefreshViewButton_5             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_5    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_5    matlab.ui.control.CheckBox
        ColorButtonNegative_5           matlab.ui.control.Button
        ShowAmountLabelPositive_5       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_5   matlab.ui.control.Label
        ShowAmountRightLabelPositive_5  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_5   matlab.ui.control.Label
        ShowAmountLabelNegative_5       matlab.ui.control.Label
        ShowAmountRightLabelNegative_5  matlab.ui.control.Label
        ColorButtonPositive_5           matlab.ui.control.Button
        Subscore_5Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_6   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_6   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_6  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_6  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_6  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_6  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_6           matlab.ui.control.CheckBox
        RefreshViewButton_6             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_6    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_6    matlab.ui.control.CheckBox
        ColorButtonNegative_6           matlab.ui.control.Button
        ShowAmountLabelPositive_6       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_6   matlab.ui.control.Label
        ShowAmountRightLabelPositive_6  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_6   matlab.ui.control.Label
        ShowAmountLabelNegative_6       matlab.ui.control.Label
        ShowAmountRightLabelNegative_6  matlab.ui.control.Label
        ColorButtonPositive_6           matlab.ui.control.Button
        Subscore_6Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_7   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_7   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_7  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_7  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_7  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_7  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_7           matlab.ui.control.CheckBox
        RefreshViewButton_7             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_7    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_7    matlab.ui.control.CheckBox
        ColorButtonNegative_7           matlab.ui.control.Button
        ShowAmountLabelPositive_7       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_7   matlab.ui.control.Label
        ShowAmountRightLabelPositive_7  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_7   matlab.ui.control.Label
        ShowAmountLabelNegative_7       matlab.ui.control.Label
        ShowAmountRightLabelNegative_7  matlab.ui.control.Label
        ColorButtonPositive_7           matlab.ui.control.Button
        Subscore_7Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_8   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_8   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_8  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_8  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_8  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_8  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_8           matlab.ui.control.CheckBox
        RefreshViewButton_8             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_8    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_8    matlab.ui.control.CheckBox
        ColorButtonNegative_8           matlab.ui.control.Button
        ShowAmountLabelPositive_8       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_8   matlab.ui.control.Label
        ShowAmountRightLabelPositive_8  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_8   matlab.ui.control.Label
        ShowAmountLabelNegative_8       matlab.ui.control.Label
        ShowAmountRightLabelNegative_8  matlab.ui.control.Label
        ColorButtonPositive_8           matlab.ui.control.Button
        Subscore_8Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_9   matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_9   matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_9  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_9  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_9  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_9  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_9           matlab.ui.control.CheckBox
        RefreshViewButton_9             matlab.ui.control.Button
        ShowNegativeFibersCheckBox_9    matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_9    matlab.ui.control.CheckBox
        ColorButtonNegative_9           matlab.ui.control.Button
        ShowAmountLabelPositive_9       matlab.ui.control.Label
        ShowAmountLeftLabelPositive_9   matlab.ui.control.Label
        ShowAmountRightLabelPositive_9  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_9   matlab.ui.control.Label
        ShowAmountLabelNegative_9       matlab.ui.control.Label
        ShowAmountRightLabelNegative_9  matlab.ui.control.Label
        ColorButtonPositive_9           matlab.ui.control.Button
        Subscore_9Tab                   matlab.ui.container.Tab
        ShowAmountEditFieldPositive_10  matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_10  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_10  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_10  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_10  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_10  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_10          matlab.ui.control.CheckBox
        RefreshViewButton_10            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_10   matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_10   matlab.ui.control.CheckBox
        ColorButtonNegative_10          matlab.ui.control.Button
        ShowAmountLabelPositive_10      matlab.ui.control.Label
        ShowAmountLeftLabelPositive_10  matlab.ui.control.Label
        ShowAmountRightLabelPositive_10  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_10  matlab.ui.control.Label
        ShowAmountLabelNegative_10      matlab.ui.control.Label
        ShowAmountRightLabelNegative_10  matlab.ui.control.Label
        ColorButtonPositive_10          matlab.ui.control.Button
        Subscore_10Tab                  matlab.ui.container.Tab
        ShowAmountEditFieldPositive_11  matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative_11  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive_11  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive_11  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative_11  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative_11  matlab.ui.control.NumericEditField
        AutoRefreshCheckBox_11          matlab.ui.control.CheckBox
        RefreshViewButton_11            matlab.ui.control.Button
        ShowNegativeFibersCheckBox_11   matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox_11   matlab.ui.control.CheckBox
        ColorButtonNegative_11          matlab.ui.control.Button
        ShowAmountLabelPositive_11      matlab.ui.control.Label
        ShowAmountLeftLabelPositive_11  matlab.ui.control.Label
        ShowAmountRightLabelPositive_11  matlab.ui.control.Label
        ShowAmountLeftLabelNegative_11  matlab.ui.control.Label
        ShowAmountLabelNegative_11      matlab.ui.control.Label
        ShowAmountRightLabelNegative_11  matlab.ui.control.Label
        ColorButtonPositive_11          matlab.ui.control.Button
        MultitractAnalysisPanel         matlab.ui.container.Panel
        InspectPCAButton                matlab.ui.control.Button
        VarianceExpSlider               matlab.ui.control.Slider
        VarianceExpSliderLabel          matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        PCsEditField                    matlab.ui.control.NumericEditField
        PCsEditFieldLabel               matlab.ui.control.Label
        SubscoresListBox                matlab.ui.control.ListBox
        ModelSetupOptionButtonGroup     matlab.ui.container.ButtonGroup
        SplitColorByPCAButton           matlab.ui.control.RadioButton
        SplitColorBySubscoreButton      matlab.ui.control.RadioButton
        SplitColorByGroupButton         matlab.ui.control.RadioButton
        SingleTractAnalysisButton       matlab.ui.control.RadioButton
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        MaskDropDown                    matlab.ui.control.DropDown
        MaskDropDownLabel               matlab.ui.control.Label
        SubcohortsDropDown_2            matlab.ui.control.DropDown
        SubcohortsDropDown_2Label       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.StateButton
        NestedCheckBox                  matlab.ui.control.CheckBox
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        MultitractWeightsButton         matlab.ui.control.Button
        ModeltypeDropDown               matlab.ui.control.DropDown
        FittoscoresCheckBox             matlab.ui.control.CheckBox
        BinsEditField                   matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        ModelNormalizationDropDown      matlab.ui.control.DropDown
        ModelNormalizationDropDownLabel  matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        SaveSettingsButton              matlab.ui.control.Button
        SettingsDropDown                matlab.ui.control.DropDown
        SettingsDropDownLabel           matlab.ui.control.Label
        Efieldmetric                    matlab.ui.control.DropDown
        EfieldmetricButtonGroup         matlab.ui.container.ButtonGroup
        ProjectionButton                matlab.ui.control.RadioButton
        MagnitudeButton                 matlab.ui.control.RadioButton
        VariableofInterestDropDown      matlab.ui.control.DropDown
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        StatFamilyLabel                 matlab.ui.control.Label
        StatTestLabel                   matlab.ui.control.Label
        StatH0Label                     matlab.ui.control.Label
        StimulationModelLabel           matlab.ui.control.Label
        StatFamilyDropDown              matlab.ui.control.DropDown
        StatTestDropDown                matlab.ui.control.DropDown
        StatH0DropDown                  matlab.ui.control.DropDown
        StimulationModel                matlab.ui.control.DropDown
        FibersSwitch                    matlab.ui.control.Switch
        ConnectedFibersLabel            matlab.ui.control.Label
        ROISwitch                       matlab.ui.control.Switch
        ROISwitchLabel                  matlab.ui.control.Label
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        CovariatesCheckBox              matlab.ui.control.CheckBox
        EfieldthresholdLabel            matlab.ui.control.Label
        EfieldthresholdSlider           matlab.ui.control.Slider
        ConnthresholdLabel              matlab.ui.control.Label
        ConnthresholdSlider             matlab.ui.control.Slider
        CovariatesListBox               matlab.ui.control.ListBox
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        ConnectomeNetworkmappingDropDown  matlab.ui.control.DropDown
        ConnectomeNetworkmappingDropDownLabel  matlab.ui.control.Label
        ConnectomeFiberfilteringDropDown  matlab.ui.control.DropDown
        ConnectomeFiberfilteringDropDownLabel  matlab.ui.control.Label
        OpenSettingsButton              matlab.ui.control.Button
    end


    properties (Access = public)
        explorer % associated ea_disctract object.

    end


    methods (Access = private)

        function results = drawcb(app)
            % app.isbusy(1);
            % if ~isempty(app.explorer.colorbar)
            %     if strcmp(app.TabGroup.SelectedTab.Title, 'Single Voxel/Tract Analysis')
            %         app.ColorBar.Visible = 'on';
            %         cb = app.explorer.colorbar;
            %         ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
            %     else
            %         app.ColorBar.Visible = 'off';
            %         cla(app.ColorBar);
            %     end
            %     app.ColorBar.Visible = 'on';
            %     cb = app.explorer.colorbar;
            %     ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
            % else
            %     app.ColorBar.Visible = 'off';
            %     cla(app.ColorBar);
            % end
            % app.isbusy(0);
        end


        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end

        function results = draw(app,force)
            
            
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            dorefresh=1;

            if app.SplitColorByPCAButton.Value && strcmp(app.PCAsettingsLabel.Enable,'off') % case in which PCA would fail and hence drawing would run into errors.
                dorefresh=0;
            end
            if (app.AutoRefreshCheckBox.Value || force) && dorefresh
                app.isbusy(1);
                drawnow;
                app.explorer.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.ea_unifiedmapping_explorer2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end

        end

        function handleSettingsChanged(app)
            scope = app.explorer.lastRecalcScope;
    
            if scope.global
                app.CalculateButton_Sweetspot.BackgroundColor = [1 0.6 0.2];
                app.CalculateButton_Fiberfiltering.BackgroundColor = [1 0.6 0.2];
                app.CalculateButton_Networkmapping.BackgroundColor = [1 0.6 0.2];
                return
            end
    
            if scope.sweetspot
                app.CalculateButton_Sweetspot.BackgroundColor = [1 0.6 0.2];
            end
            if scope.fiber
                app.CalculateButton_Fiberfiltering.BackgroundColor = [1 0.6 0.2];
            end
            if scope.network
                app.CalculateButton_Networkmapping.BackgroundColor = [1 0.6 0.2];
            end
        end


        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.explorer.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.explorer.covars = app.explorer.M.clinical.vars(is);
                    app.explorer.covarlabels = app.explorer.M.clinical.labels(is);
                else % none selected
                    app.explorer.covars = {};
                    app.explorer.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.explorer.covars = [{app.explorer.getstimamp},app.explorer.covars];
                    app.explorer.covarlabels = [{'Stimulation Amplitude'},app.explorer.covarlabels];
                end

                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.explorer.covars = [{app.explorer.getvtavolumes},app.explorer.covars];
                    app.explorer.covarlabels = [{'VTA Volume'},app.explorer.covarlabels];
                end

                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.explorer.covars = [{app.explorer.getefieldmagnitudes},app.explorer.covars];
                    app.explorer.covarlabels = [{'E-Field Magnitude Sum'},app.explorer.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.explorer.covars = [{app.explorer.getcohortregressor},app.explorer.covars];
                    app.explorer.covarlabels = [{'Group / Cohort regressor'},app.explorer.covarlabels];
                end
            else
                app.explorer.covars = {};
                app.explorer.covarlabels = {};
            end
        end

        function setefieldsliders(app)
            %% Set slider limits
            switch app.explorer.statsettings.stimulationmodel
                case 'Sigmoid Field' % this has to be adjusted to represent percentiles
                    if app.explorer.statsettings.efieldthreshold > 1
                        app.explorer.statsettings.efieldthreshold = ea_SigmoidFromEfield(app.explorer.statsettings.efieldthreshold);
                    end
                    app.EfieldthresholdSlider.Limits=[round(ea_SigmoidFromEfield(app.explorer.calcsettings.calcthreshold),2),1]; % probability activation
                case 'Electric Field'
                    if evalin('base','exist(''threshold_method'',''var'')') % percentile method
                        threshold_method = evalin('base','threshold_method');
                        app.EfieldthresholdSlider.Limits = [0 100];
                        if app.explorer.statsettings.efieldthreshold < 0
                            app.explorer.statsettings.efieldthreshold = 0;
                        elseif app.explorer.statsettings.efieldthreshold > 100
                            app.explorer.statsettings.efieldthreshold = 100;
                        end
                        %set a default starting value if empty or invalid
                        if isempty(app.explorer.statsettings.efieldthreshold)||isnan(app.explorer.statsettings.efieldthreshold) || app.explorer.statsettings.efieldthreshold > 100
                            app.explorer.statsettings.efieldthreshold = 50;
                        end
                    else % old method
                        switch lower(app.explorer.statsettings.efieldmetric)
                            case 'sum'
                                app.EfieldthresholdSlider.Limits=[0.5,5]*1000; % in V/m
                            case 'mean'
                                app.EfieldthresholdSlider.Limits=[0.0025,1]*1000; % in V/m will not be in 1000s.
                            case 'peak'
                                app.EfieldthresholdSlider.Limits=[0.05,5]*1000; % in V/m
                            case 'peak 5%'
                                app.EfieldthresholdSlider.Limits=[0.05,5]*1000; % in V/m
                        end
                        %app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                        if app.explorer.statsettings.efieldthreshold < app.EfieldthresholdSlider.Limits(1) % it is below min, set to min
                            app.explorer.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(1);
                        end
                        if app.explorer.statsettings.efieldthreshold > app.EfieldthresholdSlider.Limits(2) % it is above max, set to max
                            app.explorer.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(2);
                        end
                        % if isempty(app.explorer.statsettings.efieldthreshold)||isnan(app.explorer.statsettings.efieldthreshold) || app.explorer.statsettings.efieldthreshold < 100
                        %     app.explorer.statsettings.efieldthreshold = 500;
                        % end
                    end

                case 'VTA' % this has to be adjusted to percentiles
                    app.EfieldthresholdSlider.Limits=[app.explorer.calcsettings.calcthreshold,500];
                    if app.explorer.statsettings.efieldthreshold < 1
                        app.explorer.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.explorer.statsettings.efieldthreshold);
                    end
            end

        end
        function result = ea_isnan(app,input_array,flag)
            if size(input_array,2) > 1
                if strcmp(flag,'any')
                    op = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    op = all(isnan(input_array));
                end
                if length(find(op)) > 1
                    result = 1;
                else
                    result = 0;

                end
            else
                if strcmp(flag,'any')
                    result = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    result = all(isnan(input_array));
                end
            end
        end
        function results = updatepca(app,whatchanged)
            if ~isfield(app.explorer.results,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)) || ~isfield(app.explorer.results,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.netmap_connectome))
                generalEnableVal='off';
            elseif app.explorer.drawTool == 2
                generalEnableVal=ea_bool2onoff(isfield(app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),ea_unifiedmapping_method2methodid(app.explorer)));
            elseif app.explorer.drawTool == 3
                generalEnableVal=ea_bool2onoff(isfield(app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.netmap_connectome)),ea_unifiedmapping_method2methodid(app.explorer)));                
            end


            if app.SplitColorByPCAButton.Value
                app.PCAsettingsLabel.Enable = generalEnableVal;
                app.VarianceExpSlider.Enable = generalEnableVal;
                app.VarianceExpSliderLabel.Enable = generalEnableVal;
                app.PCsEditField.Enable = generalEnableVal;
                app.PCsEditFieldLabel.Enable = generalEnableVal;
            else
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';

                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end

            % prep PCA:
            for i=1:length(app.explorer.subscore.vars)
                selected_subscores{i} = app.explorer.subscore.vars{i}(app.explorer.patientselection);
            end
            subvars=ea_nanzscore(cell2mat(selected_subscores));
            if size(subvars,2) <= 2
                ea_warndlg("You may not have enough subscores & this might result in errors. Please consider selecting more subscores.")
            end

            %subvars=ea_nanzscore(cell2mat(app.tractset.subscore.vars'));
            %try
            [~,~,~,~,explained]=pca(subvars,'Rows','complete');
            

            if ea_isnan(app,explained,'any') || isempty(explained) %PCA function did not fail, but the values are empty
                app.PCAsettingsLabel.Enable = 'off';
                app.VarianceExpSlider.Enable = 'off';
                app.VarianceExpSliderLabel.Enable = 'off';
                app.PCsEditField.Enable = 'off';
                app.PCsEditFieldLabel.Enable = 'off';
                return
            end
            cumexplained=cumsum(explained);
            app.VarianceExpSlider.MajorTickLabelsMode='manual';
            app.VarianceExpSlider.MajorTicks=cumexplained;
            app.VarianceExpSlider.MinorTicksMode='manual';

            app.VarianceExpSlider.MajorTickLabels(dsearchn(cumexplained,80)+1:end)={''}; % display var exp up until 80% on PCs to avoid crowding of tick labels.
            for pc=1:dsearchn(cumexplained,80)
                app.VarianceExpSlider.MajorTickLabels{pc}=num2str(round(cumexplained(pc)));
            end
            app.VarianceExpSlider.MinorTicks=[];
            if app.PCsEditField.Value > length(cumexplained)
                app.PCsEditField.Value = length(cumexplained);
            end
            if exist('whatchanged','var')
                switch whatchanged
                    case 'slider' % user has changed the slider
                        correctcomponent=dsearchn(cumexplained,app.VarianceExpSlider.Value);
                        app.PCsEditField.Value=correctcomponent;
                    case 'edit' % user has edited the edit field
                        % nothing additional to do in this case.
                end
            end
            app.VarianceExpSlider.Value=cumexplained(app.PCsEditField.Value);
            app.explorer.numpcs=app.PCsEditField.Value; % also feed back to model
            app.VarianceExpSliderLabel.Text = [sprintf('%0.1f',cumexplained(app.explorer.numpcs)),'% Variance Explained'];

        end


        function results = tabupdate(app)
            n_group = unique(app.explorer.M.patient.group);
            if  app.SplitColorBySubscoreButton.Value
                app.SingleVoxelTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                iter = length(app.SubscoresListBox.Value);
            elseif app.SplitColorByGroupButton.Value
                app.SingleVoxelTractAnalysisTab.Title = 'Group:Combined Visualization';
                iter = length(n_group);
            elseif app.SplitColorByPCAButton.Value
                app.SingleVoxelTractAnalysisTab.Title = 'PCA:Combined Visualization';
                iter = app.PCsEditField.Value;
            else
                app.SingleVoxelTractAnalysisTab.Parent = app.TabGroup;
                app.SingleVoxelTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
                app.ColorBar.Visible = 'on';

                return
            end

            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonPositive.Enable = 'off';
            for i=1:10
                tab_title = (['Subscore_' num2str(i) 'Tab']);
                negcolor_btn_title = (['ColorButtonNegative_' num2str(i+1)]);
                poscolor_btn_title = (['ColorButtonPositive_' num2str(i+1)]);
                showposfib_cb = ['ShowPositiveFibersCheckBox_' num2str(i+1)];
                shownegfib_cb = ['ShowNegativeFibersCheckBox_' num2str(i+1)];
                showposamt_editfield = ['ShowAmountEditFieldPositive_' num2str(i+1)];
                showposamt_label = ['ShowAmountLabelPositive_' num2str(i+1)];
                shownegamt_editfield = ['ShowAmountEditFieldNegative_' num2str(i+1)];
                shownegamt_label = ['ShowAmountLabelNegative_' num2str(i+1)];

                showposamt_l_editfield = ['ShowAmountLeftEditFieldPositive_' num2str(i+1)];
                showposamt_l_label = ['ShowAmountLeftLabelPositive_' num2str(i+1)];
                showposamt_r_editfield = ['ShowAmountRightEditFieldPositive_' num2str(i+1)];
                showposamt_r_label = ['ShowAmountRightLabelPositive_' num2str(i+1)];

                shownegamt_l_editfield = ['ShowAmountLeftEditFieldNegative_' num2str(i+1)];
                shownegamt_l_label = ['ShowAmountLeftLabelNegative_' num2str(i+1)];
                shownegamt_r_editfield = ['ShowAmountRightEditFieldNegative_' num2str(i+1)];
                shownegamt_r_label = ['ShowAmountRightLabelNegative_' num2str(i+1)];

                if i <= iter
                    % find the tab that has the title
                    app.(tab_title).Parent = app.TabGroup;
                    if app.SplitColorBySubscoreButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.explorer.subscore.colors{1,1}(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.explorer.subscore.colors{1,2}(i,:);
                    elseif app.SplitColorByPCAButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.explorer.subscore.pcacolors(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.explorer.subscore.pcacolors(i,:);
                    elseif app.SplitColorByGroupButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.explorer.M.groups.color(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.explorer.M.groups.color(i,:);
                    end
                    if app.ShowPositiveCheckBox.Value
                        app.(showposfib_cb).Value = 1;
                        app.explorer.subscore.posvisible(i) = 1;
                        app.(showposfib_cb).Enable = 'on';
                        app.(poscolor_btn_title).Enable = 'on';
                    else
                        app.(showposfib_cb).Value = 0;
                        app.explorer.subscore.posvisible(i) = 0;
                    end
                    if app.ShowNegativeCheckBox.Value
                        app.(shownegfib_cb).Value = 1;
                        app.explorer.subscore.negvisible(i) = 1;
                        app.(shownegfib_cb).Enable = 'on';
                        app.(negcolor_btn_title).Enable = 'on';
                    else
                        app.(shownegfib_cb).Value = 0;
                        app.explorer.subscore.negvisible(i) = 0;
                    end
                    if app.ShowPositiveCheckBox.Value
                        app.(showposamt_editfield).Value = app.ShowAmountEditFieldPositive.Value;
                        app.(showposamt_editfield).Enable = 'on';
                        app.(showposamt_label).Enable = 'on';
                    else
                        app.(showposamt_editfield).Value = app.ShowAmountEditFieldPositive.Value;
                        app.(showposamt_editfield).Enable = 'off';
                        app.(showposamt_label).Enable = 'off';
                    end
                    if app.ShowNegativeCheckBox.Value
                        app.(shownegamt_editfield).Value = app.ShowAmountEditFieldNegative.Value;
                        app.(shownegamt_editfield).Enable = 'on';
                        app.(shownegamt_label).Enable = 'on';
                    else
                        app.(shownegamt_editfield).Value = app.ShowAmountEditFieldNegative.Value;
                        app.(shownegamt_editfield).Enable = 'off';
                        app.(shownegamt_label).Enable = 'off';
                    end
                    if app.ShowPositiveCheckBox.Value
                        app.(showposamt_l_editfield).Value = app.ShowAmountLeftEditFieldPositive.Value;
                        app.explorer.subscore.vis.showposamount(i,2) = app.ShowAmountLeftEditFieldPositive.Value;
                        app.(showposamt_l_editfield).Enable = 'on';
                        app.(showposamt_l_label).Enable = 'on';
                    else
                        app.(showposamt_l_editfield).Value = app.ShowAmountLeftEditFieldPositive.Value;
                        app.(showposamt_l_editfield).Enable = 'off';
                        app.(showposamt_l_label).Enable = 'off';
                    end
                    if app.ShowPositiveCheckBox.Value
                        app.(showposamt_r_editfield).Value = app.ShowAmountRightEditFieldPositive.Value;
                        app.explorer.subscore.vis.showposamount(i,1) = app.ShowAmountRightEditFieldPositive.Value;
                        app.(showposamt_r_editfield).Enable = 'on';
                        app.(showposamt_r_label).Enable = 'on';
                    else
                        app.(showposamt_r_editfield).Value = app.ShowAmountRightEditFieldPositive.Value;
                        app.(showposamt_r_editfield).Enable = 'off';
                        app.(showposamt_r_label).Enable = 'off';
                    end
                    if app.ShowNegativeCheckBox.Value
                        app.(shownegamt_l_editfield).Value = app.ShowAmountLeftEditFieldNegative.Value;
                        %update the new value into the model
                        app.explorer.subscore.vis.shownegamount(i,2) = app.ShowAmountLeftEditFieldNegative.Value;
                        app.(shownegamt_l_editfield).Enable = 'on';
                        app.(shownegamt_l_label).Enable = 'on';
                    else
                        app.(shownegamt_l_editfield).Value = app.ShowAmountLeftEditFieldNegative.Value;
                        app.(shownegamt_l_editfield).Enable = 'off';
                        app.(shownegamt_l_label).Enable = 'off';
                    end
                    if app.ShowNegativeCheckBox.Value
                        app.(shownegamt_r_editfield).Value = app.ShowAmountRightEditFieldNegative.Value;
                        app.explorer.subscore.vis.shownegamount(i,1) = app.ShowAmountRightEditFieldNegative.Value;
                        app.(shownegamt_r_editfield).Enable = 'on';
                        app.(shownegamt_r_label).Enable = 'on';
                    else
                        app.(shownegamt_r_editfield).Value = app.ShowAmountRightEditFieldNegative.Value;
                        app.(shownegamt_r_editfield).Enable = 'off';
                        app.(shownegamt_r_label).Enable = 'off';
                    end

                else
                    app.(tab_title).Parent = [];
                end
            end
        end
        function results = tabfill(app)

            app.ShowPositiveCheckBox.Value = app.explorer.posvisible;
            app.ShowNegativeCheckBox.Value = app.explorer.negvisible;
            app.ShowAmountEditFieldPositive.Value = mean(app.explorer.showposamount);
            app.ShowAmountEditFieldNegative.Value = mean(app.explorer.shownegamount);
            app.ShowAmountLeftEditFieldPositive.Value = app.explorer.showposamount(2);
            app.ShowAmountRightEditFieldPositive.Value = app.explorer.showposamount(1);
            app.ShowAmountLeftEditFieldNegative.Value = app.explorer.shownegamount(2);
            app.ShowAmountRightEditFieldNegative.Value = app.explorer.shownegamount(1);

            num_tabs = length(app.explorer.subscore.labels);
            for indx=1:num_tabs
                checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
                pos_label = ['ShowAmountLabelPositive_' num2str(indx+1)];
                left_pos_label = ['ShowAmountLeftLabelPositive_' num2str(indx+1)];
                right_pos_label = ['ShowAmountRightLabelPositive_' num2str(indx+1)];
                app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.explorer.subscore.vis.pos_available(indx,:))),')'];
                app.(pos_label).Text = {'Show Amount', ['(',num2str(sum(app.explorer.subscore.vis.pos_shown(indx,:))),')']};
                app.(left_pos_label).Text = {'Left', ['(',num2str(app.explorer.subscore.vis.pos_shown(indx,2)),')']};
                app.(right_pos_label).Text = {'Right', ['(',num2str(app.explorer.subscore.vis.pos_shown(indx,1)),')']};
                checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
                neg_label = ['ShowAmountLabelNegative_' num2str(indx+1)];
                left_label = ['ShowAmountLeftLabelNegative_' num2str(indx+1)];
                right_label = ['ShowAmountRightLabelNegative_' num2str(indx+1)];
                app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.explorer.subscore.vis.neg_available(indx,:))),')'];
                app.(neg_label).Text = {'Show Amount', ['(',num2str(sum(app.explorer.subscore.vis.neg_shown(indx,:))),')']};
                app.(left_label).Text = {'Left', ['(',num2str(app.explorer.subscore.vis.neg_shown(indx,2)),')']};
                app.(right_label).Text = {'Right', ['(',num2str(app.explorer.subscore.vis.neg_shown(indx,1)),')']};
            end
        end


        function results = ea_unifiedmapping_explorer2UI(app)
            app.isbusy(1);           

            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.explorer.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.explorer.leadgroup;
            app.AnalysisIDEditField.Value = app.explorer.ID;


            % update activation switches from object
            if ~isfield(app.explorer.activated,'sweetspotmapping')
                app.explorer.activated.sweetspotmapping='Off';
            end
            if ~isfield(app.explorer.activated,'fiberfiltering')
                app.explorer.activated.fiberfiltering='Off';
            end
            if ~isfield(app.explorer.activated,'networkmapping')
                app.explorer.activated.networkmapping='Off';
            end
            app.SweetspotSwitch.Value = app.explorer.activated.sweetspotmapping;
            app.FiberfilteringSwitch.Value = app.explorer.activated.fiberfiltering;
            app.NetworkmappingSwitch.Value = app.explorer.activated.networkmapping;


            app.explorer.compat_statmetric;   % legacy check, convert statmetric to statsettings

            % set stimstats GUI
            app.pullstatsettings;
            if ~isempty(app.explorer.statsettings.efieldthreshold)
                app.setefieldsliders;
                try
                    app.EfieldthresholdSlider.Value = app.explorer.statsettings.efieldthreshold;
                end
            end
         

            app.ConsiderSignificantValuesonlyCheckBox.Value = app.explorer.showsignificantonly;
            app.levelEditField.Value = (app.explorer.alphalevel);
            app.MultTestStrategy.Value = app.explorer.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.explorer.basepredictionon;
            end
            

            app.MirrorSidesCheckBox.Value = app.explorer.mirrorsides;

            app.PatientsListBox.Items=app.explorer.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.explorer.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.explorer.setlabels];
            for set=1:length(app.explorer.setlabels)
                if isequal(app.explorer.patientselection,find(app.explorer.setselections{set}))
                    app.SubcohortsDropDown.Value=app.explorer.setlabels{set};
                end
            end
            if isequal(app.explorer.patientselection,1:length(app.explorer.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end
            % app.SmoothFingerPrintsCheckBox.Value = app.explorer.smooth_fp;
            % 
            % app.NormalizeFingerPrintsCheckBox.Value = app.explorer.normalize_fp;
            app.Efieldmetric.Value=app.explorer.statsettings.efieldmetric;

            if app.SplitColorBySubscoreButton.Value && isempty(app.explorer.subscore.labels)
                ea_warndlg('No subscores found. Return to "Single Tract Analysis"!');
                app.explorer.multitractmode = 'Single Tract Analysis';
            end

            if app.explorer.roivisible
                app.ROISwitch.Value='Show';
            else
                app.ROISwitch.Value='Hide';
            end
            if app.explorer.connfibvisible
                app.FibersSwitch.Value='Show';
            else
                app.FibersSwitch.Value='Hide';
            end

            if isfield(app.explorer.M,'pseudoM')
                app.ROISwitchLabel.Text='ROI';
            else
                app.ROISwitchLabel.Text='VTAs';
            end
            % setup calculation threshold (only used when calculating


            if ~isempty(app.explorer.statsettings.efieldthreshold)
                app.setefieldsliders;
                try app.EfieldthresholdSlider.Value = app.explorer.statsettings.efieldthreshold; end
            end

            switch app.explorer.statsettings.stimulationmodel
                case 'Electric Field'
                    if evalin('base','exist(''threshold_method'',''var'')') % percentile method
                        app.EfieldthresholdLabel.Text= {['Voxels/Tracts connected if ', lower(app.explorer.statsettings.efieldmetric), ' E-Field Magnitude'], ...
                            ['they traverse in the top ', sprintf('%.0f', app.EfieldthresholdSlider.Value), '% percentile']};
                    else
                        app.EfieldthresholdLabel.Text={['Voxels/Tracts connected if ',lower(app.explorer.statsettings.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
                    end

                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Voxels/Tracts connected if ',lower(app.explorer.statsettings.efieldmetric),' probability'] ['of their activation > ',sprintf('%.1f',app.EfieldthresholdSlider.Value)]};
            end

            app.ConnthresholdSlider.Value = app.explorer.statsettings.connthreshold;

            switch app.explorer.statsettings.stimulationmodel
                case 'VTA'
                    switch app.explorer.statsettings.statfamily
                        case '1-Sample Tests'
                            bubbles='VTAs';
                            app.ConnthresholdLabel.Text = ['Voxels/Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                        case '2-Sample Tests'
                            bubbles='VTAs';
                            app.ConnthresholdLabel.Text = {['Voxels/Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' %'],['and < ',sprintf('%.1f',100-app.ConnthresholdSlider.Value),' % of ',bubbles]};
                    end
                case 'Electric Field'
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Voxels/Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
                case 'Descriptive'
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Voxels/Tracts connected to > ',sprintf('%.1f',app.ConnthresholdSlider.Value),' % of ',bubbles];
            end

            app.PosthoccorrectforgroupCheckBox.Value = app.explorer.adjustforgroups;

            app.VariableofInterestDropDown.Items = app.explorer.M.clinical.labels;
            if ismember(app.explorer.responsevarlabel,app.explorer.M.clinical.labels)
                app.VariableofInterestDropDown.Value = app.explorer.responsevarlabel;
            else
                app.VariableofInterestDropDown.Value = app.explorer.M.clinical.labels{1};
            end
            app.FittoscoresCheckBox.Value = app.explorer.doactualprediction;

            app.SubscoresListBox.Items = app.explorer.M.clinical.labels;
            if ~isempty(app.explorer.subscore.labels)
                app.SubscoresListBox.Value = app.explorer.subscore.labels;
            end
            [is,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            if is
                if ~isempty(ix)
                    app.CovariatesListBox.Items(ix)=[];
                end
            end
            if ~isempty(app.explorer.results)
                app.CovariatesCheckBox.Value=~isempty(app.explorer.covarlabels);
            end

            switch app.explorer.multitractmode
                case 'Single Tract Analysis'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 1;
                case 'Split & Color By Group'
                    app.SplitColorByGroupButton.Value = 1;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By PCA'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 1;
                    app.SplitColorBySubscoreButton.Value = 0;
                    app.SingleTractAnalysisButton.Value = 0;
                case 'Split & Color By Subscore'
                    app.SplitColorByGroupButton.Value = 0;
                    app.SplitColorByPCAButton.Value = 0;
                    app.SplitColorBySubscoreButton.Value = 1;
                    app.SingleTractAnalysisButton.Value = 0;
            end

            app.PCsEditField.Value = app.explorer.numpcs;

            app.FittoscoresCheckBox.Value = app.explorer.doactualprediction;

            app.ShowPositiveCheckBox.Value = app.explorer.posvisible;
            try
                if strcmp(app.explorer.statsettings.stattest,'N-Map')
                    app.ShowPositiveCheckBox.Text = ['Show Connected Fibers (',num2str(sum(app.explorer.stats.pos.available)),')'];
                else
                    app.ShowPositiveCheckBox.Text = ['Show Positive Fibers (',num2str(sum(app.explorer.stats.pos.available)),')'];
                end
            end

            app.ShowNegativeCheckBox.Value = app.explorer.negvisible;
            try
                app.ShowNegativeCheckBox.Text = ['Show Negative Fibers (',num2str(sum(app.explorer.stats.neg.available)),')'];
            end

            app.ThresholdingDropDown.Value = app.explorer.threshstrategy;
            % Amount EditField:
            switch app.explorer.threshstrategy
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = sum(app.explorer.showposamount);
                    
                    try
                      app.ShowAmountEditFieldLabel.Text = {'Show Amount', ['(',num2str(sum(app.explorer.stats.pos.shown)),')']};
                    end
                        app.ShowAmountEditFieldNegative.Value = sum(app.explorer.shownegamount);
                    try
                       app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.explorer.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.explorer.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.explorer.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.explorer.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.explorer.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.explorer.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.explorer.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.explorer.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.explorer.stats.neg.shown(1)),')']};
                    end

                    if size(app.explorer.subscore.labels,2) > 1
                        keyboard
                    end
                case {'Histogram (CDF)', 'Fixed Fiber Value'} % edit fields
                    if app.explorer.showposamount(1) == app.explorer.showposamount(2)
                        app.ShowAmountEditFieldPositive.Value = app.explorer.showposamount(1);
                        app.ShowAmountEditFieldPositive.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldPositive.Value = 0;
                        app.ShowAmountEditFieldPositive.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountEditFieldLabel.Text = {'Show Amount', ['(',num2str(sum(app.explorer.stats.pos.shown)),')']};
                    end

                    if app.explorer.shownegamount(1) == app.explorer.shownegamount(2)
                        app.ShowAmountEditFieldNegative.Value = app.explorer.shownegamount(1);
                        app.ShowAmountEditFieldNegative.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldNegative.Value = 0;
                        app.ShowAmountEditFieldNegative.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.explorer.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.explorer.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.explorer.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.explorer.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.explorer.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.explorer.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.explorer.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.explorer.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.explorer.stats.neg.shown(1)),')']};
                    end

                    if size(app.explorer.subscore.labels,2) > 1
                        keyboard
                    end
                otherwise
                    if app.explorer.showposamount(1) == app.explorer.showposamount(2)
                        app.ShowAmountEditFieldPositive.Value = app.explorer.showposamount(1);
                        app.ShowAmountEditFieldPositive.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldPositive.Value = 0;
                        app.ShowAmountEditFieldPositive.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelPositive.Text = {'Show Amount', ['(',num2str(sum(app.explorer.stats.pos.shown)),')']};
                    end

                    if app.explorer.shownegamount(1) == app.explorer.shownegamount(2)
                        app.ShowAmountEditFieldNegative.Value = app.explorer.shownegamount(1);
                        app.ShowAmountEditFieldNegative.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldNegative.Value = 0;
                        app.ShowAmountEditFieldNegative.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.explorer.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.explorer.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.explorer.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.explorer.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.explorer.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.explorer.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.explorer.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.explorer.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.explorer.stats.neg.shown(1)),')']};
                    end

                    if size(app.explorer.subscore.labels,2) > 1
                        keyboard
                    end
            end

            % Labels for subscores
            if strcmp(app.explorer.multitractmode,'Split & Color By Subscore')
                %find the index of the current subscore you are working
                %with
                selected_indx = cellfun(@(x)isequal(x,app.TabGroup.SelectedTab.Title),app.explorer.subscore.labels);
                if ~isempty(find(selected_indx)) %will be empty when single tract analysis is chosen
                    indx = find(selected_indx);
                end
            elseif strcmp(app.explorer.multitractmode,'Split & Color By PCA') || strcmp(app.explorer.multitractmode,'Split & Color By Group') %usually,in PCAs and Groups
                % there is no reason to use the isequal cell function, because
                % they are already ordered (and will be when they are
                % called in the ea_distract)
                selected_indx = strsplit(app.TabGroup.SelectedTab.Title,'_');
                if length(selected_indx) > 1
                    indx = str2double(selected_indx{2});
                end
            end

            if exist('indx','var')
                checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
                pos_label = ['ShowAmountLabelPositive_' num2str(indx+1)];
                left_pos_label = ['ShowAmountLeftLabelPositive_' num2str(indx+1)];
                right_pos_label = ['ShowAmountRightLabelPositive_' num2str(indx+1)];
                app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.explorer.subscore.vis.pos_available(indx,:))),')'];
                app.(pos_label).Text = {'Show Amount', ['(',num2str(sum(app.explorer.subscore.vis.pos_shown(indx,:))),')']};
                app.(left_pos_label).Text = {'Left', ['(',num2str(app.explorer.subscore.vis.pos_shown(indx,2)),')']};
                app.(right_pos_label).Text = {'Right', ['(',num2str(app.explorer.subscore.vis.pos_shown(indx,1)),')']};
                checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
                neg_label = ['ShowAmountLabelNegative_' num2str(indx+1)];
                left_label = ['ShowAmountLeftLabelNegative_' num2str(indx+1)];
                right_label = ['ShowAmountRightLabelNegative_' num2str(indx+1)];
                app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.explorer.subscore.vis.neg_available(indx,:))),')'];
                app.(neg_label).Text = {'Show Amount', ['(',num2str(sum(app.explorer.subscore.vis.neg_shown(indx,:))),')']};
                app.(left_label).Text = {'Left', ['(',num2str(app.explorer.subscore.vis.neg_shown(indx,2)),')']};
                app.(right_label).Text = {'Right', ['(',num2str(app.explorer.subscore.vis.neg_shown(indx,1)),')']};
            end

            app.ModeltypeDropDown.Value = app.explorer.predictionmodel;

            app.LiveVisualizeCheckBox.Value = app.explorer.cvlivevisualize;

            app.BinsEditField.Value = app.explorer.numBins;
            app.ModelNormalizationDropDown.Value = app.explorer.modelNormalization;

            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.explorer.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.explorer.negcolor;


            % Base prediction on:
            try
                app.BasePredictiononDropDown.Value = app.explorer.basepredictionon;
            end
            if app.explorer.nestedLOO
                app.NestedCheckBox.Value = 1;
            end
            app.MaskDropDown.Value = app.explorer.cvmask;
            app.IterationsEditField.Value = app.explorer.kIter;
            app.ea_unifiedmapping_ui_enablerules;

            % refresh enablerules
            app.isbusy(0);

        end

        function results = ea_unifiedmapping_ui_enablerules(app,flag)
            
            app.isbusy(1);
            ss_val = 0;
            ff_val = 0;
            nm_val = 0;
            
            % Generally always on if LG/explorer was provided
            app.OpenSettingsButton.Enable = 'on';
            app.ConnectomeFiberfilteringDropDown.Enable = 'on';
            app.ConnectomeFiberfilteringDropDownLabel.Enable = 'on';
            app.ConnectomeNetworkmappingDropDown.Enable = 'on';
            app.ConnectomeNetworkmappingDropDownLabel.Enable = 'on';
            app.CalculateButton_Sweetspot.Enable = 'on';
            app.CalculateButton_Fiberfiltering.Enable = 'on';
            app.CalculateButton_Networkmapping.Enable = 'on';

            % Allow connectome selection
                [mdl,sf]=ea_genmodlist;
                dmri_connectomenames = mdl(sf==1);
                dmri_mtconnectomenames = mdl(sf==3);
                app.ConnectomeFiberfilteringDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];

                fmri_connectomenames = mdl;
                app.ConnectomeNetworkmappingDropDown.Items = fmri_connectomenames;

            %check which tool can be enabled:
             %sweetspot
            if isfield(app.explorer.results,'sweetspotmapping') 
                ss_val= 1;
                app.SweetspotSwitch.Enable = 'on';
                app.SweetspotSwitchLabel.Enable = 'on';
                app.SaveSettingsButton.Enable = "on"; %switch it on because you can now save settings
                app.CalculateButton_Sweetspot.BackgroundColor = [0.9 0.9 0.9];

            end
            %fiberfiltering
            if isfield(app.explorer.results,'fiberfiltering')
                if isfield(app.explorer.results.fiberfiltering,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome))
                    ff_val = 1;
                    app.FiberfilteringSwitch.Enable = 'on';
                    app.FiberfilteringSwitchLabel.Enable = 'on';
                    app.SaveSettingsButton.Enable = "on";
                    app.CalculateButton_Fiberfiltering.BackgroundColor = [0.9 0.9 0.9];

                end
            end
            %networkmapping
            if isfield(app.explorer.results,'networkmapping')
                nm_val= 1;
                if isfield(app.explorer.results.networkmapping,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.netmap_connectome))
                    app.NetworkmappingSwitch.Enable = 'on';
                    app.NetworkmappingSwitchLabel.Enable = 'on';
                    app.SaveSettingsButton.Enable = "on";
                    app.CalculateButton_Networkmapping.BackgroundColor = [0.9 0.9 0.9];
                    state = lower(app.explorer.activated.networkmapping); % "on" or "off"
                    app.NetMapVisualizeDropDown.Enable = state;
                    app.NetMapModelDropDown.Enable = state;
                    if strcmp(app.NetworkmappingSwitch.Value,'On')
                        app.RHCheckBox.Enable='on';
                        app.LHCheckBox.Enable='on';
                    else
                        app.RHCheckBox.Enable='off';
                        app.LHCheckBox.Enable='off';
                    end
                else
                    app.NetworkmappingSwitch.Enable = 'off';
                    % app.NetworkmappingSwitchLabel.Enable = 'off';
                    app.NetworkmappingSwitch.Enable = 'off';
                    % app.NetworkmappingSwitchLabel.Enable = 'off';
                    app.SaveSettingsButton.Enable = "off";
                    app.RHCheckBox.Enable='off';
                    app.LHCheckBox.Enable='off';

                end
            end
               

            try
                if drawTool == 1
                    numsides=numel(app.explorer.results.sweetspotmapping.efield);
                elseif drawTool == 2
                    numsides=numel(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).(ea_unifiedmapping_method2methodid(app.explorer)).fibsval);
                else
                    numsides=2; %for networkmapping just consider it to always operate on both sides since the results themselves are concatenated.
                end
            catch % default
                numsides=2;
            end


            %% Stimulation Setup GUI.
            app.SettingsDropDown.Enable = ss_val || nm_val || ff_val;
            app.SettingsDropDownLabel.Enable = ss_val || nm_val || ff_val;
            % app.StimulationModel.Enable = ss_val || ff_val || nm_val; %present in all three tools
            % app.StimulationModelLabel.Enable = ss_val || ff_val || nm_val;%present in all three tools
            app.StatFamilyDropDown.Enable = ss_val || ff_val || nm_val; %only in sweetspot and fiberfiltering
            app.StatFamilyLabel.Enable = ss_val || ff_val|| nm_val;
            app.StatTestDropDown.Enable = ss_val || ff_val|| nm_val;
            app.StatTestLabel.Enable = ss_val || ff_val|| nm_val;
            switch app.StatFamilyDropDown.Value
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                    app.StatH0Label.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
                    app.StatH0Label.Enable = false;
            end

            % hide e-field metrics when using PAM
            % if app.explorer.calcsettings.connectivity_type == 2 && ff_val %only involved in fiberfiltering
            %     app.EfieldmetricButtonGroup.Visible = 0;
            %     app.Efieldmetric.Visible = 0;
            % elseif ff_val
            %     app.EfieldmetricButtonGroup.Visible = 1;
            %     app.Efieldmetric.Visible = 1;
            % 
            %     % Disable if E-projection was not computed
            %     if ~isfield(app.explorer.results.fiberfiltering,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome))
            %         app.EfieldmetricButtonGroup.Enable = "off";
            %         app.explorer.e_field_metric = 'Magnitude';
            %         app.EfieldmetricButtonGroup.SelectedObject = app.EfieldmetricButtonGroup.Children(2);
            %     elseif ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),'VAT_Ttest_proj')
            %         app.EfieldmetricButtonGroup.Enable = "off";
            %         app.explorer.e_field_metric = 'Magnitude';
            %         app.EfieldmetricButtonGroup.SelectedObject = app.EfieldmetricButtonGroup.Children(2);
            %     end
            % 
            % end
            app.MirrorSidesCheckBox.Enable = ss_val || ff_val || nm_val;
            app.VariableofInterestDropDown.Enable = ss_val || ff_val || nm_val;
            % app.SmoothFingerPrintsCheckBox.Enable = nm_val;
            % app.NormalizeFingerPrintsCheckBox.Enable = nm_val;
            groupsassigned=(length(unique(app.explorer.M.patient.group))>1);
            if groupsassigned
                app.SplitColorByGroupButton.Enable = ss_val || ff_val || nm_val;
            else
                app.SplitColorByGroupButton.Enable = 'off';
                app.SplitColorByGroupButton.Value = 0;
            end

            app.SplitColorBySubscoreButton.Enable = ss_val || ff_val || nm_val;
            app.SingleTractAnalysisButton.Enable = ss_val || ff_val || nm_val;
            app.SplitColorByPCAButton.Enable = ff_val;

            % Thresholding Method:
            app.ThresholdingDropDown.Enable = ss_val || ff_val || nm_val;
            app.ThresholdingDropDownLabel.Enable = ss_val || ff_val || nm_val;
            app.ShowPositiveCheckBox.Enable = ss_val || ff_val || nm_val;
            app.ShowNegativeCheckBox.Enable = ss_val || ff_val || nm_val;

            app.ColorButtonPositive.Enable = ss_val || ff_val || nm_val;
            app.ColorButtonNegative.Enable = ss_val || ff_val || nm_val;
            app.ConnthresholdSlider.Enable = ss_val || ff_val;
            app.ConnthresholdLabel.Enable = ss_val || ff_val;

            app.VariableofInterestDropDownLabel.Enable = ss_val || ff_val || nm_val;
            app.CovariatesCheckBox.Enable = ss_val || ff_val || nm_val;
            app.ColorBar.Visible = ss_val || ff_val || nm_val;
            if ~ss_val && ~ff_val && ~nm_val
                cla(app.ColorBar);
            end
            app.ExportasAtlasButton.Enable = ss_val || ff_val || nm_val;
            cvEnable = ss_val || ff_val || nm_val;
            app.ExportModelButton.Enable = ss_val || ff_val || nm_val;
           
            %%for now it is only in fiberfiltering
            app.SubscoresListBox.Enable = ff_val;
            app.MultitractWeightsButton.Enable = ff_val;


            app.ROISwitch.Enable= 'off'; %TO DOff_val
            app.ROISwitchLabel.Enable='off';
            app.FibersSwitch.Enable='off';
            app.ConnectedFibersLabel.Enable='off';

            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ModelNormalizationDropDown.Enable = cvEnable;
            app.ModelNormalizationDropDownLabel.Enable = cvEnable;
            app.MaskDropDown.Enable = nm_val;
            app.MaskDropDownLabel.Enable = nm_val;
            switch app.BasePredictiononDropDown.Value
                case {'Histogram','z-scored Histogram'}
                    app.BinsEditField.Enable = cvEnable;
                    app.BinsEditFieldLabel.Enable = cvEnable;
                otherwise
                    app.BinsEditField.Enable = 'off';
                    app.BinsEditFieldLabel.Enable = 'off';
            end

            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.explorer.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.explorer.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.explorer.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    [~, patNames] = fileparts(app.explorer.M.patient.list);
                    app.TrainOnListBox.Items = patNames;
                    app.PredictListBox.Items = patNames;
                case 'Custom (Cohorts)'
                    if length(app.explorer.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.explorer.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.explorer.M.groups.group));
                    end
                case 'Import Model'
                    if ~strcmp(app.explorer.ExternalModelFile, 'None')
                        [~, ExternalModelFileName] = fileparts(app.explorer.ExternalModelFile);
                        app.LoadModelButton.Value = ExternalModelFileName;
                    end

                    [~, patNames] = fileparts(app.explorer.M.patient.list);
                    app.PredictListBox.Items = patNames;
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(1:str2double(app.kEditField.Value))');
                        app.PredictListBox.Items=sprintfc('%d',(1:str2double(app.kEditField.Value))');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.explorer.setlabels;
                    app.PredictListBox.Items=app.explorer.setlabels;
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Import Model'
                    cvlEnable=cvEnable;
                    if isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.explorer.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end

                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end

            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            app.ModeltypeDropDown.Enable = cvlEnable;

            app.AutoRefreshCheckBox.Enable = ss_val || ff_val || nm_val;
            app.RefreshViewButton.Enable = ss_val || ff_val || nm_val;
            app.LiveVisualizeCheckBox.Enable = ss_val || ff_val || nm_val;

            if ismember(app.StatTestDropDown.Value,{'Skipped Spearman','Skipped Pearson','N-Map','Mean-Map'})
                app.ConsiderSignificantValuesonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantValuesonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                app.ConsiderSignificantValuesonlyCheckBox.Enable = ss_val || ff_val || nm_val;
                app.levelEditField.Enable = ss_val || ff_val || nm_val;
                app.levelEditFieldLabel.Enable = ss_val || ff_val || nm_val;
                app.MultTestStrategy.Enable = ss_val || ff_val || nm_val;
            end

            if ismember(app.StatTestDropDown.Value,{'N-Map','Wilcoxon Rank-Sum Test','Wilcoxon Signed-Rank Test'})
                app.ShowNegativeCheckBox.Enable = 'off';
                app.ShowNegativeCheckBox.Value = 0;
            else
                app.ShowNegativeCheckBox.Enable = ss_val || ff_val || nm_val;
            end


            app.BasePredictiononDropDown.Enable = cvEnable;
            app.BasePredictiononDropDownLabel.Enable = cvEnable;
            %app.ModelSetupOptionButtonGroup.Enable = val;


            app.FittoscoresCheckBox.Enable = cvEnable;

            if app.SplitColorByPCAButton.Value % when doing a PCA based prediction, we need to fit back tract estimates to responsevars.
                app.FittoscoresCheckBox.Value = 1;
                app.FittoscoresCheckBox.Enable = 'off';
                app.explorer.doactualprediction = 1; % redundancy just to make sure in fact this should not be needed.
            end

            switch app.FittoscoresCheckBox.Value
                case 1
                    app.ModeltypeDropDown.Enable = cvEnable;
                case 0
                    app.ModeltypeDropDown.Enable = 'off';
            end


            switch app.StimulationModel.Value
                case 'VTA'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                otherwise
                    app.EfieldthresholdSlider.Enable = ff_val || ss_val || nm_val;
                    app.EfieldthresholdLabel.Enable = ff_val || ss_val || nm_val;
                    app.Efieldmetric.Enable = ff_val || ss_val || nm_val;
            end

            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'Sigmoid Field'
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend','Histogram','z-scored Histogram'};
                case 'VTA'
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
            end

           
            if ~ismember(app.StatTestDropDown.Value,app.StatTestDropDown.Items)
                app.StatTestDropDown.Value=app.StatTestDropDown.Items{1};
            end


            switch app.StrategyDropDown.Value
                case 'Import Model'
                    app.explorer.useExternalModel = true;
                    app.Efieldmetric.Enable = 'off';
                    app.PredictListBoxLabel.Position(1) = 149;
                    app.PredictListBox.Position(1) = 200;
                    app.PredictListBox.Position(3) = 251;
                    app.TrainOnListBox.Visible = 'off';  % this might need to be placed above
                    app.LoadModelButton.Visible = 'on';

                otherwise
                    app.explorer.useExternalModel = false;
                    app.PredictListBoxLabel.Position(1) = 245;
                    app.PredictListBox.Position(1) = 297;
                    app.PredictListBox.Position(3) = 156;
                    app.TrainOnListBox.Visible = 'on';
                    app.LoadModelButton.Visible = 'off';
            end

            app.PatientsListBox.Enable=ff_val || ss_val || nm_val;

            app.SubcohortsDropDown.Enable=ff_val || ss_val || nm_val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.explorer.setlabels];
            if ~isempty(app.explorer.setlabels)
                app.SubcohortsDropDown_2.Items=[app.explorer.setlabels];
            end


            switch app.ShowNegativeCheckBox.Value
                case 0
                    app.ShowAmountEditFieldNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountLabelNegative.Enable = 'off';

                    app.ShowAmountLeftEditFieldNegative.Enable = 'off';
                    app.ShowAmountRightEditFieldNegative.Enable = 'off';

                    app.ShowAmountLeftLabelNegative.Enable = 'off';
                    app.ShowAmountRightLabelNegative.Enable = 'off';
                case 1
                    app.ShowAmountEditFieldNegative.Enable = ss_val || ff_val || nm_val;
                    app.ColorButtonNegative.Enable = ss_val || ff_val || nm_val;
                    app.ShowAmountLabelNegative.Enable = ss_val || ff_val || nm_val;
                    if numsides==2 % usual bilateral case
                        app.ShowAmountLeftEditFieldNegative.Enable = ff_val;
                        app.ShowAmountRightEditFieldNegative.Enable = ff_val;
                        app.ShowAmountLeftLabelNegative.Enable = ff_val;
                        app.ShowAmountRightLabelNegative.Enable = ff_val;
                       
                    else % scripting case e.g. when using only one side
                        app.ShowAmountLeftEditFieldNegative.Enable = 'off';
                        app.ShowAmountRightEditFieldNegative.Enable = 'off';

                        app.ShowAmountLeftLabelNegative.Enable = 'off';
                        app.ShowAmountRightLabelNegative.Enable = 'off';
                    end
            end

            switch app.ShowPositiveCheckBox.Value
                case 0
                    app.ShowAmountEditFieldPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.ShowAmountLabelPositive.Enable = 'off';

                    app.ShowAmountLeftEditFieldPositive.Enable = 'off';
                    app.ShowAmountRightEditFieldPositive.Enable = 'off';

                    app.ShowAmountLeftLabelPositive.Enable = 'off';
                    app.ShowAmountRightLabelPositive.Enable = 'off';
                case 1
                    app.ShowAmountEditFieldPositive.Enable = ss_val || ff_val || nm_val;
                    app.ColorButtonPositive.Enable = ss_val || ff_val || nm_val;
                    app.ShowAmountLabelPositive.Enable = ss_val || ff_val || nm_val;
                    if numsides==2 % usual bilateral case
                        app.ShowAmountLeftEditFieldPositive.Enable = ff_val;
                        app.ShowAmountRightEditFieldPositive.Enable = ff_val;
                        app.ShowAmountLeftLabelPositive.Enable = ff_val;
                        app.ShowAmountRightLabelPositive.Enable = ff_val;
                    else % scripting case e.g. when using only one side
                        app.ShowAmountLeftEditFieldPositive.Enable = 'off';
                        app.ShowAmountRightEditFieldPositive.Enable = 'off';

                        app.ShowAmountLeftLabelPositive.Enable = 'off';
                        app.ShowAmountRightLabelPositive.Enable = 'off';
                    end

            end

            % Logic of multitract panel radio buttons - enabling of pca panel is
            % handled in updatepca()
            if app.SingleTractAnalysisButton.Value
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = ff_val;
                app.CovariatesListBox.Enable = ff_val;
                app.MultitractWeightsButton.Enable = 'off';
                app.SingleVoxelTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
            end
            if app.SplitColorByGroupButton.Value
                
                app.SubscoresListBox.Enable = 'off';
                app.CovariatesCheckBox.Enable = ff_val;
                app.CovariatesListBox.Enable = ff_val;
                app.MultitractWeightsButton.Enable = 'off';
                n_groups = unique(app.explorer.M.patient.group);
                app.SingleVoxelTractAnalysisTab.Title = 'Group Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(n_groups)
                        app.(tab_title).Parent = app.TabGroup;
                        new_tabtitle = ['Group' num2str(i)];
                        if strcmp(app.(tab_title).Title,new_tabtitle)
                            app.(tab_title).Title = ['dummy' num2str(randi(2^52))];
                            app.(tab_title).Title = ['Group' num2str(i)];
                        else
                            app.(tab_title).Title = ['Group' num2str(i)];
                        end
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            if app.SplitColorBySubscoreButton.Value
              
                app.SubscoresListBox.Enable = 'on';
                app.CovariatesCheckBox.Enable = 'on';
                app.CovariatesListBox.Enable = 'on';
                app.MultitractWeightsButton.Enable = cvEnable;
                app.SingleVoxelTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= length(app.explorer.subscore.labels)
                        app.(tab_title).Parent = app.TabGroup;
                        if strcmp(app.(tab_title).Title,app.explorer.subscore.labels{i})
                            %if you want to change the name to a name that
                            %is already present in MATLAB'S memory, it
                            %switches to the default name for this tab
                            %"Subscore_n"
                            app.(tab_title).Title = ['dummy' num2str(randi(2^52))];
                            app.(tab_title).Title = app.explorer.subscore.labels{i};
                        else
                            app.(tab_title).Title = app.explorer.subscore.labels{i};
                        end
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            if app.SplitColorByPCAButton.Value
                app.NestedCheckBox.Visible = 'off';
                app.ColorButtonPositive.Enable = ff_val;
                app.ColorButtonNegative.Enable = ff_val;
                app.SubscoresListBox.Enable = ff_val;
                app.CovariatesCheckBox.Enable = 'on';
                app.CovariatesListBox.Enable = 'on';
                app.MultitractWeightsButton.Enable = 'off';
                app.SingleVoxelTractAnalysisTab.Title = 'PCA Fiber Visualization';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    if i <= app.explorer.numpcs
                        app.(tab_title).Parent = app.TabGroup;
                        new_tabtitle = ['PC' num2str(i)];
                        if strcmp(app.(tab_title).Title,new_tabtitle)
                            app.(tab_title).Title = ['dummy' num2str(randi(2^52))];
                            app.(tab_title).Title = ['PC' num2str(i)];
                        else
                            app.(tab_title).Title = ['PC' num2str(i)];
                        end
                    else
                        app.(tab_title).Parent = [];
                    end
                end
            end
            %end
            app.updatepca;
            % turn light back to green
            app.isbusy(0);
        end

        function results = enforcestatrules(app)
            app.StimulationModel.Value=app.explorer.statsettings.stimulationmodel;
            %% Enforce Rules Based on the Model
            stattests=ea_explorer_statlist(app.explorer.responsevar);
            testidx=cellfun(@ismember,stattests.compatibility,repmat({app.explorer.statsettings.stimulationmodel},size(stattests.compatibility)),'UniformOutput',false);
            testidx=cellfun(@any,testidx);
            tmptests =stattests(testidx,:);
            if ~any(app.explorer.statsettings.statfamily==tmptests.type)
                %set family to default
                app.explorer.statsettings.statfamily = tmptests.type(1);
                app.StatFamilyDropDown.Items = unique(tmptests.type);
                app.StatFamilyDropDown.Value= char(tmptests.type(1));
                % set tests to default
                % app.fiberfiltering.statsettings.statest=tmptests.name(1);
                % app.StatTestDropDown.Items=tmptests.name;
                % app.StatTestDropDown.Value=tmptests.name(1);
                ea_cprintf('CmdWinWarnings', 'Test Family reset according to Stimulation Model!\n');
            else % set family in GUI
                app.StatFamilyDropDown.Items = unique(tmptests.type);
%                 if strcmp(app.explorer.statsettings.stimulationmodel,'Electric Field')
%                     app.StatFamilyDropDown.Value = 'Correlations';
%                     app.explorer.statsettings.statfamily = 'Correlations';
%                 else
%                     app.StatFamilyDropDown.Value=char(app.explorer.statsettings.statfamily);
%                 end
            end
            %% Enforce Rules Based on Test Family
            tmptests=tmptests(app.explorer.statsettings.statfamily==tmptests.type,:);
            if ~any(app.explorer.statsettings.stattest==tmptests.name)
                app.explorer.statsettings.statest=tmptests.name(1);
                app.StatTestDropDown.Items=tmptests.name;
                app.StatTestDropDown.Value=char(tmptests.name(1));
                ea_cprintf('CmdWinWarnings', 'Statistical Test set to Default for this Test Family!\n');
            else
                app.StatTestDropDown.Items=tmptests.name;
                app.StatTestDropDown.Value=char(app.explorer.statsettings.stattest);
            end
            %%
            app.StatH0DropDown.Value=app.explorer.statsettings.H0;
            switch app.explorer.statsettings.statfamily
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
            end

            stack = dbstack;
            % Do not save GUI settings into fiberfiltering when loading the results
            if ~ismember('ea_unifiedmappingexplorer.startupFcn', {stack.name})
                app.pushstatsettings;

            end

            drawnow
        end

        function results = pushstatsettings(app)
            % app.explorer.statsettings.stimulationmodel = app.StimulationModel.Value;
            % app.explorer.statsettings.efieldmetric = app.Efieldmetric.Value; % if statmetric == ;Correlations / E-fields (Irmen 2020)p, efieldmetric can calculate sum, mean or peak along tracts
            app.explorer.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.explorer.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.explorer.statsettings.statfamily = app.StatFamilyDropDown.Value; % the
            app.explorer.statsettings.stattest = app.StatTestDropDown.Value;
            app.explorer.statsettings.H0 = app.StatH0DropDown.Value;
        end

        function pullSettingsFromExplorerForMovedFields(app)
            app.StimulationModel.Value   = app.explorer.statsettings.stimulationmodel; %this is the minimal working solution, should be changed to work smarter
            app.Efieldmetric.Value = app.explorer.statsettings.efieldmetric;
            % app.EfieldmetricDropDown.Value       = app.explorer.e_field_metric;
        end


        function results = pullstatsettings(app,mystatsettings)
            if exist('mystatsettings','var')
                app.explorer.statsettings = mystatsettings;
            end
            app.enforcestatrules;
            drawnow
        end

        function results = pushthresholdingsettings(app)
            app.explorer.thresholding.showsignificantonly = app.ShowonlysignificantCheckBox.Value;
            app.explorer.thresholding.alphalevel = app.levelEditField.Value;
            app.explorer.thresholding.multcompstrategy = app.MultTestStrategy.Value;
            app.explorer.thresholding.threshstrategy = app.ThresholdingDropDown.Value;
            app.explorer.thresholding.posvisible = app.ShowPositiveCheckBox.Value;
            app.explorer.thresholding.negvisible = app.ShowNegativeCheckBox.Value;
            app.explorer.thresholding.showposamount = [app.ShowAmountEditFieldPositive.Value,app.ShowAmountEditFieldPositive.Value];
            app.explorer.thresholding.shownegamount = [app.ShowAmountEditFieldNegative.Value,app.ShowAmountEditFieldNegative.Value];
            app.explorer.thresholding.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.explorer.thresholding.negcolor = app.ColorButtonNegative.BackgroundColor;
        end
        
        function results = onCalcSettingsApplied(app)
            app.enforcestatrules;
            app.setefieldsliders;
            app.ea_unifiedmapping_ui_enablerules;
            app.pushstatsettings;   % only if this does NOT overwrite the moved fields
            app.draw;
            
        end
    end
    
    methods (Access = public)
        
        function results = populateSettingsJson(app,filepath)
           
            [DBSMappingFolder,~,~] = fileparts(filepath);
            if isfield(app.explorer.results,'fiberfiltering')
                conn2check = ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome);
            elseif isfield(app.explorer.results,'networkmapping')
                conn2check = ea_unifiedmapping_conn2connid(app.explorer.calcsettings.netmap_connectome);
            elseif isfield(app.explorer.results,'sweetspotmapping')
                conn2check = 'default';
            end
          
            str2check = ['Settings-',app.explorer.ID,'_conn-',conn2check];
            if ~isfolder(DBSMappingFolder)
                ea_mkdir(DBSMappingFolder); %clearly there are no settings file!!
                return
            end
            all_settings_file = ea_regexpdir(DBSMappingFolder,'Settings-.*_conn-.*.json',0,'f');
            if ~isempty(all_settings_file)
                [~,filenames,~] = fileparts(all_settings_file);
                if ~iscell(filenames)
                    filenames = {filenames};
                end
                app.SettingsDropDown.Items = filenames';
            
                % try to find exact match first
                idx = find(strcmp(filenames, str2check), 1);
            
                if isempty(idx)
                    % fallback to default
                    default_name = ['Settings-', app.explorer.ID, '_conn-default'];
                    idx = find(strcmp(filenames, default_name), 1);
                end
            
                if ~isempty(idx)
                    % read the JSON file
                    fid = fopen(all_settings_file{idx}, 'r');
                    raw = fread(fid, inf, '*char')';
                    fclose(fid);
                    data = jsondecode(raw);
                else
                    % no matching file at all
                    data = struct(); 
                end
            else
                % no settings files found
                data = struct();
            end

% populate explorer with fields from JSON
fields = fieldnames(data);
for i = 1:length(fields)
    if ~isprop(app.explorer, fields{i})
        addprop(app.explorer, fields{i});                
    end
    app.explorer.(fields{i}) = data.(fields{i});
end

        end


        function  CalculateButtonPushedCallBack(app,event)
            app.explorer = getappdata(app.explorer.resultfig,'explorer');
            app.explorer.leadgroup=app.LeadGroupPathLabel.Text;

            %% set up object properties:
            app.explorer.posvisible = app.ShowPositiveCheckBox.Value;
            app.explorer.negvisible = app.ShowNegativeCheckBox.Value;
            app.explorer.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.explorer.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.explorer.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.explorer.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.explorer.patientselection = ix;


            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.explorer.M.clinical.labels);
            app.explorer.responsevar = app.explorer.M.clinical.vars{ix};
            app.explorer.responsevarlabel = app.explorer.M.clinical.labels{ix};

            setcovars(app);
            app.setefieldsliders;


            app.explorer.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.explorer.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.explorer.mirrorsides = app.MirrorSidesCheckBox.Value;

            % calculate general setup
            app.explorer.calculate;

            % save dataset:
            app.explorer.save;
            app.explorer.saveObjectToJson;
            app.populateSettingsJson(app.explorer.analysispath);
            app.ea_unifiedmapping_ui_enablerules;

            % draw results
            app.draw;

        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            % use supplied information to setup analysis
            % if ~exist('lgpath','var')
            %     [FileName,PathName]=uigetfile('*','Please Choose Explorer File or Lead-Group Analysis project...');
            %     if isnumeric(FileName) && FileName == 0
            %         ea_cprintf('CmdWinErrors', 'No lead group analysis or explorer file selected!\n');
            %         delete(app);
            %         return;
            %     else
            %         [~,fn,ext]=fileparts(FileName);
            %         switch ext
            %             case {'.explorer','.mat'} % assume lg file
            %                 lgpath = fullfile(PathName,FileName);
            %             otherwise
            %                 ea_cprintf('CmdWinErrors', 'No lead group analysis or explorer file selected!\n');
            %                 delete(app);
            %                 return;
            %         end
            %     end
            % elseif ~isfile(lgpath)
            %     ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
            %     delete(app);
            %     return;
            % end
            if ~exist('lgpath','var') || isempty(lgpath)
                app.UIFigure.Name='Unified Mapping Explorer';
                return;
            elseif ~isfile(lgpath)
                ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
                delete(app);
                return;
            end
            lgpath = GetFullPath(lgpath);

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Opening application');
            d.Value = 0;
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Unified Mapping Explorer';

            app.CalculateButton_Sweetspot.BackgroundColor = [1 0.6 0.2];
            app.CalculateButton_Fiberfiltering.BackgroundColor = [1 0.6 0.2];
            app.CalculateButton_Networkmapping.BackgroundColor = [1 0.6 0.2];
            app.OpenanalysisButton.BackgroundColor = [0.96, 0.96, 0.96];

            app.IterationsEditField.Visible = 'off';
            app.IterationsEditFieldLabel.Visible = 'off';
            % Add modlist (structural connectomes)
            
            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            d.Value = 0.33;
            % Add Value to connthreshsliderlabel
            changingValue=app.EfieldthresholdSlider.Value;

            letsdraw=0; % flag to state whether to draw directly.

            varinfo=who('-file',lgpath); % check if file is a group analysis matfile or a stored explorer object with var name 'explorer'
            if ismember('explorer',varinfo)
                
                D = load(lgpath,'-mat');
                app.explorer = D.explorer; % load stored disctract object
                app.populateSettingsJson(lgpath);
                app.explorer.analysispath=lgpath;
                if isprop(app.explorer,'nestedLOO')
                    app.NestedCheckBox.Value = app.explorer.nestedLOO;
                end
                app.NestedCheckBox.Visible = 'off';
                app.explorer.activateby={};

                % Fix fiberfiltering ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                %if ~strcmp(app.explorer.ID, ID)
                %    app.explorer.ID = ID;
                %end

                % do not initialize with Import Model
                if strcmp(app.StrategyDropDown.Value, 'Import Model')
                    app.StrategyDropDown.Value = 'Custom (Patients)';
                end

                % Fix group folder in case it has been moved
                tractGroupFolder = regexprep(app.explorer.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['UnifiedMappingExplorer', filesep, app.explorer.ID, '.explorer'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.explorer.analysispath = strrep(app.explorer.analysispath, tractGroupFolder, UIGroupFolder);
                    app.explorer.leadgroup = strrep(app.explorer.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.explorer.M.root = strrep(app.explorer.M.root, tractGroupFolder, UIGroupFolder);
                end

                d.Value = 0.50;
                
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                app.explorer.resultfig = resultfig; % attach to figure

                if ~isempty(app.explorer.results)
                    if isfield(app.explorer.results,'sweetspotmapping') || ...
                       isfield(app.explorer.results,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)) || ...
                       isfield(app.explorer.results,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.netmap_connectome))
                        letsdraw=1;
                    end
                end
                                
                % should we give support for old fiberfiltering files? 
                %  if old fibfilt is used, assume it was computed with VATs
                if isfield(app.explorer.results,'fiberfiltering')
                    if ~isempty(app.explorer.results.fiberfiltering)
                        if ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'VAT_Ttest') && ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),'PAM_Ttest')
                            app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('VAT_Ttest') = app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('ttests');
                            app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)) = rmfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'ttests');
                        end

                        % if both results are available, use e-fields
                        if isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'VAT_Ttest') && isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),'PAM_Ttest')
                            app.explorer.calcsettings.connectivity_type = 1;
                            app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).fibcell = app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_fibers').fibcell;
                        elseif ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'VAT_Ttest') && isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),'PAM_Ttest')
                            % if only PAM, adjust GUI
                            app.explorer.calcsettings.connectivity_type = 2;
                            app.ConnectivityModelButtonGroup.SelectedObject = app.ConnectivityModelButtonGroup.Children(1);
                        end

                        if ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'connFiberInd_VAT') && ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'connFiberInd_PAM')
                            if iscell(app.explorer.connFiberInd) && ~isempty(app.explorer.connFiberInd)
                                waitfor(msgbox("Connectivity indices were imported from the old version fibfilt."));
                                app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).connFiberInd_VAT = app.explorer.connFiberInd;
                            else
                                waitfor(msgbox(sprintf("Connectivity indices were not found!\nRecalcute or use with truncated functionality.")));
                            end
                        end

                        if isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'connFiberInd')
                            app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).connFiberInd_VAT = app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).connFiberInd;
                            app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)) = rmfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)), 'connFiberInd');
                        end
                    end
                end

            else % init from M struct of LEAD group file
                app.explorer = ea_unifiedmapping;
                app.NestedCheckBox.Visible = 'off';
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                try
                    app.explorer.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
                catch ME
                    close(resultfig);
                    app.UIFigureCloseRequest;
                    rethrow(ME);
                end
            end
            
            d.Value = 0.75;
            app.explorer.compat_statmetric;
            if letsdraw
                app.draw; % show its results
            else
                app.ea_unifiedmapping_explorer2UI;
            end

            app.setefieldsliders;
            app.ea_unifiedmapping_ui_enablerules;
            if strcmp(app.explorer.multitractmode,'Split & Color By Subscore') && ~isempty(app.explorer.subscore.labels)
                app.tabfill;
            end
           

            app.isbusy(0);
            d.Value = 1.00;
            close(d);
            hint={'Welcome to the Lead-DBS Unified Mapping Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'Thihs explorer integrates Fiber Filtering, Network Mapping, and'
                'Sweet Spot Mapping within a common interface to facilitate'
                'comparative and complementary analyses.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create  models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');

        end

        % Callback function
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)


        end

        % Callback function
        function ModelSetupDropDownValueChanged(app, event)
            app.explorer.statmetric=app.ModelSetupDropDown.Value;
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)'
                    app.ConnectivityModelButtonGroup.Children(1).Enable = 'on';
                    if strcmp(app.ConnectivityModelButtonGroup.SelectedObject.Text, 'Stimulation Volume')
                        app.explorer.calcsettings.connectivity_type = 1;
                        if app.explorer.statsettings.connthreshold > 40
                            app.explorer.statsettings.connthreshold = 20; % reset to a suitable value
                        end
                    else
                        app.explorer.calcsettings.connectivity_type = 2;
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.ConnectivityModelButtonGroup.SelectedObject = app.ConnectivityModelButtonGroup.Children(2);
                    % app.ConnectivityModelButtonGroup.Children(1).Enable = 'off';
                    app.explorer.calcsettings.connectivity_type = 1;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)'
                    app.ConnectivityModelButtonGroup.SelectedObject = app.ConnectivityModelButtonGroup.Children(2);
                    % app.ConnectivityModelButtonGroup.Children(1).Enable = 'off';
                    app.explorer.calcsettings.connectivity_type = 1;
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Proportion Test (Chi-Square) / VTAs (binary vars)'
                    if app.explorer.statsettings.connthreshold > 40
                        app.explorer.statsettings.connthreshold = 20; % reset to a suitable value
                    end
                case 'Binomial Tests / VTAs (binary vars)'
                    if app.explorer.statsettings.connthreshold > 40
                        app.explorer.statsettings.connthreshold = 20; % reset to a suitable value
                    end
                case 'Reverse T-Tests / E-Fields (binary vars)'
                    % also make sure efieldmetric sliders are properly set:
                    app.setefieldsliders;
                case 'Odds Ratios / EF-Sigmoid (Jergas 2023)'
                    if app.explorer.statsettings.connthreshold > 40
                        app.explorer.statsettings.connthreshold = 20; % reset to a suitable value
                    end
                case 'Plain Connections'
                    if app.explorer.statsettings.connthreshold < 1
                        app.explorer.statsettings.connthreshold = 1; % reset to a suitable value
                    end
            end

            % check if the model changed from PAM to VAT or vice versa:
            % error thrown here if we remove PAM (OSS-DBS) as an option
            % from the menu

            %if (strcmp(app.ModelSetupDropDown.Value, 'T-Tests / PAM (OSS-DBS)') && strcmp('T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', event.PreviousValue)) || (strcmp(app.ModelSetupDropDown.Value, 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)') && strcmp('T-Tests / PAM (OSS-DBS)', event.PreviousValue))
            %    app.tractset.switch_connectivity = 1;
            %else
            %    app.tractset.switch_connectivity = 0;
            %end

            app.ea_unifiedmapping_explorer2UI;
            app.ea_unifiedmapping_ui_enablerules;
            if app.explorer.drawTool == 1
                if ~isfield(app.explorer.results,'sweetspotmapping')
                    val=0;
                else
                    val = 1;
                end
            elseif app.explorer.drawTool == 2
                if ~isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),ea_unifiedmapping_method2methodid(app.explorer))
                    val = 0;
                else
                    val = 1;
                end
            elseif app.explorer.drawTool == 3
                if ~isfield(app.explorer.results.networkmapping, ea_unifiedmapping_conn2connid(app.explorer.calcsettings.netmap_connectome))
                    val = 0;
                else
                    val = 1;
                end
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.explorer.SigmoidTransform == 1
                app.EfieldthresholdLabel.Text={['Voxels/Tracts connected if ',lower(app.explorer.statsettings.efieldmetric),' probability'] ['of their activation > ',sprintf('%.2f',changingValue)]}; %not edited for percentile yet
            else
                if evalin('base','exist(''threshold_method'',''var'')') % percentile method
                    app.EfieldthresholdLabel.Text= {['Voxels/Tracts connected if ', lower(app.explorer.statsettings.efieldmetric), ' E-Field Magnitude'], ...
                        ['they traverse in the top ', sprintf('%.0f', app.EfieldthresholdSlider.Value), '% percentile']};
                else
                    app.EfieldthresholdLabel.Text={['Voxels/Tracts connected if ',lower(app.explorer.statsettings.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
                end
            end
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume','VTA Size',app.explorer.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.explorer.M.clinical.labels);
            app.explorer.responsevar = app.explorer.M.clinical.vars{ix};
            app.explorer.responsevarlabel = app.explorer.M.clinical.labels{ix};
            app.enforcestatrules;

            app.draw;

        end

        % Callback function
        function CalculateButtonPushed(app, event)
            %app.tractset.app=app; % need to change
            app.explorer.leadgroup=app.LeadGroupPathLabel.Text;

            %% set up object properties:
            app.explorer.posvisible = app.ShowPositiveCheckBox.Value;
            app.explorer.negvisible = app.ShowNegativeCheckBox.Value;
            app.explorer.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.explorer.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.explorer.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.explorer.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.explorer.patientselection = ix;
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.explorer.M.clinical.labels);
            app.explorer.responsevar = app.explorer.M.clinical.vars{ix};
            app.explorer.responsevarlabel = app.explorer.M.clinical.labels{ix};

            setcovars(app);

            app.setefieldsliders;


            app.explorer.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.explorer.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.explorer.mirrorsides = app.MirrorSidesCheckBox.Value;

            % calculate general setup
            app.explorer.calculate;

            % save dataset:
            app.explorer.save;
            
            app.ea_unifiedmapping_ui_enablerules;

            % draw results
            app.draw;
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.explorer.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            app.ea_unifiedmapping_ui_enablerules;
        end

        % Value changed function: ShowPositiveCheckBox
        function ShowPositiveCheckBoxValueChanged(app, event)
            value = app.ShowPositiveCheckBox.Value;
            app.explorer.posvisible = value;
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                %component_changed = 'ShowPositiveFibersCheckBox';
                tabupdate(app);
            end
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ShowNegativeCheckBox
        function ShowNegativeCheckBoxValueChanged(app, event)
            value = app.ShowNegativeCheckBox.Value;
            app.explorer.negvisible = value;
            if value
                if app.ShowAmountEditFieldNegative.Value==0
                    app.explorer.shownegamount(:)=1;
                    app.ShowAmountEditFieldNegative.Value=1; % set to a minimal value so it doesnt get turned off directly again.
                end
            end
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                tabupdate(app);
                %component_changed = 'ShowNegativeFibersCheckBox';
                %tabupdate(app,component_changed);
            end
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.explorer.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'],'file')
                app.AnalysisIDEditField.Value = app.explorer.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end

            if exist([fileparts(app.explorer.leadgroup),filesep,'fiberfiltering',filesep,app.explorer.ID,'.fibfilt'],'file')
                movefile([fileparts(app.explorer.leadgroup),filesep,'fiberfiltering',filesep,app.explorer.ID,'.fibfilt'],...
                    [fileparts(app.explorer.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt']);
            end

            ht = getappdata(app.explorer.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.explorer.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.explorer.leadgroup),filesep,'fiberfiltering',filesep,value,'.fibfilt'], app.explorer.resultfig});
            end

            app.explorer.ID=value;
            app.explorer.save;

        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.explorer.patientselection = ix;
            app.draw;
            app.ea_unifiedmapping_explorer2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.explorer.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.explorer.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)

            app.explorer.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.draw;
        end

        % Callback function
        function ConnectomeDropDownValueChanged(app, event)
% %             value = app.ConnectomeDropDown_2.Value;
% %             %if the selected connectomes have multiple .mat files inside
% %             %it, then it is a multi tract file and
% %             %app.tractset.multi_pathways = 1
% %             if exist(fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown_2.Value),'dir') %this is inside the dMRI_MultiTract folder
% %                 if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown_2.Value),'dir') %same connectome exists in both
% %                     warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
% %                     app.explorer.multi_pathways = 0;
% %                 else
% %                     %if it only exists in the multitract pathway, then
% %                     %check once more for the multiple .mat files and
% %                     %set app.tractset.multi_pathways = 1;
% %                     mt_connectome = fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown_2.Value);
% %                     mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
% %                     mt_files = {mt_connectome_dir.name};
% %                     if length(mt_files) > 1
% %                         app.explorer.multi_pathways = 1;
% %                     else
% %                         app.explorer.multi_pathways = 0;
% %                     end
% %                 end
% %             else
% %                 app.explorer.multi_pathways = 0;
% %             end
% %             app.explorer.connectome = value;
% %             app.ea_unifiedmapping_ui_enablerules;
% %             if ~isfield(app.explorer.results,ea_unifiedmapping_conn2connid(app.explorer.connectome))
% %                 val=0;
% %                 % reset E-metric to Magnitude
% %                 app.explorer.e_field_metric = 'Magnitude';
% %                 app.EfieldmetricButtonGroup.SelectedObject = app.EfieldmetricButtonGroup.Children(2);
% %             else
% %                 val=(isfield(app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.connectome)),ea_unifiedmapping_method2methodid(app.explorer)));
% %             end
% %             if val % already calculated
% %                 app.draw;
% %             end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.explorer.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.explorer.statmetric
                case {'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)','Proportion Test (Chi-Square) / VTAs (binary vars)','Binomial Tests / VTAs (binary vars)'}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Voxels/Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case {'Correlations / E-fields (Irmen 2020)','Reverse T-Tests / E-Fields (binary vars)', 'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)', 'Odds Ratios / EF-Sigmoid (Jergas 2023)',3}
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Voxels/Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
                case {'One-Sample Tests / VTAs / PAM (OSS-DBS)','Plain Connections'}
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = ['Voxels/Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.explorer.statsettings.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            %app.explorer.CleartuneOptim = 0;
            customconfig.trainonitems=app.TrainOnListBox.Items;
            customconfig.trainonvalues=app.TrainOnListBox.Value;
            customconfig.predictonitems=app.PredictListBox.Items;
            customconfig.predictonvalues=app.PredictListBox.Value;

            ea_unifiedmapping_crossval(1,app.explorer,...
                app.StrategyDropDown.Value,...
                app.IterationsEditField.Value,...
                app.PosthoccorrectforgroupCheckBox.Value,...
                customconfig);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            if strcmp(app.StrategyDropDown.Value,'Leave-One-Patient-Out') || strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.NestedCheckBox.Visible = 'on';
            else
                app.NestedCheckBox.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value,'Import Model')
                app.SubcohortsDropDown_2.Visible = 'on';
                app.SubcohortsDropDown_2Label.Visible = 'on';
            else
                app.SubcohortsDropDown_2.Visible = 'off';
                app.SubcohortsDropDown_2Label.Visible = 'off';
            end


            if strcmp(app.StrategyDropDown.Value,'Import Model')
                app.LoadModelButton.Visible = 'on';
            else
                app.LoadModelButton.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.IterationsEditFieldLabel.Visible = 'on';
                app.IterationsEditField.Visible = 'on';
                app.IterationsEditFieldLabel.Enable = 'on';
                app.IterationsEditField.Enable = 'on';
                app.IterationsEditField.Value = 1;
            else
                app.IterationsEditFieldLabel.Visible = 'off';
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Enable = 'off';
                app.IterationsEditField.Enable = 'off';
            end
            app.ea_unifiedmapping_ui_enablerules;

            % redraw if swtiching to and from Imported Model
            if strcmp(app.StrategyDropDown.Value,'Import Model') || strcmp(event.PreviousValue, 'Import Model')
                app.draw
            end

            app.ea_unifiedmapping_explorer2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %check if explorere is loaded/valid
            explorerLoaded = ~isempty(app.explorer) && isvalid(app.explorer);
            if explorerLoaded
                answer = questdlg("Save Changes?",'Close Discriminative Fiber Toolbox','Yes','No','Yes');
                if isempty(answer) 
                    return
                end

                if strcmp(answer,'Yes') && ~isempty(app.explorer)
                     app.isbusy(1,'saving');
                     app.explorer.save;
                end

                % Clean up draw objects
                if isprop(app.explorer, 'drawobject') && isstruct(app.explorer.drawobject)
                    for tract = 1:numel(app.explorer.drawobject)
                        try
                            delete(app.explorer.drawobject.fiberfiltering{tract});
                        end
                    end
                end
        
                % Close result fig if it exists and is a blank/unloaded one
                if isprop(app.explorer, 'resultfig') ...
                        && isvalid(app.explorer.resultfig) ...
                        && startsWith(app.explorer.resultfig.Name, 'No Patient Selected')
                    close(app.explorer.resultfig);
                end

            end

%             if exist('event', 'var')
%                  answer = questdlg("Save Changes?",'Close Discriminative Fiber Toolbox','Yes','No','Yes');
%              else
%                  answer = 'Yes';
%              end
% 
%              if strcmp(answer,'Yes') && ~isempty(app.explorer)
%                  app.isbusy(1,'saving');
%                  app.explorer.save;
%              end
%              % else
%              %     delete(app);
%              % end
% 
%              if isprop(app.explorer, 'drawobject') && isstruct(app.explorer.drawobject)
%                  for tract=1:numel(app.explorer.drawobject)
%                      try
%                          delete(app.explorer.drawobject.fiberfiltering{tract});
%                      end
%                  end
%              end
% 
% %             if isprop(app.explorer, 'resultfig') && isvalid(app.explorer.resultfig) && startsWith(app.explorer.resultfig.Name, 'No Patient Selected')
% %                 close(app.explorer.resultfig);
% %             end
% 
%             % close result fig if exists
%             if isprop(app.explorer,'resultfig') && isvalid(app.explorer.resultfig) ...
%                     && startsWith(app.explorer.resultfig.Name,'No Patient Selected')
%                 close(app.explorer.resultfig);
%             end
% % 
            app.isbusy(0);
            delete(app);

        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
            %only useful for fiberfiltering. Will not show any results for
            %the other tools
            value = app.Efieldmetric.Value;
            app.setefieldsliders;
            app.explorer.statsettings.efieldmetric=value;

            app.ea_unifiedmapping_ui_enablerules;
            app.ea_unifiedmapping_explorer2UI;

            if ~isfield(app.explorer.results.fiberfiltering,ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome))
                val=0;
            else
                val=(isfield(app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),ea_unifiedmapping_method2methodid(app.explorer)));
            end

            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.explorer.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.explorer.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.explorer.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(1:app.explorer.Nsets)');
                        app.PredictListBox.Items=sprintfc('%d',(1:app.explorer.Nsets)');
                    end
            end
        end

        % Value changed function: CovariatesCheckBox
        function CovariatesCheckBoxValueChanged(app, event)
            value = app.CovariatesCheckBox.Value;
            if value
                if isempty(app.CovariatesListBox.Value)
                    app.CovariatesListBox.Value=app.CovariatesListBox.Items{1};
                end
            end
            app.ea_unifiedmapping_ui_enablerules;
            if ~value
                app.explorer.covars = {};
                app.explorer.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end

        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.explorer.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshinformationfromLeadGroupanalysisMenu
        function RefreshinformationfromLeadGroupanalysisMenuSelected(app, event)
            app.explorer.refreshlg;
            app.ea_unifiedmapping_explorer2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: ConsiderSignificantValuesonlyCheckBox
        function ConsiderSignificantValuesonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantValuesonlyCheckBox.Value;
            app.explorer.showsignificantonly=value;
            if app.SplitColorBySubscoreButton.Value
                app.tabupdate;
            end
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            ss_val = 0;
            ff_val = 0;
            nm_val = 0;
            if isfield(app.explorer.results,'sweetspotmapping')
                ss_val = 1;
            end
            if isfield(app.explorer.results,'fiberfiltering')
                ff_val = 1;
            end
            if isfield(app.explorer.results,'networkmapping')
                nm_val = 1;
            end
            ea_export_as_atlas(app, ss_val, ff_val, nm_val);

        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.ea_unifiedmapping_explorer2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.ea_unifiedmapping_ui_enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.ea_unifiedmapping_ui_enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.explorer.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.explorer.multcompstrategy=value;
            app.draw;
        end

        % Callback function
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.explorer.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.explorer.showsignificantonly=0;
            end
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.explorer.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.explorer.basepredictionon=value;
            app.ea_unifiedmapping_ui_enablerules;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.explorer.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.explorer.setlabels{end+1}=setname;
                    app.explorer.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.explorer.setlabels{end+1}=setname;
                    app.explorer.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.explorer.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.explorer.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.explorer.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.ea_unifiedmapping_ui_enablerules;
        end

        % Menu selected function: SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.explorer.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.explorer.rngseed = str2double(rngseed{1});
            end
        end

        % Value changed function: ModelNormalizationDropDown
        function ModelNormalizationDropDownValueChanged(app, event)
            value = app.ModelNormalizationDropDown.Value;
            app.explorer.modelNormalization=value;
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            value = app.BinsEditField.Value;
            app.numBins=value;
        end

        % Selection changed function: ModelSetupOptionButtonGroup
        function ModelSetupOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelSetupOptionButtonGroup.SelectedObject;
            app.explorer.multitractmode=selectedButton.Text;
            if strcmp(selectedButton.Text,'Split & Color By Group')
                app.explorer.subscore.splitbysubscore = 0;
                app.explorer.doactualprediction = 0;
                app.ea_unifiedmapping_ui_enablerules;
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By Subscore')
                %removing the covariates box since it doesn't make sense
                %anymore
                app.explorer.subscore.splitbysubscore = 1;
                app.explorer.doactualprediction = 0;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.explorer.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.explorer.subscore.labels{subscore,1}),app.explorer.M.clinical.labels);
                    app.explorer.subscore.vars{subscore,1} = app.explorer.M.clinical.vars{ix};
                end
                app.ea_unifiedmapping_ui_enablerules;
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Single Tract Analysis')
                app.explorer.subscore.splitbysubscore = 0;
                app.explorer.doactualprediction = 0;
                app.ea_unifiedmapping_ui_enablerules;
                tabupdate(app);
                app.draw;
            elseif strcmp(selectedButton.Text,'Split & Color By PCA')
                app.explorer.subscore.splitbysubscore = 0;
                app.explorer.doactualprediction = 1;
                app.FittoscoresCheckBox.Value = 1;
                for subscore = 1:length(app.SubscoresListBox.Value)
                    app.explorer.subscore.labels{subscore,1} = app.SubscoresListBox.Value{subscore};
                    ix = cellfun(@(x)isequal(x,app.explorer.subscore.labels{subscore,1}),app.explorer.M.clinical.labels);
                    app.explorer.subscore.vars{subscore,1} = app.explorer.M.clinical.vars{ix};
                end
                app.ea_unifiedmapping_ui_enablerules;
                tabupdate(app);
                app.draw;
            end
            % save dataset:
        end

        % Value changed function: SubscoresListBox
        function SubscoresListBoxValueChanged(app, event)

            app.explorer.subscore.labels = (app.SubscoresListBox.Value)';
            app.explorer.subscore.vars = cell(length(app.explorer.subscore.labels),1);
            try % keep prior weights if we can
                app.explorer.subscore.weights=app.explorer.subscore.weights(1:length(app.explorer.subscore.labels));
            catch
                app.explorer.subscore.weights = zeros(length(app.explorer.subscore.labels),1);
            end

            for subscore = 1:length(app.SubscoresListBox.Value)
                [is,ix] = ismember(app.explorer.subscore.labels{subscore,1},app.explorer.M.clinical.labels);
                app.explorer.subscore.vars{subscore,1} = app.explorer.M.clinical.vars{ix};
            end

            app.ea_unifiedmapping_ui_enablerules;
            app.draw;

        end

        % Button pushed function: MultitractWeightsButton
        function MultitractWeightsButtonPushed(app, event)

            f = uifigure('Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 630;
            f.Position(4) = 800;
            for i=1:length(app.explorer.subscore.vars)
                slider_name{i} = app.explorer.subscore.labels{i};
            end


            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 700;
            m = 682;

            for i=1:length(slider_name)
                slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text','Weight Using..','Position',[20,m,80,25]);
                base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.explorer.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.explorer.subscore.weights(i))
                    slider_val_name{i}.Value=app.explorer.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.explorer.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    base_pred_dd{i}.Value=app.explorer.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChange(slider_text_box,slider_val_name,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChanging(event,slider_text_box,slider_val_name,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChange_text(slider_text_box,slider_val_name,i);
                base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


            function base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i)
                saved_labels = app.explorer.subscore.labels{i};
                saved_vars = app.explorer.subscore.vars{i};
                if strcmp(base_pred_dd{i}.Value,'None')
                    slider_text_box{i}.Enable = 'on';
                    slider_val_name{i}.Enable = 'on';
                    app.explorer.subscore.weightvars{i,1} = '';
                    app.explorer.subscore.weightvars{i,1} = [];
                    app.explorer.subscore.weights(i)=slider_val_name{i}.Value/100;
                else
                    slider_text_box{i}.Enable = 'off';
                    slider_val_name{i}.Enable = 'off';
                    index_of_new_subscore = cellfun(@(x)isequal(x,base_pred_dd{i}.Value),app.explorer.M.clinical.labels);
                    app.explorer.subscore.weightvars{i,1} = app.explorer.M.clinical.vars{index_of_new_subscore};
                    app.explorer.subscore.weightvarlabels{i,1} = base_pred_dd{i}.Value;
                    app.explorer.subscore.weights(i)=nan;
                end

            end


            function SliderChange_text(slider_text_box,slider_val_name,i)

                text_value = slider_text_box{i}.Value;
                remainingamount=1-(text_value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.explorer.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_text_box{slider}.Value=weights(slider)*100;
                        app.explorer.subscore.weights(slider) = [weights(slider)];
                        slider_val_name{slider}.Value = weights(slider)*100;
                    end
                end
                app.draw;
            end


            function SliderChange(slider_text_box,slider_val_name,i)
                %change value using sliders
                remainingamount=1-(slider_val_name{i}.Value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.explorer.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.explorer.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end

                app.draw;
            end

            function SliderChanging(event,slider_text_box,slider_val_name,i)
                remainingamount=1-(event.Value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.explorer.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=event.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.explorer.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
            end
        end

        % Callback function
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.explorer.calcthreshold = value;


            hint={'Note that changing this value will not have an effect on values already calculated.'
                'You would need to repeat the calculation for each connectome of interest for changes to have an effect.'};
            uigetpref('LeadDBS','FiberFilteringShowCalcThreshHint','Fiber Filtering Explorer',hint,'Makes Sense');


        end

        % Value changed function: FittoscoresCheckBox
        function FittoscoresCheckBoxValueChanged(app, event)
            value = app.FittoscoresCheckBox.Value;
            app.explorer.doactualprediction = value;
            app.ModeltypeDropDown.Enable = ea_bool2onoff(value);

        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            app.explorer.predictionmodel = app.ModeltypeDropDown.Value;
        end

        % Value changed function: PCsEditField
        function PCsEditFieldValueChanged(app, event)
            value = app.PCsEditField.Value;
            if value > length(app.explorer.subscore.vars)
                app.PCsEditField.Value = length(app.explorer.subscore.vars);
                msgbox("You cannot set more PCs than the number of variables");
            else
                app.explorer.numpcs=value;
                app.updatepca('edit');
                app.ea_unifiedmapping_ui_enablerules;
                app.draw;
            end
        end

        % Value changed function: VarianceExpSlider
        function VarianceExpSliderValueChanged(app, event)
            value = app.VarianceExpSlider.Value;
            app.updatepca('slider');
            app.draw;
        end

        % Value changing function: VarianceExpSlider
        function VarianceExpSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: AutoRefreshCheckBox
        function AutoRefreshCheckBoxValueChanged(app, event)
            value = app.AutoRefreshCheckBox.Value;

        end

        % Callback function
        function ConsiderSignificantFibersonlyCheckBox_2ValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox_2.Value;
            app.explorer.subscore.showsignificantonly{1}=value;
            app.draw;
        end

        % Callback function
        function levelEditField_2ValueChanged(app, event)
            value = app.levelEditField_2.Value;
            app.explorer.subscore.alphalevel(1)=(value);
            app.draw;
        end

        % Callback function
        function MultTestStrategy_2ValueChanged(app, event)
            value = app.MultTestStrategy_2.Value;
            app.explorer.subscore.multcompstrategy{1}=value;
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_2
        function ShowNegativeFibersCheckBox_2ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_2.Value;
            app.explorer.subscore.negvisible(1) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_2.Enable = 'off';
                %                 app.ShowAmountLabelNegative_2.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_2.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_2.Enable = 'off';
                %                 app.ColorButtonNegative_2.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_2.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_2.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_2.Enable = 'on';
                app.ShowAmountLabelNegative_2.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_2.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_2.Enable = 'on';
                app.ColorButtonNegative_2.Enable = 'on';
                app.ShowAmountLeftLabelNegative_2.Enable = 'on';
                app.ShowAmountRightLabelNegative_2.Enable = 'on';
                app.draw;
            end


        end

        % Value changed function: ShowPositiveFibersCheckBox_2
        function ShowPositiveFibersCheckBox_2ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_2.Value;
            app.explorer.subscore.posvisible(1) = value;

            if ~value
                % app.ShowAmountEditFieldPositive_2.Enable = 'off';
                % app.ShowAmountLabelPositive_2.Enable = 'off';
                % app.ShowAmountLeftEditFieldPositive_2.Enable = 'off';
                % app.ShowAmountRightEditFieldPositive_2.Enable = 'off';
                % app.ColorButtonPositive_2.Enable = 'off';
                % app.ShowAmountLeftLabelPositive_2.Enable = 'off';
                % app.ShowAmountRightLabelPositive_2.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_2.Enable = 'on';
                app.ShowAmountLabelPositive_2.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_2.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_2.Enable = 'on';
                app.ColorButtonPositive_2.Enable = 'on';
                app.ShowAmountLeftLabelPositive_2.Enable = 'on';
                app.ShowAmountRightLabelPositive_2.Enable = 'on';
                app.draw;
            end

        end

        % Button down function: ColorBar
        function ColorBarButtonDown(app, event)

        end

        % Button pushed function: ColorButtonNegative_2
        function ColorButtonNegative_2Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_2.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(1,:) = app.ColorButtonNegative_2.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_2
        function ColorButtonPositive_2Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_2.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(1,:) = app.ColorButtonPositive_2.BackgroundColor;
            end
            app.draw;
        end

        % Value changed function: ShowNegativeFibersCheckBox_3
        function ShowNegativeFibersCheckBox_3ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_3.Value;
            app.explorer.subscore.negvisible(2) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_3.Enable = 'off';
                %                 app.ShowAmountLabelNegative_3.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_3.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_3.Enable = 'off';
                %                 app.ColorButtonNegative_3.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_3.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_3.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_3.Enable = 'on';
                app.ShowAmountLabelNegative_3.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_3.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_3.Enable = 'on';
                app.ColorButtonNegative_3.Enable = 'on';
                app.ShowAmountLeftLabelNegative_3.Enable = 'on';
                app.ShowAmountRightLabelNegative_3.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_3
        function ShowPositiveFibersCheckBox_3ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_3.Value;
            app.explorer.subscore.posvisible(2) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_3.Enable = 'off';
                %                 app.ShowAmountLabelPositive_3.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_3.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_3.Enable = 'off';
                %                 app.ColorButtonPositive_3.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_3.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_3.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_3.Enable = 'on';
                app.ShowAmountLabelPositive_3.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_3.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_3.Enable = 'on';
                app.ColorButtonPositive_3.Enable = 'on';
                app.ShowAmountLeftLabelPositive_3.Enable = 'on';
                app.ShowAmountRightLabelPositive_3.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowPositiveFibersCheckBox_4
        function ShowPositiveFibersCheckBox_4ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_4.Value;
            app.explorer.subscore.posvisible(3) = value;
            if ~value
                %                 app.ShowAmountEditFieldPositive_4.Enable = 'off';
                %                 app.ShowAmountLabelPositive_4.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_4.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_4.Enable = 'off';
                %                 app.ColorButtonPositive_4.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_4.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_4.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_4.Enable = 'on';
                app.ShowAmountLabelPositive_4.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_4.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_4.Enable = 'on';
                app.ColorButtonPositive_4.Enable = 'on';
                app.ShowAmountLeftLabelPositive_4.Enable = 'on';
                app.ShowAmountRightLabelPositive_4.Enable = 'on';
                app.draw;
            end

        end

        % Value changed function: ShowNegativeFibersCheckBox_4
        function ShowNegativeFibersCheckBox_4ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_4.Value;
            app.explorer.subscore.negvisible(3) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_4.Enable = 'off';
                %                 app.ShowAmountLabelNegative_4.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_4.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_4.Enable = 'off';
                %                 app.ColorButtonNegative_4.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_4.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_4.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_4.Enable = 'on';
                app.ShowAmountLabelNegative_4.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_4.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_4.Enable = 'on';
                app.ColorButtonNegative_4.Enable = 'on';
                app.ShowAmountLeftLabelNegative_4.Enable = 'on';
                app.ShowAmountRightLabelNegative_4.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_5
        function ShowPositiveFibersCheckBox_5ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_5.Value;

            app.explorer.subscore.posvisible(4) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_5.Enable = 'off';
                %                 app.ShowAmountLabelPositive_5.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_5.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_5.Enable = 'off';
                %                 app.ColorButtonPositive_5.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_5.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_5.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_5.Enable = 'on';
                app.ShowAmountLabelPositive_5.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_5.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_5.Enable = 'on';
                app.ColorButtonPositive_5.Enable = 'on';
                app.ShowAmountLeftLabelPositive_5.Enable = 'on';
                app.ShowAmountRightLabelPositive_5.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_5
        function ShowNegativeFibersCheckBox_5ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_5.Value;
            app.explorer.subscore.negvisible(4) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_5.Enable = 'off';
                %                 app.ShowAmountLabelNegative_5.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_5.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_5.Enable = 'off';
                %                 app.ColorButtonNegative_5.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_5.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_5.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_5.Enable = 'on';
                app.ShowAmountLabelNegative_5.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_5.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_5.Enable = 'on';
                app.ColorButtonNegative_5.Enable = 'on';
                app.ShowAmountLeftLabelNegative_5.Enable = 'on';
                app.ShowAmountRightLabelNegative_5.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_6
        function ShowPositiveFibersCheckBox_6ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_6.Value;

            app.explorer.subscore.posvisible(5) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_6.Enable = 'off';
                %                 app.ShowAmountLabelPositive_6.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_6.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_6.Enable = 'off';
                %                 app.ColorButtonPositive_6.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_6.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_6.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_6.Enable = 'on';
                app.ShowAmountLabelPositive_6.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_6.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_6.Enable = 'on';
                app.ColorButtonPositive_6.Enable = 'on';
                app.ShowAmountLeftLabelPositive_6.Enable = 'on';
                app.ShowAmountRightLabelPositive_6.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_6
        function ShowNegativeFibersCheckBox_6ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_6.Value;
            app.explorer.subscore.negvisible(5) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_6.Enable = 'off';
                %                 app.ShowAmountLabelNegative_6.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_6.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_6.Enable = 'off';
                %                 app.ColorButtonNegative_6.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_6.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_6.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_6.Enable = 'on';
                app.ShowAmountLabelNegative_6.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_6.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_6.Enable = 'on';
                app.ColorButtonNegative_6.Enable = 'on';
                app.ShowAmountLeftLabelNegative_6.Enable = 'on';
                app.ShowAmountRightLabelNegative_6.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_7
        function ShowPositiveFibersCheckBox_7ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_7.Value;
            app.explorer.subscore.posvisible(6) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_7.Enable = 'off';
                %                 app.ShowAmountLabelPositive_7.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_7.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_7.Enable = 'off';
                %                 app.ColorButtonPositive_7.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_7.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_7.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_7.Enable = 'on';
                app.ShowAmountLabelPositive_7.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_7.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_7.Enable = 'on';
                app.ColorButtonPositive_7.Enable = 'on';
                app.ShowAmountLeftLabelPositive_7.Enable = 'on';
                app.ShowAmountRightLabelPositive_7.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_7
        function ShowNegativeFibersCheckBox_7ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_7.Value;
            app.explorer.subscore.negvisible(6) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_7.Enable = 'off';
                %                 app.ShowAmountLabelNegative_7.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_7.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_7.Enable = 'off';
                %                 app.ColorButtonNegative_7.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_7.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_7.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_7.Enable = 'on';
                app.ShowAmountLabelNegative_7.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_7.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_7.Enable = 'on';
                app.ColorButtonNegative_7.Enable = 'on';
                app.ShowAmountLeftLabelNegative_7.Enable = 'on';
                app.ShowAmountRightLabelNegative_7.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_8
        function ShowPositiveFibersCheckBox_8ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_8.Value;
            app.explorer.subscore.posvisible(7) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_8.Enable = 'off';
                %                 app.ShowAmountLabelPositive_8.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_8.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_8.Enable = 'off';
                %                 app.ColorButtonPositive_8.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_8.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_8.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_8.Enable = 'on';
                app.ShowAmountLabelPositive_8.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_8.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_8.Enable = 'on';
                app.ColorButtonPositive_8.Enable = 'on';
                app.ShowAmountLeftLabelPositive_8.Enable = 'on';
                app.ShowAmountRightLabelPositive_8.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_8
        function ShowNegativeFibersCheckBox_8ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_8.Value;
            app.explorer.subscore.negvisible(7) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_8.Enable = 'off';
                %                 app.ShowAmountLabelNegative_8.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_8.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_8.Enable = 'off';
                %                 app.ColorButtonNegative_8.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_8.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_8.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_8.Enable = 'on';
                app.ShowAmountLabelNegative_8.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_8.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_8.Enable = 'on';
                app.ColorButtonNegative_8.Enable = 'on';
                app.ShowAmountLeftLabelNegative_8.Enable = 'on';
                app.ShowAmountRightLabelNegative_8.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_9
        function ShowPositiveFibersCheckBox_9ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_9.Value;
            app.explorer.subscore.posvisible(8) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_9.Enable = 'off';
                %                 app.ShowAmountLabelPositive_9.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_9.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_9.Enable = 'off';
                %                 app.ColorButtonPositive_9.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_9.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_9.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_9.Enable = 'on';
                app.ShowAmountLabelPositive_9.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_9.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_9.Enable = 'on';
                app.ColorButtonPositive_9.Enable = 'on';
                app.ShowAmountLeftLabelPositive_9.Enable = 'on';
                app.ShowAmountRightLabelPositive_9.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_9
        function ShowNegativeFibersCheckBox_9ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_9.Value;
            app.explorer.subscore.negvisible(8) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_9.Enable = 'off';
                %                 app.ShowAmountLabelNegative_9.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_9.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_9.Enable = 'off';
                %                 app.ColorButtonNegative_9.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_9.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_9.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_9.Enable = 'on';
                app.ShowAmountLabelNegative_9.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_9.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_9.Enable = 'on';
                app.ColorButtonNegative_9.Enable = 'on';
                app.ShowAmountLeftLabelNegative_9.Enable = 'on';
                app.ShowAmountRightLabelNegative_9.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_10
        function ShowPositiveFibersCheckBox_10ValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox_10.Value;

            app.explorer.subscore.posvisible(9) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_10.Enable = 'off';
                %                 app.ShowAmountLabelPositive_10.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_10.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_10.Enable = 'off';
                %                 app.ColorButtonPositive_10.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_10.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_10.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_10.Enable = 'on';
                app.ShowAmountLabelPositive_10.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_10.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_10.Enable = 'on';
                app.ColorButtonPositive_10.Enable = 'on';
                app.ShowAmountLeftLabelPositive_10.Enable = 'on';
                app.ShowAmountRightLabelPositive_10.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_10
        function ShowNegativeFibersCheckBox_10ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_10.Value;
            app.explorer.subscore.negvisible(9) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_10.Enable = 'off';
                %                 app.ShowAmountLabelNegative_10.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_10.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_10.Enable = 'off';
                %                 app.ColorButtonNegative_10.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_10.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_10.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_10.Enable = 'on';
                app.ShowAmountLabelNegative_10.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_10.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_10.Enable = 'on';
                app.ColorButtonNegative_10.Enable = 'on';
                app.ShowAmountLeftLabelNegative_10.Enable = 'on';
                app.ShowAmountRightLabelNegative_10.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox_11
        function ShowPositiveFibersCheckBox_11ValueChanged(app, event)

            value = app.ShowPositiveFibersCheckBox_11.Value;

            app.explorer.subscore.posvisible(10) = value;

            if ~value
                %                 app.ShowAmountEditFieldPositive_11.Enable = 'off';
                %                 app.ShowAmountLabelPositive_11.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldPositive_11.Enable = 'off';
                %                 app.ShowAmountRightEditFieldPositive_11.Enable = 'off';
                %                 app.ColorButtonPositive_11.Enable = 'off';
                %                 app.ShowAmountLeftLabelPositive_11.Enable = 'off';
                %                 app.ShowAmountRightLabelPositive_11.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldPositive_11.Enable = 'on';
                app.ShowAmountLabelPositive_11.Enable = 'on';
                app.ShowAmountLeftEditFieldPositive_11.Enable = 'on';
                app.ShowAmountRightEditFieldPositive_11.Enable = 'on';
                app.ColorButtonPositive_11.Enable = 'on';
                app.ShowAmountLeftLabelPositive_11.Enable = 'on';
                app.ShowAmountRightLabelPositive_11.Enable = 'on';
                app.draw;
            end
        end

        % Value changed function: ShowNegativeFibersCheckBox_11
        function ShowNegativeFibersCheckBox_11ValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox_11.Value;
            app.explorer.subscore.negvisible(10) = value;
            if ~value
                %                 app.ShowAmountEditFieldNegative_11.Enable = 'off';
                %                 app.ShowAmountLabelNegative_11.Enable = 'off';
                %                 app.ShowAmountLeftEditFieldNegative_11.Enable = 'off';
                %                 app.ShowAmountRightEditFieldNegative_11.Enable = 'off';
                %                 app.ColorButtonNegative_11.Enable = 'off';
                %                 app.ShowAmountLeftLabelNegative_11.Enable = 'off';
                %                 app.ShowAmountRightLabelNegative_11.Enable = 'off';
                app.draw;
            else
                app.ShowAmountEditFieldNegative_11.Enable = 'on';
                app.ShowAmountLabelNegative_11.Enable = 'on';
                app.ShowAmountLeftEditFieldNegative_11.Enable = 'on';
                app.ShowAmountRightEditFieldNegative_11.Enable = 'on';
                app.ColorButtonNegative_11.Enable = 'on';
                app.ShowAmountLeftLabelNegative_11.Enable = 'on';
                app.ShowAmountRightLabelNegative_11.Enable = 'on';
                app.draw;
            end
        end

        % Button pushed function: ColorButtonPositive_3
        function ColorButtonPositive_3Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_3.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(2,:) = app.ColorButtonPositive_3.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_3
        function ColorButtonNegative_3Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_3.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(2,:) = app.ColorButtonNegative_3.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_4
        function ColorButtonPositive_4Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_4.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(3,:) = app.ColorButtonPositive_4.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_5
        function ColorButtonPositive_5Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_5.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(4,:) = app.ColorButtonPositive_5.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_6
        function ColorButtonPositive_6Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_6.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(5,:) = app.ColorButtonPositive_6.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_7
        function ColorButtonPositive_7Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_7.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(6,:) = app.ColorButtonPositive_7.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_8
        function ColorButtonPositive_8Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_8.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(7,:) = app.ColorButtonPositive_8.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_9
        function ColorButtonPositive_9Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_9.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(8,:) = app.ColorButtonPositive_9.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_10
        function ColorButtonPositive_10Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_10.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(9,:) = app.ColorButtonPositive_10.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonPositive_11
        function ColorButtonPositive_11Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive_11.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,2}(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(10,:) = app.ColorButtonPositive_11.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_11
        function ColorButtonNegative_11Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_11.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(10,:) = app.ColorButtonNegative_11.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_10
        function ColorButtonNegative_10Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_10.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(9,:) = app.ColorButtonNegative_10.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_9
        function ColorButtonNegative_9Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_9.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(8,:) = app.ColorButtonNegative_9.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_8
        function ColorButtonNegative_8Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_8.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(7,:) = app.ColorButtonNegative_8.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_7
        function ColorButtonNegative_7Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_7.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(6,:) = app.ColorButtonNegative_7.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_6
        function ColorButtonNegative_6Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_6.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(5,:) = app.ColorButtonNegative_6.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_5
        function ColorButtonNegative_5Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_5.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(4,:) = app.ColorButtonNegative_5.BackgroundColor;
            end
            app.draw;
        end

        % Button pushed function: ColorButtonNegative_4
        function ColorButtonNegative_4Pushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative_4.BackgroundColor=c;
            if app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.colors{1,1}(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            elseif app.SplitColorByPCAButton.Value
                app.explorer.subscore.pcacolors(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            elseif app.SplitColorByGroupButton.Value
                app.explorer.M.groups.color(3,:) = app.ColorButtonNegative_4.BackgroundColor;
            end
            app.draw;
        end

        % Callback function
        function OpeninCleartuneButtonPushed(app, event)
            config.fibfiltmodelpath=fullfile(fileparts(app.explorer.leadgroup),'fiberfiltering',[app.explorer.ID,'.fibfilt']);
            config.tractset=app.explorer;
            config.resultfig=app.explorer.resultfig;
            ea_cleartune(config)
        end

        % Callback function
        function ConnectomeDropDownOpening(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown_2.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Callback function
        function ConnectomeDropDownOpening2(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown_2.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Value changed function: NestedCheckBox
        function NestedCheckBoxValueChanged(app, event)
            value = app.NestedCheckBox.Value;
            if app.NestedCheckBox.Value
                app.explorer.nestedLOO = true;
            else
                app.explorer.nestedLOO = false;
            end
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.explorer.kIter = app.IterationsEditField.Value;
        end

        % Callback function
        function ConnectivityModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConnectivityModelButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Stimulation Volume')
                %app.ModelSetupDropDown.Items = {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Proportion Test (Chi-Square) / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                %app.ModelSetupDropDown.Items = {'Weighted Linear Regression / EF-Sigmoid (Dembek 2023)', 'Odds Ratios / EF-Sigmoid (Jergas 2023)', 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'One-Sample Tests / VTAs / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Proportion Test (Chi-Square) / VTAs (binary vars)', 'Binomial Tests / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                
                app.explorer.calcsettings.switch_connectivity = 1;
                app.explorer.calcsettings.connectivity_type = 1;
                app.explorer.statmetric = 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                app.EfieldmetricButtonGroup.Enable = 'on';

                if isfield(app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),'efield_fibers')
                    app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).fibcell = app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_fibers').fibcell;
                else
                    ea_warndlg("Some results are missing, please recalculate")
                end
                   
            elseif strcmp(selectedButton.Text,'PAM')
                %app.ModelSetupDropDown.Items = {'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'One-Sample Tests / VTAs / PAM (OSS-DBS)','Proportion Test (Chi-Square) / VTAs (binary vars)', 'Binomial Tests / VTAs (binary vars)', 'Plain Connections'};
                %if strcmp(app.ModelSetupDropDown.Value,'Correlations / E-fields (Irmen 2020)')
                %    app.ModelSetupDropDown.Value = 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                %end
                app.explorer.calcsettings.connectivity_type = 2;
                app.explorer.statmetric = 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                app.EfieldthresholdSlider.Enable = 'off';
                app.EfieldthresholdLabel.Enable = 'off';
                app.EfieldmetricButtonGroup.Enable = 'off';
                app.explorer.calcsettings.switch_connectivity = 1;
                app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                if ~ismember(app.explorer.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                    app.explorer.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                end

                if isfield(app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)),'pam_fibers')
                    app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).fibcell = app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('pam_fibers').fibcell;
                else
                    ea_warndlg("Some results are missing, please recalculate")
                end
            end

            app.ea_unifiedmapping_ui_enablerules;

            % change to connectivity type
            %1 = vta, 2= pam
        end

        % Value changed function: ShowAmountEditFieldNegative
        function ShowAmountEditFieldNegativeValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative.Value = value/2;
                    app.ShowAmountRightEditFieldNegative.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative.Value = value;
                    app.ShowAmountRightEditFieldNegative.Value = value;
            end

            app.explorer.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountEditFieldPositive
        function ShowAmountEditFieldPositiveValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive.Value = value/2;
                    app.ShowAmountRightEditFieldPositive.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive.Value = value;
                    app.ShowAmountRightEditFieldPositive.Value = value;
            end

            app.explorer.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative
        function ShowAmountLeftEditFieldNegativeValueChanged(app, event)
            app.explorer.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountRightEditFieldNegative
        function ShowAmountRightEditFieldNegativeValueChanged(app, event)
            app.explorer.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive
        function ShowAmountLeftEditFieldPositiveValueChanged(app, event)
            
            app.explorer.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ShowAmountRightEditFieldPositive
        function ShowAmountRightEditFieldPositiveValueChanged(app, event)
            app.explorer.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            if app.SplitColorByGroupButton.Value || app.SplitColorByPCAButton.Value || app.SplitColorBySubscoreButton.Value
                app.explorer.subscore.special_case = 1;
                app.tabupdate;
            end
            app.draw;
            app.explorer.subscore.special_case = 0;
        end

        % Value changed function: ThresholdingDropDown
        function ThresholdingDropDownValueChanged(app, event)
            value = app.ThresholdingDropDown.Value;
            app.explorer.threshstrategy=value;
            app.ShowAmountEditFieldPositive.Enable = 'on';
            app.ShowAmountEditFieldNegative.Enable = 'on';
            switch value
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountRightEditFieldPositive.Value = 0;
                    app.ShowAmountLeftEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                    app.ShowAmountRightEditFieldNegative.Value = 0;
                    app.ShowAmountLeftEditFieldNegative.Value = 0;
                    app.PlotDistributionButton.Visible = 'off';
                case 'Histogram (CDF)'
                    app.ShowAmountEditFieldPositive.Value = 0.01;
                    app.ShowAmountRightEditFieldPositive.Value = 0.01;
                    app.ShowAmountLeftEditFieldPositive.Value = 0.01;
                    app.ShowAmountEditFieldNegative.Value = 0.01;
                    app.ShowAmountRightEditFieldNegative.Value = 0.01;
                    app.ShowAmountLeftEditFieldNegative.Value = 0.01;
                    app.PlotDistributionButton.Visible = 'on';
                case 'Fixed Fiber Value'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                    app.ShowAmountRightEditFieldPositive.Value = 0;
                    app.ShowAmountLeftEditFieldPositive.Value = 0;
                    app.ShowAmountRightEditFieldNegative.Value = 0;
                    app.ShowAmountLeftEditFieldNegative.Value = 0;
                    app.PlotDistributionButton.Visible = 'on';
                otherwise
                    app.ShowAmountEditFieldPositive.Value = 25;
                    app.ShowAmountEditFieldNegative.Value = 25;
                    app.ShowAmountRightEditFieldPositive.Value = 25;
                    app.ShowAmountLeftEditFieldPositive.Value = 25;
                    app.ShowAmountRightEditFieldNegative.Value = 25;
                    app.ShowAmountLeftEditFieldNegative.Value = 25;
                    app.PlotDistributionButton.Visible = 'off';
            end
            app.explorer.showposamount=[app.ShowAmountRightEditFieldPositive.Value,app.ShowAmountLeftEditFieldPositive.Value];
            app.explorer.shownegamount=[app.ShowAmountRightEditFieldNegative.Value,app.ShowAmountLeftEditFieldNegative.Value];
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Menu selected function: RecreatevisualizationfigureMenu
        function RecreatevisualizationfigureMenuSelected(app, event)
            if isvalid(app.explorer.resultfig)
                close(app.explorer.resultfig);
            end
            app.explorer.resultfig = ea_mnifigure;
            app.draw(1);
        end

        % Value changed function: ShowAmountEditFieldPositive_2
        function ShowAmountEditFieldPositive_2ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_2.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_2.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_2.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_2.Value = value;
                    app.ShowAmountRightEditFieldPositive_2.Value = value;
            end

            app.explorer.subscore.vis.showposamount(1,:) = [app.ShowAmountRightEditFieldPositive_2.Value, app.ShowAmountLeftEditFieldPositive_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_2
        function ShowAmountEditFieldNegative_2ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_2.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_2.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_2.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_2.Value = value;
                    app.ShowAmountRightEditFieldNegative_2.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(1,:) = [app.ShowAmountRightEditFieldNegative_2.Value, app.ShowAmountLeftEditFieldNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_3
        function ShowAmountEditFieldPositive_3ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_3.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_3.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_3.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_3.Value = value;
                    app.ShowAmountRightEditFieldPositive_3.Value = value;
            end

            app.explorer.subscore.vis.showposamount(2,:) = [app.ShowAmountRightEditFieldPositive_3.Value, app.ShowAmountLeftEditFieldPositive_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_3
        function ShowAmountEditFieldNegative_3ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_3.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_3.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_3.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_3.Value = value;
                    app.ShowAmountRightEditFieldNegative_3.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(2,:) = [app.ShowAmountRightEditFieldNegative_3.Value, app.ShowAmountLeftEditFieldNegative_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_4
        function ShowAmountEditFieldPositive_4ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_4.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_4.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_4.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_4.Value = value;
                    app.ShowAmountRightEditFieldPositive_4.Value = value;
            end

            app.explorer.subscore.vis.showposamount(3,:) = [app.ShowAmountRightEditFieldPositive_4.Value, app.ShowAmountLeftEditFieldPositive_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_4
        function ShowAmountEditFieldNegative_4ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_4.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_4.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_4.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_4.Value = value;
                    app.ShowAmountRightEditFieldNegative_4.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(3,:) = [app.ShowAmountRightEditFieldNegative_4.Value, app.ShowAmountLeftEditFieldNegative_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_5
        function ShowAmountEditFieldPositive_5ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_5.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_5.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_5.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_5.Value = value;
                    app.ShowAmountRightEditFieldPositive_5.Value = value;
            end

            app.explorer.subscore.vis.showposamount(4,:) = [app.ShowAmountRightEditFieldPositive_5.Value, app.ShowAmountLeftEditFieldPositive_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_5
        function ShowAmountEditFieldNegative_5ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_5.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_5.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_5.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_5.Value = value;
                    app.ShowAmountRightEditFieldNegative_5.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(4,:) = [app.ShowAmountRightEditFieldNegative_5.Value, app.ShowAmountLeftEditFieldNegative_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_6
        function ShowAmountEditFieldPositive_6ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_6.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_6.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_6.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_6.Value = value;
                    app.ShowAmountRightEditFieldPositive_6.Value = value;
            end

            app.explorer.subscore.vis.showposamount(5,:) = [app.ShowAmountRightEditFieldPositive_6.Value, app.ShowAmountLeftEditFieldPositive_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_6
        function ShowAmountEditFieldNegative_6ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_6.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_6.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_6.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_6.Value = value;
                    app.ShowAmountRightEditFieldNegative_6.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(5,:) = [app.ShowAmountRightEditFieldNegative_6.Value, app.ShowAmountLeftEditFieldNegative_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_7
        function ShowAmountEditFieldPositive_7ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_7.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_7.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_7.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_7.Value = value;
                    app.ShowAmountRightEditFieldPositive_7.Value = value;
            end

            app.explorer.subscore.vis.showposamount(6,:) = [app.ShowAmountRightEditFieldPositive_7.Value, app.ShowAmountLeftEditFieldPositive_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_7
        function ShowAmountEditFieldNegative_7ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_7.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_7.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_7.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_7.Value = value;
                    app.ShowAmountRightEditFieldNegative_7.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(6,:) = [app.ShowAmountRightEditFieldNegative_7.Value, app.ShowAmountLeftEditFieldNegative_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_8
        function ShowAmountEditFieldPositive_8ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_8.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_8.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_8.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_8.Value = value;
                    app.ShowAmountRightEditFieldPositive_8.Value = value;
            end

            app.explorer.subscore.vis.showposamount(7,:) = [app.ShowAmountRightEditFieldPositive_8.Value, app.ShowAmountLeftEditFieldPositive_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_8
        function ShowAmountEditFieldNegative_8ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_8.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_8.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_8.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_8.Value = value;
                    app.ShowAmountRightEditFieldNegative_8.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(7,:) = [app.ShowAmountRightEditFieldNegative_8.Value, app.ShowAmountLeftEditFieldNegative_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_9
        function ShowAmountEditFieldPositive_9ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_9.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_9.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_9.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_9.Value = value;
                    app.ShowAmountRightEditFieldPositive_9.Value = value;
            end

            app.explorer.subscore.vis.showposamount(8,:) = [app.ShowAmountRightEditFieldPositive_9.Value, app.ShowAmountLeftEditFieldPositive_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_9
        function ShowAmountEditFieldNegative_9ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_9.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_9.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_9.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_9.Value = value;
                    app.ShowAmountRightEditFieldNegative_9.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(8,:) = [app.ShowAmountRightEditFieldNegative_9.Value, app.ShowAmountLeftEditFieldNegative_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_10
        function ShowAmountEditFieldPositive_10ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_10.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_10.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_10.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_10.Value = value;
                    app.ShowAmountRightEditFieldPositive_10.Value = value;
            end

            app.explorer.subscore.vis.showposamount(9,:) = [app.ShowAmountRightEditFieldPositive_10.Value, app.ShowAmountLeftEditFieldPositive_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_10
        function ShowAmountEditFieldNegative_10ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_10.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_10.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_10.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_10.Value = value;
                    app.ShowAmountRightEditFieldNegative_10.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(9,:) = [app.ShowAmountRightEditFieldNegative_10.Value, app.ShowAmountLeftEditFieldNegative_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive_11
        function ShowAmountEditFieldPositive_11ValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive_11.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive_11.Value = value/2;
                    app.ShowAmountRightEditFieldPositive_11.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive_11.Value = value;
                    app.ShowAmountRightEditFieldPositive_11.Value = value;
            end

            app.explorer.subscore.vis.showposamount(10,:) = [app.ShowAmountRightEditFieldPositive_11.Value, app.ShowAmountLeftEditFieldPositive_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldNegative_11
        function ShowAmountEditFieldNegative_11ValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative_11.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative_11.Value = value/2;
                    app.ShowAmountRightEditFieldNegative_11.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative_11.Value = value;
                    app.ShowAmountRightEditFieldNegative_11.Value = value;
            end

            app.explorer.subscore.vis.shownegamount(10,:) = [app.ShowAmountRightEditFieldNegative_11.Value, app.ShowAmountLeftEditFieldNegative_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_2
        function ShowAmountLeftEditFieldPositive_2ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(1,:) = [app.ShowAmountRightEditFieldPositive_2.Value, app.ShowAmountLeftEditFieldPositive_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_2
        function ShowAmountRightEditFieldPositive_2ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(1,:) = [app.ShowAmountRightEditFieldPositive_2.Value, app.ShowAmountLeftEditFieldPositive_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_2
        function ShowAmountLeftEditFieldNegative_2ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(1,:) = [app.ShowAmountRightEditFieldNegative_2.Value, app.ShowAmountLeftEditFieldNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_2
        function ShowAmountRightEditFieldNegative_2ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(1,:) = [app.ShowAmountRightEditFieldNegative_2.Value, app.ShowAmountLeftEditFieldNegative_2.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_3
        function ShowAmountLeftEditFieldPositive_3ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(2,:) = [app.ShowAmountRightEditFieldPositive_3.Value, app.ShowAmountLeftEditFieldPositive_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_3
        function ShowAmountRightEditFieldPositive_3ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(2,:) = [app.ShowAmountRightEditFieldPositive_3.Value, app.ShowAmountLeftEditFieldPositive_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_3
        function ShowAmountLeftEditFieldNegative_3ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(2,:) = [app.ShowAmountRightEditFieldNegative_3.Value, app.ShowAmountLeftEditFieldNegative_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_3
        function ShowAmountRightEditFieldNegative_3ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(2,:) = [app.ShowAmountRightEditFieldNegative_3.Value, app.ShowAmountLeftEditFieldNegative_3.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_4
        function ShowAmountLeftEditFieldPositive_4ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(3,:) = [app.ShowAmountRightEditFieldPositive_4.Value, app.ShowAmountLeftEditFieldPositive_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_4
        function ShowAmountRightEditFieldPositive_4ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(3,:) = [app.ShowAmountRightEditFieldPositive_4.Value, app.ShowAmountLeftEditFieldPositive_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_4
        function ShowAmountLeftEditFieldNegative_4ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(3,:) = [app.ShowAmountRightEditFieldNegative_4.Value, app.ShowAmountLeftEditFieldNegative_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_4
        function ShowAmountRightEditFieldNegative_4ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(3,:) = [app.ShowAmountRightEditFieldNegative_4.Value, app.ShowAmountLeftEditFieldNegative_4.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_5
        function ShowAmountLeftEditFieldPositive_5ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(4,:) = [app.ShowAmountRightEditFieldPositive_5.Value, app.ShowAmountLeftEditFieldPositive_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_5
        function ShowAmountRightEditFieldPositive_5ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(4,:) = [app.ShowAmountRightEditFieldPositive_5.Value, app.ShowAmountLeftEditFieldPositive_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_5
        function ShowAmountLeftEditFieldNegative_5ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(4,:) = [app.ShowAmountRightEditFieldNegative_5.Value, app.ShowAmountLeftEditFieldNegative_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_5
        function ShowAmountRightEditFieldNegative_5ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(4,:) = [app.ShowAmountRightEditFieldNegative_5.Value, app.ShowAmountLeftEditFieldNegative_5.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_6
        function ShowAmountLeftEditFieldPositive_6ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(5,:) = [app.ShowAmountRightEditFieldPositive_6.Value, app.ShowAmountLeftEditFieldPositive_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_6
        function ShowAmountRightEditFieldPositive_6ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(5,:) = [app.ShowAmountRightEditFieldPositive_6.Value, app.ShowAmountLeftEditFieldPositive_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_6
        function ShowAmountLeftEditFieldNegative_6ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(5,:) = [app.ShowAmountRightEditFieldNegative_6.Value, app.ShowAmountLeftEditFieldNegative_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_6
        function ShowAmountRightEditFieldNegative_6ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(5,:) = [app.ShowAmountRightEditFieldNegative_6.Value, app.ShowAmountLeftEditFieldNegative_6.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_7
        function ShowAmountLeftEditFieldPositive_7ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(6,:) = [app.ShowAmountRightEditFieldPositive_7.Value, app.ShowAmountLeftEditFieldPositive_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_7
        function ShowAmountRightEditFieldPositive_7ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(6,:) = [app.ShowAmountRightEditFieldPositive_7.Value, app.ShowAmountLeftEditFieldPositive_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_7
        function ShowAmountLeftEditFieldNegative_7ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(6,:) = [app.ShowAmountRightEditFieldNegative_7.Value, app.ShowAmountLeftEditFieldNegative_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_7
        function ShowAmountRightEditFieldNegative_7ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(6,:) = [app.ShowAmountRightEditFieldNegative_7.Value, app.ShowAmountLeftEditFieldNegative_7.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_8
        function ShowAmountLeftEditFieldPositive_8ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(7,:) = [app.ShowAmountRightEditFieldPositive_8.Value, app.ShowAmountLeftEditFieldPositive_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_8
        function ShowAmountRightEditFieldPositive_8ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(7,:) = [app.ShowAmountRightEditFieldPositive_8.Value, app.ShowAmountLeftEditFieldPositive_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_8
        function ShowAmountLeftEditFieldNegative_8ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(7,:) = [app.ShowAmountRightEditFieldNegative_8.Value, app.ShowAmountLeftEditFieldNegative_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_8
        function ShowAmountRightEditFieldNegative_8ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(7,:) = [app.ShowAmountRightEditFieldNegative_8.Value, app.ShowAmountLeftEditFieldNegative_8.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_9
        function ShowAmountLeftEditFieldPositive_9ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(8,:) = [app.ShowAmountRightEditFieldPositive_9.Value, app.ShowAmountLeftEditFieldPositive_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_9
        function ShowAmountRightEditFieldPositive_9ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(8,:) = [app.ShowAmountRightEditFieldPositive_9.Value, app.ShowAmountLeftEditFieldPositive_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_9
        function ShowAmountLeftEditFieldNegative_9ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(8,:) = [app.ShowAmountRightEditFieldNegative_9.Value, app.ShowAmountLeftEditFieldNegative_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_9
        function ShowAmountRightEditFieldNegative_9ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(8,:) = [app.ShowAmountRightEditFieldNegative_9.Value, app.ShowAmountLeftEditFieldNegative_9.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_10
        function ShowAmountLeftEditFieldPositive_10ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(9,:) = [app.ShowAmountRightEditFieldPositive_10.Value, app.ShowAmountLeftEditFieldPositive_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_10
        function ShowAmountRightEditFieldPositive_10ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(9,:) = [app.ShowAmountRightEditFieldPositive_10.Value, app.ShowAmountLeftEditFieldPositive_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_10
        function ShowAmountLeftEditFieldNegative_10ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(9,:) = [app.ShowAmountRightEditFieldNegative_10.Value, app.ShowAmountLeftEditFieldNegative_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_10
        function ShowAmountRightEditFieldNegative_10ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(9,:) = [app.ShowAmountRightEditFieldNegative_10.Value, app.ShowAmountLeftEditFieldNegative_10.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive_11
        function ShowAmountLeftEditFieldPositive_11ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(10,:) = [app.ShowAmountRightEditFieldPositive_11.Value, app.ShowAmountLeftEditFieldPositive_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive_11
        function ShowAmountRightEditFieldPositive_11ValueChanged(app, event)
            app.explorer.subscore.vis.showposamount(10,:) = [app.ShowAmountRightEditFieldPositive_11.Value, app.ShowAmountLeftEditFieldPositive_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative_11
        function ShowAmountLeftEditFieldNegative_11ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(10,:) = [app.ShowAmountRightEditFieldNegative_11.Value, app.ShowAmountLeftEditFieldNegative_11.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative_11
        function ShowAmountRightEditFieldNegative_11ValueChanged(app, event)
            app.explorer.subscore.vis.shownegamount(10,:) = [app.ShowAmountRightEditFieldNegative_11.Value, app.ShowAmountLeftEditFieldNegative_11.Value];
            app.draw;
        end

        % Button pushed function: PlotDistributionButton
        function PlotDistributionButtonPushed(app, event)
            switch app.explorer.multitractmode
                case 'Single Tract Analysis'
                    posvisible = app.explorer.posvisible;
                    negvisible = app.explorer.negvisible;
                    showposamount = app.explorer.showposamount;
                    shownegamount = app.explorer.shownegamount;
                    threshstrategy = app.explorer.threshstrategy;

                    app.explorer.posvisible = 1;
                    app.explorer.negvisible = 1;
                    app.explorer.showposamount = [100, 100];
                    app.explorer.shownegamount = [100, 100];
                    app.explorer.threshstrategy = 'Percentage Relative to Peak';

                    selection = app.explorer.patientselection;

                    vals = ea_unifiedmapping_calcstats(app.explorer, selection);

                    app.explorer.posvisible = posvisible;
                    app.explorer.negvisible = negvisible;
                    app.explorer.showposamount = showposamount;
                    app.explorer.shownegamount = shownegamount;
                    app.explorer.threshstrategy = threshstrategy;

                    vals = [vals{1}; vals{2}];
                    posval = vals(vals>0);
                    negval = vals(vals<0);

                    if isempty(posval) && isempty(negval)
                        ea_cprintf('CmdWinWarnings', 'No valid fiber found!\n');
                        return;
                    elseif ~isempty(posval) && ~isempty(negval)
                        rowNum = 2;
                        indOffset = 2;
                    elseif ~isempty(posval)
                        rowNum = 1;
                        indOffset = 2;
                    else
                        rowNum = 1;
                        indOffset = 0;
                    end

                    figure('NumberTitle', 'off', 'Name', 'Fiber Value Distribution', 'MenuBar', 'none');
                    lim = [0.5, 1];

                    if ~isempty(posval)
                        subplot(rowNum, 2, 1);
                        histogram(posval, 'BinWidth', 0.01);
                        title('Histogram - Positive Values');

                        subplot(rowNum, 2, 2);
                        [fx, x] = ecdf(posval);
                        plot(x, fx);
                        ylim(lim);

                        for sample = [0.9, 0.95, 0.99]
                            % Positive vals, vertical line and text
                            line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7]);
                            switch app.explorer.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.25, num2str(x(find(fx>=sample,1)), '%.2f'), 'Rotation', 90, 'Color', [0.7, 0.7, 0.7]);
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.15, ['TH. ', num2str(x(find(fx>=sample,1)), '%.2f')], 'Rotation', 90, 'Color', '#0072BD');
                            end
                            % Positive vals, horizontal line and text
                            line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7])
                            switch app.explorer.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1))*0.25, fx(find(fx>=sample,1)), ['TH. ', num2str(1-fx(find(fx>=sample,1)), '%.2f')], 'Color', '#0072BD');
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1))/2, fx(find(fx>=sample,1)), num2str(1-fx(find(fx>=sample,1)), '%.2f'), 'Color', [0.7, 0.7, 0.7]);
                            end
                        end

                        title('CDF - Positive Values');
                    end

                    if ~isempty(negval)
                        subplot(rowNum, 2, 1+indOffset);
                        histogram(-negval, 'BinWidth', 0.01);
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('Histogram - Negative Values');

                        subplot(rowNum, 2, 2+indOffset);
                        [fx, x] = ecdf(-negval);
                        plot(x, fx);
                        ylim(lim);

                        for sample = [0.9, 0.95, 0.99]
                            % Negative vals, vertical line and text
                            line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7])
                            switch app.explorer.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.25, num2str(-x(find(fx>=sample,1)), '%.2f'), 'Rotation', 90, 'Color', [0.7, 0.7, 0.7]);
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1)), fx(find(fx>=sample,1))/2+0.15, ['TH. ', num2str(-x(find(fx>=sample,1)), '%.2f')], 'Rotation', 90, 'Color', '#0072BD');
                            end
                            % Negative vals, horizontal line and text
                            line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'Color', [0.7, 0.7, 0.7])
                            switch app.explorer.threshstrategy
                                case 'Histogram (CDF)'
                                    text(x(find(fx>=sample,1))*0.25, fx(find(fx>=sample,1)), ['TH. ', num2str(1-fx(find(fx>=sample,1)), '%.2f')], 'Color', '#0072BD');
                                case 'Fixed Fiber Value'
                                    text(x(find(fx>=sample,1))/2, fx(find(fx>=sample,1)), num2str(1-fx(find(fx>=sample,1)), '%.2f'), 'Color', [0.7, 0.7, 0.7]);
                            end
                        end

                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('CDF - Negative Values');
                    end

                case {'Split & Color By PCA', 'Split & Color By Subscore'}
                    posvisible = app.explorer.subscore.posvisible;
                    negvisible = app.explorer.subscore.negvisible;
                    showposamount = app.explorer.subscore.vis.showposamount;
                    shownegamount = app.explorer.subscore.vis.shownegamount;
                    threshstrategy = app.explorer.threshstrategy;


                    app.explorer.subscore.posvisible = ones(size(app.explorer.subscore.posvisible));
                    app.explorer.subscore.negvisible = ones(size(app.explorer.subscore.negvisible));
                    app.explorer.subscore.vis.showposamount = 100*ones(size(app.explorer.subscore.vis.showposamount));
                    app.explorer.subscore.vis.shownegamount = 100*ones(size(app.explorer.subscore.vis.shownegamount));
                    app.explorer.threshstrategy = 'Percentage Relative to Peak';

                    selection = app.explorer.patientselection;

                    vals = ea_discfibers_calcstats(app.explorer, selection);

                    app.explorer.subscore.posvisible = posvisible;
                    app.explorer.subscore.negvisible = negvisible;
                    app.explorer.subscore.vis.showposamount = showposamount;
                    app.explorer.subscore.vis.shownegamount = shownegamount;
                    app.explorer.threshstrategy = threshstrategy;

                    switch app.explorer.multitractmode
                        case 'Split & Color By PCA'
                            vis_colors = app.explorer.subscore.pcacolors;
                            n_tabs = app.explorer.numpcs;
                        case 'Split & Color By Subscore'
                            vis_colors = app.explorer.subscore.colors{1,2};
                            n_tabs = size(app.explorer.subscore.vars, 1);
                    end

                    for ti = 1:n_tabs
                        tempvals{ti} = [vals{ti, 1}; vals{ti, 2}];
                        posval{ti} = tempvals{ti}(tempvals{ti}>0);
                        negval{ti} = tempvals{ti}(tempvals{ti}<0);
                    end

                    if sum(cellfun(@numel, posval))==0 && sum(cellfun(@numel, negval))==0
                        ea_cprintf('CmdWinWarnings', 'No valid fiber found!\n');
                        return;
                    elseif sum(cellfun(@numel, posval))>0 && sum(cellfun(@numel, negval))>0
                        rowNum = 2;
                        indOffset = 2;
                    elseif sum(cellfun(@numel, posval))>0
                        rowNum = 1;
                        indOffset = 2;
                    else
                        rowNum = 1;
                        indOffset = 0;
                    end

                    figure('NumberTitle', 'off', 'Name', 'Fiber Value Distribution', 'MenuBar', 'none');
                    lim = [0.5, 1];

                    if sum(cellfun(@numel, posval))>0
                        subplot(rowNum, 2, 1); hold on
                        for ti = find(cellfun(@numel, posval)>0)
                            histogram(posval{ti}, 'BinWidth', 0.01, 'FaceColor', vis_colors(ti, :), 'FaceAlpha', 0.4)
                        end
                        title('Histogram - Positive Values');

                        subplot(rowNum, 2, 2), hold on
                        for ti = find(cellfun(@numel, posval)>0)
                            [fx, x] = ecdf(posval{ti});
                            plot(x, fx, 'color', vis_colors(ti, :), 'linew', 2);
                            ylim(lim);

                            for sample = [0.9, 0.95, 0.99]
                                % Positive vals, vertical line and text
                                line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color',  vis_colors(ti, :));
                                % Positive vals, horizontal line and text
                                line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color', [.7, .7, .7]);
                            end
                        end
                        title('CDF - Positive Values');
                    end

                    if sum(cellfun(@numel, negval))>0
                        subplot(rowNum, 2, 1+indOffset); hold on
                        for ti = find(cellfun(@numel, negval)>0)
                            histogram(-negval{ti}, 'BinWidth', 0.01, 'FaceColor', vis_colors(ti, :), 'FaceAlpha', 0.4)
                        end
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('Histogram - Negative Values');

                        subplot(rowNum, 2, 2+indOffset), hold on
                        for ti = find(cellfun(@numel, negval)>0)
                            [fx, x] = ecdf(-negval{ti});
                            plot(x, fx, 'color', vis_colors(ti, :), 'linew', 2);
                            ylim(lim);

                            for sample = [0.9, 0.95, 0.99]
                                % Negative vals, vertical line and text
                                line([x(find(fx>=sample,1)), x(find(fx>=sample,1))], [0, fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color', vis_colors(ti, :))
                                % Negative vals, horizontal line and text
                                line([0, x(find(fx>=sample,1))], [fx(find(fx>=sample,1)), fx(find(fx>=sample,1))], 'LineStyle', '--', 'Color', [0.7, 0.7, 0.7])
                            end
                        end
                        labels = xticklabels;
                        labels(2:end) = append('-', labels(2:end));
                        xticklabels(labels);
                        title('CDF - Negative Values');
                    end

                otherwise
                    fprintf(['This has not been implemented yet for ' app.explorer.multitractmode '.\n'])
            end
        end

        % Button pushed function: RefreshViewButton_2
        function RefreshViewButton_2Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_3
        function RefreshViewButton_3Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_4
        function RefreshViewButton_4Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_5
        function RefreshViewButton_5Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_6
        function RefreshViewButton_6Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_7
        function RefreshViewButton_7Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_8
        function RefreshViewButton_8Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_9
        function RefreshViewButton_9Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_10
        function RefreshViewButton_10Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: RefreshViewButton_11
        function RefreshViewButton_11Pushed(app, event)
            app.draw(1);
        end

        % Button pushed function: InspectPCAButton
        function InspectPCAButtonPushed(app, event)
            for i=1:length(app.explorer.subscore.vars)
                selected_subscores{i} = app.explorer.subscore.vars{i}(app.explorer.patientselection);
            end
            data = cell2mat(selected_subscores);
            var_names = app.explorer.subscore.labels;
            op_dir = fullfile(app.explorer.M.root,'fiberfiltering','pcaresults');
            if sum(isnan(data(:)))
                ea_warndlg(['Your data contains NaNs. The data from patients that ' ...
                    'have any NaN value will be ignored in PCA calculation. If this '...
                    'is not what you want, consider handling these values before using PCA.']);
            end
            ea_get_PCA_graphs(data,var_names,op_dir)
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            [FileName,PathName]=uiputfile('*.mat','File to save the Model...');
            if isequal(FileName, 0) || isequal(PathName,0)
                return;
            end
            ExternalModelFile = fullfile(PathName,FileName);
            ss_val = 0;
            ff_val = 0;
            nm_val = 0;
            if isfield(app.explorer.results,'sweetspotmapping')
                if strcmp(app.explorer.activated.sweetspotmapping, 'On')
                    ss_val = 1;
                end
            end
            if isfield(app.explorer.results,'fiberfiltering')
                if strcmp(app.explorer.activated.fiberfiltering, 'On')
                    ff_val = 1;
                end
            end
            if isfield(app.explorer.results,'networkmapping')
                if strcmp(app.explorer.activated.networkmapping, 'On')
                    nm_val = 1;
                end
            end
            app.draw(1);
            ea_unified_save_model(app, ExternalModelFile, ss_val, ff_val, nm_val);
        end

        % Value changed function: LoadModelButton
        function LoadModelButtonValueChanged(app, event)
            [FileName,PathName]=uigetfile('*.mat','Please Choose a Fiber Model for the selected connectome...');
            if strcmp(string(FileName),'0')
                waitfor(msgbox('The Fiber Model was not chosen!'));
                return;
            end

            [~, ExternalModelFileName] = fileparts(app.explorer.ExternalModelFile);
            app.TrainOnListBox.Items = string(ExternalModelFileName); % this does not update on-the-fly
            app.explorer.ExternalModelFile = fullfile(PathName,FileName);
            app.draw(1)
        end

        % Value changed function: SubcohortsDropDown_2
        function SubcohortsDropDown_2ValueChanged(app, event)
            value = app.SubcohortsDropDown_2.Value;
            [~,whichset]=ismember(value,app.explorer.setlabels);
            app.PredictListBox.Value=app.PatientsListBox.Items(app.explorer.setselections{whichset});


            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.explorer.patientselection = ix;


            %             tsets = find(ismember(app.tractset.setlabels, app.TrainOnListBox.Value));
            %             training=[];
            %             for ts=1:length(tsets)
            %                 training = [training,find(app.tractset.setselections{tsets(ts)})];
            %             end
            %             training=unique(training);
            %             tsets = find(ismember(app.tractset.setlabels, app.PredictListBox.Value));
            %             test=[];
            %             for ts=1:length(tsets)
            %                 test = [test,find(app.tractset.setselections{tsets(ts)})];
            %             end
            %             test=unique(test);
            %             % Patient selected based on the training and test indices
            %             app.tractset.customselection = unique([training, test]);
            %             % Construct cvp struct
            %             cvp.training{1} = ismember(app.tractset.customselection, training);
            %             cvp.test{1} = ismember(app.tractset.customselection, test);


            %             app.tractset.customselection = unique([training, test]);
            %             % Construct cvp struct
            %             cvp.training{1} = ismember(app.tractset.customselection, training);
            %             cvp.test{1} = ismember(app.tractset.customselection, test);

            %app.draw;
            % always reset to "Choose..."
            app.ea_unifiedmapping_ui_enablerules;


        end

        % Value changed function: ROISwitch
        function ROISwitchValueChanged(app, event)
            app.explorer.roivisible = strcmp(app.ROISwitch.Value,'Show');
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Value changed function: FibersSwitch
        function FibersSwitchValueChanged(app, event)
            app.explorer.connfibvisible = strcmp(app.FibersSwitch.Value,'Show');
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Menu selected function: OptimizeParametersontrainingsetMenu
        function OptimizeParametersontrainingsetMenuSelected(app, event)

        end

        % Menu selected function: CrossvalidationsMenu
        function CrossvalidationsMenuSelected(app, event)
            ea_discfibers_optimize(app.explorer,app,'cv');
            app.ea_unifiedmapping_explorer2UI;
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Menu selected function: SimilaritiesDissimilaritiesMenu
        function SimilaritiesDissimilaritiesMenuSelected(app, event)
            ea_discfibers_optimize(app.explorer,app,'sim');
            app.ea_unifiedmapping_explorer2UI;
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Menu selected function: CombinedMenu
        function CombinedMenuSelected(app, event)
            ea_discfibers_optimize(app.explorer,app,'comb');
            app.ea_unifiedmapping_explorer2UI;
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Menu selected function: LoadPriorOptimization
        function LoadPriorOptimizationMenuSelected(app, event)
            optFile = uigetfile('*.mat', 'Choose Prior Optmization Status File', fileparts(app.explorer.leadgroup));
            if ischar(optFile)
                ea_discfibers_optimize(app.explorer, app, 'comb', optFile, 'stop');
                app.ea_unifiedmapping_explorer2UI;
                app.ea_unifiedmapping_ui_enablerules;
                app.draw;
            end
        end

        % Menu selected function: LoadPriorOptimizationAndResume
        function LoadPriorOptimizationAndResumeMenuSelected(app, event)
            optFile = uigetfile('*.mat', 'Choose Prior Optmization Status File', fileparts(app.explorer.leadgroup));
            if ischar(optFile)
                ea_discfibers_optimize(app.explorer, app, 'comb', optFile, 'resume');
                app.ea_unifiedmapping_explorer2UI;
                app.ea_unifiedmapping_ui_enablerules;
                app.draw;
            end
        end

        % Callback function
        function OpeninCurrentuneButtonPushed(app, event)
            f = uifigure('Name','Currentune','Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 1030;
            f.Position(4) = 800;
            for i=1:length(app.explorer.subscore.vars)
                slider_name{i} = app.explorer.subscore.labels{i};
            end

            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 690;
            m = 682;

            frame = uipanel(f,'Title', 'Weights', 'FontSize',12, 'Position', [10, m - 10, 550,20]);

            % Current Optimizer
            frame2 = uipanel(f,'Title', 'Current Optimizer', 'FontSize',12, 'Position', [580, 547, 280,200]);

            ConcLbl = uilabel(f,'Text','Current Limits for Cylindrical Contacts, mA','Position',[590,697,300,25],'FontWeight','bold');
            ConcLblMin = uilabel(f,'Text','Min:','Position',[590,667,40,25]);
            ConcMin = uieditfield(f,'numeric','Value',-4.0, 'Position',[630,667,40,25], "ValueChangedFcn",@(ConcMin,event) ConcMinChanged(ConcMin));
            ConcMin.Limits = [-10.0, 10.0];  % m
            ConcLblMax = uilabel(f,'Text','Max:','Position',[700,667,40,25]);
            ConcMax = uieditfield(f,'numeric','Value',4.0, 'Position',[740,667,40,25], "ValueChangedFcn",@(ConcMax,event) ConcMaxChanged(ConcMax));
            ConcMax.Limits = [-10.0, 10.0];  % mA

            SegmLbl = uilabel(f,'Text','Current Limits for Segment Contacts, mA','Position',[590,627,300,25],'FontWeight','bold');
            SegmLblMin = uilabel(f,'Text','Min:','Position',[590,597,40,25]);
            SegmMin = uieditfield(f,'numeric','Value',-2.0, 'Position',[630,597,40,25], "ValueChangedFcn",@(SegmMin,event) SegmMinChanged(SegmMin));
            SegmMin.Limits = [-5.0, 5.0];  % mA
            SegmLblMax = uilabel(f,'Text','Max:','Position',[700,597,40,25]);
            SegmMax = uieditfield(f,'numeric','Value',2.0, 'Position',[740,597,40,25], "ValueChangedFcn",@(SegmMax,event) SegmMaxChanged(SegmMax));
            SegmMax.Limits = [-5.0, 5.0];  % mA


            % create a button for Export and Launch
            ExportSymptomPathwayBTN = uibutton(f,'Text','Export Item-grouped Pathways', 'Position', [10,m+80,200,25], 'ButtonPushedFcn', @(ExportSymptomPathwayBTN,event) ExportSymptomPathwayButtonPushed(ExportSymptomPathwayBTN));
            Launch_NetBlend = uibutton(f,'Text','Launch Network Blender', 'Position', [800,500,200,25], 'ButtonPushedFcn', @(Launch_NetBlend,event) Launch_NetBlendButtonPushed(Launch_NetBlend,slider_name));

            for i=1:length(slider_name)
                %slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text',slider_name{i},'Position',[20,m,180,25],'FontWeight','bold');

                fixed_weights{i} = uicheckbox(f,'Text', 'fixed', 'Position',[200,m,80,25]);

                %base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.explorer.subscore.weights(i))
                    slider_val_name{i}.Value=app.explorer.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.explorer.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    %fixed_weights{i}.Value = app.tractset.subscore.CheckedWeights{i};

                    %base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChangeCurrentune(slider_text_box,slider_val_name,fixed_weights,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChangingCurrentune(event,slider_text_box,slider_val_name,fixed_weights,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChangeCurrentune_text(slider_text_box,slider_val_name,fixed_weights,i);
                fixed_weights{i}.ValueChangedFcn = @(app,event) FixedWeightcBoxChanged(fixed_weights,slider_val_name,Launch_NetBlend,i);

                %base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


            PatientID = uieditfield(f,'text','Value','John Doe', 'Position',[190,m+5,150,25], "Editable","off", "ValueChangedFcn",@(PatientID,event) PatientIDChanged(PatientID));
            ChoosePatient = uibutton(f,'Text','Choose a patient', 'Position', [10,m+5,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) ChoosePatientButtonPushed(ChoosePatient,PatientID));
            PredictImprovement = uibutton(f,'Text','Predict Improvement', 'Position', [380,m+5,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) PredictImprovementButtonPushed(PredictImprovement,fixed_weights,slider_name));
            CurrentOptimizer = uibutton(f,'Text','Optimize Currents', 'Position', [380,m+5-40,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) CurrentOptimizerButtonPushed(CurrentOptimizer,fixed_weights,slider_name));

            GenerateTrainTest = uibutton(f,'Text','Generate Train/Test sets for ANN', 'Position', [580,500,200,25], 'ButtonPushedFcn', @(GenerateTrainTest,event) GenerateTrainTestButtonPushed(GenerateTrainTest,PatientID));

            lower_lim = m - 10 + 60;

            %m = 682;

            % adjust the frame size
            frame.Position(2) = lower_lim;
            frame.Position(4) = 682 + 65 - lower_lim;

            function GenerateTrainTestButtonPushed(GenerateTrainTest, PatientID)
                % we will choose number of samples depending on the
                % electrode model within Network Blending

                % add a sanity check for current limits
                app.explorer.currentune.min_conc = ConcMin.Value;
                app.explorer.currentune.max_conc = ConcMax.Value;
                app.explorer.currentune.min_segm = SegmMin.Value;
                app.explorer.currentune.max_segm = SegmMax.Value;

                if strcmp(PatientID.Value, 'John Doe')
                    ea_warndlg("Choose a patient first.")
                    return
                end

                % for now, hardwired to run in native
                app.explorer.currentune.stimfolder = fullfile(app.explorer.currentune.patient, 'stimulations/native/NB_stim/');
                mkdir(app.explorer.currentune.stimfolder)

                % we need to pass the reconstruction file
                reconstruction_file = dir([app.explorer.currentune.patient,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);

                try
                    reconst = load([app.explorer.currentune.patient,filesep,'reconstruction',filesep,reconstruction_file.name]);
                catch
                    ea_warndlg('The reconstruction file was not found')
                    return
                end

                if length(reconst.reco.props) == 2
                    %sides = 2;  % both sides implanted
                    el_model_right = strrep(reconst.reco.props(1).elmodel, ' ','_');
                    el_model_left = strrep(reconst.reco.props(2).elmodel, ' ','_');
                elseif reconst.reco.mni.markers(1).head(1) > 0.0
                    %sides = 0; % right side
                    el_model_right = strrep(reconst.reco.props(1).elmodel, ' ','_');
                    el_model_left = -1;
                else
                    %sides = 1; % left side
                    el_model_right = -1;
                    el_model_left = strrep(reconst.reco.props(2).elmodel, ' ','_');
                end

                % call .py script
                system(['python', ' ', ea_getearoot, 'currentune/TrainTest_Generator.py ', ...
                    app.explorer.currentune.stimfolder(1:end-1), ' ', el_model_right, ' ', el_model_left, ' ', num2str(app.explorer.currentune.min_conc), ' ', num2str(app.explorer.currentune.max_conc), ' ', num2str(app.explorer.currentune.min_segm), ' ', num2str(app.explorer.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
            end

            function ChoosePatientButtonPushed(ChoosePatient,PatientID)
                [selpath]=uigetdir('*.mat','Please Choose a Patient Folder');
                if strcmp(string(selpath),'0')
                    waitfor(msgbox('No patient folder was not chosen!'));
                    return;
                else
                    app.explorer.currentune.patient = selpath;
                    [folderpath,foldername,~] = fileparts(app.explorer.currentune.patient);
                    PatientID.Value = foldername;
                end
            end

            function ConcMinChanged(ConcMin)
                app.explorer.currentune.min_conc = ConcMin.Value;
            end

            function ConcMaxChanged(ConcMax)
                app.explorer.currentune.max_conc = ConcMax.Value;
            end

            function SegmMinChanged(SegmMin)
                app.explorer.currentune.min_segm = SegmMin.Value;
            end

            function SegmMaxChanged(SegmMax)
                app.explorer.currentune.max_segm = SegmMax.Value;
            end

            function PredictImprovementButtonPushed(PredictImprovement, fixed_weights, slider_name)
                % add a sanity check for current limits
                app.explorer.currentune.min_conc = ConcMin.Value;
                app.explorer.currentune.max_conc = ConcMax.Value;
                app.explorer.currentune.min_segm = SegmMin.Value;
                app.explorer.currentune.max_segm = SegmMax.Value;

                % for now, hardwired to run in native
                app.explorer.currentune.stimfolder = fullfile(app.explorer.currentune.patient, 'stimulations/native/NB_stim/');
                mkdir(app.explorer.currentune.stimfolder)

                % create a json with fixed symptoms and weights
                fixed_weight_flag = 0;
                for i = 1:length(fixed_weights)
                    if fixed_weights{i}.Value == 1
                        jsonDict.fixed_symptom_weights.(genvarname(slider_name{i})) = app.explorer.subscore.weights(i);
                        fixed_weight_flag = fixed_weight_flag + 1;
                    end
                end

                ea_warndlg("Compute PAM for the exported connectome and press OK")

                if fixed_weight_flag == 0
                    ea_warndlg("No symptoms were fixed, please choose at least one")
                    return
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.explorer.currentune.min_conc), ' ', num2str(app.explorer.currentune.max_conc), ' ', num2str(app.explorer.currentune.min_segm), ' ', num2str(app.explorer.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
                else
                    % save where the fiberfiltering file is
                    %[filepath,~,~] = fileparts(app.tractset.leadgroup);

                    json_file = [app.explorer.currentune.stimfolder,'Fixed_symptoms.json'];

                    jsonText = jsonencode(jsonDict);
                    fid = fopen(json_file, 'w');
                    fprintf(fid, '%s', jsonText)
                    fclose(fid);

                    % we can also run OSS-DBS directly from here, but not
                    % for now

                    % IMPORTANT: for now we predict only the right side (0)
                    system(['python', ' ', ea_getearoot, 'currentune/Improvement4Protocol.py ', ...
                        app.explorer.currentune.stimfolder(1:end-1), ' ', num2str(0), ' ', app.explorer.currentune.ActivationProfileDict, ' ', json_file]);	% 0 is right side, 1 is the left side here
                end

            end

            function Launch_NetBlendButtonPushed(Launch_NetBlend, fixed_weights, slider_name)

                % add a sanity check for current limits
                app.explorer.currentune.min_conc = ConcMin.Value;
                app.explorer.currentune.max_conc = ConcMax.Value;
                app.explorer.currentune.min_segm = SegmMin.Value;
                app.explorer.currentune.max_segm = SegmMax.Value;

                % create a json with fixed symptoms and weights
                fixed_weight_flag = 0;
                for i = 1:length(fixed_weights)
                    if fixed_weights{i}.Value == 1
                        jsonDict.fixed_symptom_weights.(genvarname(slider_name{i})) = app.explorer.subscore.weights(i);
                        fixed_weight_flag = fixed_weight_flag + 1;
                    end
                end

                % in this case, throw a warning and run a current
                % optimization only
                if fixed_weight_flag == length(fixed_weights)
                    ea_warndlg("All weights were fixed, only currents will be optimized")
                elseif fixed_weight_flag == 0
                    ea_warndlg("No symptoms were fixed")
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.explorer.currentune.min_conc), ' ', num2str(app.explorer.currentune.max_conc), ' ', num2str(app.explorer.currentune.min_segm), ' ', num2str(app.explorer.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
                else
                    % save where the fiberfiltering file is
                    [filepath,~,~] = fileparts(app.explorer.leadgroup);
                    json_file = [filepath,filesep,'Fixed_symptoms.json'];

                    jsonText = jsonencode(jsonDict);
                    fid = fopen(json_file, 'w');
                    fprintf(fid, '%s', jsonText)
                    fclose(fid);

                    % call NetBlend
                    % pass the connectome and the location of json and ea_getearoot
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.explorer.currentune.min_conc), ' ', num2str(app.explorer.currentune.max_conc), ' ', num2str(app.explorer.currentune.min_segm), ' ', num2str(app.explorer.currentune.max_segm), ' ', json_file]);	% 0 is right side, 1 is the left side here

                end

                % add selection of a stimfolder, some parameters will be
                % taken from there

                % retrieve new weights for non-fixed. Disable their adjustment?

            end

            function FixedWeightcBoxChanged(fixed_weights,slider_val_name,Launch_NetBlend,i)
                %app.tractset.subscore.CheckedWeights{i} = fixed_weights{i}.Value;

                % initialize
                if all(isnan(app.explorer.subscore.weights))
                    app.explorer.subscore.weights(:) = 1 / size(app.explorer.subscore.weights,1);
                end


                N_fixed = 0;
                inx_nonfixed = [];
                for k = 1:length(fixed_weights)
                    app.explorer.currentune.CheckedWeights{k} = fixed_weights{k}.Value;
                    slider_val_name{k}.Enable = 1; % reinitialize
                    Launch_NetBlend.Enable = 1;
                    if fixed_weights{k}.Value == 1
                        N_fixed = N_fixed + 1;
                    else
                        inx_nonfixed = [inx_nonfixed,k];
                    end
                end

                % if all fixed, disable sliders
                if N_fixed == length(fixed_weights)
                    for k = 1:length(fixed_weights)
                        slider_val_name{k}.Enable = 0; % no weights to optimize
                    end
                    Launch_NetBlend.Enable = 0;
                    % if all but one fixed, disable only that slider
                elseif N_fixed == length(fixed_weights) - 1
                    slider_val_name{inx_nonfixed}.Enable = 0; % is only one value in this case
                    Launch_NetBlend.Enable = 0; % no weights to optimize
                end
            end

            function ExportSymptomPathwayButtonPushed(ExportSymptomPathwayBTN)
                [FileName,PathName]=uiputfile('*.json','File to save the symptom-tract dictionary...');
                app.explorer.currentune.ActivationProfileDict = fullfile(PathName,FileName);
                ea_export_symptoms_tracts(app.explorer,app.explorer.currentune.ActivationProfileDict);
            end

            function SliderChangeCurrentune(app,slider_text_box,slider_val_name,fixed_weights,i)
                %change value using sliders
                %remainingamount=1-(slider_val_name{i}.Value/100);

                % change limits to the remainingamount for non-fixed
                % symptoms
                remainingamount = 1;
                idx_bool = zeros(length(fixed_weights),1);
                for k = 1:length(fixed_weights)
                    if k == i || fixed_weights{k}.Value == 1
                        idx_bool(k) = 1;
                        remainingamount = remainingamount - app.explorer.subscore.weights(k);
                    end
                end
                idx_bool = logical(idx_bool);


                % enforce limits on adjusted if all other fixed
                if all(idx_bool)
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    slider_val_name{k}.Limits(2) = ceil(1 - ea_nansum(app.explorer.subscore.weights(nonadjusted)));
                else
                    for k = 1:length(idx_bool)
                        if ~idx_bool(k)
                            slider_val_name{k}.Limits(2) = ceil(remainingamount * 100);
                        end
                    end
                end

                otherentries=1:length(slider_val_name);
                %otherentries(i)=[];
                otherentries(idx_bool) = [];

                weights=app.explorer.subscore.weights;
                %                 if ~all(idx_bool)
                %                     weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                %
                %                     nonadjusted = 1:length(slider_val_name);
                %                     nonadjusted(i)=[];
                %                     weights(i)= remainingamount - ea_nansum(weights(nonadjusted));
                %                 end
                %                 %weights(i)=slider_val_name{i}.Value/100;

                if ~all(idx_bool)
                    % some weights are free for auto-adjustment
                    weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                    weights(i)=event.Value/100;
                else
                    % special case if all but one fixed
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    weights(i)= remainingamount - floor(ea_nansum(weights(nonadjusted)));
                end

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.explorer.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end

                app.draw;
            end

            function SliderChangingCurrentune(event,slider_text_box,slider_val_name,fixed_weights,i)


                %remainingamount=1-(event.Value/100);


                % change limits to the remainingamount for non-fixed
                % symptoms
                remainingamount = 1;
                idx_bool = zeros(length(fixed_weights),1);
                for k = 1:length(fixed_weights)
                    if k == i || fixed_weights{k}.Value == 1
                        idx_bool(k) = 1;
                        remainingamount = remainingamount - app.explorer.subscore.weights(k);
                    end
                end
                idx_bool = logical(idx_bool);

                % enforce limits on adjusted if all other fixed
                if all(idx_bool)
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    slider_val_name{k}.Limits(2) = ceil(1 - ea_nansum(app.explorer.subscore.weights(nonadjusted)));
                else
                    for k = 1:length(idx_bool)
                        if ~idx_bool(k)
                            slider_val_name{k}.Limits(2) = ceil(remainingamount * 100);
                        end
                    end
                end

                otherentries=1:length(slider_val_name);
                %otherentries(i)=[];
                otherentries(idx_bool) = [];
                weights=app.explorer.subscore.weights;

                if ~all(idx_bool)
                    % some weights are free for auto-adjustment
                    weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                    weights(i)=event.Value/100;
                else
                    % special case if all but one fixed
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    weights(i)= remainingamount - floor(ea_nansum(weights(nonadjusted)));
                end
                %weights(i)=event.Value/100;


                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.explorer.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
            end


            function SliderChangeCurrentune_text(slider_text_box,slider_val_name,i)

                text_value = slider_text_box{i}.Value;
                remainingamount=1-(text_value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.explorer.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_text_box{slider}.Value=weights(slider)*100;
                        app.explorer.subscore.weights(slider) = [weights(slider)];
                        slider_val_name{slider}.Value = weights(slider)*100;
                    end
                end
                app.draw;
            end

        end

        % Button pushed function: RefineFiberColor
        function RefineFiberColorButtonPushed(app, event)
            ea_unifiedmapping_refinefibercolor(app.explorer.drawobject, app.explorer.drawvals);
            % ea_discfibers_refinefibercolor(app.explorer.drawobject, app.explorer.drawvals);
        end

        % Menu selected function: LoadSettingsMenu
        function LoadSettingsMenuSelected(app, event)

            % Load the settings
            [file,path] = uigetfile({"*.mat";'.'},...
                "Select settings .mat File");
            loaded_tractset = load(fullfile(path, file));

            % Update app settings
            app.explorer = ea_unifiedmapping_update_settings(app.explorer,loaded_tractset);

            % Update settings on GUI
            app.ea_unifiedmapping_tractset2UI

        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)

            %Let the user choose the file to save at
            [file,path,~] = uiputfile({'*.mat';'*.*'},"Save as");

            % Save the settings
            ea_unifiedmapping_save_settings(app.explorer,fullfile(path,file))

        end

        % Callback function
        function SampleSwitchValueChanged(app, event)
            value = app.SampleSwitch.Value;
            app.explorer.twoSampleWeighted = value;
            app.draw;
        end

        % Callback function
        function EFSigmoidCheckBoxValueChanged(app, event)
            value = app.EFSigmoidCheckBox.Value;
            app.explorer.SigmoidTransform = value;
            app.draw;
        end

        % Button pushed function: WeighttractsbyROIButton
        function WeighttractsbyROIButtonPushed(app, event)
            [roiFilename,PathName]=uigetfile('*.nii','Please Choose Nifti file...');
            if strcmp(string(roiFilename),'0')
                waitfor(msgbox('The Fiber Model was not chosen!'));
                return;
            end
            app.WeighttractsbyROIButton.Text = 'Loaded';
            app.explorer.roiintersectdata = {fullfile(PathName,roiFilename)};
            app.explorer.roithresh = app.ThresholdEditField_2.Value;
            app.explorer.activateby = 'roiintersect';
            app.draw;
        end

        % Value changed function: ThresholdEditField_2
        function ThresholdEditField_2ValueChanged(app, event)
            app.explorer.roithresh = app.ThresholdEditField_2.Value;
            app.explorer.activateby = 'roiintersect';
            app.draw;
        end

        % Value changed function: StimulationModel
        function StimulationModelValueChanged(app, event)
            app.StimulationModel.Value = event.Value;
            app.explorer.statsettings.stimulationmodel=event.Value;
            switch event.Value
                case 'Electric Field'
                    app.Efieldmetric.Visible=true;
                    app.Efieldmetric.Enable=true;
                    app.explorer.statsettings.efieldmetric=app.Efieldmetric.Value;
                    app.explorer.SigmoidTransform = 0;
                    app.explorer.statsettings.statfamily = 'Correlations';
                    app.explorer.statsettings.stattest = 'Spearman';
                case 'Sigmoid Field'
                    app.Efieldmetric.Visible=false;
                    app.Efieldmetric.Value='Peak';
                    app.explorer.SigmoidTransform = 1;
                case 'VTA'
                    app.Efieldmetric.Visible=false;
                    app.Efieldmetric.Value='Peak';
                    app.explorer.SigmoidTransform = 0;
                    app.explorer.statsettings.statfamily = '1 - Sample T-Test';
                    app.explorer.statsettings.stattest = 'Spearman';
            end
            app.enforcestatrules;
            app.setefieldsliders;
            app.ea_unifiedmapping_ui_enablerules;
            app.pushstatsettings;
            app.draw;
        end

        % Value changed function: StatFamilyDropDown
        function StatFamilyDropDownValueChanged(app, event)
            app.StatFamilyDropDown.Value = event.Value;
            app.explorer.statsettings.statfamily = event.Value;
            app.enforcestatrules;
            
            app.pushstatsettings;
            app.draw;
        end

        % Value changed function: StatTestDropDown
        function StatTestDropDownValueChanged(app, event)
            app.explorer.statsettings.stattest=event.Value;
            app.pushstatsettings;
            app.draw;

        end

        % Value changed function: StatH0DropDown
        function StatH0DropDownValueChanged(app, event)
            app.StatH0DropDown.Value=event.Value;
            app.explorer.statsettings.H0 = event.Value;
            app.pushstatsettings;
            app.draw;

        end

        % Callback function
        function CalculateinNativeButtonPushed(app, event)
            
            space = 'native';

            % get VAT list
            if isfield(app.explorer.M,'pseudoM')
                ea_warndlg("The option is not supported for pseudoM");
                return
            else
                vatlist = ea_unifiedmapping_getvats(app.explorer);
            end
            
            % warp connectome to native space and compute E-field metrics
            % there
            ea_unifiedmapping_getEproj(app.explorer,NaN,space)

            if app.explorer.multi_pathways == 1
                [filepath,~,~] = fileparts(app.explorer.leadgroup);
                cfile = [filepath,filesep,app.explorer.calcsettings.fibfilt_connectome,filesep,'merged_pathways.mat'];
            else
                cfile = [ea_getconnectomebase('dMRI'), app.explorer.calcsettings.fibfilt_connectome, filesep, 'data.mat'];
            end

            % load e-field projection metrics 
            [fibsvalBin_proj, fibsvalSum_proj, fibsvalMean_proj, fibsvalPeak_proj, fibsval5Peak_proj, fibcell_proj, connFiberInd_proj,fibsvalBin_magn, fibsvalSum_magn, fibsvalMean_magn, fibsvalPeak_magn, fibsval5Peak_magn, fibcell_magn, connFiberInd_magn, totalFibers] = ea_discfibers_native_calcvals(vatlist, cfile, app.explorer.calcsettings.calcthreshold, app.explorer);

            % update connectivity results
            % this should be stored in a separate struct in the future
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).totalFibers = totalFibers; % total number of fibers in the connectome to work with global indices
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('VAT_Ttest').fibsval = fibsvalBin_magn;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_sum').fibsval = fibsvalSum_magn;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_mean').fibsval = fibsvalMean_magn;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_peak').fibsval = fibsvalPeak_magn;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_5peak').fibsval = fibsval5Peak_magn;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('plainconn').fibsval = fibsvalBin_magn;
            
            % for now stay with magnitude fibcell (fibers of E-proj is always a subset of E-magn if the same threshold is used!)
            if strcmp(app.explorer.e_field_metric,'Magnitude')
                app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_fibers').fibcell = fibcell_magn;
                app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).connFiberInd_VAT = connFiberInd_magn; % old fiberfiltering files do not have these data and will fail when using pathway atlases
            else
                app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_fibers').fibcell = fibcell_proj;
                app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).connFiberInd_VAT = connFiberInd_proj; % old fiberfiltering files do not have these data and will fail when using pathway atlases
            end
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).fibcell = app.explorer.results.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_fibers').fibcell;

            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('VAT_Ttest_proj').fibsval = fibsvalBin_proj;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_proj_sum').fibsval = fibsvalSum_proj;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_proj_mean').fibsval = fibsvalMean_proj;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_proj_peak').fibsval = fibsvalPeak_proj;
            app.explorer.results.tractset.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('efield_proj_5peak').fibsval = fibsval5Peak_proj;
            app.explorer.results.fiberfiltering.(ea_unifiedmapping_conn2connid(app.explorer.calcsettings.fibfilt_connectome)).('plainconn_proj').fibsval = fibsvalBin_proj;

          
        end

        % Selection changed function: EfieldmetricButtonGroup
        function EfieldmetricButtonGroupSelectionChanged(app, event)
            selectedButton = app.EfieldmetricButtonGroup.SelectedObject;
            app.explorer.e_field_metric = selectedButton.Text;
        end

        % Callback function
        function ToolsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToolsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Sweetspot')
                %rules for display
                app.ConnectomeDropDown_2.Enable = 'off';
                app.ConnectomeDropDown_2Label.Enable = 'off';
                app.ThresholdEditField_2.Enable = 'off';
                app.ThresholdEditField_2Label.Enable = 'off';
                app.ResolutionDropDown.Enable = 'off';
                app.ResolutionDropDownLabel.Enable = 'off';
                app.CalculateinNativeButton_2.Enable = 'off';
            elseif strcmp(selectedButton.Text, 'Fiberfiltering')
                %rules for display
                app.ConnectomeDropDown_2.Enable = 'on';
                app.ConnectomeDropDown_2Label.Enable = 'on';
                app.ThresholdEditField_2.Enable = 'on';
                app.ThresholdEditField_2Label.Enable = 'on';
                app.ResolutionDropDown.Enable = 'off';
                app.ResolutionDropDownLabel.Enable = 'off';
                app.CalculateinNativeButton_2.Enable = 'on';
            elseif strcmp(selectedButton.Text,'Networkmapping')
                %rules for display
                app.ConnectomeDropDown_2.Enable = 'on';
                app.ConnectomeDropDown_2Label.Enable = 'on';
                app.ThresholdEditField_2.Enable = 'off';
                app.ThresholdEditField_2Label.Enable = 'off';
                app.ResolutionDropDown.Enable = 'on';
                app.ResolutionDropDownLabel.Enable = 'on';
                app.OpenSettingsButton.Enable = 'on';
                app.CalculateinNativeButton_2.Enable = 'off';
            end


        end

        % Callback function
        function OpenSettingsButtonPushed(app, event)

        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
        end

        % Value changed function: SweetspotSwitch
        function SweetspotSwitchValueChanged(app, event)
            app.explorer.activated.sweetspotmapping = app.SweetspotSwitch.Value;
            app.draw;
        end

        % Value changed function: FiberfilteringSwitch
        function FiberfilteringSwitchValueChanged(app, event)
            app.explorer.activated.fiberfiltering = app.FiberfilteringSwitch.Value;

            if strcmp(app.FiberfilteringSwitch.Value, 'On')
                app.RefineFiberColor.Enable = 'on';
            else
                app.RefineFiberColor.Enable = 'off';
            end

            app.draw;
        end

        % Value changed function: NetworkmappingSwitch
        function NetworkmappingSwitchValueChanged(app, event)
            app.explorer.activated.networkmapping = app.NetworkmappingSwitch.Value;
            app.draw;
        end

        % Value changed function: SettingsDropDown
        function SettingsDropDownValueChanged(app, event)
            value = app.SettingsDropDown.Value;
            
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.explorer.save;
            app.explorer.saveObjectToJson;
            app.populateSettingsJson()
        end

        % Button pushed function: OpenSettingsButton
        function OpenSettingsButtonPushed2(app, event)
            setappdata(app.explorer.resultfig, 'calcsettings_saved', false);
            waitfor(ea_calculate_unifiedmapping(app.explorer));
            didSave = getappdata(app.explorer.resultfig, 'calcsettings_saved');
            if ~didSave
                return;
            end
            % Pull moved fields from explorer object into main UI controls
            app.pullSettingsFromExplorerForMovedFields();
            % Apply existing logic (must not overwrite moved fields)
            app.enforcestatrules;
            app.setefieldsliders;
            app.ea_unifiedmapping_ui_enablerules;
            % Leave OFF unless pushstatsettings is cleaned
            % app.pushstatsettings;
            app.draw;

        end

        % Value changed function: NetMapVisualizeDropDown
        function NetMapVisualizeDropDownValueChanged(app, event)
            value = app.NetMapVisualizeDropDown.Value;
            app.explorer.vizmode = value;
            if strcmp(value,'Surface (Surfice)')
                app.AutoRefreshCheckBox.Value = false;
            end
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Value changed function: NetMapModelDropDown
        function NetMapModelDropDownValueChanged2(app, event)
            value = app.NetMapModelDropDown.Value;
            app.explorer.model = value;
            app.ea_unifiedmapping_ui_enablerules;
            app.draw;
        end

        % Callback function
        function SmoothFingerPrintsCheckBoxValueChanged(app, event)
            value = app.SmoothFingerPrintsCheckBox.Value;
            app.explorer.smooth_fp = app.SmoothFingerPrintsCheckBox.Value;
            
        end

        % Size changed function: InteractiveModelSetupPanel
        function InteractiveModelSetupPanelSizeChanged(app, event)
            position = app.InteractiveModelSetupPanel.Position;
            
        end

        % Callback function
        function NormalizeFingerPrintsCheckBoxValueChanged(app, event)
            value = app.NormalizeFingerPrintsCheckBox.Value;
            app.explorer.normalize_fp = app.NormalizeFingerPrintsCheckBox.Value;
        end

        % Value changed function: MaskDropDown
        function MaskDropDownValueChanged(app, event)
            value = app.MaskDropDown.Value;
           
            switch value
                case 'Custom Equation'
                    eq=inputdlg('Enter equation (X = Image to be masked):','Equation',1,{'X>ea_nanmean(X)+ea_nanstd(X)'});
                    app.explorer.cvmask=['=Eq:',eq{1}];
                case 'Custom Image'
                    [file,path]=uigetfile({'*.nii','*.gz'},'Please Choose Mask Image File...');
                    app.explorer.cvmask=['=Im:',fullfile(path,file)];
                otherwise
                    app.explorer.cvmask=value;
            end
        end

        % Value changed function: ExportDropDown
        function ExportDropDownValueChanged(app, event)
            value = app.ExportDropDown.Value;
            switch value
                case 'Sweetspot as NIfTI...'
                    [file,path]=uiputfile('*.nii','Save Model as...','model.nii');
                    if isnumeric(file)
                        return;
                    end
                    app.explorer.draw;
                    
                    if length(app.explorer.drawobject.sweetspotmapping)==2
                        suffx={'_r','_l'};
                    else
                        suffx={''};
                    end
                    

                    exportfns={};
                    for exp=1:length(app.explorer.drawobject.sweetspotmapping)
                        expfile=app.explorer.drawobject.sweetspotmapping{exp}{1}.nii;
                        expfile.fname=fullfile(path,[ea_stripext(file),suffx{exp},'.nii']);
                        ea_write_nii(expfile);
                        exportfns{exp}=expfile.fname;
                    end
                    prefs=ea_prefs;
                    ea_sum_niftis_to_union_bbox(fullfile(path,[ea_stripext(file),'.nii']), exportfns, repmat(app.explorer.statsettings.sweetspotresolution,1,3),'linear');
            end
            app.ExportDropDown.Value='Modality...'; % reset
        end

        % Callback function
        function NMVisualizationDropDownValueChanged(app, event)

        end

        % Callback function
        function NMModelDropDownValueChanged(app, event)

        end

        % Value changed function: LHCheckBox
        function LHCheckBoxValueChanged(app, event)
            value = app.LHCheckBox.Value;
            app.explorer.NMviz.modelLH = value;
            app.draw;
        end

        % Value changed function: RHCheckBox
        function RHCheckBoxValueChanged(app, event)
            value = app.RHCheckBox.Value;
            app.explorer.NMviz.modelRH = value;
            app.draw;
        end

        % Button pushed function: CalculateButton_Sweetspot
        function CalculateButton_SweetspotPushed(app, event)
            selectedTool = 1;
            app.explorer.calcsettings.selectedTool = selectedTool;
            app.explorer.calculate
            % save
            app.explorer.save;
            app.ea_unifiedmapping_ui_enablerules;

        end

        % Button pushed function: CalculateButton_Fiberfiltering
        function CalculateButton_FiberfilteringPushed(app, event)
            selectedTool = 2;
            app.explorer.calcsettings.selectedTool = selectedTool;

            app.explorer.calcsettings.fibfilt_connectome = app.ConnectomeFiberfilteringDropDown.Value;
            if exist(fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeFiberfilteringDropDown.Value),'dir') %this is inside the dMRI_MultiTract folder
                if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeFiberfilteringDropDown.Value),'dir') %same connectome exists in both
                    warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
                    app.explorer.calcsettings.multi_pathways = 0;
                else
                    %if it only exists in the multitract pathway, then
                    %check once more for the multiple .mat files and
                    %set app.tractset.multi_pathways = 1;
                    mt_connectome = fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeFiberfilteringDropDown.Value);
                    mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
                    mt_files = {mt_connectome_dir.name};
                    if length(mt_files) > 1
                        app.explorer.calcsettings.multi_pathways = 1;
                    else
                        app.explorer.calcsettings.multi_pathways = 0;
                    end
                end
            else
                app.explorer.calcsettings.multi_pathways = 0;
            end

            app.explorer.calculate
            app.explorer.save;
            app.ea_unifiedmapping_ui_enablerules;


        end

        % Button pushed function: CalculateButton_Networkmapping
        function CalculateButton_NetworkmappingPushed2(app, event)
            selectedTool = 3;
            app.explorer.calcsettings.selectedTool = selectedTool;
            app.explorer.calcsettings.netmap_connectome = app.ConnectomeNetworkmappingDropDown.Value;
            app.explorer.calculate
            app.explorer.save;
            app.ea_unifiedmapping_ui_enablerules;

        end

        % Value changed function: ConnectomeFiberfilteringDropDown
        function ConnectomeFiberfilteringDropDownValueChanged(app, event)
            value = app.ConnectomeFiberfilteringDropDown.Value;
            app.explorer.calcsettings.fibfilt_connectome = value;
            app.ea_unifiedmapping_ui_enablerules(app);
            val=1;
            if ~isfield(app.explorer.results.fiberfiltering,ea_conn2connid(app.explorer.calcsettings.fibfilt_connectome))
                val=0;
                app.CalculateButton_Fiberfiltering.BackgroundColor=[1 0.6 0.2];
            end
            if val % already calculated
                app.draw;
            end

        end

        % Value changed function: ConnectomeNetworkmappingDropDown
        function ConnectomeNetworkmappingDropDownValueChanged(app, event)
            value = app.ConnectomeNetworkmappingDropDown.Value;
            app.explorer.calcsettings.netmap_connectome = value;
            app.ea_unifiedmapping_ui_enablerules(app);
            val=1;
            if ~isfield(app.explorer.results,ea_conn2connid(app.explorer.calcsettings.netmap_connectome))
                val=0;
                app.CalculateButton_Networkmapping.BackgroundColor=[1 0.5 0];
            end
            if val % already calculated
                app.draw;
            end
        end

        % Button pushed function: OpenanalysisButton
        function OpenanalysisButtonPushed(app, event)
            [FileName, PathName] = uigetfile({'*.explorer;*.mat','Unified Mapping files (*.explorer, *.mat)'}, ...
            'Please choose a file for Unified Mapping analysis (.explorer or .mat)');

            if isnumeric(FileName) && FileName == 0
                return;
            end

            try
                startupFcn(app, fullfile(PathName, FileName));              
            catch ME
                % complain like a civilized program
                disp(ME.message);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.isbusy(1,'saving');
            app.explorer.save;
            app.isbusy(0);
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            % Open Save As dialog
            [filename, pathname] = uiputfile( ...
                '*.explorer', ...
                'Save Explorer File As', ...
                app.explorer.ID + ".explorer");
            
            % User cancelled the dialog
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            
            saveas = fullfile(pathname, filename);
            
            app.isbusy(1, 'saving');
            app.explorer.save(saveas);
            app.isbusy(0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshinformationfromLeadGroupanalysisMenu = uimenu(app.ToolsMenu);
            app.RefreshinformationfromLeadGroupanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshinformationfromLeadGroupanalysisMenuSelected, true);
            app.RefreshinformationfromLeadGroupanalysisMenu.Text = 'Refresh information from Lead-Group analysis';
            app.RecreatevisualizationfigureMenu = uimenu(app.ToolsMenu);
            app.RecreatevisualizationfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @RecreatevisualizationfigureMenuSelected, true);
            app.RecreatevisualizationfigureMenu.Text = 'Recreate visualization figure';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.OptimizeParametersontrainingsetMenu = uimenu(app.ToolsMenu);
            app.OptimizeParametersontrainingsetMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizeParametersontrainingsetMenuSelected, true);
            app.OptimizeParametersontrainingsetMenu.Text = 'Optimize Parameters (on training set)';
            app.CrossvalidationsMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.CrossvalidationsMenu.MenuSelectedFcn = createCallbackFcn(app, @CrossvalidationsMenuSelected, true);
            app.CrossvalidationsMenu.Text = 'Crossvalidations';
            app.SimilaritiesDissimilaritiesMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.SimilaritiesDissimilaritiesMenu.MenuSelectedFcn = createCallbackFcn(app, @SimilaritiesDissimilaritiesMenuSelected, true);
            app.SimilaritiesDissimilaritiesMenu.Text = 'Similarities/Dissimilarities';
            app.CombinedMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.CombinedMenu.MenuSelectedFcn = createCallbackFcn(app, @CombinedMenuSelected, true);
            app.CombinedMenu.Text = 'Combined';
            app.LoadPriorOptimization = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.LoadPriorOptimization.MenuSelectedFcn = createCallbackFcn(app, @LoadPriorOptimizationMenuSelected, true);
            app.LoadPriorOptimization.Text = 'Load Pior Optimization';
            app.LoadPriorOptimizationAndResume = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.LoadPriorOptimizationAndResume.MenuSelectedFcn = createCallbackFcn(app, @LoadPriorOptimizationAndResumeMenuSelected, true);
            app.LoadPriorOptimizationAndResume.Text = 'Load Pior Optimization and Resume';
            app.LoadSettingsMenu = uimenu(app.ToolsMenu);
            app.LoadSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsMenuSelected, true);
            app.LoadSettingsMenu.Text = 'Load Settings';
            app.SaveSettingsMenu = uimenu(app.ToolsMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.OpenSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.OpenSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSettingsButtonPushed2, true);
            app.OpenSettingsButton.Enable = 'off';
            app.OpenSettingsButton.Text = 'Open Settings ';
            app.ConnectomeFiberfilteringDropDownLabel = uilabel(app.SettingsPanel);
            app.ConnectomeFiberfilteringDropDownLabel.Enable = 'off';
            app.ConnectomeFiberfilteringDropDownLabel.Text = 'Connectome Fiberfiltering';
            app.ConnectomeFiberfilteringDropDown = uidropdown(app.SettingsPanel);
            app.ConnectomeFiberfilteringDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeFiberfilteringDropDownValueChanged, true);
            app.ConnectomeFiberfilteringDropDown.Enable = 'off';
            app.ConnectomeNetworkmappingDropDownLabel = uilabel(app.SettingsPanel);
            app.ConnectomeNetworkmappingDropDownLabel.Enable = 'off';
            app.ConnectomeNetworkmappingDropDownLabel.Text = 'Connectome Networkmapping';
            app.ConnectomeNetworkmappingDropDown = uidropdown(app.SettingsPanel);
            app.ConnectomeNetworkmappingDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeNetworkmappingDropDownValueChanged, true);
            app.ConnectomeNetworkmappingDropDown.Enable = 'off';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.InteractiveModelSetupPanel.SizeChangedFcn = createCallbackFcn(app, @InteractiveModelSetupPanelSizeChanged, true);
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.Limits = [0 49];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts/Voxels should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.EfieldthresholdSlider.Limits = [1 40];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 2.5;
            app.EfieldthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts/Voxels are modulated > '; 'sum E-Field of  V/m'};
            app.CovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesCheckBoxValueChanged, true);
            app.CovariatesCheckBox.Enable = 'off';
            app.CovariatesCheckBox.Text = 'Covariates';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.ROISwitchLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ROISwitchLabel.Enable = 'off';
            app.ROISwitchLabel.Text = 'ROI';
            app.ROISwitch = uiswitch(app.InteractiveModelSetupPanel, 'slider');
            app.ROISwitch.Items = {'Hide', 'Show'};
            app.ROISwitch.ValueChangedFcn = createCallbackFcn(app, @ROISwitchValueChanged, true);
            app.ROISwitch.Enable = 'off';
            app.ROISwitch.Value = 'Hide';
            app.ConnectedFibersLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnectedFibersLabel.Enable = 'off';
            app.ConnectedFibersLabel.Text = 'Fibers';
            app.FibersSwitch = uiswitch(app.InteractiveModelSetupPanel, 'slider');
            app.FibersSwitch.Items = {'Hide', 'Show'};
            app.FibersSwitch.ValueChangedFcn = createCallbackFcn(app, @FibersSwitchValueChanged, true);
            app.FibersSwitch.Enable = 'off';
            app.FibersSwitch.Value = 'Hide';
            app.StimulationModel = uidropdown(app.InteractiveModelSetupPanel);
            app.StimulationModel.Items = {'Electric Field', 'Sigmoid Field', 'VTA'};
            app.StimulationModel.ValueChangedFcn = createCallbackFcn(app, @StimulationModelValueChanged, true);
            app.StimulationModel.Enable = 'off';
            app.StimulationModel.Visible = 'off';
            app.StimulationModel.Value = 'Electric Field';
            app.StatH0DropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatH0DropDown.Items = {'Average', 'Zero'};
            app.StatH0DropDown.ValueChangedFcn = createCallbackFcn(app, @StatH0DropDownValueChanged, true);
            app.StatH0DropDown.Enable = 'off';
            app.StatH0DropDown.Value = 'Average';
            app.StatTestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatTestDropDown.Items = {'Spearman', 'Pearson', 'N Image', 'Mean Image'};
            app.StatTestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatTestDropDownValueChanged, true);
            app.StatTestDropDown.Enable = 'off';
            app.StatTestDropDown.Value = 'Spearman';
            app.StatFamilyDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatFamilyDropDown.Items = {'Correlations', '1-Sample Tests', '2-Sample Tests'};
            app.StatFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @StatFamilyDropDownValueChanged, true);
            app.StatFamilyDropDown.Enable = 'off';
            app.StatFamilyDropDown.Value = 'Correlations';
            app.StimulationModelLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StimulationModelLabel.Enable = 'off';
            app.StimulationModelLabel.Visible = 'off';
            app.StimulationModelLabel.Text = 'Stimulation Model';
            app.StatH0Label = uilabel(app.InteractiveModelSetupPanel);
            app.StatH0Label.Enable = 'off';
            app.StatH0Label.Text = 'Against';
            app.StatTestLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatTestLabel.Enable = 'off';
            app.StatTestLabel.Text = 'Statistical Test';
            app.StatFamilyLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatFamilyLabel.Enable = 'off';
            app.StatFamilyLabel.Text = 'Test Family';
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.EfieldmetricButtonGroup = uibuttongroup(app.InteractiveModelSetupPanel);
            app.EfieldmetricButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EfieldmetricButtonGroupSelectionChanged, true);
            app.EfieldmetricButtonGroup.Enable = 'off';
            app.EfieldmetricButtonGroup.Title = 'E-field metric';
            app.EfieldmetricButtonGroup.Visible = 'off';
            app.MagnitudeButton = uiradiobutton(app.EfieldmetricButtonGroup);
            app.MagnitudeButton.Enable = 'off';
            app.MagnitudeButton.Visible = 'off';
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.ProjectionButton = uiradiobutton(app.EfieldmetricButtonGroup);
            app.ProjectionButton.Enable = 'off';
            app.ProjectionButton.Visible = 'off';
            app.ProjectionButton.Text = 'Projection';
            app.Efieldmetric = uidropdown(app.InteractiveModelSetupPanel);
            app.Efieldmetric.Items = {'Sum', 'Mean', 'Peak', 'Peak 5%'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Visible = 'off';
            app.Efieldmetric.Value = 'Sum';
            app.SettingsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SettingsDropDownLabel.Enable = 'off';
            app.SettingsDropDownLabel.Text = 'Settings';
            app.SettingsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @SettingsDropDownValueChanged, true);
            app.SettingsDropDown.Enable = 'off';
            app.SaveSettingsButton = uibutton(app.InteractiveModelSetupPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Enable = 'off';
            app.SaveSettingsButton.Visible = 'off';
            app.SaveSettingsButton.Text = 'Save Settings';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)', 'Import Model'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Enable = 'off';
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores', 'Histogram', 'z-scored Histogram'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Enable = 'off';
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ModelNormalizationDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDownLabel.Enable = 'off';
            app.ModelNormalizationDropDownLabel.Text = 'Model Normalization';
            app.ModelNormalizationDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDown.Items = {'None', 'z-score', 'van Albada 2007'};
            app.ModelNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelNormalizationDropDownValueChanged, true);
            app.ModelNormalizationDropDown.Enable = 'off';
            app.ModelNormalizationDropDown.Value = 'None';
            app.BinsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Visible = 'off';
            app.BinsEditFieldLabel.Text = '#Bins';
            app.BinsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Visible = 'off';
            app.BinsEditField.Value = 15;
            app.FittoscoresCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.FittoscoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @FittoscoresCheckBoxValueChanged, true);
            app.FittoscoresCheckBox.Enable = 'off';
            app.FittoscoresCheckBox.Text = 'Fit to scores';
            app.ModeltypeDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModeltypeDropDown.Items = {'Constant', 'Linear', 'Quadratic'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Enable = 'off';
            app.ModeltypeDropDown.Value = 'Linear';
            app.MultitractWeightsButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.MultitractWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @MultitractWeightsButtonPushed, true);
            app.MultitractWeightsButton.Enable = 'off';
            app.MultitractWeightsButton.Text = 'Multitract Weights...';
            app.IterationsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.IterationsEditFieldLabel.Enable = 'off';
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Enable = 'off';
            app.NestedCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.NestedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NestedCheckBoxValueChanged, true);
            app.NestedCheckBox.Enable = 'off';
            app.NestedCheckBox.Text = 'Nested';
            app.LoadModelButton = uibutton(app.CrossvalidationPredictionPanel, 'state');
            app.LoadModelButton.ValueChangedFcn = createCallbackFcn(app, @LoadModelButtonValueChanged, true);
            app.LoadModelButton.Visible = 'off';
            app.LoadModelButton.Text = 'Load Model';
            app.SubcohortsDropDown_2Label = uilabel(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2Label.Visible = 'off';
            app.SubcohortsDropDown_2Label.Text = 'Subcohorts';
            app.SubcohortsDropDown_2 = uidropdown(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDown_2ValueChanged, true);
            app.SubcohortsDropDown_2.Visible = 'off';
            app.MaskDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.MaskDropDownLabel.Enable = 'off';
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.MaskDropDown.Items = {'Gray Matter', 'Brain', 'Cortex & Cerebellum', 'Cortex', 'Cerebellum', 'Custom Image', 'Custom Equation'};
            app.MaskDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskDropDownValueChanged, true);
            app.MaskDropDown.Enable = 'off';
            app.MaskDropDown.Value = 'Gray Matter';
            app.MultitractAnalysisPanel = uipanel(app.UIFigure);
            app.MultitractAnalysisPanel.Title = 'Multitract Analysis';
            app.ModelSetupOptionButtonGroup = uibuttongroup(app.MultitractAnalysisPanel);
            app.ModelSetupOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSetupOptionButtonGroupSelectionChanged, true);
            app.ModelSetupOptionButtonGroup.Title = 'Model Setup Option';
            app.SingleTractAnalysisButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SingleTractAnalysisButton.Enable = 'off';
            app.SingleTractAnalysisButton.Text = 'Single Tract Analysis';
            app.SingleTractAnalysisButton.Value = true;
            app.SplitColorByGroupButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByGroupButton.Enable = 'off';
            app.SplitColorByGroupButton.Text = 'Split & Color By Group';
            app.SplitColorBySubscoreButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorBySubscoreButton.Enable = 'off';
            app.SplitColorBySubscoreButton.Text = 'Split & Color By Subscore';
            app.SplitColorByPCAButton = uiradiobutton(app.ModelSetupOptionButtonGroup);
            app.SplitColorByPCAButton.Enable = 'off';
            app.SplitColorByPCAButton.Text = 'Split & Color By PCA';
            app.SubscoresListBox = uilistbox(app.MultitractAnalysisPanel);
            app.SubscoresListBox.Multiselect = 'on';
            app.SubscoresListBox.ValueChangedFcn = createCallbackFcn(app, @SubscoresListBoxValueChanged, true);
            app.SubscoresListBox.Enable = 'off';
            app.SubscoresListBox.Value = {'Item 1'};
            app.PCsEditFieldLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCsEditFieldLabel.Enable = 'off';
            app.PCsEditFieldLabel.Text = '# PCs';
            app.PCsEditField = uieditfield(app.MultitractAnalysisPanel, 'numeric');
            app.PCsEditField.RoundFractionalValues = 'on';
            app.PCsEditField.ValueChangedFcn = createCallbackFcn(app, @PCsEditFieldValueChanged, true);
            app.PCsEditField.Enable = 'off';
            app.PCsEditField.Value = 4;
            app.PCAsettingsLabel = uilabel(app.MultitractAnalysisPanel);
            app.PCAsettingsLabel.Enable = 'off';
            app.PCAsettingsLabel.Text = 'PCA settings';
            app.VarianceExpSliderLabel = uilabel(app.MultitractAnalysisPanel);
            app.VarianceExpSliderLabel.Enable = 'off';
            app.VarianceExpSliderLabel.Text = 'Variance Exp';
            app.VarianceExpSlider = uislider(app.MultitractAnalysisPanel);
            app.VarianceExpSlider.ValueChangedFcn = createCallbackFcn(app, @VarianceExpSliderValueChanged, true);
            app.VarianceExpSlider.ValueChangingFcn = createCallbackFcn(app, @VarianceExpSliderValueChanging, true);
            app.VarianceExpSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.VarianceExpSlider.Enable = 'off';
            app.InspectPCAButton = uibutton(app.MultitractAnalysisPanel, 'push');
            app.InspectPCAButton.ButtonPushedFcn = createCallbackFcn(app, @InspectPCAButtonPushed, true);
            app.InspectPCAButton.Text = 'Inspect PCA';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleVoxelTractAnalysisTab = uitab(app.TabGroup);
            app.SingleVoxelTractAnalysisTab.Title = 'Single Voxel/Tract Analysis';
            app.ColorBar = uiaxes(app.SingleVoxelTractAnalysisTab);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorBar.ButtonDownFcn = createCallbackFcn(app, @ColorBarButtonDown, true);
            app.ColorButtonPositive = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.levelEditFieldLabel = uilabel(app.SingleVoxelTractAnalysisTab);
            app.levelEditFieldLabel.Enable = 'off';
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Enable = 'off';
            app.levelEditField.Value = 0.05;
            app.ShowAmountRightLabelNegative = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ShowAmountRightLabelNegative.Enable = 'off';
            app.ShowAmountRightLabelNegative.Text = 'Right';
            app.ShowAmountLabelNegative = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ShowAmountLabelNegative.Enable = 'off';
            app.ShowAmountLabelNegative.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ShowAmountLeftLabelNegative.Enable = 'off';
            app.ShowAmountLeftLabelNegative.Text = 'Left';
            app.ShowAmountRightLabelPositive = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ShowAmountRightLabelPositive.Enable = 'off';
            app.ShowAmountRightLabelPositive.Text = 'Right';
            app.ShowAmountLeftLabelPositive = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ShowAmountLeftLabelPositive.Enable = 'off';
            app.ShowAmountLeftLabelPositive.Text = 'Left';
            app.ShowAmountLabelPositive = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ShowAmountLabelPositive.Enable = 'off';
            app.ShowAmountLabelPositive.Text = 'Show Amount';
            app.ColorButtonNegative = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveCheckBox = uicheckbox(app.SingleVoxelTractAnalysisTab);
            app.ShowPositiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveCheckBoxValueChanged, true);
            app.ShowPositiveCheckBox.Enable = 'off';
            app.ShowPositiveCheckBox.Text = 'Show Positive';
            app.ShowPositiveCheckBox.Value = true;
            app.ShowNegativeCheckBox = uicheckbox(app.SingleVoxelTractAnalysisTab);
            app.ShowNegativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeCheckBoxValueChanged, true);
            app.ShowNegativeCheckBox.Enable = 'off';
            app.ShowNegativeCheckBox.Text = 'Show Negative';
            app.ExportasAtlasButton = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ConsiderSignificantValuesonlyCheckBox = uicheckbox(app.SingleVoxelTractAnalysisTab);
            app.ConsiderSignificantValuesonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantValuesonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantValuesonlyCheckBox.Enable = 'off';
            app.ConsiderSignificantValuesonlyCheckBox.Text = 'Consider Significant Values only';
            app.RefreshViewButton = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.SingleVoxelTractAnalysisTab);
            app.AutoRefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRefreshCheckBoxValueChanged, true);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.MultTestStrategy = uidropdown(app.SingleVoxelTractAnalysisTab);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Enable = 'off';
            app.MultTestStrategy.Value = 'FDR';
            app.ShowAmountLeftEditFieldNegative = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ShowAmountLeftEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegativeValueChanged, true);
            app.ShowAmountLeftEditFieldNegative.Enable = 'off';
            app.ShowAmountRightEditFieldNegative = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ShowAmountRightEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegativeValueChanged, true);
            app.ShowAmountRightEditFieldNegative.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ShowAmountLeftEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositiveValueChanged, true);
            app.ShowAmountLeftEditFieldPositive.Enable = 'off';
            app.ShowAmountRightEditFieldPositive = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ShowAmountRightEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositiveValueChanged, true);
            app.ShowAmountRightEditFieldPositive.Enable = 'off';
            app.ShowAmountEditFieldPositive = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ShowAmountEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositiveValueChanged, true);
            app.ShowAmountEditFieldPositive.Enable = 'off';
            app.ShowAmountEditFieldNegative = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ShowAmountEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegativeValueChanged, true);
            app.ShowAmountEditFieldNegative.Enable = 'off';
            app.ThresholdingDropDownLabel = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ThresholdingDropDownLabel.Enable = 'off';
            app.ThresholdingDropDownLabel.Text = 'Thresholding';
            app.ThresholdingDropDown = uidropdown(app.SingleVoxelTractAnalysisTab);
            app.ThresholdingDropDown.Items = {'Unthresholded', 'Percentage Relative to Peak', 'Percentage Relative to Amount', 'Fixed Amount', 'Histogram (CDF)', 'Fixed Fiber Value'};
            app.ThresholdingDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdingDropDownValueChanged, true);
            app.ThresholdingDropDown.Enable = 'off';
            app.ThresholdingDropDown.Value = 'Percentage Relative to Peak';
            app.PlotDistributionButton = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.PlotDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistributionButtonPushed, true);
            app.PlotDistributionButton.Enable = 'off';
            app.PlotDistributionButton.Text = 'Plot Distribution';
            app.ExportModelButton = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Enable = 'off';
            app.ExportModelButton.Text = 'Export Model';
            app.RefineFiberColor = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.RefineFiberColor.ButtonPushedFcn = createCallbackFcn(app, @RefineFiberColorButtonPushed, true);
            app.RefineFiberColor.Enable = 'off';
            app.RefineFiberColor.Text = 'Refine FIber Color';
            app.WeighttractsbyROIButton = uibutton(app.SingleVoxelTractAnalysisTab, 'push');
            app.WeighttractsbyROIButton.ButtonPushedFcn = createCallbackFcn(app, @WeighttractsbyROIButtonPushed, true);
            app.WeighttractsbyROIButton.Visible = 'off';
            app.WeighttractsbyROIButton.Text = 'Weight tracts by ROI';
            app.ThresholdEditField_2Label = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ThresholdEditField_2Label.Visible = 'off';
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField_2 = uieditfield(app.SingleVoxelTractAnalysisTab, 'numeric');
            app.ThresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_2ValueChanged, true);
            app.ThresholdEditField_2.Visible = 'off';
            app.ThresholdEditField_2.Value = 200;
            app.NMVisualizeDropDownLabel = uilabel(app.SingleVoxelTractAnalysisTab);
            app.NMVisualizeDropDownLabel.Enable = 'off';
            app.NMVisualizeDropDownLabel.Text = 'NM Visualize ';
            app.NetMapVisualizeDropDown = uidropdown(app.SingleVoxelTractAnalysisTab);
            app.NetMapVisualizeDropDown.Items = {'Regions', 'Surface (Elvis)', 'Surface (Surfice)'};
            app.NetMapVisualizeDropDown.ValueChangedFcn = createCallbackFcn(app, @NetMapVisualizeDropDownValueChanged, true);
            app.NetMapVisualizeDropDown.Enable = 'off';
            app.NetMapVisualizeDropDown.Value = 'Regions';
            app.NMModelLabel = uilabel(app.SingleVoxelTractAnalysisTab);
            app.NMModelLabel.Enable = 'off';
            app.NMModelLabel.Text = 'NM Model';
            app.NetMapModelDropDown = uidropdown(app.SingleVoxelTractAnalysisTab);
            app.NetMapModelDropDown.Items = {'Smoothed', 'Full'};
            app.NetMapModelDropDown.ValueChangedFcn = createCallbackFcn(app, @NetMapModelDropDownValueChanged2, true);
            app.NetMapModelDropDown.Enable = 'off';
            app.NetMapModelDropDown.Value = 'Smoothed';
            app.ExportDropDownLabel = uilabel(app.SingleVoxelTractAnalysisTab);
            app.ExportDropDownLabel.Enable = 'off';
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.SingleVoxelTractAnalysisTab);
            app.ExportDropDown.Items = {'Modality...', 'Fibers as Atlas...', 'Fibers as Model...', 'Sweetspot as NIfTI...', 'Network Map as NIfTI...'};
            app.ExportDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportDropDownValueChanged, true);
            app.ExportDropDown.Enable = 'off';
            app.ExportDropDown.Value = 'Fibers as Atlas...';
            app.LHCheckBox = uicheckbox(app.SingleVoxelTractAnalysisTab);
            app.LHCheckBox.ValueChangedFcn = createCallbackFcn(app, @LHCheckBoxValueChanged, true);
            app.LHCheckBox.Enable = 'off';
            app.LHCheckBox.Text = 'LH';
            app.LHCheckBox.Value = true;
            app.RHCheckBox = uicheckbox(app.SingleVoxelTractAnalysisTab);
            app.RHCheckBox.ValueChangedFcn = createCallbackFcn(app, @RHCheckBoxValueChanged, true);
            app.RHCheckBox.Enable = 'off';
            app.RHCheckBox.Text = 'RH';
            app.RHCheckBox.Value = true;
            app.Subscore_1Tab = uitab(app.TabGroup);
            app.Subscore_1Tab.Title = 'Subscore_1';
            app.ColorButtonPositive_2 = uibutton(app.Subscore_1Tab, 'push');
            app.ColorButtonPositive_2.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_2Pushed, true);
            app.ColorButtonPositive_2.Text = 'Color';
            app.ShowAmountRightLabelNegative_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountRightLabelNegative_2.Enable = 'off';
            app.ShowAmountRightLabelNegative_2.Text = 'Right';
            app.ShowAmountLabelNegative_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLabelNegative_2.Enable = 'off';
            app.ShowAmountLabelNegative_2.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLeftLabelNegative_2.Enable = 'off';
            app.ShowAmountLeftLabelNegative_2.Text = 'Left';
            app.ShowAmountRightLabelPositive_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountRightLabelPositive_2.Text = 'Right';
            app.ShowAmountLeftLabelPositive_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLeftLabelPositive_2.Text = 'Left';
            app.ShowAmountLabelPositive_2 = uilabel(app.Subscore_1Tab);
            app.ShowAmountLabelPositive_2.Text = 'Show Amount';
            app.ColorButtonNegative_2 = uibutton(app.Subscore_1Tab, 'push');
            app.ColorButtonNegative_2.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_2Pushed, true);
            app.ColorButtonNegative_2.Text = 'Color';
            app.ShowPositiveFibersCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.ShowPositiveFibersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_2ValueChanged, true);
            app.ShowPositiveFibersCheckBox_2.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_2.Value = true;
            app.ShowNegativeFibersCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.ShowNegativeFibersCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_2ValueChanged, true);
            app.ShowNegativeFibersCheckBox_2.Text = 'Show Negative Fibers';
            app.RefreshViewButton_2 = uibutton(app.Subscore_1Tab, 'push');
            app.RefreshViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_2Pushed, true);
            app.RefreshViewButton_2.Text = 'Refresh View';
            app.AutoRefreshCheckBox_2 = uicheckbox(app.Subscore_1Tab);
            app.AutoRefreshCheckBox_2.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_2.Value = true;
            app.ShowAmountLeftEditFieldNegative_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_2ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_2.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_2ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_2.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_2ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_2ValueChanged, true);
            app.ShowAmountEditFieldNegative_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountEditFieldNegative_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_2ValueChanged, true);
            app.ShowAmountEditFieldNegative_2.Enable = 'off';
            app.ShowAmountEditFieldPositive_2 = uieditfield(app.Subscore_1Tab, 'numeric');
            app.ShowAmountEditFieldPositive_2.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_2ValueChanged, true);
            app.Subscore_2Tab = uitab(app.TabGroup);
            app.Subscore_2Tab.Title = 'Subscore_2';
            app.ColorButtonPositive_3 = uibutton(app.Subscore_2Tab, 'push');
            app.ColorButtonPositive_3.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_3Pushed, true);
            app.ColorButtonPositive_3.Text = 'Color';
            app.ShowAmountRightLabelNegative_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountRightLabelNegative_3.Enable = 'off';
            app.ShowAmountRightLabelNegative_3.Text = 'Right';
            app.ShowAmountLabelNegative_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLabelNegative_3.Enable = 'off';
            app.ShowAmountLabelNegative_3.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLeftLabelNegative_3.Enable = 'off';
            app.ShowAmountLeftLabelNegative_3.Text = 'Left';
            app.ShowAmountRightLabelPositive_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountRightLabelPositive_3.Text = 'Right';
            app.ShowAmountLeftLabelPositive_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLeftLabelPositive_3.Text = 'Left';
            app.ShowAmountLabelPositive_3 = uilabel(app.Subscore_2Tab);
            app.ShowAmountLabelPositive_3.Text = 'Show Amount';
            app.ColorButtonNegative_3 = uibutton(app.Subscore_2Tab, 'push');
            app.ColorButtonNegative_3.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_3Pushed, true);
            app.ColorButtonNegative_3.Text = 'Color';
            app.ShowPositiveFibersCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.ShowPositiveFibersCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_3ValueChanged, true);
            app.ShowPositiveFibersCheckBox_3.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_3.Value = true;
            app.ShowNegativeFibersCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.ShowNegativeFibersCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_3ValueChanged, true);
            app.ShowNegativeFibersCheckBox_3.Text = 'Show Negative Fibers';
            app.RefreshViewButton_3 = uibutton(app.Subscore_2Tab, 'push');
            app.RefreshViewButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_3Pushed, true);
            app.RefreshViewButton_3.Text = 'Refresh View';
            app.AutoRefreshCheckBox_3 = uicheckbox(app.Subscore_2Tab);
            app.AutoRefreshCheckBox_3.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_3.Value = true;
            app.ShowAmountLeftEditFieldNegative_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_3ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_3.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_3ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_3.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_3ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_3ValueChanged, true);
            app.ShowAmountEditFieldNegative_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountEditFieldNegative_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_3ValueChanged, true);
            app.ShowAmountEditFieldNegative_3.Enable = 'off';
            app.ShowAmountEditFieldPositive_3 = uieditfield(app.Subscore_2Tab, 'numeric');
            app.ShowAmountEditFieldPositive_3.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_3ValueChanged, true);
            app.Subscore_3Tab = uitab(app.TabGroup);
            app.Subscore_3Tab.Title = 'Subscore_3';
            app.ColorButtonPositive_4 = uibutton(app.Subscore_3Tab, 'push');
            app.ColorButtonPositive_4.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_4Pushed, true);
            app.ColorButtonPositive_4.Text = 'Color';
            app.ShowAmountRightLabelNegative_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountRightLabelNegative_4.Enable = 'off';
            app.ShowAmountRightLabelNegative_4.Text = 'Right';
            app.ShowAmountLabelNegative_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLabelNegative_4.Enable = 'off';
            app.ShowAmountLabelNegative_4.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLeftLabelNegative_4.Enable = 'off';
            app.ShowAmountLeftLabelNegative_4.Text = 'Left';
            app.ShowAmountRightLabelPositive_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountRightLabelPositive_4.Text = 'Right';
            app.ShowAmountLeftLabelPositive_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLeftLabelPositive_4.Text = 'Left';
            app.ShowAmountLabelPositive_4 = uilabel(app.Subscore_3Tab);
            app.ShowAmountLabelPositive_4.Text = 'Show Amount';
            app.ColorButtonNegative_4 = uibutton(app.Subscore_3Tab, 'push');
            app.ColorButtonNegative_4.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_4Pushed, true);
            app.ColorButtonNegative_4.Text = 'Color';
            app.ShowPositiveFibersCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.ShowPositiveFibersCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_4ValueChanged, true);
            app.ShowPositiveFibersCheckBox_4.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_4.Value = true;
            app.ShowNegativeFibersCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.ShowNegativeFibersCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_4ValueChanged, true);
            app.ShowNegativeFibersCheckBox_4.Text = 'Show Negative Fibers';
            app.RefreshViewButton_4 = uibutton(app.Subscore_3Tab, 'push');
            app.RefreshViewButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_4Pushed, true);
            app.RefreshViewButton_4.Text = 'Refresh View';
            app.AutoRefreshCheckBox_4 = uicheckbox(app.Subscore_3Tab);
            app.AutoRefreshCheckBox_4.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_4.Value = true;
            app.ShowAmountLeftEditFieldNegative_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_4ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_4.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_4ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_4.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_4ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_4ValueChanged, true);
            app.ShowAmountEditFieldNegative_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountEditFieldNegative_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_4ValueChanged, true);
            app.ShowAmountEditFieldNegative_4.Enable = 'off';
            app.ShowAmountEditFieldPositive_4 = uieditfield(app.Subscore_3Tab, 'numeric');
            app.ShowAmountEditFieldPositive_4.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_4ValueChanged, true);
            app.Subscore_4Tab = uitab(app.TabGroup);
            app.Subscore_4Tab.Title = 'Subscore_4';
            app.ColorButtonPositive_5 = uibutton(app.Subscore_4Tab, 'push');
            app.ColorButtonPositive_5.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_5Pushed, true);
            app.ColorButtonPositive_5.Text = 'Color';
            app.ShowAmountRightLabelNegative_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountRightLabelNegative_5.Enable = 'off';
            app.ShowAmountRightLabelNegative_5.Text = 'Right';
            app.ShowAmountLabelNegative_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLabelNegative_5.Enable = 'off';
            app.ShowAmountLabelNegative_5.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLeftLabelNegative_5.Enable = 'off';
            app.ShowAmountLeftLabelNegative_5.Text = 'Left';
            app.ShowAmountRightLabelPositive_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountRightLabelPositive_5.Text = 'Right';
            app.ShowAmountLeftLabelPositive_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLeftLabelPositive_5.Text = 'Left';
            app.ShowAmountLabelPositive_5 = uilabel(app.Subscore_4Tab);
            app.ShowAmountLabelPositive_5.Text = 'Show Amount';
            app.ColorButtonNegative_5 = uibutton(app.Subscore_4Tab, 'push');
            app.ColorButtonNegative_5.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_5Pushed, true);
            app.ColorButtonNegative_5.Text = 'Color';
            app.ShowPositiveFibersCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.ShowPositiveFibersCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_5ValueChanged, true);
            app.ShowPositiveFibersCheckBox_5.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_5.Value = true;
            app.ShowNegativeFibersCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.ShowNegativeFibersCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_5ValueChanged, true);
            app.ShowNegativeFibersCheckBox_5.Text = 'Show Negative Fibers';
            app.RefreshViewButton_5 = uibutton(app.Subscore_4Tab, 'push');
            app.RefreshViewButton_5.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_5Pushed, true);
            app.RefreshViewButton_5.Text = 'Refresh View';
            app.AutoRefreshCheckBox_5 = uicheckbox(app.Subscore_4Tab);
            app.AutoRefreshCheckBox_5.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_5.Value = true;
            app.ShowAmountLeftEditFieldNegative_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_5ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_5.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_5ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_5.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_5ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_5ValueChanged, true);
            app.ShowAmountEditFieldNegative_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountEditFieldNegative_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_5ValueChanged, true);
            app.ShowAmountEditFieldNegative_5.Enable = 'off';
            app.ShowAmountEditFieldPositive_5 = uieditfield(app.Subscore_4Tab, 'numeric');
            app.ShowAmountEditFieldPositive_5.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_5ValueChanged, true);
            app.Subscore_5Tab = uitab(app.TabGroup);
            app.Subscore_5Tab.Title = 'Subscore_5';
            app.ColorButtonPositive_6 = uibutton(app.Subscore_5Tab, 'push');
            app.ColorButtonPositive_6.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_6Pushed, true);
            app.ColorButtonPositive_6.Text = 'Color';
            app.ShowAmountRightLabelNegative_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountRightLabelNegative_6.Enable = 'off';
            app.ShowAmountRightLabelNegative_6.Text = 'Right';
            app.ShowAmountLabelNegative_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLabelNegative_6.Enable = 'off';
            app.ShowAmountLabelNegative_6.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLeftLabelNegative_6.Enable = 'off';
            app.ShowAmountLeftLabelNegative_6.Text = 'Left';
            app.ShowAmountRightLabelPositive_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountRightLabelPositive_6.Text = 'Right';
            app.ShowAmountLeftLabelPositive_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLeftLabelPositive_6.Text = 'Left';
            app.ShowAmountLabelPositive_6 = uilabel(app.Subscore_5Tab);
            app.ShowAmountLabelPositive_6.Text = 'Show Amount';
            app.ColorButtonNegative_6 = uibutton(app.Subscore_5Tab, 'push');
            app.ColorButtonNegative_6.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_6Pushed, true);
            app.ColorButtonNegative_6.Text = 'Color';
            app.ShowPositiveFibersCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.ShowPositiveFibersCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_6ValueChanged, true);
            app.ShowPositiveFibersCheckBox_6.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_6.Value = true;
            app.ShowNegativeFibersCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.ShowNegativeFibersCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_6ValueChanged, true);
            app.ShowNegativeFibersCheckBox_6.Text = 'Show Negative Fibers';
            app.RefreshViewButton_6 = uibutton(app.Subscore_5Tab, 'push');
            app.RefreshViewButton_6.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_6Pushed, true);
            app.RefreshViewButton_6.Text = 'Refresh View';
            app.AutoRefreshCheckBox_6 = uicheckbox(app.Subscore_5Tab);
            app.AutoRefreshCheckBox_6.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_6.Value = true;
            app.ShowAmountLeftEditFieldNegative_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_6ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_6.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_6ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_6.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_6ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_6ValueChanged, true);
            app.ShowAmountEditFieldNegative_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountEditFieldNegative_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_6ValueChanged, true);
            app.ShowAmountEditFieldNegative_6.Enable = 'off';
            app.ShowAmountEditFieldPositive_6 = uieditfield(app.Subscore_5Tab, 'numeric');
            app.ShowAmountEditFieldPositive_6.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_6ValueChanged, true);
            app.Subscore_6Tab = uitab(app.TabGroup);
            app.Subscore_6Tab.Title = 'Subscore_6';
            app.ColorButtonPositive_7 = uibutton(app.Subscore_6Tab, 'push');
            app.ColorButtonPositive_7.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_7Pushed, true);
            app.ColorButtonPositive_7.Text = 'Color';
            app.ShowAmountRightLabelNegative_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountRightLabelNegative_7.Enable = 'off';
            app.ShowAmountRightLabelNegative_7.Text = 'Right';
            app.ShowAmountLabelNegative_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLabelNegative_7.Enable = 'off';
            app.ShowAmountLabelNegative_7.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLeftLabelNegative_7.Enable = 'off';
            app.ShowAmountLeftLabelNegative_7.Text = 'Left';
            app.ShowAmountRightLabelPositive_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountRightLabelPositive_7.Text = 'Right';
            app.ShowAmountLeftLabelPositive_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLeftLabelPositive_7.Text = 'Left';
            app.ShowAmountLabelPositive_7 = uilabel(app.Subscore_6Tab);
            app.ShowAmountLabelPositive_7.Text = 'Show Amount';
            app.ColorButtonNegative_7 = uibutton(app.Subscore_6Tab, 'push');
            app.ColorButtonNegative_7.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_7Pushed, true);
            app.ColorButtonNegative_7.Text = 'Color';
            app.ShowPositiveFibersCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.ShowPositiveFibersCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_7ValueChanged, true);
            app.ShowPositiveFibersCheckBox_7.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_7.Value = true;
            app.ShowNegativeFibersCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.ShowNegativeFibersCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_7ValueChanged, true);
            app.ShowNegativeFibersCheckBox_7.Text = 'Show Negative Fibers';
            app.RefreshViewButton_7 = uibutton(app.Subscore_6Tab, 'push');
            app.RefreshViewButton_7.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_7Pushed, true);
            app.RefreshViewButton_7.Text = 'Refresh View';
            app.AutoRefreshCheckBox_7 = uicheckbox(app.Subscore_6Tab);
            app.AutoRefreshCheckBox_7.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_7.Value = true;
            app.ShowAmountLeftEditFieldNegative_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_7ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_7.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_7ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_7.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_7ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_7ValueChanged, true);
            app.ShowAmountEditFieldNegative_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountEditFieldNegative_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_7ValueChanged, true);
            app.ShowAmountEditFieldNegative_7.Enable = 'off';
            app.ShowAmountEditFieldPositive_7 = uieditfield(app.Subscore_6Tab, 'numeric');
            app.ShowAmountEditFieldPositive_7.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_7ValueChanged, true);
            app.Subscore_7Tab = uitab(app.TabGroup);
            app.Subscore_7Tab.Title = 'Subscore_7';
            app.ColorButtonPositive_8 = uibutton(app.Subscore_7Tab, 'push');
            app.ColorButtonPositive_8.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_8Pushed, true);
            app.ColorButtonPositive_8.Text = 'Color';
            app.ShowAmountRightLabelNegative_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountRightLabelNegative_8.Enable = 'off';
            app.ShowAmountRightLabelNegative_8.Text = 'Right';
            app.ShowAmountLabelNegative_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLabelNegative_8.Enable = 'off';
            app.ShowAmountLabelNegative_8.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLeftLabelNegative_8.Enable = 'off';
            app.ShowAmountLeftLabelNegative_8.Text = 'Left';
            app.ShowAmountRightLabelPositive_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountRightLabelPositive_8.Text = 'Right';
            app.ShowAmountLeftLabelPositive_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLeftLabelPositive_8.Text = 'Left';
            app.ShowAmountLabelPositive_8 = uilabel(app.Subscore_7Tab);
            app.ShowAmountLabelPositive_8.Text = 'Show Amount';
            app.ColorButtonNegative_8 = uibutton(app.Subscore_7Tab, 'push');
            app.ColorButtonNegative_8.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_8Pushed, true);
            app.ColorButtonNegative_8.Text = 'Color';
            app.ShowPositiveFibersCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.ShowPositiveFibersCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_8ValueChanged, true);
            app.ShowPositiveFibersCheckBox_8.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_8.Value = true;
            app.ShowNegativeFibersCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.ShowNegativeFibersCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_8ValueChanged, true);
            app.ShowNegativeFibersCheckBox_8.Text = 'Show Negative Fibers';
            app.RefreshViewButton_8 = uibutton(app.Subscore_7Tab, 'push');
            app.RefreshViewButton_8.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_8Pushed, true);
            app.RefreshViewButton_8.Text = 'Refresh View';
            app.AutoRefreshCheckBox_8 = uicheckbox(app.Subscore_7Tab);
            app.AutoRefreshCheckBox_8.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_8.Value = true;
            app.ShowAmountLeftEditFieldNegative_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_8ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_8.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_8ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_8.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_8ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_8ValueChanged, true);
            app.ShowAmountEditFieldNegative_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountEditFieldNegative_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_8ValueChanged, true);
            app.ShowAmountEditFieldNegative_8.Enable = 'off';
            app.ShowAmountEditFieldPositive_8 = uieditfield(app.Subscore_7Tab, 'numeric');
            app.ShowAmountEditFieldPositive_8.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_8ValueChanged, true);
            app.Subscore_8Tab = uitab(app.TabGroup);
            app.Subscore_8Tab.Title = 'Subscore_8';
            app.ColorButtonPositive_9 = uibutton(app.Subscore_8Tab, 'push');
            app.ColorButtonPositive_9.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_9Pushed, true);
            app.ColorButtonPositive_9.Text = 'Color';
            app.ShowAmountRightLabelNegative_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountRightLabelNegative_9.Enable = 'off';
            app.ShowAmountRightLabelNegative_9.Text = 'Right';
            app.ShowAmountLabelNegative_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLabelNegative_9.Enable = 'off';
            app.ShowAmountLabelNegative_9.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLeftLabelNegative_9.Enable = 'off';
            app.ShowAmountLeftLabelNegative_9.Text = 'Left';
            app.ShowAmountRightLabelPositive_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountRightLabelPositive_9.Text = 'Right';
            app.ShowAmountLeftLabelPositive_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLeftLabelPositive_9.Text = 'Left';
            app.ShowAmountLabelPositive_9 = uilabel(app.Subscore_8Tab);
            app.ShowAmountLabelPositive_9.Text = 'Show Amount';
            app.ColorButtonNegative_9 = uibutton(app.Subscore_8Tab, 'push');
            app.ColorButtonNegative_9.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_9Pushed, true);
            app.ColorButtonNegative_9.Text = 'Color';
            app.ShowPositiveFibersCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.ShowPositiveFibersCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_9ValueChanged, true);
            app.ShowPositiveFibersCheckBox_9.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_9.Value = true;
            app.ShowNegativeFibersCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.ShowNegativeFibersCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_9ValueChanged, true);
            app.ShowNegativeFibersCheckBox_9.Text = 'Show Negative Fibers';
            app.RefreshViewButton_9 = uibutton(app.Subscore_8Tab, 'push');
            app.RefreshViewButton_9.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_9Pushed, true);
            app.RefreshViewButton_9.Text = 'Refresh View';
            app.AutoRefreshCheckBox_9 = uicheckbox(app.Subscore_8Tab);
            app.AutoRefreshCheckBox_9.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_9.Value = true;
            app.ShowAmountLeftEditFieldNegative_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_9ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_9.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_9ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_9.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_9ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_9ValueChanged, true);
            app.ShowAmountEditFieldNegative_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountEditFieldNegative_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_9ValueChanged, true);
            app.ShowAmountEditFieldNegative_9.Enable = 'off';
            app.ShowAmountEditFieldPositive_9 = uieditfield(app.Subscore_8Tab, 'numeric');
            app.ShowAmountEditFieldPositive_9.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_9ValueChanged, true);
            app.Subscore_9Tab = uitab(app.TabGroup);
            app.Subscore_9Tab.Title = 'Subscore_9';
            app.ColorButtonPositive_10 = uibutton(app.Subscore_9Tab, 'push');
            app.ColorButtonPositive_10.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_10Pushed, true);
            app.ColorButtonPositive_10.Text = 'Color';
            app.ShowAmountRightLabelNegative_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountRightLabelNegative_10.Enable = 'off';
            app.ShowAmountRightLabelNegative_10.Text = 'Right';
            app.ShowAmountLabelNegative_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLabelNegative_10.Enable = 'off';
            app.ShowAmountLabelNegative_10.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLeftLabelNegative_10.Enable = 'off';
            app.ShowAmountLeftLabelNegative_10.Text = 'Left';
            app.ShowAmountRightLabelPositive_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountRightLabelPositive_10.Text = 'Right';
            app.ShowAmountLeftLabelPositive_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLeftLabelPositive_10.Text = 'Left';
            app.ShowAmountLabelPositive_10 = uilabel(app.Subscore_9Tab);
            app.ShowAmountLabelPositive_10.Text = 'Show Amount';
            app.ColorButtonNegative_10 = uibutton(app.Subscore_9Tab, 'push');
            app.ColorButtonNegative_10.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_10Pushed, true);
            app.ColorButtonNegative_10.Text = 'Color';
            app.ShowPositiveFibersCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.ShowPositiveFibersCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_10ValueChanged, true);
            app.ShowPositiveFibersCheckBox_10.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_10.Value = true;
            app.ShowNegativeFibersCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.ShowNegativeFibersCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_10ValueChanged, true);
            app.ShowNegativeFibersCheckBox_10.Text = 'Show Negative Fibers';
            app.RefreshViewButton_10 = uibutton(app.Subscore_9Tab, 'push');
            app.RefreshViewButton_10.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_10Pushed, true);
            app.RefreshViewButton_10.Text = 'Refresh View';
            app.AutoRefreshCheckBox_10 = uicheckbox(app.Subscore_9Tab);
            app.AutoRefreshCheckBox_10.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_10.Value = true;
            app.ShowAmountLeftEditFieldNegative_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_10ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_10.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_10ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_10.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_10ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_10ValueChanged, true);
            app.ShowAmountEditFieldNegative_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountEditFieldNegative_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_10ValueChanged, true);
            app.ShowAmountEditFieldNegative_10.Enable = 'off';

            app.ShowAmountEditFieldPositive_10 = uieditfield(app.Subscore_9Tab, 'numeric');
            app.ShowAmountEditFieldPositive_10.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_10ValueChanged, true);
            app.Subscore_10Tab = uitab(app.TabGroup);
            app.Subscore_10Tab.Title = 'Subscore_10';
            app.ColorButtonPositive_11 = uibutton(app.Subscore_10Tab, 'push');
            app.ColorButtonPositive_11.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositive_11Pushed, true);
            app.ColorButtonPositive_11.Text = 'Color';
            app.ShowAmountRightLabelNegative_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountRightLabelNegative_11.Enable = 'off';
            app.ShowAmountRightLabelNegative_11.Text = 'Right';
            app.ShowAmountLabelNegative_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLabelNegative_11.Enable = 'off';
            app.ShowAmountLabelNegative_11.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLeftLabelNegative_11.Enable = 'off';
            app.ShowAmountLeftLabelNegative_11.Text = 'Left';
            app.ShowAmountRightLabelPositive_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountRightLabelPositive_11.Text = 'Right';
            app.ShowAmountLeftLabelPositive_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLeftLabelPositive_11.Text = 'Left';
            app.ShowAmountLabelPositive_11 = uilabel(app.Subscore_10Tab);
            app.ShowAmountLabelPositive_11.Text = 'Show Amount';
            app.ColorButtonNegative_11 = uibutton(app.Subscore_10Tab, 'push');
            app.ColorButtonNegative_11.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegative_11Pushed, true);
            app.ColorButtonNegative_11.Text = 'Color';
            app.ShowPositiveFibersCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.ShowPositiveFibersCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBox_11ValueChanged, true);
            app.ShowPositiveFibersCheckBox_11.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox_11.Value = true;
            app.ShowNegativeFibersCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.ShowNegativeFibersCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBox_11ValueChanged, true);
            app.ShowNegativeFibersCheckBox_11.Text = 'Show Negative Fibers';
            app.RefreshViewButton_11 = uibutton(app.Subscore_10Tab, 'push');
            app.RefreshViewButton_11.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButton_11Pushed, true);
            app.RefreshViewButton_11.Text = 'Refresh View';
            app.AutoRefreshCheckBox_11 = uicheckbox(app.Subscore_10Tab);
            app.AutoRefreshCheckBox_11.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox_11.Value = true;
            app.ShowAmountLeftEditFieldNegative_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountLeftEditFieldNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegative_11ValueChanged, true);
            app.ShowAmountLeftEditFieldNegative_11.Enable = 'off';
            app.ShowAmountRightEditFieldNegative_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountRightEditFieldNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegative_11ValueChanged, true);
            app.ShowAmountRightEditFieldNegative_11.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountLeftEditFieldPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositive_11ValueChanged, true);
            app.ShowAmountRightEditFieldPositive_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountRightEditFieldPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositive_11ValueChanged, true);
            app.ShowAmountEditFieldNegative_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountEditFieldNegative_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegative_11ValueChanged, true);
            app.ShowAmountEditFieldNegative_11.Enable = 'off';
            app.ShowAmountEditFieldPositive_11 = uieditfield(app.Subscore_10Tab, 'numeric');
            app.ShowAmountEditFieldPositive_11.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositive_11ValueChanged, true);
            app.SweetspotSwitchLabel = uilabel(app.UIFigure);
            app.SweetspotSwitchLabel.Text = 'Sweetspot';
            app.SweetspotSwitch = uiswitch(app.UIFigure, 'slider');
            app.SweetspotSwitch.ValueChangedFcn = createCallbackFcn(app, @SweetspotSwitchValueChanged, true);
            app.SweetspotSwitch.Enable = 'off';
            app.FiberfilteringSwitchLabel = uilabel(app.UIFigure);
            app.FiberfilteringSwitchLabel.Text = 'Fiberfiltering';
            app.FiberfilteringSwitch = uiswitch(app.UIFigure, 'slider');
            app.FiberfilteringSwitch.ValueChangedFcn = createCallbackFcn(app, @FiberfilteringSwitchValueChanged, true);
            app.FiberfilteringSwitch.Enable = 'off';
            app.NetworkmappingSwitchLabel = uilabel(app.UIFigure);
            app.NetworkmappingSwitchLabel.Text = 'Networkmapping';
            app.NetworkmappingSwitch = uiswitch(app.UIFigure, 'slider');
            app.NetworkmappingSwitch.ValueChangedFcn = createCallbackFcn(app, @NetworkmappingSwitchValueChanged, true);
            app.NetworkmappingSwitch.Enable = 'off';
            app.CalculateButton_Sweetspot = uibutton(app.UIFigure, 'push');
            app.CalculateButton_Sweetspot.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_SweetspotPushed, true);
            app.CalculateButton_Sweetspot.Enable = 'off';
            app.CalculateButton_Sweetspot.Text = 'Calculate';
            app.CalculateButton_Fiberfiltering = uibutton(app.UIFigure, 'push');
            app.CalculateButton_Fiberfiltering.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_FiberfilteringPushed, true);
            app.CalculateButton_Fiberfiltering.Enable = 'off';
            app.CalculateButton_Fiberfiltering.Text = 'Calculate';
            app.CalculateButton_Networkmapping = uibutton(app.UIFigure, 'push');
            app.CalculateButton_Networkmapping.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_NetworkmappingPushed2, true);
            app.CalculateButton_Networkmapping.Enable = 'off';
            app.CalculateButton_Networkmapping.Text = 'Calculate';
            app.SaveasButton = uibutton(app.UIFigure, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenanalysisButton = uibutton(app.UIFigure, 'push');
            app.OpenanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenanalysisButtonPushed, true);
            app.OpenanalysisButton.Text = 'Open analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_unifiedmappingexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end