classdef LeG_label < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutoNameUIFigure            matlab.ui.Figure
        ApplyButton                 matlab.ui.control.Button
        LabelingAtlasDropDown       matlab.ui.control.DropDown
        LabelingAtlasDropDownLabel  matlab.ui.control.Label
        ElectrodeTable              matlab.ui.control.Table
    end

    %linked to autoname button do command f %check to edit that code
    properties (Access = public)
        LeGUI 
        AutoNameOut %check
        WasApplied = false
        SelElec
        AssignApp
        SelectedShaft = []
        ModelList
        AtlasFolder
        AtlasFiles
        CurrentAtlasPath
        CurrentAtlasLUT
    end
    
    methods (Access = private)
        
        function populateTable(app)
            out = app.AutoNameOut;
            nShafts = numel(out.ShaftNames);
            tableData = cell(nShafts,5);
            for s = 1:nShafts
                shaftIndex = out.Shafts(s).indices(:); %SaftMembership is num of electrodes

                tableData{s,1} =s; %elec number
                tableData{s,2} = numel(shaftIndex); %num of contacts
                modelName = app.guessModelFromContacts(shaftIndex);
                tableData{s,3} = modelName;
                tableData{s,4} = app.getAtlasLabelForShaft(shaftIndex); %anatomical name
                tableData{s,5} = ''; %custom names

            end
            app.ElectrodeTable.Data = tableData;
            app.ElectrodeTable.ColumnName = {'Electrode Number', 'Number of Contacts', 'Model', 'Tip Location', 'Electrode Name'};
            app.ElectrodeTable.ColumnEditable = [false false true false true];

            app.ElectrodeTable.ColumnFormat = ...
                {'numeric', 'numeric', app.ModelList, 'char', 'char'};
        end

        function models = getModelList(app)

            try
                models = ea_resolve_elspec;
            catch
                models = {'Unknown'};
            end

            if isstring(models)
                models = cellstr(models);
            end

            models = models(:)';

            % Keep mostly SEEG/depth electrode models
            keep = contains(models, {'DIXI','AdTech','PMT','SDE','EPC','NeuroPace'}, ...
                'IgnoreCase', true);

            models = models(keep);

            if isempty(models)
                models = {'Unknown'};
            end
        end


        function modelName = getModelFromRecoProps(app, shaftNum)

            modelName = '';

            try
                reco = [];

                % Try app.LeGUI.Reco first
                if isprop(app.LeGUI, 'Reco') && ~isempty(app.LeGUI.Reco)
                    reco = app.LeGUI.Reco;
                end

                % Try loading recofile if needed
                if isempty(reco) && isprop(app.LeGUI, 'recofile') && exist(app.LeGUI.recofile, 'file')
                    S = load(app.LeGUI.recofile);
                    if isfield(S, 'reco')
                        reco = S.reco;
                    end
                end

                if isempty(reco) || ~isfield(reco, 'props') || numel(reco.props) < shaftNum
                    return;
                end

                props = reco.props;

                if isstruct(props) && isfield(props, 'elmodel') && ~isempty(props(shaftNum).elmodel)
                    modelName = char(string(props(shaftNum).elmodel));
                elseif istable(props) && any(strcmp(props.Properties.VariableNames, 'elmodel'))
                    val = props.elmodel{shaftNum};
                    modelName = char(string(val));
                end

                % Only use it if it exists in dropdown list
                if ~any(strcmp(app.ModelList, modelName))
                    modelName = '';
                end

            catch
                modelName = '';
            end
        end

        function updateRecoPropsModels(app, selectedModels, data, out)

            try
                % Find reconstruction file
                recoFile = '';

                if isprop(app.LeGUI,'recofile') && ~isempty(app.LeGUI.recofile)
                    recoFile = app.LeGUI.recofile;
                elseif isprop(app.LeGUI,'options') && isfield(app.LeGUI.options,'root') ...
                        && isfield(app.LeGUI.options,'patientname')
                    recoFile = fullfile(app.LeGUI.options.root, app.LeGUI.options.patientname, ...
                        'reconstruction', [app.LeGUI.options.patientname '_desc-reconstruction.mat']);
                end

                if isempty(recoFile)
                    warning('Could not find reconstruction file path.');
                    return;
                end

                % Load existing reco if it exists
                if exist(recoFile,'file')
                    S = load(recoFile);
                    if isfield(S,'reco')
                        reco = S.reco;
                    else
                        reco = struct();
                    end
                else
                    reco = struct();
                end

                nShafts = numel(selectedModels);

                % Make clean props
                props = repmat(struct( ...
                    'elmodel', '', ...
                    'elname', '', ...
                    'labels', {{}}, ...
                    'manually_corrected', 1), 1, nShafts);

                for s = 1:nShafts

                    props(s).elmodel = selectedModels{s};
                    props(s).elname = data{s,5};  % final electrode name from table

                    shaftIdx = out.Shafts(s).indices(:);
                    labels = out.ElecMapRaw(shaftIdx,1);
                    props(s).labels = labels;

                    props(s).manually_corrected = 1;

                end

                reco.props = props;

                % Save back to actual reconstruction file
                save(recoFile, 'reco');

                % Also keep app copy updated
                app.LeGUI.Reco = reco;

            catch ME
                warning('Could not update reco.props.elmodel: %s', E.message);
            end
        end

        function modelName = guessModelFromContacts(app, shaftIdx)

            models = app.ModelList;
            nObs = numel(shaftIdx);

            coords = app.LeGUI.ElecXYZProjRaw(shaftIdx, :);

            if nObs > 1
                observedPitch = median(sqrt(sum(diff(coords,1,1).^2,2)));
            else
                observedPitch = NaN;
            end

            bestScore = inf;
            modelName = models{1};

            for i = 1:numel(models)

                mdl = models{i};

                try
                    opts.elmodel = mdl;
                    resolved = ea_resolve_elspec(opts);

                    if isfield(resolved, 'elspec')
                        spec = resolved.elspec;
                    else
                        spec = resolved;
                    end

                    if ~isfield(spec, 'numContacts')
                        continue;
                    end

                    nPred = spec.numContacts;

                    % Strongly require matching contact count
                    if nPred ~= nObs
                        continue;
                    end

                    % Predicted center-to-center spacing
                    if isfield(spec, 'contact_length') && isfield(spec, 'contact_spacing')
                        spacing = spec.contact_spacing;

                        if numel(spacing) > 1
                            spacing = median(spacing);
                        end

                        predictedPitch = spec.contact_length + spacing;
                        pitchError = abs(observedPitch - predictedPitch);
                    else
                        pitchError = 0.5;
                    end

                    score = pitchError;

                    if score < bestScore
                        bestScore = score;
                        modelName = mdl;
                    end

                catch
                end
            end
        end

        function setupAtlasDropdown(app)

            niiFiles = dir(fullfile(app.AtlasFolder, '*.nii'));
            niiGzFiles = dir(fullfile(app.AtlasFolder, '*.nii.gz'));

            files = [niiFiles; niiGzFiles];

            names = {files.name};

            if isempty(names)
                app.LabelingAtlasDropDown.Items = {'No atlases found'};
                app.CurrentAtlasPath = '';
                app.CurrentAtlasLUT = containers.Map('KeyType','double','ValueType','char');
                return;
            end

            app.AtlasFiles = fullfile(app.AtlasFolder, names);
            app.LabelingAtlasDropDown.Items = names;

            defaultName = 'Automated Anatomical Labeling (Tzourio-Mazoyer 2002).nii';
            idx = find(strcmp(names, defaultName), 1);

            if isempty(idx)
                idx = 1;
            end

            app.LabelingAtlasDropDown.Value = names{idx};
            app.CurrentAtlasPath = app.AtlasFiles{idx};
            app.CurrentAtlasLUT = app.readAtlasTxt(app.CurrentAtlasPath);

        end

        function LUT = readAtlasTxt(app, atlasPath)

            LUT = containers.Map('KeyType','double','ValueType','char');

            [folder, base, ext] = fileparts(atlasPath);

            if strcmp(ext, '.gz')
                [~, base] = fileparts(base);
            end

            txtPath = fullfile(folder, [base '.txt']);

            if ~exist(txtPath, 'file')
                return;
            end

            fid = fopen(txtPath, 'r');
            if fid < 0
                return;
            end

            C = textscan(fid, '%f %s', 'Delimiter', {' ', '\t'}, ...
                'MultipleDelimsAsOne', true);
            fclose(fid);

            ids = C{1};
            names = C{2};

            for i = 1:numel(ids)
                LUT(ids(i)) = names{i};
            end

        end

        function labelName = getAtlasLabelForShaft(app, shaftIdx)

            labelName = '';

            if isempty(app.CurrentAtlasPath) || ~exist(app.CurrentAtlasPath, 'file')
                return;
            end

            distalIdx = shaftIdx(1);

            if isprop(app.LeGUI, 'ElecXYZMNIRaw') && ~isempty(app.LeGUI.ElecXYZMNIRaw)
                xyz = app.LeGUI.ElecXYZMNIRaw(distalIdx, :);
            else
                xyz = app.LeGUI.ElecXYZProjRaw(distalIdx, :);
            end

            try
                V = spm_vol(app.CurrentAtlasPath);
                atlasImg = spm_read_vols(V);

                ijk = round(inv(V.mat) * [xyz(:); 1]);
                ijk = ijk(1:3)';

                if any(ijk < 1) || ijk(1) > size(atlasImg,1) || ...
                        ijk(2) > size(atlasImg,2) || ijk(3) > size(atlasImg,3)
                    return;
                end

                atlasVal = round(atlasImg(ijk(1), ijk(2), ijk(3)));

                if atlasVal == 0
                    return;
                end

                if isKey(app.CurrentAtlasLUT, atlasVal)
                    labelName = app.CurrentAtlasLUT(atlasVal);
                else
                    labelName = sprintf('Region_%d', atlasVal);
                end

                labelName = regexprep(labelName, '\s+', '_');
                labelName = regexprep(labelName, '[^A-Za-z0-9_]', '');

            catch
                labelName = '';
            end
        end

        function closeAutoName(app)

            try
                uiresume(app.AutoNameUIFigure);
            catch
            end

            try
                delete(app.AutoNameUIFigure);
            catch
            end

        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AssignApp, out)
            app.AssignApp = AssignApp;
            app.LeGUI = AssignApp.LeGUI;
            app.AutoNameOut = out; %check
            app.ModelList = app.getModelList();
            leadRoot = fileparts(which('ea.m'));

            if isempty(leadRoot)
                p = which('ea_resolve_elspec');
                leadRoot = fileparts(fileparts(p));
            end

            % If leadRoot already points to /templates, go one folder up
            [~, lastFolder] = fileparts(leadRoot);
            if strcmp(lastFolder, 'templates')
                leadRoot = fileparts(leadRoot);
            end

            app.AtlasFolder = fullfile(leadRoot, ...
                'templates', 'space', 'MNI152NLin2009bAsym', 'labeling');

            disp(app.AtlasFolder)
            disp(app.AtlasFolder)
            app.setupAtlasDropdown();

            app.populateTable()


            app.AutoNameUIFigure.CloseRequestFcn = @(src,event) closeAutoName(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            data = app.ElectrodeTable.Data;
            out = app.AutoNameOut;

            % Reset maps so we rebuild them cleanly
            nContacts = size(out.ElecMapRaw, 1);
            maxContacts = size(out.LabelMap, 1);
            nShafts = numel(out.Shafts);

            out.ElecMapRaw = cell(nContacts, 3);
            out.ElecMapRaw(:,1) = {'NaN'};
            out.ElecMapRaw(:,2:3) = num2cell(nan(nContacts, 2));

            out.LabelMap = repmat({'NaN'}, maxContacts, nShafts);
            out.ChannelMap1 = nan(maxContacts, nShafts);
            out.ChannelMap2 = nan(maxContacts, nShafts);
            out.ShaftMembership = nan(nContacts, 1);

            chan = 1;


            % Check all custom names before applying anything
            customNames = string(data(:,5));
            customNames = strtrim(customNames);

            missingRows = find(customNames == "");

            if ~isempty(missingRows)
                missingText = strjoin(string(missingRows), ', ');

                uialert(app.AutoNameUIFigure, ...
                    sprintf('Missing name for electrode(s): %s\n\nPlease enter a custom label for every electrode before applying.', missingText), ...
                    'Missing name');

                return;
            end

            for s = 1:size(data,1)

                customName = strtrim(string(data{s,5}));

                finalName = customName;

                finalName = regexprep(finalName, '\s+', '_');
                finalName = regexprep(finalName, '[^A-Za-z0-9_]', '');

                if finalName == ""
                    uialert(app.AutoNameUIFigure, ...
                        sprintf('Invalid name for electrode %d. Please use letters, numbers, or underscores.', s), ...
                        'Invalid name');
                    return;
                end


                % This must be ordered distal -> proximal.
                shaftIdx = out.Shafts(s).indices(:);


                rows = maxContacts:-1:(maxContacts-numel(shaftIdx)+1);

                for c = 1:numel(shaftIdx)

                    newLabel = sprintf('%s%d', finalName, c);

                    % assign label c directly to contact shaftIdx(c)
                    out.ElecMapRaw(shaftIdx(c), :) = {newLabel, chan, nan};

                    out.LabelMap{rows(c), s} = newLabel;
                    out.ChannelMap1(rows(c), s) = chan;
                    out.ShaftMembership(shaftIdx(c)) = s;

                    chan = chan + 1;

                end
            end

            % Push updated AutoName result into main LeGUI
            app.LeGUI.ElecMapRaw = out.ElecMapRaw;
            app.LeGUI.LabelMap = out.LabelMap;
            app.LeGUI.ChannelMap1 = out.ChannelMap1;
            app.LeGUI.ChannelMap2 = out.ChannelMap2;
            app.LeGUI.DepthElecRaw = out.DepthElecRaw;
            app.LeGUI.MicroElecRaw = out.MicroElecRaw;
            app.LeGUI.RefElecRaw = out.RefElecRaw;
            app.LeGUI.GndElecRaw = out.GndElecRaw;
            app.LeGUI.ChangeDetected = true;

            % Also update the open AssignElectrodes GUI
            app.AssignApp.GLM = out.LabelMap;
            app.AssignApp.GCM1 = reshape(strtrim(cellstr(num2str(out.ChannelMap1(:)))), size(out.ChannelMap1));
            app.AssignApp.GCM2 = reshape(strtrim(cellstr(num2str(out.ChannelMap2(:)))), size(out.ChannelMap2));
            app.AssignApp.GridSize = size(out.LabelMap);

            app.AssignApp.RowEditH.Value = num2str(app.AssignApp.GridSize(1));
            app.AssignApp.ColEditH.Value = num2str(app.AssignApp.GridSize(2));

            app.AssignApp.MapTypePupH.Value = 'LabelMap';
            app.AssignApp.MapTableH.Data = app.AssignApp.GLM;


            app.AssignApp.SelElec = [];
            app.AssignApp.CurTableIdx = [];
            app.AssignApp.SelCntH.Value = 0;

            app.AssignApp.MapTypePupH.Value = 'LabelMap';
            app.AssignApp.MapTableH.Data = app.AssignApp.GLM;

            selectedModels = data(:,3); %for elec model in table
            app.LeGUI.ShaftModels = selectedModels;
            app.updateRecoPropsModels(selectedModels, data, out);

            app.WasApplied = true;

            closeAutoName(app);

        end

        % Cell selection callback: ElectrodeTable
        function ElectrodeTableCellSelection(app, event)

            if isempty(event.Indices)
                return;
            end

            row = event.Indices(1);
            app.SelectedShaft = row;

            out = app.AutoNameOut;

            shaftIdx = out.Shafts(row).indices(:);

            app.SelElec = shaftIdx(:);

            % Send selection back to AssignElectrodesGUI
            app.AssignApp.SelElec = app.SelElec;
            app.AssignApp.CurTableIdx = [];

            % Highlight selected contacts in red
            set(app.AssignApp.ElecXYZProjRawH, 'Color', [0 0 0]);  
            set(app.AssignApp.ElecXYZProjRawH(app.SelElec), 'Color', [1 0 0]);

            app.AssignApp.SelCntH.Value = length(app.SelElec);
            
        end

        % Value changed function: LabelingAtlasDropDown
        function LabelingAtlasDropDownValueChanged(app, event)

            idx = find(strcmp(app.LabelingAtlasDropDown.Items, app.LabelingAtlasDropDown.Value), 1);

            if isempty(idx)
                return;
            end

            app.CurrentAtlasPath = app.AtlasFiles{idx};
            app.CurrentAtlasLUT = app.readAtlasTxt(app.CurrentAtlasPath);

            app.populateTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutoNameUIFigure = uifigure('Visible', 'off');
            app.AutoNameUIFigure.Name = 'Auto Name';
            app.ElectrodeTable = uitable(app.AutoNameUIFigure);
            app.ElectrodeTable.ColumnName = {'Electrode Number'; 'Number of Contacts'; 'Model'; 'Tip Location'; 'Electrode Name'};
            app.ElectrodeTable.RowName = {};
            app.ElectrodeTable.CellSelectionCallback = createCallbackFcn(app, @ElectrodeTableCellSelection, true);
            app.LabelingAtlasDropDownLabel = uilabel(app.AutoNameUIFigure);
            app.LabelingAtlasDropDownLabel.Text = 'Labeling Atlas';
            app.LabelingAtlasDropDown = uidropdown(app.AutoNameUIFigure);
            app.LabelingAtlasDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelingAtlasDropDownValueChanged, true);
            app.ApplyButton = uibutton(app.AutoNameUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply Changes';
            % Show the figure after all components are created
            app.AutoNameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_label(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutoNameUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutoNameUIFigure)
        end
    end
end