classdef ea_ppam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofSamplesSpinner          matlab.ui.control.Spinner
        NumberofSamplesSpinnerLabel     matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ProbailisticPathwayActivationModelingLabel  matlab.ui.control.Label
        UpperBoundaryEditField          matlab.ui.control.NumericEditField
        UpperBoundaryEditFieldLabel     matlab.ui.control.Label
        LowerBoundaryEditField          matlab.ui.control.NumericEditField
        LowerBoundaryEditFieldLabel     matlab.ui.control.Label
        SamplingDropDown                matlab.ui.control.DropDown
        SamplingDropDownLabel           matlab.ui.control.Label
        ProbabilisticParameterDropDown  matlab.ui.control.DropDown
        ProbabilisticParameterDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        prefs = ea_prefs('') % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Value changed function: NumberofSamplesSpinner
        function NumberofSamplesSpinnerValueChanged(app, event)
            value = app.NumberofSamplesSpinner.Value;
            app.prefs.machine.vatsettings.butenko_N_samples = app.NumberofSamplesSpinner.Value;
            
        end

        % Value changed function: ProbabilisticParameterDropDown
        function ProbabilisticParameterDropDownValueChanged(app, event)
            value = app.ProbabilisticParameterDropDown.Value;
            if strcmp(app.ProbabilisticParameterDropDown.Value,'Axon Length')
                app.LowerBoundaryEditField.Value = 5;
                app.UpperBoundaryEditField.Value = 100;
            elseif strcmp(app.ProbabilisticParameterDropDown.Value,'Encapsulation Thickness')
                app.LowerBoundaryEditField.Value = 0;
                app.UpperBoundaryEditField.Value = 1.0;
            elseif strcmp(app.ProbabilisticParameterDropDown.Value,'Fiber Diameter')
                app.LowerBoundaryEditField.Value = 2;
                app.UpperBoundaryEditField.Value = 4;
            end
            app.prefs.machine.vatsettings.butenko_probabilistic_parameter = app.ProbabilisticParameterDropDown.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.prefs.machine.vatsettings.butenko_probabilistic_parameter = app.ProbabilisticParameterDropDown.Value;
            app.prefs.machine.vatsettings.butenko_sampling_distribution = app.SamplingDropDown.Value;
            app.prefs.machine.vatsettings.butenko_N_samples = app.NumberofSamplesSpinner.Value;
            app.prefs.machine.vatsettings.butenko_parameter_limits = [app.LowerBoundaryEditField.Value, app.UpperBoundaryEditField.Value];
            vatsettings = app.prefs.machine.vatsettings;
            ea_setprefs('vatsettings',vatsettings);
            disp("Preferences saved successfully ***")
            delete(app);
        end

        % Value changed function: SamplingDropDown
        function SamplingDropDownValueChanged(app, event)
            value = app.SamplingDropDown.Value;
            app.prefs.machine.vatsettings.butenko_sampling_distribution = app.SamplingDropDown.Value;
        end

        % Value changed function: LowerBoundaryEditField
        function LowerBoundaryEditFieldValueChanged(app, event)
            value = app.LowerBoundaryEditField.Value;
            app.prefs.machine.vatsettings.butenko_parameter_limits = [app.LowerBoundaryEditField.Value, app.UpperBoundaryEditField.Value];
        end

        % Value changed function: UpperBoundaryEditField
        function UpperBoundaryEditFieldValueChanged(app, event)
            value = app.UpperBoundaryEditField.Value;
            app.prefs.machine.vatsettings.butenko_parameter_limits = [app.LowerBoundaryEditField.Value, app.UpperBoundaryEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProbabilisticParameterDropDownLabel = uilabel(app.UIFigure);
            app.ProbabilisticParameterDropDownLabel.Text = 'Probabilistic Parameter';
            app.ProbabilisticParameterDropDown = uidropdown(app.UIFigure);
            app.ProbabilisticParameterDropDown.Items = {'Fiber Diameter', 'Axon Length', 'Encapsulation Thickness'};
            app.ProbabilisticParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ProbabilisticParameterDropDownValueChanged, true);
            app.ProbabilisticParameterDropDown.Value = 'Fiber Diameter';
            app.SamplingDropDownLabel = uilabel(app.UIFigure);
            app.SamplingDropDownLabel.Text = 'Sampling';
            app.SamplingDropDown = uidropdown(app.UIFigure);
            app.SamplingDropDown.Items = {'Equidistant', 'Uniform', 'Normal'};
            app.SamplingDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingDropDownValueChanged, true);
            app.SamplingDropDown.Value = 'Equidistant';
            app.LowerBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundaryEditFieldLabel.Text = 'Lower Boundary';
            app.LowerBoundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerBoundaryEditField.ValueChangedFcn = createCallbackFcn(app, @LowerBoundaryEditFieldValueChanged, true);
            app.LowerBoundaryEditField.Value = 2;
            app.UpperBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundaryEditFieldLabel.Text = 'Upper Boundary';
            app.UpperBoundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperBoundaryEditField.ValueChangedFcn = createCallbackFcn(app, @UpperBoundaryEditFieldValueChanged, true);
            app.UpperBoundaryEditField.Value = 4;
            app.ProbailisticPathwayActivationModelingLabel = uilabel(app.UIFigure);
            app.ProbailisticPathwayActivationModelingLabel.Text = 'Probailistic Pathway Activation Modeling';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NumberofSamplesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofSamplesSpinnerLabel.Text = 'Number of Samples';
            app.NumberofSamplesSpinner = uispinner(app.UIFigure);
            app.NumberofSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesSpinnerValueChanged, true);
            app.NumberofSamplesSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_ppam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end