classdef ea_selectdataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MessageLabel                  matlab.ui.control.Label
        RecentDatasetsDropDown        matlab.ui.control.DropDown
        SelectdatasetdirectoryButton  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
    end

    properties (Access = private)
        leadfigure % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, message, leadfigure)
            if exist('message', 'var') && ~isempty(message)
                app.MessageLabel.Text = message;
            end

            app.leadfigure = leadfigure;

            if isfile([ea_getearoot, 'common', filesep, 'ea_recentpatients.mat'])
                load([ea_getearoot, 'common', filesep, 'ea_recentpatients.mat'], 'recentfolders');
                if ~strcmp('No recent patients found', recentfolders)
                    recentfolders = regexprep(recentfolders, ['\', filesep, 'derivatives.*$'], '');
                    recentfolders = ['Recent Datasets'; unique(recentfolders)];
                    app.RecentDatasetsDropDown.Items = recentfolders;
                    app.RecentDatasetsDropDown.Value = 'Recent Datasets';
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setappdata(app.leadfigure, 'datasetdir', app.SelectdatasetdirectoryButton.Text);
            delete(app);
        end

        % Button pushed function: SelectdatasetdirectoryButton
        function SelectdatasetdirectoryButtonPushed(app, event)
            selectedPath = uigetdir(pwd);
            if selectedPath == 0
                return
            end

            app.SelectdatasetdirectoryButton.Text = selectedPath;
            app.SelectdatasetdirectoryButton.Tooltip = selectedPath;
            app.SelectdatasetdirectoryButton.FontWeight = 'bold';
            app.RecentDatasetsDropDown.Value = 'Recent Datasets';
            app.OKButton.Enable = 'on';
        end

        % Value changed function: RecentDatasetsDropDown
        function RecentDatasetsDropDownValueChanged(app, event)
            selectedPath = app.RecentDatasetsDropDown.Value;
            if ~strcmp(selectedPath, 'Recent Datasets')
                app.SelectdatasetdirectoryButton.Text = selectedPath;
                app.SelectdatasetdirectoryButton.Tooltip = selectedPath;
                app.SelectdatasetdirectoryButton.FontWeight = 'bold';
                app.RecentDatasetsDropDown.Value = 'Recent Datasets';
                app.OKButton.Enable = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            setappdata(app.leadfigure, 'datasetdir', app.SelectdatasetdirectoryButton.Text);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.SelectdatasetdirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectdatasetdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatasetdirectoryButtonPushed, true);
            app.SelectdatasetdirectoryButton.Text = 'Select dataset directory';
            app.RecentDatasetsDropDown = uidropdown(app.UIFigure);
            app.RecentDatasetsDropDown.Items = {'Recent Datasets'};
            app.RecentDatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @RecentDatasetsDropDownValueChanged, true);
            app.RecentDatasetsDropDown.Tooltip = {'Recent Datasets'};
            app.RecentDatasetsDropDown.Value = 'Recent Datasets';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Please select the output dataset directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_selectdataset(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end