classdef lead_migrate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        ConverttoDicomusingDropDownLabel  matlab.ui.control.Label
        ConverttoDicomusingDropDown  matlab.ui.control.DropDown
        MigrateOptionsButtonGroup    matlab.ui.container.ButtonGroup
        RetainsourcedirectorycopiesdataButton  matlab.ui.control.RadioButton
        MovefilesandcreateanewdirectoryButton  matlab.ui.control.RadioButton
        MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox  matlab.ui.control.CheckBox
        SelectpatientsButton         matlab.ui.control.Button
        ConvertlegacydatasettoBIDSComplaintdatasetCheckBox  matlab.ui.control.CheckBox
        SelectoutputdirectoryoptionalButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        source_files % Description
        output_files
        flag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectpatientsButton
        function SelectpatientsButtonPushed(app, event)
            selpath = ea_uigetdir(pwd);
            
            %selpath now contains a list of input directories.
            for selection = 1:length(selpath)
                files_inside = dir_without_dots(selpath{selection});
                files_inside = {files_inside.name};
                %detection function: if the pat has derivatives and 
                % raw data, but not in BIDS format then it should be
                % migrated.
                
                if any(ismember(files_inside,'glanat.nii')) && ~any(cellfun('isempty',regexp(files_inside,'raw_anat.*.nii'))) && all(cellfun('isempty',regexpi(files_inside,'DICOM')))  && all(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                     msgbox("Looks like preprocessing has been completed, but no dicom images are present. Conitinuing to migrate into BIDS-compliant dataset..");
                     app.ConvertlegacydatasettoBIDSComplaintdatasetCheckBox.Enable = 'off';
                     app.ConverttoDicomusingDropDown.Enable = 'off';
                     app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                     app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                     app.flag = 'onlyMigrate';
                    %only raw nifti available, no dicom is available. Migrate
                 %will be done, but only to move & rename the raw files
                elseif ~any(ismember(files_inside,'glanat.nii')) && ~any(cellfun('isempty',regexp(files_inside,'raw_anat.*.nii'))) && all(cellfun('isempty',regexpi(files_inside,'DICOM')))  && all(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                    msgbox("Raw Nifti detected without any DICOM images. Continuing to run Migration to generate raw BIDS-compliant dataset only..");
                    app.ConverttoDicomusingDropDown.Enable = 'off';
                    app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                    app.flag = 'onlyMigrate';
                %only DICOM files available
                elseif ~any(endsWith(files_inside,'.nii')) && ~all(cellfun('isempty',regexpi(files_inside,'DICOM')))  && ~all(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                    msgbox("DICOM files detected. Continuing to run dicom to nifti conversion, and nifti to Lead-BIDS conversion.");
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                    app.flag = 'onlyDCMconv';
                %BIDS Compliant raw data nifti files are available! in this case,
                %dcm -> bids conversion should require special
                %handling. Only the conversion from nifti -> lead bids
                %will be performed
                elseif any(ismember(files_inside,'rawdata')) && any(endsWith(files_inside,'.nii')) && ~all(cellfun('isempty',regexpi(files_inside,'DICOM')))  && ~all(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                    app.ConverttoDicomusingDropDown.Enable = 'off';
                    app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                    app.flag = 'onlyDCMconv';
                %both dicom & derivatives available. Requires special
                %handling in the migrate code.
                elseif any(ismember(files_inside,'glanat.nii')) && ~any(cellfun('isempty',regexp(files_inside,'raw_anat.*.nii'))) && all(cellfun('isempty',regexpi(files_inside,'DICOM')))  && all(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                    app.ConverttoDicomusingDropDown.Enable = 'on';
                    app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                    app.flag = 'MigrateDCMconv';
                end
            end
            app.source_files = selpath;
        end

        % Value changed function: 
        % ConvertlegacydatasettoBIDSComplaintdatasetCheckBox
        function ConvertlegacydatasettoBIDSComplaintdatasetCheckBoxValueChanged(app, event)
            value = app.ConvertlegacydatasettoBIDSComplaintdatasetCheckBox.Value;
            
        end

        % Value changed function: ConverttoDicomusingDropDown
        function ConverttoDicomusingDropDownValueChanged(app, event)
            value = app.ConverttoDicomusingDropDown.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            source = app.source_files;
            if ~isempty(app.output_files)
                dest = app.output_files;
            else
                dest = source;
            end
            if strcmp(app.MigrateOptionsButtonGroup.SelectedObject,app.MigrateOptionsButtonGroup.Buttons{2}) 
                %user chooses to move file
                move = 1;
            else
                %user chooses to copy file
                move = 0;
            end
            %for johannes
            convert_using = app.ConverttoDicomusingDropDown.Value;
            if strcmp(app.flag,'onlyMigrate')
               legacy2bids(source,dest,0,'',move);
            end
            if strcmp(app.flag,'MigrateDCMconv')
                legacy2bids(source,dest,1,dicom_dir,move,1);
            end
        end

        % Selection changed function: MigrateOptionsButtonGroup
        function MigrateOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MigrateOptionsButtonGroup.SelectedObject;

        end

        % Value changed function: 
        % MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox
        function MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged(app, event)
            value = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
            
        end

        % Button pushed function: 
        % SelectoutputdirectoryoptionalButton
        function SelectoutputdirectoryoptionalButtonPushed(app, event)
            app.output_files = uigetdir(app.source_files);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConverttoDicomusingDropDownLabel = uilabel(app.UIFigure);
            app.ConverttoDicomusingDropDownLabel.Text = 'Convert to Dicom using..';
            app.ConverttoDicomusingDropDown = uidropdown(app.UIFigure);
            app.ConverttoDicomusingDropDown.Items = {'SPM', 'dcm2niix', 'dcm2nii'};
            app.ConverttoDicomusingDropDown.ValueChangedFcn = createCallbackFcn(app, @ConverttoDicomusingDropDownValueChanged, true);
            app.ConverttoDicomusingDropDown.Value = 'SPM';
            app.MigrateOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.MigrateOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MigrateOptionsButtonGroupSelectionChanged, true);
            app.MigrateOptionsButtonGroup.Title = 'Migrate Options';
            app.RetainsourcedirectorycopiesdataButton = uiradiobutton(app.MigrateOptionsButtonGroup);
            app.RetainsourcedirectorycopiesdataButton.Tooltip = {'Using this option results in longer processing for large datasets. It is safer as it retains a copy of the old dataset.'};
            app.RetainsourcedirectorycopiesdataButton.Text = 'Retain source directory (copies data)';
            app.RetainsourcedirectorycopiesdataButton.Value = true;
            app.MovefilesandcreateanewdirectoryButton = uiradiobutton(app.MigrateOptionsButtonGroup);
            app.MovefilesandcreateanewdirectoryButton.Tooltip = {'Using this option results in faster processing. Use with caution as a copy of the old dataset will not be retained. '};
            app.MovefilesandcreateanewdirectoryButton.Text = 'Move files and create a new directory';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox = uicheckbox(app.UIFigure);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged, true);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Text = 'Migrate legacy Lead-DBS dataset to BIDS dataset';
            app.SelectpatientsButton = uibutton(app.UIFigure, 'push');
            app.SelectpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpatientsButtonPushed, true);
            app.SelectpatientsButton.Text = 'Select patient(s)';
            app.ConvertlegacydatasettoBIDSComplaintdatasetCheckBox = uicheckbox(app.UIFigure);
            app.ConvertlegacydatasettoBIDSComplaintdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertlegacydatasettoBIDSComplaintdatasetCheckBoxValueChanged, true);
            app.ConvertlegacydatasettoBIDSComplaintdatasetCheckBox.Text = 'Convert legacy dataset to BIDS Complaint dataset';
            app.SelectoutputdirectoryoptionalButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputdirectoryoptionalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputdirectoryoptionalButtonPushed, true);
            app.SelectoutputdirectoryoptionalButton.Text = 'Select output directory (optional)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_migrate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end