classdef plot_studio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DATASELECTIONPanel              matlab.ui.container.Panel
        XaxislabelEditFieldLabel        matlab.ui.control.Label
        XaxislabelEditField             matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        YaxislabelEditFieldLabel        matlab.ui.control.Label
        YaxislabelEditField             matlab.ui.control.EditField
        OKButton_2                      matlab.ui.control.Button
        STATISTICSPanel                 matlab.ui.container.Panel
        CorrelationTypeDropDownLabel    matlab.ui.control.Label
        CorrelationTypeDropDown         matlab.ui.control.DropDown
        DisplayasranksCheckBox          matlab.ui.control.CheckBox
        HighlightoutliersCheckBox       matlab.ui.control.CheckBox
        CalculateerrorusingDropDownLabel  matlab.ui.control.Label
        CalculateerrorusingDropDown     matlab.ui.control.DropDown
        REFRESHVIEWButton               matlab.ui.control.Button
        SaveasDropDownLabel             matlab.ui.control.Label
        SaveasDropDown                  matlab.ui.control.DropDown
        EXPORTSAVEButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CohortColorsTab                 matlab.ui.container.Tab
        MarkerColor1DropDownLabel       matlab.ui.control.Label
        MarkerColor1DropDown            matlab.ui.control.DropDown
        MarkerColor2DropDownLabel       matlab.ui.control.Label
        MarkerColor2DropDown            matlab.ui.control.DropDown
        MarkerColor3DropDownLabel       matlab.ui.control.Label
        MarkerColor3DropDown            matlab.ui.control.DropDown
        MarkerColor4DropDownLabel       matlab.ui.control.Label
        MarkerColor4DropDown            matlab.ui.control.DropDown
        MarkerColor5DropDownLabel       matlab.ui.control.Label
        MarkerColor5DropDown            matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        DiseaseMarkersTab               matlab.ui.container.Tab
        MarkerType1DropDownLabel        matlab.ui.control.Label
        MarkerType1DropDown             matlab.ui.control.DropDown
        MarkerType2DropDownLabel        matlab.ui.control.Label
        MarkerType2DropDown             matlab.ui.control.DropDown
        ThisdefinesthediseasetypeLabel  matlab.ui.control.Label
        MarkerSizeEditFieldLabel        matlab.ui.control.Label
        MarkerSizeEditField             matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %To test this, comment out the variables. 
        %X = randn(40,1);
        %Y = randn(40,1);
        X_axis_title 
        Y_axis_title
        title_fig
        I
        Ihat
        Marker_type_1
        Marker_type_2
        Marker_type_3
        Marker_type_4
        Marker_type_5
        Marker_color_1
        Marker_color_2
        Marker_color_3
        Marker_color_4
        Marker_color_5
        Marker_size
        save_as
        rankdisp
        highlite
        cohorts
        error_calc
       % cohorts = {'Berlin','London'}
        diseases
        % diseases = {'Parkinsons','Alzhimers'}       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, I, Ihat, cohorts, diseases)
            app.I = I;
            app.Ihat = Ihat;
            app.cohorts = cohorts;
            app.diseases = diseases;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.X_axis_title = app.XaxislabelEditField.Value; 
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            app.Y_axis_title = app.YaxislabelEditField.Value;
        end

        % Callback function
        function OKButton_3Pushed(app, event)
            app.title_fig = app.TitleEditField.Value;
        end

        % Button pushed function: REFRESHVIEWButton
        function REFRESHVIEWButtonPushed(app, event)
            Xval = app.I;
            Yval = app.Ihat;
            %Xval = app.X
            %Yval = app.Y;
            lx = length(Xval);
            Yrank = tiedrank(Yval);
            Yrank = cellstr(num2str(Yrank));
            [R,p] = corr(Xval,Yval,'Type',app.CorrelationTypeDropDown.Value);
            ax = app.UIAxes;
            ax.Title.String = ['R-value = ' num2str(R) ', P-value = ' num2str(p) ', ' app.CorrelationTypeDropDown.Value];
            grp_cohorts = [];
            grp_disease = [];
            for i = 1:length(app.cohorts)
                temp = cell(lx/length(app.cohorts),1);
                temp(:) = app.cohorts(i);
                grp_cohorts = [grp_cohorts;temp];
                for j = 1:length(app.diseases)
                    temp_dis = cell(lx/(length(app.cohorts)*length(app.diseases)),1);
                    temp_dis(:) = app.diseases(j);
                    grp_disease = [grp_disease;temp_dis];
                end
            end
            
            group = {grp_cohorts,grp_disease};  % Description % Description
            %group 1 tab details:
            app.Marker_color_1 = app.MarkerColor1DropDown.Value;
            app.Marker_color_2 = app.MarkerColor2DropDown.Value;
            app.Marker_color_3 = app.MarkerColor3DropDown.Value;
            app.Marker_color_4 = app.MarkerColor4DropDown.Value;
            app.Marker_color_5 = app.MarkerColor5DropDown.Value;
            
            %group 2 tab details:
            app.Marker_type_1 = app.MarkerType1DropDown.Value;
            app.Marker_type_2 = app.MarkerType2DropDown.Value;
           
            if strcmp(app.Marker_color_1,"Red")
                app.Marker_color_1 = 'r';
            elseif strcmp(app.Marker_color_1,"Yellow")
                app.Marker_color_1 = 'y';
            elseif strcmp(app.Marker_color_1,"Magenta")
                app.Marker_color_1 = 'm';
            elseif strcmp(app.Marker_color_1,"Blue")
                app.Marker_color_1 = 'b';
            elseif strcmp(app.Marker_color_1,"Black")
                app.Marker_color_1 = 'k';
            elseif strcmp(app.Marker_color_1,"Cyan")
                app.Marker_color_1 = 'c';  
            elseif strcmp(app.Marker_color_1,"White")
                app.Marker_color_1 = 'w';
            elseif strcmp(app.Marker_color_1,"Green")
                app.Marker_color_1 = 'g';
            else
                app.Marker_color_1 = 'n';
            end
            
            if strcmp(app.Marker_color_2,"Red")
                app.Marker_color_2 = 'r';
            elseif strcmp(app.Marker_color_2,"Yellow")
                app.Marker_color_2 = 'y';
            elseif strcmp(app.Marker_color_2,"Magenta")
                app.Marker_color_2 = 'm';
            elseif strcmp(app.Marker_color_2,"Blue")
                app.Marker_color_2 = 'b';
            elseif strcmp(app.Marker_color_2,"Black")
                app.Marker_color_2 = 'k';
            elseif strcmp(app.Marker_color_2,"Cyan")
                app.Marker_color_2 = 'c';  
            elseif strcmp(app.Marker_color_2,"White")
                app.Marker_color_2 = 'w';
            elseif strcmp(app.Marker_color_2,"Green")
                app.Marker_color_2 = 'g';
            else
                app.Marker_color_2 = 'n';
            end
            if strcmp(app.Marker_color_3,"Red")
                app.Marker_color_3 = 'r';
            elseif strcmp(app.Marker_color_3,"Yellow")
                app.Marker_color_3 = 'y';
            elseif strcmp(app.Marker_color_3,"Magenta")
                app.Marker_color_3 = 'm';
            elseif strcmp(app.Marker_color_3,"Blue")
                app.Marker_color_3 = 'b';
            elseif strcmp(app.Marker_color_3,"Black")
                app.Marker_color_3 = 'k';
            elseif strcmp(app.Marker_color_3,"Cyan")
                app.Marker_color_3 = 'c';  
            elseif strcmp(app.Marker_color_3,"White")
                app.Marker_color_3 = 'w';
            elseif strcmp(app.Marker_color_3,"Green")
                app.Marker_color_3 = 'g';
            else
                app.Marker_color_3 = 'n';
            end
            if strcmp(app.Marker_color_4,"Red")
                app.Marker_color_4 = 'r';
            elseif strcmp(app.Marker_color_4,"Yellow")
                app.Marker_color_4 = 'y';
            elseif strcmp(app.Marker_color_4,"Magenta")
                app.Marker_color_4 = 'm';
            elseif strcmp(app.Marker_color_4,"Blue")
                app.Marker_color_4 = 'b';
            elseif strcmp(app.Marker_color_4,"Black")
                app.Marker_color_4 = 'k';
            elseif strcmp(app.Marker_color_4,"Cyan")
                app.Marker_color_4 = 'c';  
            elseif strcmp(app.Marker_color_4,"White")
                app.Marker_color_4 = 'w';
            elseif strcmp(app.Marker_color_4,"Green")
                app.Marker_color_4 = 'g';
            else
                app.Marker_color_4 = 'n';
            end
            if strcmp(app.Marker_color_5,"Red")
                app.Marker_color_5 = 'r';
            elseif strcmp(app.Marker_color_5,"Yellow")
                app.Marker_color_5 = 'y';
            elseif strcmp(app.Marker_color_5,"Magenta")
                app.Marker_color_5 = 'm';
            elseif strcmp(app.Marker_color_5,"Blue")
                app.Marker_color_5 = 'b';
            elseif strcmp(app.Marker_color_5,"Black")
                app.Marker_color_5 = 'k';
            elseif strcmp(app.Marker_color_5,"Cyan")
                app.Marker_color_5 = 'c';  
            elseif strcmp(app.Marker_color_5,"White")
                app.Marker_color_5 = 'w';
            elseif strcmp(app.Marker_color_5,"Green")
                app.Marker_color_5 = 'g';
            else
                app.Marker_color_5 = 'n';
            end
            if strcmp(app.Marker_type_1,'None')
                app.Marker_type_1 = 'n';
            end
            if strcmp(app.Marker_type_2,'None')
                app.Marker_type_2 = 'n';
            end
            
            
            xnam = app.XaxislabelEditField.Value;
            ynam = app.YaxislabelEditField.Value;
            full_clr = [app.Marker_color_1,app.Marker_color_2,app.Marker_color_3,app.Marker_color_4,app.Marker_color_5];
            colorlist = [];
            for i=1:length(app.cohorts)
                for j=1:length(app.diseases)
                    colorlist = [colorlist,full_clr(i)];
                end
            end
            full_mrkr = [app.Marker_type_1,app.Marker_type_2];
            full_mrkr = full_mrkr(1:length(app.diseases));

            ymean = nanmean(Yval);
            if strcmp(app.CorrelationTypeDropDown.Value,'Spearman') && app.rankdisp == 1
               if strcmp(app.error_calc,'Confidence Interval')
                   ci=1.96*nanstd(Yval)./sqrt(sum(~isnan(Yval)));
                   yci = bsxfun(@plus,ymean,[-ci;ci]); 
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
                   text(ax,Xval,Yval,Yrank,'FontSize',10,'Color','red');
               elseif strcmp(app.error_calc,'Std. dev')
                   ci=nanstd(Yval);
                   yci =bsxfun(@plus,ymean,[-ci;ci]);
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
                   text(ax,Xval,Yval,Yrank,'FontSize',10,'Color','red');
               elseif strcmp(app.error_calc, '95 percentile')
                   ymean=nanmedian(Yval);
                   yci=prctile(Yval,[2.5 97.5]);
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
                   text(ax,Xval,Yval,Yrank,'FontSize',10,'Color','red');
               else
                  ci=nanstd(Yval)./sqrt(sum(~isnan(Yval)));
                  yci=bsxfun(@plus,ymean,[-ci;ci]); 
                  neg = repmat(yci(1),length(Yval),1);
                  pos = repmat(yci(2),length(Yval),1);
                  errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
                  text(ax,Xval,Yval,Yrank,'FontSize',10,'Color','red');
               end
               hold (app.UIAxes,'on');
               coeffs = polyfit(Xval,Yval,1);
               h1 = refline(ax,coeffs);
               h1.Color = 'r';
               h1.LineWidth = 1.2;
               gscatter(ax,Xval,Yval,group,colorlist,full_mrkr,app.Marker_size,'on',xnam,ynam); 
               hold (app.UIAxes,'off');
            else
               if strcmp(app.error_calc,'Confidence Interval')
                   ci=1.96*nanstd(Yval)./sqrt(sum(~isnan(Yval)));
                   yci = bsxfun(@plus,ymean,[-ci;ci]); 
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
               elseif strcmp(app.error_calc,'Std. dev')
                   ci=nanstd(Yval);
                   yci =bsxfun(@plus,ymean,[-ci;ci]);
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
               elseif strcmp(app.error_calc, '95 percentile')
                   ymean=nanmedian(Yval);
                   yci=prctile(Yval,[2.5 97.5]);
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
               else
                  ci=nanstd(Yval)./sqrt(sum(~isnan(Yval)));
                  yci=bsxfun(@plus,ymean,[-ci;ci]); 
                  neg = repmat(yci(1),length(Yval),1);
                  pos = repmat(yci(2),length(Yval),1);
                  errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
               end
               hold (app.UIAxes,'on');              
               coeffs = polyfit(Xval,Yval,1);
               h1 = refline(ax,coeffs);
               h1.Color = 'r';
               h1.LineWidth = 1.2;
               gscatter(ax,Xval,Yval,group,colorlist,full_mrkr,app.Marker_size,'on',xnam,ynam); 
               hold (app.UIAxes,'off')
               end
            
            if app.highlite == 1
               %plot with the desired parameters, points inside the
               %distribution's width
               if strcmp(app.error_calc,'Confidence Interval')
                   ci=1.96*nanstd(Yval)./sqrt(sum(~isnan(Yval)));
                   yci = bsxfun(@plus,ymean,[-ci;ci]); 
                   neg = repmat(yci(1),length(Yval),1);
                   pos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,neg,pos,'LineStyle','none','LineWidth',1);
               elseif strcmp(app.error_calc,'Std. dev')
                   ci=nanstd(Yval);
                   yci =bsxfun(@plus,ymean,[-ci;ci]);
                   yneg = repmat(yci(1),length(Yval),1);
                   ypos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,yneg,ypos,'LineStyle','none','LineWidth',1);
               elseif strcmp(app.error_calc, '95 percentile')
                   ymean=nanmedian(Yval);
                   yci=prctile(Yval,[2.5 97.5]);
                   yneg = repmat(yci(1),length(Yval),1);
                   ypos = repmat(yci(2),length(Yval),1);
                   errorbar(ax,Xval,Yval,yneg,ypos,'LineStyle','none','LineWidth',1);
               else
                  ci=nanstd(Yval)./sqrt(sum(~isnan(Yval)));
                  yci=bsxfun(@plus,ymean,[-ci;ci]); 
                  yneg = repmat(yci(1),length(Yval),1);
                  ypos = repmat(yci(2),length(Yval),1);
                  errorbar(ax,Xval,Yval,yneg,ypos,'LineStyle','none','LineWidth',1);
               
               % defined as atleast three standard deviations away from the
               % mean
               Y_std = isoutlier(Yval);
               hold (app.UIAxes,'on');
               c = ones(length(Xval),1);
               c(Y_std == 1) = 0;
               coeffs = polyfit(Xval,Yval,1);
               h1 = refline(ax,coeffs);
               h1.Color = 'r';
               h1.LineWidth = 1.2;
               gscatter(ax,Xval,Yval,c,'kn','*n',10,'on');
               legend(ax,'outliers')
               gscatter(ax,Xval,Yval,group,colorlist,full_mrkr,app.Marker_size,'on');
               hold (app.UIAxes,'off');
               end          
            end
            
        end

        % Callback function
        function MarkerSizeEditFieldValueChanged(app, event)
            
        end

        % Value changed function: MarkerColor1DropDown
        function MarkerColor1DropDownValueChanged(app, event)
            value = app.MarkerColor1DropDown.Value;
            
        end

        % Size changed function: DiseaseMarkersTab
        function VaryCircleSizeCheckBoxValueChanged(app, event)
            position = app.DiseaseMarkersTab.Position;
            
        end

        % Button pushed function: EXPORTSAVEButton
        function EXPORTSAVEButtonPushed(app, event)
            app.save_as = app.SaveasDropDown.Value;
            ax = app.UIAxes;
            if strcmp(app.save_as,'PNG')
                exportgraphics(ax,['/Users/nanditharajamani/' ax.Title.String '.png']);
            elseif strcmp(app.save_as, 'JPEG')
                exportgraphics(ax,['/Users/nanditharajamani/' ax.Title.String '.jpg']);
            elseif strcmp(app.save_as, 'PDF')
                exportgraphics(ax,['/Users/nanditharajamani/' ax.Title.String '.pdf']);
            else
                exportgraphics(ax,['/Users/nanditharajamani/' ax.Title.String '.fig']);
            end
        end

        % Callback function
        function CircleColorsDropDownValueChanged(app, event)
            
        end

        % Callback function
        function VaryColorsCheckBoxValueChanged(app, event)
            app.VaryClr = app.VaryColorsCheckBox.Value;
        end

        % Value changed function: MarkerColor2DropDown
        function MarkerColor2DropDownValueChanged(app, event)
            app.Marker_color_1 = app.MarkerColor2DropDown.Value;
        end

        % Value changed function: MarkerType1DropDown
        function MarkerType1DropDownValueChanged(app, event)
            app.Marker_type_1 = app.MarkerType1DropDown.Value;   
        end

        % Callback function
        function Markertype2DropDownValueChanged(app, event)
            
        end

        % Callback function
        function MarkerSizeEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged2(app, event)
            app.Marker_size = app.MarkerSizeEditField.Value;          
        end

        % Value changed function: DisplayasranksCheckBox
        function DisplayasranksCheckBoxValueChanged(app, event)
            app.rankdisp = app.DisplayasranksCheckBox.Value;
            
        end

        % Callback function
        function DisplayErrorBarsCheckBoxValueChanged(app, event)
            app.ErrorBox = app.DisplayErrorBarsCheckBox.Value;
            
        end

        % Value changed function: HighlightoutliersCheckBox
        function HighlightoutliersCheckBoxValueChanged(app, event)
            app.highlite = app.HighlightoutliersCheckBox.Value;
            
        end

        % Drop down opening function: CalculateerrorusingDropDown
        function CalculateerrorusingDropDownOpening(app, event)
        
        end

        % Value changed function: CalculateerrorusingDropDown
        function CalculateerrorusingDropDownValueChanged(app, event)
            app.error_calc = app.CalculateerrorusingDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DATASELECTIONPanel = uipanel(app.UIFigure);
            app.DATASELECTIONPanel.Title = 'DATA SELECTION';
            app.XaxislabelEditFieldLabel = uilabel(app.DATASELECTIONPanel);
            app.XaxislabelEditFieldLabel.Text = 'X axis label';
            app.XaxislabelEditField = uieditfield(app.DATASELECTIONPanel, 'text');
            app.XaxislabelEditField.Value = 'X axis';
            app.OKButton = uibutton(app.DATASELECTIONPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.YaxislabelEditFieldLabel = uilabel(app.DATASELECTIONPanel);
            app.YaxislabelEditFieldLabel.Text = 'Y axis label';
            app.YaxislabelEditField = uieditfield(app.DATASELECTIONPanel, 'text');
            app.YaxislabelEditField.Value = 'Y axis';
            app.OKButton_2 = uibutton(app.DATASELECTIONPanel, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.STATISTICSPanel = uipanel(app.UIFigure);
            app.STATISTICSPanel.Title = 'STATISTICS';
            app.CorrelationTypeDropDownLabel = uilabel(app.STATISTICSPanel);
            app.CorrelationTypeDropDownLabel.Text = 'Correlation Type ';
            app.CorrelationTypeDropDown = uidropdown(app.STATISTICSPanel);
            app.CorrelationTypeDropDown.Items = {'Pearson', 'Spearman', 'Kendall'};
            app.CorrelationTypeDropDown.Value = 'Pearson';
            app.DisplayasranksCheckBox = uicheckbox(app.STATISTICSPanel);
            app.DisplayasranksCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayasranksCheckBoxValueChanged, true);
            app.DisplayasranksCheckBox.Text = 'Display as ranks';
            app.HighlightoutliersCheckBox = uicheckbox(app.STATISTICSPanel);
            app.HighlightoutliersCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighlightoutliersCheckBoxValueChanged, true);
            app.HighlightoutliersCheckBox.Text = 'Highlight outliers';
            app.CalculateerrorusingDropDownLabel = uilabel(app.STATISTICSPanel);
            app.CalculateerrorusingDropDownLabel.Text = 'Calculate error using';
            app.CalculateerrorusingDropDown = uidropdown(app.STATISTICSPanel);
            app.CalculateerrorusingDropDown.Items = {'Confidence Interval', 'Std. dev', '95 percentile', 'sem'};
            app.CalculateerrorusingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CalculateerrorusingDropDownOpening, true);
            app.CalculateerrorusingDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateerrorusingDropDownValueChanged, true);
            app.CalculateerrorusingDropDown.Value = 'Std. dev';
            app.REFRESHVIEWButton = uibutton(app.UIFigure, 'push');
            app.REFRESHVIEWButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHVIEWButtonPushed, true);
            app.REFRESHVIEWButton.Text = 'REFRESH VIEW';
            app.SaveasDropDownLabel = uilabel(app.UIFigure);
            app.SaveasDropDownLabel.Text = 'Save as..';
            app.SaveasDropDown = uidropdown(app.UIFigure);
            app.SaveasDropDown.Items = {'PNG', 'JPEG', 'Matlab .fig', 'PDF'};
            app.SaveasDropDown.Value = 'PNG';
            app.EXPORTSAVEButton = uibutton(app.UIFigure, 'push');
            app.EXPORTSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTSAVEButtonPushed, true);
            app.EXPORTSAVEButton.Text = 'EXPORT & SAVE';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CohortColorsTab = uitab(app.TabGroup);
            app.CohortColorsTab.Title = 'Cohort Colors';
            app.MarkerColor1DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor1DropDownLabel.Text = 'Marker Color 1';
            app.MarkerColor1DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor1DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor1DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor1DropDownValueChanged, true);
            app.MarkerColor1DropDown.Value = 'Red';
            app.MarkerColor2DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor2DropDownLabel.Text = 'Marker Color 2';
            app.MarkerColor2DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor2DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor2DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor2DropDownValueChanged, true);
            app.MarkerColor2DropDown.Value = 'Red';
            app.MarkerColor3DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor3DropDownLabel.Text = 'Marker Color 3';
            app.MarkerColor3DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor3DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor3DropDown.Value = 'Red';
            app.MarkerColor4DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor4DropDownLabel.Text = 'Marker Color 4';
            app.MarkerColor4DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor4DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor4DropDown.Value = 'Red';
            app.MarkerColor5DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor5DropDownLabel.Text = 'Marker Color 5';
            app.MarkerColor5DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor5DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor5DropDown.Value = 'Red';
            app.Label = uilabel(app.CohortColorsTab);
            app.Label.Text = '';
            app.DiseaseMarkersTab = uitab(app.TabGroup);
            app.DiseaseMarkersTab.SizeChangedFcn = createCallbackFcn(app, @VaryCircleSizeCheckBoxValueChanged, true);
            app.DiseaseMarkersTab.Title = 'Disease Markers';
            app.MarkerType1DropDownLabel = uilabel(app.DiseaseMarkersTab);
            app.MarkerType1DropDownLabel.Text = 'Marker Type 1';
            app.MarkerType1DropDown = uidropdown(app.DiseaseMarkersTab);
            app.MarkerType1DropDown.Items = {'o', 's', 'd', '.', '^', 'v', '>', '<', 'p', 'h', '*', '+', 'x', 'None'};
            app.MarkerType1DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerType1DropDownValueChanged, true);
            app.MarkerType1DropDown.Value = '.';
            app.MarkerType2DropDownLabel = uilabel(app.DiseaseMarkersTab);
            app.MarkerType2DropDownLabel.Text = 'Marker Type 2';
            app.MarkerType2DropDown = uidropdown(app.DiseaseMarkersTab);
            app.MarkerType2DropDown.Items = {'o', 's', 'd', '.', '^', 'v', '>', '<', 'p', 'h', '*', '+', 'x', 'None'};
            app.MarkerType2DropDown.Value = '.';
            app.ThisdefinesthediseasetypeLabel = uilabel(app.DiseaseMarkersTab);
            app.ThisdefinesthediseasetypeLabel.Text = 'This defines the disease type';
            app.MarkerSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.MarkerSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged2, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Xaxis')
            ylabel(app.UIAxes, 'Yaxis')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_studio(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end